classdef app_fcdMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FCDMaskUIFigure     matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        NotButton           matlab.ui.control.StateButton
        LogicDropDown       matlab.ui.control.DropDown
        LogicDropDownLabel  matlab.ui.control.Label
        PreviewButton       matlab.ui.control.StateButton
        AcceptButton        matlab.ui.control.Button
        ShapeDropDown       matlab.ui.control.DropDown
        ShapeDropDownLabel  matlab.ui.control.Label
        AddshapeButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        iw
        im
        rois
    end
    properties (Access = private)
        lastShape = 'Rectangle'
        lastpgon
    end
    
    methods 
        function set.rois(app,val)
            app.rois = val;
            updateRois(app)
        end
        function updateRois(app)
            if isempty(app.rois)
                return
            end
            delete(findall(app.UIAxes,'Type','Images'))
            arrayfun(@(roi) addShape(app,'set',roi),app.rois)
        end
    end
    methods (Access = private)
        function addShape(app,event,eventData)
            if nargin<2
                event = 'callback';
            end
            switch event
                case 'callback'
                    shape       = lower(app.ShapeDropDown.Value);
                    invert      = '';
                    if app.NotButton.Value
                        invert = '~';
                    end
                    logic       = join([invert,app.LogicDropDown.Value]);
                    command     = "pgon=draw%s(app.UIAxes);";
                case 'set'
                    shape       = extractAfter(eventData.type,'roi.');
                    logic       = eventData.inverted;
                    position    = eventData.position; %#ok<NASGU> 
                    command     = "pgon=draw%s(app.UIAxes,'Position',position);";
            end
            exec            = sprintf(command,shape);
            eval(exec)
            pgon.UserData   = logic;
            app.lastpgon    = pgon;
            pgon.Label      = logic;
            addlistener(pgon,'ROIClicked',@(varargin) roiClicked(app,pgon));
            addlistener(pgon,'ROIMoved',@(varargin) roiMoved(app,pgon));
            addlistener(pgon,'DeletingROI',@(varargin) roiDeleted(app,pgon));

            if app.PreviewButton.Value;previewCallback(app);end
        end
        
        function updateAxes(app)
            imagesc(app.UIAxes,app.im)
            colormap(app.UIAxes,"gray")
            axis(app.UIAxes,"tight")
        end
        function roiDeleted(app,pgon)
            ROIS = findall(app.UIAxes,'Type','Images');
            delete(ROIS(ROIS==pgon))
            roiMoved(app)
        end
        function roiMoved(app,~)
            if app.PreviewButton.Value
                previewCallback(app)
            end
        end
        function roiClicked(app,pgon)
            masks = findall(app.UIAxes,'Type','Images');
            set(masks,'LabelVisible','off')
            pgon.LabelVisible = 'on';
            app.lastpgon = pgon;
            app.NotButton.Value = false;
            if contains(pgon.Label,'~')
                app.NotButton.Value = true;
            end
            app.lastShape = app.ShapeDropDown.Value;
            app.ShapeDropDown.Value = extractAfter(class(pgon),'images.roi.');
            app.LogicDropDown.Value = strrep((pgon.UserData),'~','');
        end
        function [maskarray,mask] = getmasks(app)
            masks = findall(app.UIAxes,'Type','Images');
            if ~isempty(masks)
                maskarray = arrayfun(@createMask,masks,'UniformOutput',false);
                logic = {masks.UserData};
            end
            ref = size(cell2mat(maskarray(1)));
            logic = logic(~cellfun(@isempty,logic));
            [logic,map] = sort(logic);
            maskarray = maskarray(map);
            if numel(maskarray)>1
                mask = zeros(ref);
                for i=1:numel(maskarray)
                    mask = double(mask);
                    nextmask = maskarray{i};
                    command = logic{i};
                    if contains(command,'~')
                        nextmask = ~maskarray{i};
                        command = extractAfter(command,'~');
                    end
                    switch lower(command)
                        case 'add'
                            mask = mask+nextmask;
                        case 'subtract'
                            mask = mask-nextmask;
                        case 'or'
                            mask = or(mask,nextmask);
                        case 'xor'
                            mask = xor(mask,nextmask);
                        case 'and'
                            mask = and(mask,nextmask);
                    end
                    mask = logical(mask);
                end
                mask = logical(mask);
            else
                mask = cell2mat(maskarray);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IWdata)

            app.iw = IWdata;
            app.im = app.iw.loadimage(app.iw.im0index,true);
            updateAxes(app)

            try %#ok<*TRYNC> 
                app.rois = app.iw.ss_fcd.maskrois;
            end
        end

        % Button pushed function: AddshapeButton
        function addShapeCallback(app, event)
            addShape(app,'callback')
        end

        % Button pushed function: AcceptButton
        function acceptCallback(app, event)
            masks                   = findall(app.UIAxes,'Type','Images');
            [~,app.iw.ss_fcd.mask]  = getmasks(app);
            app.iw.ss_fcd.maskrois  = struct('position',{masks.Position},'type',{masks.Type},'inverted',{masks.UserData});
        end

        % Value changed function: LogicDropDown, NotButton
        function logicCallback(app, event)
            value = app.LogicDropDown.Value;
            if app.NotButton.Value
                value = sprintf('~%s',value);
            end
            app.lastpgon.UserData = value;
            app.lastpgon.Label = value;
            
            if app.PreviewButton.Value
                previewCallback(app)
            end
        end

        % Value changed function: PreviewButton
        function previewCallback(app, event)
            imdata = findall(app.UIAxes,'Type','Image');
            masks = 1;
            app.PreviewButton.Text = 'Preview';
            if app.PreviewButton.Value
                [~,masks] = getmasks(app);
                app.PreviewButton.Text = 'Return';
            end
            imdata.AlphaData = masks;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FCDMaskUIFigure = uifigure('Visible', 'off');
            app.FCDMaskUIFigure.Name = 'FCD Mask';
            app.GridLayout = uigridlayout(app.FCDMaskUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AddshapeButton = uibutton(app.GridLayout2, 'push');
            app.AddshapeButton.ButtonPushedFcn = createCallbackFcn(app, @addShapeCallback, true);
            app.AddshapeButton.Text = 'Add shape';
            app.ShapeDropDownLabel = uilabel(app.GridLayout2);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.GridLayout2);
            app.ShapeDropDown.Items = {'Rectangle', 'Polygon', 'Circle'};
            app.ShapeDropDown.Value = 'Rectangle';
            app.AcceptButton = uibutton(app.GridLayout2, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @acceptCallback, true);
            app.AcceptButton.Text = 'Accept';
            app.PreviewButton = uibutton(app.GridLayout2, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @previewCallback, true);
            app.PreviewButton.Text = 'Preview';
            app.LogicDropDownLabel = uilabel(app.GridLayout2);
            app.LogicDropDownLabel.Text = 'Logic';
            app.LogicDropDown = uidropdown(app.GridLayout2);
            app.LogicDropDown.Items = {'Add', 'Subtract', 'Xor', 'Or', 'And'};
            app.LogicDropDown.ValueChangedFcn = createCallbackFcn(app, @logicCallback, true);
            app.LogicDropDown.Value = 'Add';
            app.NotButton = uibutton(app.GridLayout2, 'state');
            app.NotButton.ValueChangedFcn = createCallbackFcn(app, @logicCallback, true);
            app.NotButton.Text = 'Not';
            % Show the figure after all components are created
            app.FCDMaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_fcdMask(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FCDMaskUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FCDMaskUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FCDMaskUIFigure)
        end
    end
end