classdef app_patternMovieDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BacklightdesignerUIFigure    matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        ColorindexEditField          matlab.ui.control.EditField
        ColorindexEditFieldLabel     matlab.ui.control.Label
        colorField                   matlab.ui.control.EditField
        OpenvideoButton              matlab.ui.control.Button
        OpenvideofolderButton        matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        GenerateButton               matlab.ui.control.Button
        FramesizexypxEditField       matlab.ui.control.EditField
        FramesizexypxEditFieldLabel  matlab.ui.control.Label
        InvertButton                 matlab.ui.control.StateButton
        DurationEditField            matlab.ui.control.NumericEditField
        DurationEditFieldLabel       matlab.ui.control.Label
        BasetimesEditField           matlab.ui.control.EditField
        BasetimesLabel               matlab.ui.control.Label
        RemoveButton                 matlab.ui.control.Button
        LambdapxEditField            matlab.ui.control.NumericEditField
        LambdapxEditFieldLabel       matlab.ui.control.Label
        PatternDropDown              matlab.ui.control.DropDown
        PatternDropDownLabel         matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        design
    end
    
    methods (Access = private)
        
        function updateTime(app)
            t = app.UITable;
            if isempty(t.Data)
                return
            end
            t.ColumnName = arrayfun(@(x) sprintf('Pattern %i',x),1:width(t.Data),'UniformOutput',false);
            t.ColumnEditable = true(1,width(t.Data));
            
            T = t.Data(2,:);
            try
                bt = eval(app.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            t.Data(end,:) = arrayfun(@(x) x,cumsum(cell2mat(T)*bt), ...
                'UniformOutput',false);

            % update label
            app.Label.Text = codifyFilename(app);
        end
        function [fname,root] = codifyFilename(app)
            d   = app.UITable.Data;
            root = fullfile(filepartsn(which('iwdata.m'),1),'reference_patterns','videos');
            root = folderOnPath(root);
            
            if isempty(d)
                fname = root;
                return
            end
            
            try
                bt = eval(app.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            sequence = sprintf('tau_%.2fs_Ttot_%.1fs',bt,d{end});
            state = 'wb';
            if app.InvertButton.Value;state = 'bw';end
            for i=1:width(d);sequence = sprintf('%s-%s%i_%s',sequence,upper(d{1,i}(1)),d{2,i},state);end
            fname = fullfile(root,sprintf( ...
                'iw_%s.avi',sequence));
        end
        function value = getColor(app)
            val = app.ColorindexEditField.Value;
            if isa(val,'char')&&~any(isstrprop(val,"digit"))
                val = strrep(val,"'",'');
                val = strrep(val,'"','');
                switch val
                    case 'b';value = [0 0 1];
                    case 'r';value = [1 0 0];
                    case 'y';value = [1 1 0];
                    case 'w';value = [1 1 1];
                    case 'k';value = [0 0 0];
                    case 'm';value = [1 0 1];
                    case 'g';value = [0 1 0];
                    case 'c';value = [0 1 1];
                end
                return
            elseif any(isstrprop(val,"digit"))
                try
                    eval([val ';']);
                catch
                    if ~all(isstrprop(val,"digit"))
                        val = (cell2mat((join(['[' join((split(val,' ')),' ') ,']']))));
                    end
                end
            end
            try
                value=eval(val);
            catch
                value = [1 1 1];
            end
            if numel(value)>1
                value(value>1)=1;
                value(value<0)=0;
                app.ColorindexEditField.Value = sprintf('[%g %g %g]',value(1),value(2),value(3));
            else
                value = value.*[1 1 1]/255;
                value(value>1)=1;
            end
            %value = value/255;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text = codifyFilename(app);
        end

        % Button pushed function: AddButton
        function addPattern(app, event)
            t = app.UITable;
            try
                bt = eval(app.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            
            newval = [{app.PatternDropDown.Value} ...
                app.DurationEditField.Value...
                app.LambdapxEditField.Value ...
                bt]';
            if isempty(t.Data)
                t.Data = newval;
            else
                t.Data(:,end+1)=newval;
            end
            updateTime(app)
        end

        % Callback function: BasetimesEditField, DurationEditField, 
        % ...and 5 other components
        function updateSelection(app, event)
            t = app.UITable;
            if event.Source==app.FramesizexypxEditField
                if numel(split(event.Value,' '))~=2
                    app.FramesizexypxEditField.Value = '[1920 1080]';
                end
            end
            if isempty(t.Data)||isempty(t.Selection)
                return
            end

            idx = t.Selection;
            switch event.Source
                case app.PatternDropDown
                    t.Data(1,idx) = {event.Value};
                case app.DurationEditField
                    t.Data(2,idx) = {event.Value};
                case app.LambdapxEditField
                    t.Data(3,idx) = {event.Value};
                case app.UITable
                    d = t.Data(:,idx);
                    app.PatternDropDown.Value   = d{1};
                    app.DurationEditField.Value = d{2};
                    app.LambdapxEditField.Value = d{3};
            end
            updateTime(app)
        end

        % Button pushed function: RemoveButton
        function removePattern(app, event)
            s = app.UITable.Selection;
            app.UITable.Data(:,s)=[];
            updateTime(app)
        end

        % Button pushed function: GenerateButton
        function generateCallback(app, event)
            d   = app.UITable.Data;
            ref = eval(app.FramesizexypxEditField.Value);
            % codify sequence
            fname = codifyFilename(app);
            try
                bt = eval(app.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            
            time        = cell2mat(d(3,:));
            
            V           = VideoWriter(fname);
            V.FrameRate = 5/bt;
            %V.Quality   = 75;
            try
                open(V)
                iw = iwdata;
                for i=1:numel(time)
                    % generate pattern
                    lambda  = d{3,i};
                    pattern = d{1,i};
                    if ~strcmp(pattern,'blank')
                        im      = iw.ss_pattern(pattern, ...
                            'lambda',lambda, ...
                            'ref',ref);
                        im = repmat(im,[1 1 3]);
                        %im = round(im*255)+1;
                    else
                        im = ones(ref(2),ref(1),3).*reshape(getColor(app),1,1,[]);
                    end
                    if app.InvertButton.Value
                        im = 1-im;
                    end

                    % repeat n frames
                    for j=1:d{2,i}*5
                        app.Label.Text = ['[Writing] ' fname];
                        writeVideo(V,im2frame(im))
                    end
                end
            catch ME
                close(V)
                rethrow(ME)
            end
            close(V)
            app.Label.Text = ['[Saved] ' fname];
            if isfile(fname)
                app.OpenvideoButton.Enable = 'on';
            end
        end

        % Button pushed function: OpenvideofolderButton
        function openFolder(app, event)
            [~,root] = codifyFilename(app);
            winopen(root)
        end

        % Button pushed function: OpenvideoButton
        function openVideo(app, event)
            winopen(codifyFilename(app))
        end

        % Value changed function: ColorindexEditField, InvertButton
        function changeColor(app, event)
            value = getColor(app);
            

            if app.InvertButton.Value
                value = 1-value;
            end
            app.colorField.BackgroundColor = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BacklightdesignerUIFigure = uifigure('Visible', 'off');
            app.BacklightdesignerUIFigure.Name = 'Backlight designer';
            app.GridLayout = uigridlayout(app.BacklightdesignerUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Pattern'};
            app.UITable.RowName = {'Pattern'; 'Duration (τ)'; 'Lambda (px)'; 'Time (s)'};
            app.UITable.SelectionType = 'column';
            app.UITable.CellEditCallback = createCallbackFcn(app, @updateSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addPattern, true);
            app.AddButton.Tooltip = {'Adds a new default pattern to the table. '};
            app.AddButton.Text = 'Add';
            app.PatternDropDownLabel = uilabel(app.GridLayout2);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.GridLayout2);
            app.PatternDropDown.Items = {'dot', 'checker', 'lattice', 'row', 'column', 'blank'};
            app.PatternDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.PatternDropDown.Value = 'dot';
            app.LambdapxEditFieldLabel = uilabel(app.GridLayout2);
            app.LambdapxEditFieldLabel.Text = 'Lambda (px)';
            app.LambdapxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LambdapxEditField.Limits = [2 Inf];
            app.LambdapxEditField.ValueChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.LambdapxEditField.Tooltip = {'Wavelength of the desired pattern (except ''blank'').'};
            app.LambdapxEditField.Value = 2;
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removePattern, true);
            app.RemoveButton.Tooltip = {'Removes the selected patterns from the table.'};
            app.RemoveButton.Text = 'Remove';
            app.BasetimesLabel = uilabel(app.GridLayout2);
            app.BasetimesLabel.Text = 'Base time τ (s)';
            app.BasetimesEditField = uieditfield(app.GridLayout2, 'text');
            app.BasetimesEditField.ValueChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.BasetimesEditField.Tooltip = {'Minimum time for a duration cycle.'};
            app.BasetimesEditField.Value = '1';
            app.DurationEditFieldLabel = uilabel(app.GridLayout2);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.DurationEditField.Tooltip = {'Multiplicative factor of the base time.'};
            app.DurationEditField.Value = 1;
            app.InvertButton = uibutton(app.GridLayout2, 'state');
            app.InvertButton.ValueChangedFcn = createCallbackFcn(app, @changeColor, true);
            app.InvertButton.Tooltip = {'Inverts the color index.'};
            app.InvertButton.Text = 'Invert';
            app.FramesizexypxEditFieldLabel = uilabel(app.GridLayout2);
            app.FramesizexypxEditFieldLabel.Text = 'Frame size [x y] (px)';
            app.FramesizexypxEditField = uieditfield(app.GridLayout2, 'text');
            app.FramesizexypxEditField.ValueChangedFcn = createCallbackFcn(app, @updateSelection, true);
            app.FramesizexypxEditField.Value = '[1920 1080]';
            app.GenerateButton = uibutton(app.GridLayout2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @generateCallback, true);
            app.GenerateButton.Text = 'Generate';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.OpenvideofolderButton = uibutton(app.GridLayout2, 'push');
            app.OpenvideofolderButton.ButtonPushedFcn = createCallbackFcn(app, @openFolder, true);
            app.OpenvideofolderButton.Text = {'Open video'; 'folder'};
            app.OpenvideoButton = uibutton(app.GridLayout2, 'push');
            app.OpenvideoButton.ButtonPushedFcn = createCallbackFcn(app, @openVideo, true);
            app.OpenvideoButton.Enable = 'off';
            app.OpenvideoButton.Text = 'Open video';
            app.colorField = uieditfield(app.GridLayout2, 'text');
            app.colorField.Editable = 'off';
            app.ColorindexEditFieldLabel = uilabel(app.GridLayout2);
            app.ColorindexEditFieldLabel.Tooltip = {''};
            app.ColorindexEditFieldLabel.Text = 'Color index';
            app.ColorindexEditField = uieditfield(app.GridLayout2, 'text');
            app.ColorindexEditField.ValueChangedFcn = createCallbackFcn(app, @changeColor, true);
            app.ColorindexEditField.Tooltip = {'Specified as either a RGB triplet (e.g. [1 1 1]) or color name (e.g. ''b'').'};
            app.ColorindexEditField.Value = '1';
            % Show the figure after all components are created
            app.BacklightdesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_patternMovieDesigner

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BacklightdesignerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BacklightdesignerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BacklightdesignerUIFigure)
        end
    end
end