classdef app_waveDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InternalWaveDetectionUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        notification                   matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        ImagemergesettingsButton       matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        FolderTree                     uiFileSystemCheckBox
        RightPanel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        FastCheckboardDemodulationTab  matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        GridLayout17                   matlab.ui.container.GridLayout
        UsehammingButton               matlab.ui.control.StateButton
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        kradiusmultiplierEditField     matlab.ui.control.NumericEditField
        kradiusmultiplierEditFieldLabel  matlab.ui.control.Label
        carrierLabel                   matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        AutomaskButton                 matlab.ui.control.StateButton
        ProcessimageButton             matlab.ui.control.Button
        fcdrolling_2                   matlab.ui.control.Spinner
        RollingskipLabel               matlab.ui.control.Label
        fcdrolling                     matlab.ui.control.Spinner
        RollingaverageSpinnerLabel     matlab.ui.control.Label
        DefinemaskButton               matlab.ui.control.Button
        fcdAxes                        matlab.ui.control.UIAxes
        OpticalFlowTab                 matlab.ui.container.Tab
        GridLayout16                   matlab.ui.container.GridLayout
        uiOpticalFlow                  uiOpticalFlow
        RenderingTab                   matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        BatchPanel                     matlab.ui.container.Panel
        GridLayout15_2                 matlab.ui.container.GridLayout
        renderingInfoLabel             matlab.ui.control.Label
        RenderButton                   matlab.ui.control.Button
        LastindexEditField             matlab.ui.control.NumericEditField
        StepEditField                  matlab.ui.control.NumericEditField
        StartindexEditField            matlab.ui.control.NumericEditField
        ParallelButton                 matlab.ui.control.StateButton
        LastindexEditFieldLabel        matlab.ui.control.Label
        StepEditFieldLabel             matlab.ui.control.Label
        StartindexEditFieldLabel       matlab.ui.control.Label
        ShowrenderSwitch               matlab.ui.control.Switch
        TogglerenderingSwitch          matlab.ui.control.Switch
        InformationstatusPanel         matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        ncInfoLabel                    matlab.ui.control.Label
        AxRendered                     matlab.ui.control.UIAxes
        lastRenderLabel                matlab.ui.control.Label
        ShowrenderSwitchLabel          matlab.ui.control.Label
        TogglerenderingSwitchLabel     matlab.ui.control.Label
        NetCDFTab                      matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        uiNetcdfDesigner               uiNetcdfDesigner
        GridLayout9                    matlab.ui.container.GridLayout
        AspectratioDropDown            matlab.ui.control.DropDown
        AspectratioDropDownLabel       matlab.ui.control.Label
        set_previousIndex              matlab.ui.control.StateButton
        set_roi                        matlab.ui.control.Button
        set_im0                        matlab.ui.control.Button
        overlay                        matlab.ui.control.DropDown
        OverlayDropDownLabel           matlab.ui.control.Label
        viewing                        matlab.ui.control.DropDown
        ViewingmodeDropDownLabel       matlab.ui.control.Label
        resize                         matlab.ui.control.NumericEditField
        ResizeEditFieldLabel           matlab.ui.control.Label
        rotation                       matlab.ui.control.Spinner
        RotationSpinnerLabel           matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        ScrollwheelstepEditField       matlab.ui.control.NumericEditField
        ScollwheelstepEditFieldLabel   matlab.ui.control.Label
        ImageIndex                     matlab.ui.control.Slider
        Image0Label                    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenuResetROI            matlab.ui.container.ContextMenu
        ResetROIMenu                   matlab.ui.container.Menu
        ContextMenuDefineMask          matlab.ui.container.ContextMenu
        ResetMenu                      matlab.ui.container.Menu
        ContextMenuAutoMask            matlab.ui.container.ContextMenu
        SetparametersMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        root    {mustBeFolder(root)} = pwd
        %iceData icedata
        iwData  iwdata
        index   (1,1) double
        datastore
    end

    properties (Access = private)
        photopath
        resultspath
        files
        filedates
        isMultiStream
        MultiStreamControls
        isRendering (1,1) logical = false
        showRender (1,1) logical = true
    end

    properties (Access = private, Hidden)
        method = 'fcd',view,scrollmult,scrolltype
        processingTime duration = seconds(nan)
    end
    %% Set
    methods
        function set.root(app,val)
            app.root = val;
            updateRoot(app)
        end
    end
    methods (Access=private)
        function updateRoot(app)
            app.FolderTree.root = app.root;
        end
    end
    %% Preset loading
    methods (Hidden,Access=protected)
        function status = CacheFcn(app,mode)
            status = true;
            if nargin<2
                mode = 'load';
            end

            cachePath = fullfile(fileparts(which('iwdata')),'cache');
            if ~isfolder(cachePath)
                mkdir(cachePath)
                mode = 'reset';
            end
            cacheFile = fullfile(cachePath,'dirs.mat');
            if ~isfile(cacheFile)
                mode = 'reset';
            end
            switch mode
                case 'reset'
                    ppath = uigetdir(pwd,'Choose image folder repository');
                    rpath = uigetdir(fullfile(ppath,'..'),'Choose results folder repository');
                    save(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
                case 'load'
                    load(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
                case 'update'
                    ppath = app.photopath;
                    rpath = app.resultspath;
                    save(cacheFile,"rpath",'ppath')
                case 'change'
                    ppath = uigetdir(app.photopath,'Choose image folder repository');
                    if ~ppath
                        status = false;
                        return
                    end
                    rpath = uigetdir(fullfile(ppath,'..'),'Choose results folder repository');
                    save(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
            end
            if ~nargout
                clear status
            end
        end
    end
    %% Imaging
    methods (Access = public)
        function parseImage(app)
            app.notification.Text = 'Processing';
            pause(1e-4)
            errorstr = '';

            % check for state objects
            app.iwData.previousindex = app.set_previousIndex.Value;
            app.iwData.automask      = app.AutomaskButton.Value;

            try
                warning off
                tic
                commands = {'method',app.method,...
                    'mode',app.view,...
                    'overlay',app.overlay.Value,...
                    'rolling',app.fcdrolling.Value,...
                    'userender',app.showRender,...
                    'render',app.isRendering};
                assignin('base','iwcommands',commands)

                checkForRenderedIndex(app)
                app.iwData.imageParser(app.index,...
                    'ax',app.UIAxes,...
                    commands{:});
                app.notification.Text = '';
                app.notification.Tooltip = '';
            catch ME
                assignin("base",'ME',ME)
                errorstr = ' [Error]';
                app.notification.Tooltip = ME.message;
            end
            t0  = toc;
            t   = t0;
            warning on

            % computation time
            computationcalls = '';
            if any(strcmp(app.view,{'u','v','q','h'}))
                time                = seconds(t);
                computations        = app.iwData.fcdComputations;
                t                   = seconds(time/computations);
                app.processingTime  = seconds(t0);%seconds(t);
                computationcalls    = sprintf('/operation %.2gs total',t0);
            end
            clearMessageAfter(app,[], ...
                sprintf('Processing%s (%.2gs%s)',errorstr,t,computationcalls), ...
                60)


            % netcdf rendering
            updateNCInfo(app)
            updateRenderingLabel(app,'last')

            % carrier signal window
            updateFCDCarrierWindow(app)

            assignin('base','iw',app.iwData)
        end
        function checkForRenderedIndex(app)
            hasRenderedIndex = false;
            try
                hasRenderedIndex = logical(app.iwData.nc_read(app.index,'rend'));
            end
            str = '[Calculated]';
            if hasRenderedIndex;str = '[Used render]';end
            app.lastRenderLabel.Text = str;
        end
    end
    %% Datastore
    methods (Access = private)
        function [state,loadedfile] = configureDataStore(app,event)
            % Default return state
            state       = false;
            loadedfile  = false;

            multiSelect = false;
            app.notification.Text = '';
            switch event.EventName
                case 'AllDeselected'
                    app.datastore = [];
                    return
                case 'AllSelected'
                    app.datastore = [];
            end

            % Get checked/selected nodes
            cn = event.Source.ActiveTree.CheckedNodes;
            if ~isempty(cn)
                [nodes,isParent] = isTwoStreams(app);
            else
                [nodes,isParent] = isTwoStreams(app,event.Source.SelectedNodes);
            end

            if isempty(nodes)||~(all(isvalid(nodes)))
                app.datastore       = [];
                app.iwData.files    = [];
                return
            end


            % get node subfolders
            count   = numel(nodes);
            fname   = {nodes.Text};
            fullpath= nodes.UserData;
            if count>1
                fullpath    = {nodes.UserData};
                multiSelect = true;
            end
            if isParent
                multiSelect = true;
                child   = dir(fullpath);
                child   = child(3:end);
                child   = child([child.isdir]);
                fname   = {child.name};
                fullpath= fullfile({child.folder},fname);
                count   = numel(child);
            end

            % check for saved files
            if checkForSavedFile(app,fullpath,fname,count)
                state           = true;
                loadedfile      = true;
                app.datastore   = app.iwData.files;
                app.files       = app.datastore.Files;
                return
            else
                app.iwData = iwdata; %#ok<*ADPROPLC>
            end

            try
                filepath = fullpath;
            catch
                return
            end

            % Look for images in folders
            try
                if ~isempty(app.datastore)
                    condition1 = all(ismember(app.datastore.Folders,filepath));
                    condition2 = all(ismember(filepath,app.datastore.Folders));
                    if all([condition1 condition2])
                        % Return if nothing new
                        state = true;
                        return
                    end
                end
                app.notification.Text = '';
                if multiSelect
                    try
                        app.notification.Text = 'Reading image datastores';
                        pause(1e-2)
                        app.datastore = imMerge( ...
                            imageDatastore(filepath{1},'FileExtensions',{'.tiff','.bmp'}),...
                            imageDatastore(filepath{2},'FileExtensions',{'.tiff','.bmp'}));
                    catch
                        app.datastore=imageDatastore(filepath,'FileExtensions',{'.tiff','.bmp'});
                    end
                else
                    app.datastore=imageDatastore(filepath,'FileExtensions',{'.tiff','.bmp'});
                end
                app.notification.Text = '';
            catch ME
                if contains(ME.identifier,'empty')
                    app.notification.Text = 'No images found in directory';
                    app.datastore = [];
                end
                return
            end
            if numel(app.datastore.Files)==0
                return;
            end


            if isempty(app.datastore.Folders)
                app.datastore.Folders = fileparts(app.datastore.Files{1});
            end
            app.files = app.datastore.Files;

            % Update iwdata class
            app.iwData.files = app.datastore;
            %app.iwData.sortByIndex
            assignin('base','iw',app.iwData)
            state = true;
        end
        function [nodes,isFolder] = isTwoStreams(app,nodes,isFolder)
            if nargin<2
                nodes = app.FolderTree.ActiveTree.CheckedNodes;
            end
            if nargin<3
                isFolder = false;
            end
            if isempty(nodes)&&~isFolder
                return
            end
            if ~isFolder
                nodes = nodes(~cellfun(@isempty,{nodes.UserData}));
            end
            app.isMultiStream = false;
            if numel(nodes)==2
                if checkForImageData(nodes)
                    % folders have ONLY imagedata
                    app.isMultiStream = true;
                end
            elseif numel(nodes)==1
                [state,newfolders] = checkForSubfolders(nodes);
                if numel(newfolders)==1
                    if checkForImageData(newfolders)
                        isFolder = true;
                        return
                    end
                end
                if state
                    [~,isFolder]=isTwoStreams(app,newfolders,true);
                    return
                end
            elseif numel(nodes)==3
                nodes = nodes([nodes(:).Parent]==nodes(1));
                isTwoStreams(app,nodes);
                return
            end
            set(app.ImagemergesettingsButton,'Enable',app.isMultiStream)
            if ~app.isMultiStream
                app.iwData.mergeSettings = struct;
            end

            if ~nargout
                clear nodes
            end

            %% Nested functions
            function state = checkForImageData(nodes)
                if isa(nodes,'matlab.ui.container.TreeNode')
                    foldername = {nodes.UserData};
                else
                    foldername = fullfile({nodes.folder},{nodes.name});
                end
                FILES  = cellfun(@dir,foldername,'UniformOutput',false);
                FILES = cat(1,FILES{:});
                FILES = FILES(~[FILES.isdir]);
                state = any(contains({FILES.name},{'.jpg','.png','.tiff'}));
            end
            function [state,newfolders] = checkForSubfolders(node)
                state = false;
                FILES = dir(node.UserData);
                FILES = FILES(3:end);
                newfolders = FILES([FILES.isdir]);
                if ~isempty(newfolders)
                    state = true;
                end
            end
        end
        function iwd = checkSelectionForData(app,fullpath,filenames)
            iwd     = repmat(iwdata,1,numel(filenames));
            flag    = false(size(iwd));
            lbl     = app.notification.Text;
            app.notification.Text = 'Reading selection';
            pause(1e-3)
            for i=1:numel(filenames)
                try
                    [~,d]   = checkForSavedFile(app,fullpath(i),filenames(i),1,false);
                catch
                    flag(i) = true;
                    continue
                end
                d.files = imageDatastore(fullpath(i));
                d.sortByIndex
                iwd(i)  = d;
            end
            iwd(flag) = [];
            app.notification.Text = lbl;
        end
        function [state,iwd] = checkForSavedFile(app,fullpath,filenames,count,assignData)
            iwd = iwdata;
            if nargin<5
                assignData = true;
            end
            state = false;

            searchPath = fileparts(fullpath);
            if isa(searchPath,'cell')
                searchPath = unique(searchPath);
            end
            FILES = dir(char(fullfile(searchPath,'*iwdata*.mat')));
            if isempty(FILES)
                return
            end

            multiStream = '+'; % tag for saved files of multistream

            pathname        = {FILES.name};
            MultiStreamTag  = contains(pathname,multiStream);
            if count==1
                MultiStreamTag=~MultiStreamTag;
            end
            pathname        = pathname(MultiStreamTag);
            FILES           = FILES(MultiStreamTag);

            valid           = cellfun(@(fname) contains(pathname,fname),filenames,'UniformOutput',false);
            valid           = cat(1,valid{:});

            if ~any(valid)
                clearMessageAfter(app,[],'No saved files detected')
                iwd = iwdata;
                if assignData
                    app.iwData = iwd;
                end
                return
            end

            if iscolumn(valid)
                valid = true;
            end

            if numel(filenames)>1
                valid = any(valid);
            end

            file2load = fullfile(FILES(valid).folder,FILES(valid).name);

            iwd = load(file2load).iw;
            if assignData
                app.iwData              = iwd;
            end
            updateButtons(app)

            clearMessageAfter(app,[],sprintf('%s loaded',char(FILES(valid).name)))
            state = true;
        end
    end
    %% NetCDF
    methods (Access = private)
        function setupNC(app)
            [state,ncfilename] = app.iwData.nc_check;
            if state
                app.uiNetcdfDesigner.filename = ncfilename;
                % check rendered data
                updateNCInfo(app)
            else
                app.uiNetcdfDesigner.resetAll;
            end

            
        end
        function [fields,count] = getNCRenderedData(app)
            fields = getNCData3D(app);
            fcn = @(fields) sum(~isnan(squeeze(ncread(app.iwData.ncfilepath,fields,[1 1 1],[1 1 app.iwData.maxIndex]))));
            count = cellfun(fcn,fields);
        end
        function [variables,sizes] = getNCData3D(app)
            ni          = ncinfo(app.iwData.ncfilepath);
            size0       = arrayfun(@(x) [x.Size],ni.Variables,'UniformOutput',false);
            sizes       = cell2mat(cellfun(@(x) prod(size(x)),size0,'UniformOutput',false)); %#ok<PSIZE>
            variables   = {ni.Variables.Name};
            variables   = variables(sizes==3)';
            sizes       = (size0(sizes==3)');
            r           = app.iwData.nc_read('rend');
            sizes       = repmat({sum(~isnan(r))},size(sizes));
        end
        function updateNCInfo(app)
            iw = app.iwData;
            state = iw.nc_check;

            if ~state
                app.ncInfoLabel.Text = 'No NetCDF data found';
                return
            end
            app.ncInfoLabel.Text = '';
            rend = app.iwData.nc_read('rend');
            str = sprintf('%i rendered',sum(~isnan(rend)));
                       
            % check frame status
            
            app.ncInfoLabel.Text = str;

            % update rendering axes
            updateRenderBar(app,rend)
        end
        function updateRenderingLabel(app,values)
            lbl = app.renderingInfoLabel;
            if nargin<2
                lbl.Text = '';
                return
            end

            t = app.processingTime;

            % update time estimate
            if ischar(values)
                if strcmp(values,'last')
                    values = lbl.UserData;
                end
            end
            
            try
                str = sprintf('(%s mm:ss)',string(t*values,'mm:ss'));
            catch
                str = '';
            end
            str = sprintf('%i frames %s',values,str);
            lbl.Text = str;
            lbl.UserData = values;
        end
        function updateFCDCarrierWindow(app)
            iw = app.iwData;
            app.carrierLabel.Text = '';
            try
                if strcmp(app.method,'fcd')&&~contains(app.view,{'Image','Difference'})
                    if iw.ss_fcd.fail
                        app.notification.Text = 'No carrier signal detected';
                    end
                    iw.ss_plot_fcd(app.fcdAxes);
                    kr = iw.ss_fcd.K(1,:);
                    ku = iw.ss_fcd.K(2,:);
                    app.carrierLabel.Text = sprintf( ...
                        'kr = %.2f,%.2f\nku = %.2f,%.2f', ...
                        kr(1),kr(2),ku(1),ku(2));
                    axis(app.fcdAxes,'square')
                elseif strcmp(app.method,'lat')
                    iw.plot_lat(app.latAxes);
                end
            end
        end
    end
    %% App functionality
    methods (Access = private)
        function configureLimits(app)
            uniqueFiles    = app.files;
            if isa(app.datastore,'imMerge')
                uniqueFiles = 1:app.datastore.maxIndex;
            else
                uniqueFiles = 1:numel(uniqueFiles);
            end

            % Adjust image index slider
            ticks           = 0:round(numel(uniqueFiles)/5):numel(uniqueFiles);
            ticks(1)        = 1;
            ticks(end+1)    = numel(uniqueFiles);

            app.ImageIndex.Enable       = 'on';
            app.ImageIndex.Visible      = 'on';
            app.ImageIndex.Limits       = [1 numel(uniqueFiles)];
            app.ImageIndex.MajorTicks   = ticks;
            app.ImageIndex.MinorTicksMode  = 'auto';
            app.ImageIndex.MajorTickLabels = cellstr(num2str(ticks'))';

            app.ScrollwheelstepEditField.UserData = false;
            getScrollWheelStep(app)

            % Adjust rendering tab sequencer
            set([app.StartindexEditField ...
                app.StepEditField ...
                app.LastindexEditField...
                ],'Limits',app.ImageIndex.Limits)
            app.StartindexEditField.Value = 1;
            app.StepEditField.Value = 1;
            app.LastindexEditField.Value = numel(uniqueFiles);

            updateRenderingLabel(app,numel(uniqueFiles))
        end
        function updateButtons(app)
            iwd = app.iwData;
            set(app.rotation,'Value',iwd.ss_dic.controls.rotation*90)
        end
        function step = getScrollWheelStep(app)
            if ~app.ScrollwheelstepEditField.UserData
                step = round(diff(app.ImageIndex.Limits)/100);
                step(step<1)=1;
                app.ScrollwheelstepEditField.Value = step;
            else
                step = app.ScrollwheelstepEditField.Value;
            end

            if ~nargout
                clear step
            end
        end
        function clearMessageAfter(app,component,message,time,lastmessage)
            if nargin<4
                time = 2;
            end
            if nargin<5
                lastmessage = '';
            end
            if isempty(component)
                component = app.notification;
            end
            set(component,'Text',message)
            try delayedFunction(@(~,~) set(component,'Text',lastmessage),time)
            catch
                set(component,'Text',lastmessage)
            end
        end
        function state = toggleSwitch(~,component)
            switch component.Value
                case 'On'
                    state = true;
                case 'Off'
                    state = false;
            end
        end
        function toggleLastSession(app,state)
            cachePath       = fullfile(fileparts(which('iwdata')),'cache');
            fname           = fullfile(cachePath,'lastsession.xml');
            currentsession  = struct('root',app.root);
            switch state
                case 'update'
                    writestruct(currentsession,fname)
                case 'load'
                    file = readstruct(fname);
                    app.root = file.root;
            end
        end
        function updateRenderBar(app,rend)
            d = findall(app.AxRendered,'Type','Bar');
            try
                l = findall(app.AxRendered,'Type','Line');
                if isempty(l)
                    line(app.AxRendered,app.index.*[1 1],ylim(app.AxRendered), ...
                        'Color','r')
                else
                    l.XData = app.index.*[1 1];
                end
            catch
                delete(l)
            end

            if nargin<2
                return
            end
            try
                if isempty(d)
                    bar(app.AxRendered,rend,'BarWidth',2)
                else
                    d.XData = 1:numel(rend);
                    d.YData = rend;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide programming labels
            set([app.lastRenderLabel ...
                app.renderingInfoLabel ...
                app.carrierLabel],'Text','')

            % open root folder
            ROOT = 'D:\PhD\Data\ablation_images';
            if ~isfolder(ROOT)
                try ROOT = '..\Desktop\PhD\Data\ablation_images';
                catch
                    ROOT = pwd;
                end
            end
            app.FolderTree.root = ROOT;
            app.FolderTree.HiddenTreeVisible = false;
            
            
            % CONSTRUCTOR
            app.iwData  = iwdata; %#ok<*ADPROP>
%             app.iceData = icedata;
            app.view    = app.viewing.Value;
%             app.iceData.dirs.photos     = app.photopath;
%             app.iceData.dirs.results    = app.resultspath;

            app.ScrollwheelstepEditField.UserData = false;

            app.iwData.ss_of = app.uiOpticalFlow.operations;


            % Last session

        end

        % Callback function: FolderTree, FolderTree, FolderTree, FolderTree
        function TreeSelectionChanged(app, event)
            %% CHECK NODES
            cn = app.FolderTree.CheckedNodes;
            sn = app.FolderTree.SelectedNodes;
                        
            hasCheckedNodes = ~isempty(cn);

            s = {sn.UserData};
            
            %% CHECK FILES IN FOLDER
            hasLoadedFile = false;
            if hasCheckedNodes
                try
                    s       = nonempty({cn.UserData});
                catch
                    return
                end
                lbls    = nonempty({cn.Text});
                if strcmp(event.EventName,'SelectionChanged')
                    return
                end
            end
            valid   = isfolder(s);
            if hasCheckedNodes
                app.FolderTree.ActiveTree.CheckedNodes = cn(valid);
            end
            
            % Configure on-screen iwdata
            try
                app.notification.Text = 'Reading datastore'; pause(1e-3)
                [state,hasLoadedFile] = configureDataStore(app,event);
                app.iwData.validateDatastore(event.Source.SelectedPath);
            catch ME
                assignin('base','ME',ME)
                state = false;
            end
            app.SaveButton.Enable = 'off';
            % send iwdata and selection to workspace
            assignin('base','iw',app.iwData)
%             try
%                 app.notification.Text = 'Checking files'; pause(1e-3)
%                 app.iwData.manageFiles
%                 app.notification.Text = '';
%             end

            %% Selection
            if ~isempty(cn)
                s       = s(valid);
                lbls    = lbls(valid);
                if numel(cn)>1
                    s = checkSelectionForData(app,s,lbls);
                end
                if isempty(app.iwData)
                    checkForSavedFile(app,{sn.UserData},{sn.Text},1);
                end
            end
            assignin('base','iwSelection',s)
            if ~state
                return
            end
            %% CHECK NETCDF FILES
            setupNC(app)
            %% SETUP OpticalFlow DEFAULTS 
            if isempty(app.iwData.ss_of)
                app.iwData.ss_of = app.uiOpticalFlow.operations;
            end
            %% CONFIGURE IMAGESLIDER
            configureLimits(app)

            if within(app.ImageIndex.Value,[1 numel(app.datastore.Files)],'exclusive')
                app.index = app.ImageIndex.Value;
            else
                app.index            = 1;
                app.ImageIndex.Value = 1;
            end

            %% Save files to IWDATA
            if ~hasLoadedFile
                app.iwData.im0      = [];
                app.iwData.im0index = 1;
                app.iwData.ss_dic.controls.im0index = 1;
                app.iwData.ss_dic.controls.rotation = app.rotation.Value/90;
            end
            app.SaveButton.Enable = 'on';
            %% PARSE IMAGE
            app.parseImage
        end

        % Value changed function: ImageIndex
        function imageSliderCallback(app, event)
            try
                app.resize.Value = app.resize.UserData*1e2;
                app.resize.UserData = [];
                app.iwData.ss_dic.controls.resize=app.resize.Value;
            end %#ok<*TRYNC>
            app.ImageIndex.UserData = round(app.ImageIndex.Value,0);
            app.ImageIndex.Value    = app.ImageIndex.UserData;
            app.index               = app.ImageIndex.UserData;
            app.Image0Label.Text = sprintf('Image\n(%i)',app.index);
            %% PARSE IMAGE
            app.parseImage
        end

        % Value changing function: ImageIndex
        function imagerSliderChangingCallback(app, event)
            switch app.viewing.Value
                case {'Image','Difference'}
                otherwise
                    return
            end
            app.ImageIndex.UserData = round(event.Value,0);
            app.index               = app.ImageIndex.UserData;
            app.Image0Label.Text = sprintf('Image\n(%i)',app.index);

            %% PARSE IMAGE
            if isempty(app.resize.UserData )
                app.resize.UserData = app.resize.Value*1e-2;
            end
            app.iwData.ss_dic.controls.resize=15;
            try tic,app.parseImage,pause(toc)
                delayedFunction(@(varargin) imageSliderCallback(app),1,'ChangingSlider')
            catch
                delayedFunction(@(varargin) imageSliderCallback(app),1,'ChangingSlider')
            end
        end

        % Callback function: ProcessimageButton, ResetROIMenu, 
        % ...and 12 other components
        function ss_callback(app, event)
            tag = split(event.Source.Tag,'-');
            command = tag{end};

            updateAxes = true;
            switch event.Source
                case app.fcdrolling
                    app.fcdrolling_2.Enable = 'off';
                    if event.Value
                        app.fcdrolling_2.Enable = 'on';
                    end
                    updateAxes = false;
                case app.fcdrolling_2
                    app.fcdrolling_2.Value(event.Value>app.fcdrolling.Value)=app.fcdrolling.Value;
                    updateAxes = false;
            end

            switch command
                case {'nc','mq','md','bo','bs','resize','fast','fcdrolling','latrolling','kmult','hamming','thresh'}
                    exec = sprintf('app.iwData.ss_dic.controls.%s=event.Value;',command);
                    eval(exec)
                case 'fcdrollingstep'
                    app.iwData.ss_dic.controls.fcdrollingstep =  app.fcdrolling_2.Value;
                case 'im0index'
                    app.iwData.ss_dic.controls.im0index = app.index;
                    app.iwData.im0index = app.index;
                case 'previousIndex'
                    app.iwData.previousindex = event.Value;
                    updateAxes = true;
                case 'roi'
                    app.iwData.setROI('set');
                case 'roireset'
                    app.iwData.setROI('reset');
                case 'view'
                    exec = split(event.Value,'-');
                    app.view = exec{1};
                case 'rotation'
                    exec = sprintf('app.iwData.ss_dic.controls.%s=event.Value/90;',command);
                    eval(exec)
            end


            if updateAxes
                app.parseImage;
            end
        end

        % Button pushed function: SaveButton
        function saveCallback(app, event)
            app.iwData.saveDesign;
        end

        % Window scroll wheel function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount    = event.VerticalScrollAmount; %#ok<NASGU>
            verticalScrollCount     = event.VerticalScrollCount;

            hFound = pointer2object(app.InternalWaveDetectionUIFigure,{'index'});

            if isempty(hFound)
                return
            end


            if contains(class(hFound),{'Slider','Grid'})
                if contains(class(hFound),'Grid')
                    for i=1:length(hFound.Children)
                        if contains(class(hFound.Children(i)),'Slider')&&hFound.Children(i).Visible
                            hFound = hFound.Children(i);
                            break
                        end
                    end
                end
            end
            if contains(class(hFound),'Slider')
                step = getScrollWheelStep(app);
                if app.scrollmult
                    switch app.scrolltype
                        case 'shift'
                            step = step*5;
                        case 'control'
                            step = 1;
                    end
                end

                val     = hFound.Value - verticalScrollCount*step;

                if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                    hFound.Value = hFound.Value - verticalScrollCount*step;
                end
                imageSliderCallback(app,event)
            else
                return
            end
        end

        % Window key press function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowKeyPress(app, event)
            if contains(event.Key,{'shift','control'})
                app.scrollmult = true;
                app.scrolltype = event.Key;
            end
        end

        % Window key release function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowKeyRelease(app, event)
            app.scrollmult = false;
        end

        % Selection change function: TabGroup
        function solverCallback(app, event)
            tab         = app.TabGroup.SelectedTab;
            app.method  = 'fcd';%tab.Tag;
            defitems    = app.viewing.Items;
            if tab==app.OpticalFlowTab
                app.method = 'of';
                app.uiOpticalFlow.AxesHandle = app.UIAxes;
                [app.uiOpticalFlow.imdef,app.uiOpticalFlow.imref] = app.iwData.loadimage(app.index,true);
            end
%             switch app.method
%                 case 'fcd'
                    defitems{end} = 'H-integrate';
%                 case 'dic'
%                     defitems{end} = 'Q-correlation';
%                 case 'lat'
%                     defitems{end} = 'Mean-state';
%             end
            app.viewing.Items = defitems;
            updateNCInfo(app)
        end

        % Callback function: AutomaskButton, ContextMenuAutoMask, 
        % ...and 2 other components
        function fcd_maskCallback(app, event)
            switch event.Source.Tag
                case 'autoparameters'
                    app.AutomaskButton.Value = true;
                    app.iwData.ss_mask_fcd(true,true)
                case 'auto'
                    if event.Value
                        app.iwData.ss_mask_fcd(true)
                    end
                case 'set'
                    app.iwData.ss_mask_fcd(false);
                case 'reset'
                    app.iwData.ss_fcd.mask = false;
            end
        end

        % Button pushed function: ImagemergesettingsButton
        function toggleImageMerger(app, event)
            ds = app.iwData.files;
            if ~isa(ds,'imMerge')
                return
            end
            [im1,im2]=ds.readImageAtIndex(app.index,false);
            app.MultiStreamControls = uiImageMergeControls('Image1',im1,'Image2',im2);
            if ~isempty(app.iwData.mergeSettings)
                app.MultiStreamControls.Operation = app.iwData.mergeSettings;
            end
            app.MultiStreamControls.ValueChangedFcn = @(varargin) updateIWData(app);

            function updateIWData(app,~)
                app.iwData.mergeSettings = app.MultiStreamControls.Operation;
                app.iwData.files.mergeSettings = app.iwData.mergeSettings;
                assignin('base','iw',app.iwData)
            end
        end

        % Value changed function: ScrollwheelstepEditField
        function ScrollWheelStepChanged(app, event)
            value = app.ScrollwheelstepEditField.Value;
            app.ScrollwheelstepEditField.UserData = false;
            if value
                app.ScrollwheelstepEditField.UserData = true;
            end
        end

        % Value changed function: AspectratioDropDown
        function changeAxisFrame(app, event)
            value = app.AspectratioDropDown.Value;
            axis(app.UIAxes,value)
        end

        % Value changed function: TogglerenderingSwitch
        function toggleRendering(app, event)
            app.isRendering = app.toggleSwitch(app.TogglerenderingSwitch);
            parseImage(app)
        end

        % Value changed function: ShowrenderSwitch
        function toggleRenderPreview(app, event)
            app.showRender = app.toggleSwitch(app.ShowrenderSwitch);
            parseImage(app)
        end

        % Button pushed function: RenderButton
        function renderBatchCallback(app, event)
            wb = uiprogressdlg(app.InternalWaveDetectionUIFigure, ...
                'Title','Parallel computation', ...
                'Message','Preallocating memory', ...
                'Cancelable','on', ...
                'ShowPercentage','on');
            
            app.iwData.renderBatch( ...
                app.StartindexEditField.Value, ...
                app.StepEditField.Value, ...
                app.LastindexEditField.Value, ...
                app.ParallelButton.Value,...
                wb);
            updateNCInfo(app)
        end

        % Value changed function: ParallelButton
        function toggleParallel(app, event)
            state = gcp('nocreate');
            if app.ParallelButton.Value
                if isempty(state)
                    gcp;
                end
            end
        end

        % Value changed function: LastindexEditField, StartindexEditField, 
        % ...and 1 other component
        function sequencerEditFieldsCallback(app, event)
            sef = app.StartindexEditField;
            lef = app.LastindexEditField;
            step = app.StepEditField.Value;

            lmin = sef.Value+step;
            smax = lef.Value-step;

            switch event.Source
                case sef
                    sef.Value(sef.Value>smax)=smax;
                case lef
                    lef.Value(lef.Value<lmin)=lmin;
            end

            % update label
            values = numel(sef.Value:step:lef.Value);
            updateRenderingLabel(app,values)
        end

        % Callback function: uiOpticalFlow
        function opticalFlowOutput(app, event)
            parseImage(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.InternalWaveDetectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InternalWaveDetectionUIFigure = uifigure('Visible', 'off');
            app.InternalWaveDetectionUIFigure.Name = 'Internal Wave Detection';
            app.InternalWaveDetectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.InternalWaveDetectionUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowScrollWheel, true);
            app.InternalWaveDetectionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowKeyPress, true);
            app.InternalWaveDetectionUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.InternalWaveDetectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.FolderTree = uiFileSystemCheckBox(app.GridLayout2);
            app.FolderTree.DateFilters = [datetime([2023 10 26]) datetime([2023 10 26])];
            app.FolderTree.HiddenTreeVisible = true;
            app.FolderTree.AllDeselectedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FolderTree.AllSelectedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FolderTree.NodesCheckedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FolderTree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.SaveButton.Tag = 'save';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Pushes the ''icedata'' superclass to a workspace file'};
            app.SaveButton.Text = 'Save';
            app.ImagemergesettingsButton = uibutton(app.GridLayout4, 'push');
            app.ImagemergesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @toggleImageMerger, true);
            app.ImagemergesettingsButton.Enable = 'off';
            app.ImagemergesettingsButton.Text = 'Image merge settings';
            app.notification = uilabel(app.GridLayout2);
            app.notification.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.GridLayout7.Tag = 'index';
            app.Image0Label = uilabel(app.GridLayout7);
            app.Image0Label.Text = {'Image'; '(0)'};
            app.ImageIndex = uislider(app.GridLayout7);
            app.ImageIndex.ValueChangedFcn = createCallbackFcn(app, @imageSliderCallback, true);
            app.ImageIndex.ValueChangingFcn = createCallbackFcn(app, @imagerSliderChangingCallback, true);
            app.ImageIndex.Tag = 'index';
            app.ScollwheelstepEditFieldLabel = uilabel(app.GridLayout7);
            app.ScollwheelstepEditFieldLabel.Text = {'Scoll wheel'; 'step'};
            app.ScrollwheelstepEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ScrollwheelstepEditField.Limits = [0 Inf];
            app.ScrollwheelstepEditField.ValueChangedFcn = createCallbackFcn(app, @ScrollWheelStepChanged, true);
            app.ScrollwheelstepEditField.Value = 5;
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.RotationSpinnerLabel = uilabel(app.GridLayout9);
            app.RotationSpinnerLabel.Text = 'Rotation';
            app.rotation = uispinner(app.GridLayout9);
            app.rotation.Step = 90;
            app.rotation.Limits = [-270 270];
            app.rotation.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.rotation.Tag = 'set-rotation';
            app.ResizeEditFieldLabel = uilabel(app.GridLayout9);
            app.ResizeEditFieldLabel.Text = 'Resize (%)';
            app.resize = uieditfield(app.GridLayout9, 'numeric');
            app.resize.Limits = [1 100];
            app.resize.RoundFractionalValues = 'on';
            app.resize.ValueDisplayFormat = '%.0f';
            app.resize.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.resize.Tag = 'set-resize';
            app.resize.Value = 50;
            app.ViewingmodeDropDownLabel = uilabel(app.GridLayout9);
            app.ViewingmodeDropDownLabel.Text = 'Viewing mode';
            app.viewing = uidropdown(app.GridLayout9);
            app.viewing.Items = {'Image', 'Difference', 'u-velocity', 'v-velocity', 'U-magnitude', 'zeta-vorticity', 'H-integrate'};
            app.viewing.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.viewing.Tag = 'set-view';
            app.viewing.Value = 'Image';
            app.OverlayDropDownLabel = uilabel(app.GridLayout9);
            app.OverlayDropDownLabel.Text = 'Overlay';
            app.overlay = uidropdown(app.GridLayout9);
            app.overlay.Items = {'None', 'Velocity vectors', 'Streamlines'};
            app.overlay.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.overlay.Tag = 'set-overlay';
            app.overlay.Value = 'None';
            app.set_im0 = uibutton(app.GridLayout9, 'push');
            app.set_im0.ButtonPushedFcn = createCallbackFcn(app, @ss_callback, true);
            app.set_im0.Tag = 'set-im0index';
            app.set_im0.Text = {'Set reference'; 'image'};
            app.set_roi = uibutton(app.GridLayout9, 'push');
            app.set_roi.ButtonPushedFcn = createCallbackFcn(app, @ss_callback, true);
            app.set_roi.Tag = 'set-roi';
            app.set_roi.Text = 'Set ROI';
            app.set_previousIndex = uibutton(app.GridLayout9, 'state');
            app.set_previousIndex.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.set_previousIndex.Tag = 'set-previousIndex';
            app.set_previousIndex.Text = 'Use previous index';
            app.AspectratioDropDownLabel = uilabel(app.GridLayout9);
            app.AspectratioDropDownLabel.Text = 'Aspect ratio';
            app.AspectratioDropDown = uidropdown(app.GridLayout9);
            app.AspectratioDropDown.Items = {'normal', 'image', 'tight', 'square'};
            app.AspectratioDropDown.ValueChangedFcn = createCallbackFcn(app, @changeAxisFrame, true);
            app.AspectratioDropDown.Value = 'normal';
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @solverCallback, true);
            app.FastCheckboardDemodulationTab = uitab(app.TabGroup);
            app.FastCheckboardDemodulationTab.Title = 'Fast Checkboard Demodulation';
            app.FastCheckboardDemodulationTab.Tag = 'fcd';
            app.GridLayout10 = uigridlayout(app.FastCheckboardDemodulationTab);
            app.fcdAxes = uiaxes(app.GridLayout10);
            app.fcdAxes.XLimitMethod = 'tight';
            app.fcdAxes.YLimitMethod = 'tight';
            app.fcdAxes.XTick = [];
            app.fcdAxes.YTick = [];
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.DefinemaskButton = uibutton(app.GridLayout12, 'push');
            app.DefinemaskButton.ButtonPushedFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.DefinemaskButton.Tag = 'set';
            app.DefinemaskButton.Text = 'Define mask';
            app.RollingaverageSpinnerLabel = uilabel(app.GridLayout12);
            app.RollingaverageSpinnerLabel.Text = 'Rolling average';
            app.fcdrolling = uispinner(app.GridLayout12);
            app.fcdrolling.Limits = [0 Inf];
            app.fcdrolling.RoundFractionalValues = 'on';
            app.fcdrolling.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.fcdrolling.Tag = 'set-fcdrolling';
            app.RollingskipLabel = uilabel(app.GridLayout12);
            app.RollingskipLabel.Text = 'Rolling skip';
            app.fcdrolling_2 = uispinner(app.GridLayout12);
            app.fcdrolling_2.Limits = [1 Inf];
            app.fcdrolling_2.RoundFractionalValues = 'on';
            app.fcdrolling_2.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.fcdrolling_2.Tag = 'set-fcdrollingstep';
            app.fcdrolling_2.Enable = 'off';
            app.fcdrolling_2.Value = 1;
            app.ProcessimageButton = uibutton(app.GridLayout12, 'push');
            app.ProcessimageButton.ButtonPushedFcn = createCallbackFcn(app, @ss_callback, true);
            app.ProcessimageButton.Text = 'Process image';
            app.AutomaskButton = uibutton(app.GridLayout12, 'state');
            app.AutomaskButton.ValueChangedFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.AutomaskButton.Tag = 'auto';
            app.AutomaskButton.Text = 'Auto-mask';
            app.GridLayout17 = uigridlayout(app.GridLayout10);
            app.carrierLabel = uilabel(app.GridLayout17);
            app.kradiusmultiplierEditFieldLabel = uilabel(app.GridLayout17);
            app.kradiusmultiplierEditFieldLabel.Text = {'k-radius '; 'multiplier'};
            app.kradiusmultiplierEditField = uieditfield(app.GridLayout17, 'numeric');
            app.kradiusmultiplierEditField.Limits = [0 2];
            app.kradiusmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.kradiusmultiplierEditField.Tag = 'set-kmult';
            app.kradiusmultiplierEditField.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout17);
            app.ThresholdEditFieldLabel.Tooltip = {'Threshold value for peak detection as fraction of the maximum value in the search range'};
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ThresholdEditField.Limits = [0 2];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.ThresholdEditField.Tag = 'set-thresh';
            app.ThresholdEditField.Value = 0.5;
            app.UsehammingButton = uibutton(app.GridLayout17, 'state');
            app.UsehammingButton.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.UsehammingButton.Tag = 'set-hamming';
            app.UsehammingButton.Text = 'Use hamming';
            app.OpticalFlowTab = uitab(app.TabGroup);
            app.OpticalFlowTab.Title = 'Optical Flow';
            app.GridLayout16 = uigridlayout(app.OpticalFlowTab);
            app.uiOpticalFlow = uiOpticalFlow(app.GridLayout16);
            app.uiOpticalFlow.Style = "horizontal";
            app.uiOpticalFlow.usesApp = true;
            app.uiOpticalFlow.ComputationCalledFcn = createCallbackFcn(app, @opticalFlowOutput, true);
            app.RenderingTab = uitab(app.TabGroup);
            app.RenderingTab.Title = 'Rendering';
            app.GridLayout13 = uigridlayout(app.RenderingTab);
            app.TogglerenderingSwitchLabel = uilabel(app.GridLayout13);
            app.TogglerenderingSwitchLabel.Text = 'Toggle rendering';
            app.ShowrenderSwitchLabel = uilabel(app.GridLayout13);
            app.ShowrenderSwitchLabel.Text = 'Show render';
            app.lastRenderLabel = uilabel(app.GridLayout13);
            app.lastRenderLabel.Text = 'last_render';
            app.InformationstatusPanel = uipanel(app.GridLayout13);
            app.InformationstatusPanel.Title = 'Information status';
            app.GridLayout15 = uigridlayout(app.InformationstatusPanel);
            app.AxRendered = uiaxes(app.GridLayout15);
            app.AxRendered.YTick = [];
            app.ncInfoLabel = uilabel(app.GridLayout15);
            app.ncInfoLabel.Interpreter = 'html';
            app.ncInfoLabel.Text = 'No NetCDF data found';
            app.TogglerenderingSwitch = uiswitch(app.GridLayout13, 'slider');
            app.TogglerenderingSwitch.ValueChangedFcn = createCallbackFcn(app, @toggleRendering, true);
            app.ShowrenderSwitch = uiswitch(app.GridLayout13, 'slider');
            app.ShowrenderSwitch.ValueChangedFcn = createCallbackFcn(app, @toggleRenderPreview, true);
            app.ShowrenderSwitch.Value = 'On';
            app.BatchPanel = uipanel(app.GridLayout13);
            app.BatchPanel.Title = 'Batch';
            app.GridLayout15_2 = uigridlayout(app.BatchPanel);
            app.StartindexEditFieldLabel = uilabel(app.GridLayout15_2);
            app.StartindexEditFieldLabel.Text = 'Start index';
            app.StepEditFieldLabel = uilabel(app.GridLayout15_2);
            app.StepEditFieldLabel.Text = 'Step';
            app.LastindexEditFieldLabel = uilabel(app.GridLayout15_2);
            app.LastindexEditFieldLabel.Text = 'Last index';
            app.ParallelButton = uibutton(app.GridLayout15_2, 'state');
            app.ParallelButton.ValueChangedFcn = createCallbackFcn(app, @toggleParallel, true);
            app.ParallelButton.Text = 'Parallel';
            app.StartindexEditField = uieditfield(app.GridLayout15_2, 'numeric');
            app.StartindexEditField.Limits = [1 Inf];
            app.StartindexEditField.ValueChangedFcn = createCallbackFcn(app, @sequencerEditFieldsCallback, true);
            app.StartindexEditField.Value = 1;
            app.StepEditField = uieditfield(app.GridLayout15_2, 'numeric');
            app.StepEditField.Limits = [1 Inf];
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @sequencerEditFieldsCallback, true);
            app.StepEditField.Value = 1;
            app.LastindexEditField = uieditfield(app.GridLayout15_2, 'numeric');
            app.LastindexEditField.Limits = [1 Inf];
            app.LastindexEditField.ValueChangedFcn = createCallbackFcn(app, @sequencerEditFieldsCallback, true);
            app.LastindexEditField.Value = Inf;
            app.RenderButton = uibutton(app.GridLayout15_2, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @renderBatchCallback, true);
            app.RenderButton.Text = 'Render';
            app.renderingInfoLabel = uilabel(app.GridLayout15_2);
            app.renderingInfoLabel.Text = 'rendering_info';
            app.NetCDFTab = uitab(app.TabGroup);
            app.NetCDFTab.Title = 'NetCDF';
            app.GridLayout14 = uigridlayout(app.NetCDFTab);
            app.uiNetcdfDesigner = uiNetcdfDesigner(app.GridLayout14);
            app.uiNetcdfDesigner.root = "D:\PhD\MATLAB_Drive\functions\appdesigner\components";
            app.ContextMenuResetROI = uicontextmenu(app.InternalWaveDetectionUIFigure);
            app.ResetROIMenu = uimenu(app.ContextMenuResetROI);
            app.ResetROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ss_callback, true);
            app.ResetROIMenu.Text = 'Reset ROI';
            app.ResetROIMenu.Tag = 'set-roireset';
            % Assign app.ContextMenuResetROI
            app.set_roi.ContextMenu = app.ContextMenuResetROI;
            app.ContextMenuDefineMask = uicontextmenu(app.InternalWaveDetectionUIFigure);
            app.ResetMenu = uimenu(app.ContextMenuDefineMask);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.ResetMenu.Text = 'Reset';
            app.ResetMenu.Tag = 'reset';
            % Assign app.ContextMenuDefineMask
            app.DefinemaskButton.ContextMenu = app.ContextMenuDefineMask;
            app.ContextMenuAutoMask = uicontextmenu(app.InternalWaveDetectionUIFigure);
            app.ContextMenuAutoMask.ContextMenuOpeningFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.ContextMenuAutoMask.Tag = 'autoparameters';
            app.SetparametersMenu = uimenu(app.ContextMenuAutoMask);
            app.SetparametersMenu.Text = 'Set parameters';
            % Assign app.ContextMenuAutoMask
            app.AutomaskButton.ContextMenu = app.ContextMenuAutoMask;
            % Show the figure after all components are created
            app.InternalWaveDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_waveDetection

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.InternalWaveDetectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.InternalWaveDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InternalWaveDetectionUIFigure)
        end
    end
end