classdef app_waveDetection_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InternalWaveDetectionUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ChangedirectoryButton          matlab.ui.control.Button
        notification                   matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        FolderTree                     matlab.ui.container.Tree
        RightPanel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        ImageIndex                     matlab.ui.control.Slider
        ImageSliderLabel               matlab.ui.control.Label
        GridLayout9                    matlab.ui.container.GridLayout
        rotation                       matlab.ui.control.Spinner
        RotationSpinnerLabel           matlab.ui.control.Label
        ResizeEditFieldLabel           matlab.ui.control.Label
        resize                         matlab.ui.control.NumericEditField
        set_roi                        matlab.ui.control.Button
        set_im0                        matlab.ui.control.Button
        overlay                        matlab.ui.control.DropDown
        OverlayDropDownLabel           matlab.ui.control.Label
        viewing                        matlab.ui.control.DropDown
        ViewingmodeDropDownLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        FastCheckboardDemodulationTab  matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        GridLayout12                   matlab.ui.container.GridLayout
        DefinemaskButton               matlab.ui.control.Button
        fcdrolling                     matlab.ui.control.Spinner
        RollingaverageSpinnerLabel     matlab.ui.control.Label
        fcdAxes                        matlab.ui.control.UIAxes
        LightAttenuationTab            matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        latAxes                        matlab.ui.control.UIAxes
        DigitalImageCorrelationTab     matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        fast                           matlab.ui.control.StateButton
        nc                             matlab.ui.control.StateButton
        mq                             matlab.ui.control.Spinner
        MinimumsolutionqualitySpinnerLabel  matlab.ui.control.Label
        md                             matlab.ui.control.Spinner
        SearchwindowscaleSpinnerLabel  matlab.ui.control.Label
        bo                             matlab.ui.control.Spinner
        BlockoverlapSpinnerLabel       matlab.ui.control.Label
        bs                             matlab.ui.control.Spinner
        BlockspacingSpinnerLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        ResetROIMenu                   matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        ResetMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        icedata,iwdata,index
    end

    properties (Access = private)
        photopath,resultspath
        folders,foldercount,folderlist
    end

    properties (Access = private, Hidden)
        method = 'fcd',view,scrollmult,scrolltype
    end

    methods (Hidden,Access=protected)
        function status = CacheFcn(app,mode)
            status = true;
            if nargin<2
                mode = 'load';
            end

            cachePath = fullfile(fileparts(matlab.desktop.editor.getActiveFilename),'cache');
            if ~isfolder(cachePath)
                mkdir(cachePath)
                mode = 'reset';
            end
            cacheFile = fullfile(cachePath,'dirs.mat');
            if ~isfile(cacheFile)
                mode = 'reset';
            end
            switch mode
                case 'reset'
                    ppath = uigetdir(pwd,'Choose image folder repository');
                    rpath = uigetdir(fullfile(ppath,'..'),'Choose results folder repository');
                    save(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
                case 'load'
                    load(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
                case 'update'
                    ppath = app.photopath;
                    rpath = app.resultspath;
                    save(cacheFile,"rpath",'ppath')
                case 'change'
                    ppath = uigetdir(app.photopath,'Choose image folder repository');
                    if ~ppath
                        status = false;
                        return
                    end
                    rpath = uigetdir(fullfile(ppath,'..'),'Choose results folder repository');
                    save(cacheFile,"rpath",'ppath')
                    app.photopath   = ppath;
                    app.resultspath = rpath;
            end
            if ~nargout
                clear status
            end
        end
    end
    methods (Access = public)
        function requestFolder(app,mode)
            %% BUILDS OR UPDATES FOLDER TREE
            %% Note: assumes the root directory is APP.PHOTOPATH
            app.notification.Text = 'Building folder tree...';
            pause(0.1)
            switch mode
                case 'startup'
                    %% TREE CONSTRUCTOR
                    app.folders = Folder(app.photopath);
                    [app.foldercount,app.folderlist] = getfoldercount;
                    app.folders.maketree('handle',app.FolderTree);
                otherwise
                    %% TREE UPDATE
                    [newfoldercount,list] = getfoldercount;
                    if app.foldercount==newfoldercount&&all(ismember(app.folderlist,list))
                        return
                    end
%                     newfolders  = setxor(list,app.folderlist);
%                     app.folders = Folder(app.photopath);
%                     f = Folder(app.photopath);
                    app.folders.maketree('handle',app.FolderTree,...
                        'update',{app.folderlist,list});
            end
            app.FolderTree.UserData = app.folders;
            app.notification.Text = '';
            
            function varargout = getfoldercount
                Path  = app.photopath;
                list  = split(genpath(Path),';');
                list  = list(1:end-1);
                varargout{1} = numel(list);
                varargout{2} = list;
            end
        end
        function parseImage(app)
            warning off
            app.iwdata.imageParser(app.index,...
                'ax',app.UIAxes,...
                'method',app.method,...
                'mode',app.view,...
                'overlay',app.overlay.Value,...
                'rolling',app.fcdrolling.Value);
            warning on

            app.notification.Text = '';
            try %#ok<TRYNC> 
                if strcmp(app.method,'fcd')&&~contains(app.view,{'Image','Difference'})
                    if app.iwdata.ss_fcd.fail
                        app.notification.Text = 'No carrier signal detected';
                    end
                    app.iwdata.ss_plot_fcd(app.fcdAxes);
                elseif strcmp(app.method,'lat')
                    app.iwdata.plot_lat(app.latAxes);
                end
                
            end
            assignin('base','iwd',app.iwdata)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% LOAD CACHED DATA
            CacheFcn(app,'load')
            %% CONSTRUCTOR
            app.iwdata  = iwdata; %#ok<*ADPROP>
            app.icedata = icedata;
            app.view    = app.viewing.Value;
            app.icedata.dirs.photos     = app.photopath;
            app.icedata.dirs.results    = app.resultspath;
            
            %[app.photopath,app.resultspath]=app.icedata.checkDirectory;
            
            requestFolder(app,'startup')
            addlistener(app.FolderTree,'NodeTextChanged',@renameFolderFcn);
        end

        % Selection changed function: FolderTree
        function folderCallback(app, event)

            %% CHECK FILES IN FOLDER
            selectedNodes = app.FolderTree.SelectedNodes;
            filepath = selectedNodes.NodeData.Path;

            try
                files = imageDatastore(filepath,'FileExtensions',{'.tiff','.bmp'});
            catch ME
                if contains(ME.identifier,'empty')
                    warning('No images found in directory')
                end
                return
            end

            if numel(files.Files)==0
                return;
            end

            %% CONFIGURE IMAGESLIDER
            ticks           = 0:round(numel(files.Files)/5):numel(files.Files);
            ticks(1)        = 1;
            ticks(end+1)    = numel(files.Files);

            % Adjust image index slider
            app.ImageIndex.Enable       = 'on';
            app.ImageIndex.Visible      = 'on';
            app.ImageIndex.Limits       = [1 numel(files.Files)];
            app.ImageIndex.MajorTicks   = ticks;
            app.ImageIndex.MinorTicksMode  = 'auto';
            app.ImageIndex.MajorTickLabels = cellstr(num2str(ticks'))';

            if within(app.ImageIndex.Value,[1 numel(files.Files)],'exclusive')
                app.index = app.ImageIndex.Value;
            else
                app.index            = 1;
                app.ImageIndex.Value = 1;
            end

            %% Save files to IWDATA
            app.iwdata.times    = [];
            app.iwdata.im0      = [];
            app.iwdata.im0index = 1;
            app.iwdata.ss_dic.controls.im0index = 1;
            app.iwdata.ss_dic.controls.rotation = app.rotation.Value/90;
            app.iwdata.files = files;
            %% PARSE IMAGE
            app.parseImage

        end

        % Value changed function: ImageIndex
        function imageSliderCallback(app, event)
            app.ImageIndex.UserData = round(app.ImageIndex.Value,0);
            app.ImageIndex.Value    = app.ImageIndex.UserData;
            app.index               = app.ImageIndex.UserData;

            %% PARSE IMAGE
            app.parseImage
        end

        % Callback function: ResetROIMenu, bo, bs, fast, fcdrolling, md,
        % mq, 
        % ...and 7 other components
        function ss_callback(app, event)
            tag = split(event.Source.Tag,'-');
            command = tag{end};
            switch command
                case {'nc','mq','md','bo','bs','resize','fast','fcdrolling','latrolling'}
                    exec = sprintf('app.iwdata.ss_dic.controls.%s=event.Value;',command);
                    eval(exec)
                case 'im0index'
                    app.iwdata.ss_dic.controls.im0index = app.index;
                    app.iwdata.im0index = app.index;
                case 'roi'
                    app.iwdata.setROI('set');
                case 'roireset'
                    app.iwdata.setROI('reset');
                case 'view'
                    exec = split(event.Value,'-');
                    app.view = exec{1};
                case 'rotation'
                    exec = sprintf('app.iwdata.ss_dic.controls.%s=event.Value/90;',command);
                    eval(exec)
            end
            app.parseImage;
        end

        % Button pushed function: SaveButton
        function saveCallback(app, event)
            app.icedata.IW = app.iwdata;
            app.icedata.saveData;
        end

        % Button pushed function: ChangedirectoryButton, RefreshButton
        function refreshCallback(app, event)
            status = true;
            switch event.Source.Tag
                case 'reset'
                    status = CacheFcn(app,'change');
            end
            if status
                requestFolder(app,'startup')%'update')
            end
        end

        % Window scroll wheel function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount    = event.VerticalScrollAmount; %#ok<NASGU>
            verticalScrollCount     = event.VerticalScrollCount;

            hFound = pointer2object(app.InternalWaveDetectionUIFigure,{'index'});
            
            if isempty(hFound)
                return
            end
            
            
             if contains(class(hFound),{'Slider','Grid'})
                    if contains(class(hFound),'Grid')
                        for i=1:length(hFound.Children)
                            if contains(class(hFound.Children(i)),'Slider')&&hFound.Children(i).Visible
                                hFound = hFound.Children(i);
                                break
                            end
                        end
                    end
             end
            if contains(class(hFound),'Slider')
                step    = (hFound.Limits(2)-hFound.Limits(1))/100;
                if app.scrollmult
                    switch app.scrolltype
                        case 'shift'
                            step = step*5;
                        case 'control'
                            step = 1;
                    end
                end

                val     = hFound.Value - verticalScrollCount*step;

                if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                    hFound.Value = hFound.Value - verticalScrollCount*step;
                end
                imageSliderCallback(app,event)
            else
                return
            end
        end

        % Window key press function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowKeyPress(app, event)
            if contains(event.Key,{'shift','control'})
                app.scrollmult = true;
                app.scrolltype = event.Key;
            end            
        end

        % Window key release function: InternalWaveDetectionUIFigure
        function InternalWaveDetectionUIFigureWindowKeyRelease(app, event)
            app.scrollmult = false;
        end

        % Selection change function: TabGroup
        function solverCallback(app, event)
            app.method  = app.TabGroup.SelectedTab.Tag;
            defitems    = app.viewing.Items;
            switch app.method
                case 'fcd'
                    defitems{end} = 'H-reconstruction';
                case 'dic'
                    defitems{end} = 'Q-correlation';
                case 'lat'
                    defitems{end} = 'Mean-state';
            end
            app.viewing.Items = defitems;
            app.parseImage
        end

        % Callback function: DefinemaskButton, ResetMenu
        function fcd_maskCallback(app, event)
            switch event.Source.Tag
                case 'set'
                    app.iwdata.ss_mask_fcd;
                case 'reset'
                    app.iwdata.ss_fcd.mask = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.InternalWaveDetectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InternalWaveDetectionUIFigure = uifigure('Visible', 'off');
            app.InternalWaveDetectionUIFigure.Name = 'Internal Wave Detection';
            app.InternalWaveDetectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.InternalWaveDetectionUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowScrollWheel, true);
            app.InternalWaveDetectionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowKeyPress, true);
            app.InternalWaveDetectionUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @InternalWaveDetectionUIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.InternalWaveDetectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.FolderTree = uitree(app.GridLayout2);
            app.FolderTree.SelectionChangedFcn = createCallbackFcn(app, @folderCallback, true);
            app.FolderTree.Tag = 'folder';
            app.FolderTree.Editable = 'on';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.SaveButton.Tag = 'save';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Pushes the ''icedata'' superclass to a workspace file'};
            app.SaveButton.Text = 'Save';
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.RefreshButton.Tag = 'Refresh';
            app.RefreshButton.Text = 'Refresh';
            app.notification = uilabel(app.GridLayout2);
            app.notification.Text = '';
            app.ChangedirectoryButton = uibutton(app.GridLayout2, 'push');
            app.ChangedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.ChangedirectoryButton.Tag = 'reset';
            app.ChangedirectoryButton.Text = 'Change directory';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @solverCallback, true);
            app.FastCheckboardDemodulationTab = uitab(app.TabGroup);
            app.FastCheckboardDemodulationTab.Title = 'Fast Checkboard Demodulation';
            app.FastCheckboardDemodulationTab.Tag = 'fcd';
            app.GridLayout10 = uigridlayout(app.FastCheckboardDemodulationTab);
            app.fcdAxes = uiaxes(app.GridLayout10);
            app.fcdAxes.XTick = [];
            app.fcdAxes.YTick = [];
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.RollingaverageSpinnerLabel = uilabel(app.GridLayout12);
            app.RollingaverageSpinnerLabel.Text = 'Rolling average';
            app.fcdrolling = uispinner(app.GridLayout12);
            app.fcdrolling.Limits = [0 Inf];
            app.fcdrolling.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.fcdrolling.Tag = 'set-fcdrolling';
            app.DefinemaskButton = uibutton(app.GridLayout12, 'push');
            app.DefinemaskButton.ButtonPushedFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.DefinemaskButton.Tag = 'set';
            app.DefinemaskButton.Text = 'Define mask';
            app.LightAttenuationTab = uitab(app.TabGroup);
            app.LightAttenuationTab.Title = 'Light Attenuation';
            app.LightAttenuationTab.Tag = 'lat';
            app.GridLayout11 = uigridlayout(app.LightAttenuationTab);
            app.latAxes = uiaxes(app.GridLayout11);
            zlabel(app.latAxes, 'Z')
            app.latAxes.XTick = [];
            app.latAxes.YTick = [];
            app.DigitalImageCorrelationTab = uitab(app.TabGroup);
            app.DigitalImageCorrelationTab.Title = 'Digital Image Correlation';
            app.DigitalImageCorrelationTab.Tag = 'dic';
            app.GridLayout6 = uigridlayout(app.DigitalImageCorrelationTab);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.BlockspacingSpinnerLabel = uilabel(app.GridLayout8);
            app.BlockspacingSpinnerLabel.Text = 'Block spacing';
            app.bs = uispinner(app.GridLayout8);
            app.bs.Limits = [1 20];
            app.bs.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.bs.Tag = 'set-bs';
            app.bs.Tooltip = {'Resolution'};
            app.bs.Value = 4;
            app.BlockoverlapSpinnerLabel = uilabel(app.GridLayout8);
            app.BlockoverlapSpinnerLabel.Text = 'Block overlap';
            app.bo = uispinner(app.GridLayout8);
            app.bo.Limits = [1 20];
            app.bo.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.bo.Tag = 'set-bo';
            app.bo.Tooltip = {'Blocksize = blockspacing + 2*borderoverlap'};
            app.bo.Value = 4;
            app.SearchwindowscaleSpinnerLabel = uilabel(app.GridLayout8);
            app.SearchwindowscaleSpinnerLabel.Text = {'Search window'; 'scale'};
            app.md = uispinner(app.GridLayout8);
            app.md.Limits = [1 20];
            app.md.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.md.Tag = 'set-md';
            app.md.Tooltip = {'Sets size of search window = blocksize + 2*maxdisp'};
            app.md.Value = 4;
            app.MinimumsolutionqualitySpinnerLabel = uilabel(app.GridLayout8);
            app.MinimumsolutionqualitySpinnerLabel.Text = {'Minimum solution'; 'quality'};
            app.mq = uispinner(app.GridLayout8);
            app.mq.Limits = [0 20];
            app.mq.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.mq.Tag = 'set-mq';
            app.mq.Tooltip = {'Minimum quality of the correlation to be a valid vector, NaN otherwise'};
            app.nc = uibutton(app.GridLayout8, 'state');
            app.nc.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.nc.Tag = 'set-nc';
            app.nc.Tooltip = {'Use normalized cross correlation? relatively slow but robust'};
            app.nc.Text = 'Normalized correlation';
            app.nc.Value = true;
            app.fast = uibutton(app.GridLayout8, 'state');
            app.fast.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.fast.Tag = 'set_fast';
            app.fast.Tooltip = {'Use cubic+symmetric (slow) or linear+none (fast) in solver'};
            app.fast.Text = 'Fast solver';
            app.fast.Value = true;
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.ViewingmodeDropDownLabel = uilabel(app.GridLayout9);
            app.ViewingmodeDropDownLabel.Text = 'Viewing mode';
            app.viewing = uidropdown(app.GridLayout9);
            app.viewing.Items = {'Image', 'Difference', 'u-velocity', 'v-velocity', 'U-magnitude', 'zeta-vorticity', 'Q-correlation'};
            app.viewing.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.viewing.Tag = 'set-view';
            app.viewing.Value = 'Image';
            app.OverlayDropDownLabel = uilabel(app.GridLayout9);
            app.OverlayDropDownLabel.Text = 'Overlay';
            app.overlay = uidropdown(app.GridLayout9);
            app.overlay.Items = {'None', 'Velocity vectors', 'Streamlines'};
            app.overlay.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.overlay.Tag = 'set-overlay';
            app.overlay.Value = 'None';
            app.set_im0 = uibutton(app.GridLayout9, 'push');
            app.set_im0.ButtonPushedFcn = createCallbackFcn(app, @ss_callback, true);
            app.set_im0.Tag = 'set-im0index';
            app.set_im0.Text = {'Set reference'; 'image'};
            app.set_roi = uibutton(app.GridLayout9, 'push');
            app.set_roi.ButtonPushedFcn = createCallbackFcn(app, @ss_callback, true);
            app.set_roi.Tag = 'set-roi';
            app.set_roi.Text = 'Set ROI';
            app.resize = uieditfield(app.GridLayout9, 'numeric');
            app.resize.Limits = [1 100];
            app.resize.RoundFractionalValues = 'on';
            app.resize.ValueDisplayFormat = '%.0f';
            app.resize.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.resize.Tag = 'set-resize';
            app.resize.Value = 50;
            app.ResizeEditFieldLabel = uilabel(app.GridLayout9);
            app.ResizeEditFieldLabel.Text = 'Resize (%)';
            app.RotationSpinnerLabel = uilabel(app.GridLayout9);
            app.RotationSpinnerLabel.Text = 'Rotation';
            app.rotation = uispinner(app.GridLayout9);
            app.rotation.Step = 90;
            app.rotation.Limits = [-270 270];
            app.rotation.ValueChangedFcn = createCallbackFcn(app, @ss_callback, true);
            app.rotation.Tag = 'set-rotation';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.GridLayout7.Tag = 'index';
            app.ImageSliderLabel = uilabel(app.GridLayout7);
            app.ImageSliderLabel.Text = 'Image';
            app.ImageIndex = uislider(app.GridLayout7);
            app.ImageIndex.ValueChangedFcn = createCallbackFcn(app, @imageSliderCallback, true);
            app.ImageIndex.Tag = 'index';
            app.ContextMenu = uicontextmenu(app.InternalWaveDetectionUIFigure);
            app.ResetROIMenu = uimenu(app.ContextMenu);
            app.ResetROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ss_callback, true);
            app.ResetROIMenu.Text = 'Reset ROI';
            app.ResetROIMenu.Tag = 'set-roireset';
            % Assign app.ContextMenu
            app.set_roi.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.InternalWaveDetectionUIFigure);
            app.ResetMenu = uimenu(app.ContextMenu2);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @fcd_maskCallback, true);
            app.ResetMenu.Text = 'Reset';
            app.ResetMenu.Tag = 'reset';
            % Assign app.ContextMenu2
            app.DefinemaskButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.InternalWaveDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_waveDetection_0

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.InternalWaveDetectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.InternalWaveDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InternalWaveDetectionUIFigure)
        end
    end
end