classdef app_waveDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InternalWaveDetectionUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RefreshButton                  matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        FolderTree                     matlab.ui.container.Tree
        RightPanel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        icedata 
        iwdata
    end
    
    properties (Access = private)
        photopath
        resultspath
    end
    
    methods (Access = public)
        
         function requestFolder(app,mode)
            %% BUILDS OR UPDATES FOLDER TREE
            %% Note: assumes the root directory is APP.PHOTOPATH
            switch mode
                case 'startup'
                    %% TREE CONSTRUCTOR
                    app.folders = Folder(app.photopath);
                    [app.foldercount,app.folderlist] = getfoldercount;
                    f = Folder(app.photopath);
                    f.maketree('handle',app.FolderTree);
                otherwise
                    %% TREE UPDATE
                    [newfoldercount,list] = getfoldercount;
                    if app.foldercount==newfoldercount
                        return
                    end
                    newfolders  = setxor(list,app.folderlist);
                    app.folders = Folder(app.photopath);
                    f = Folder(app.photopath);
                    f.maketree('handle',app.FolderTree,...
                        'update',newfolders);
            end
            app.FolderTree.UserData = f;

            function varargout = getfoldercount
                Path  = app.photopath;
                list  = split(genpath(Path),';');
                list  = list(1:end-1);
                varargout{1} = numel(list);
                varargout{2} = list;
            end
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% CONSTRUCTOR
            app.icedata = icedata; %#ok<*ADPROP>
            [app.photopath,app.resultspath]=app.icedata.checkDirectory;
            requestFolder(app,'startup')
            addlistener(app.FolderTree,'NodeTextChanged',@renameFolderFcn);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.InternalWaveDetectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InternalWaveDetectionUIFigure = uifigure('Visible', 'off');
            app.InternalWaveDetectionUIFigure.Name = 'Internal Wave Detection';
            app.InternalWaveDetectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.InternalWaveDetectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.FolderTree = uitree(app.GridLayout2);
            app.FolderTree.Tag = 'folder';
            app.FolderTree.Editable = 'on';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.Tag = 'save';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Pushes the ''icedata'' superclass to a workspace file'};
            app.SaveButton.Text = 'Save';
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.Tag = 'Refresh';
            app.RefreshButton.Text = 'Refresh';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            % Show the figure after all components are created
            app.InternalWaveDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_waveDetection

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.InternalWaveDetectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.InternalWaveDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InternalWaveDetectionUIFigure)
        end
    end
end