classdef uiDesign < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        ParameterspacePanel       matlab.ui.container.Panel
        GridLayout2_3             matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        acceptParams              matlab.ui.control.Button
        InputsPanel               matlab.ui.container.Panel
        GridLayout2_2             matlab.ui.container.GridLayout
        acceptInputs              matlab.ui.control.Button
        timeLabel                 matlab.ui.control.Label
        bucketLevelLabel          matlab.ui.control.Label
        IceSlopeEditField         matlab.ui.control.NumericEditField
        FlowRateEditField         matlab.ui.control.NumericEditField
        SaDyeEditField            matlab.ui.control.NumericEditField
        SaMassEditField           matlab.ui.control.NumericEditField
        bucketLevelEditField      matlab.ui.control.NumericEditField
        SLabel_5                  matlab.ui.control.Label
        SLabel_2                  matlab.ui.control.Label
        TLabel_2                  matlab.ui.control.Label
        TLabel_3                  matlab.ui.control.Label
        TLabel_4                  matlab.ui.control.Label
        StratificationsetupPanel  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        acceptStratification      matlab.ui.control.Button
        NEditField                matlab.ui.control.NumericEditField
        rhoaEditField             matlab.ui.control.NumericEditField
        rho0EditField             matlab.ui.control.NumericEditField
        SaditField                matlab.ui.control.NumericEditField
        S0EditField               matlab.ui.control.NumericEditField
        TaEditField               matlab.ui.control.NumericEditField
        NradsLabel                matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        SLabel_3                  matlab.ui.control.Label
        SLabel                    matlab.ui.control.Label
        TLabel                    matlab.ui.control.Label
    end

    properties (Access = public)
        H = 1.1; % Tank depth
        InputsStructure struct
        ParameterSpaceData table
        StratificationStructure struct
        V = 0.33; % Volume of tank (m³)
        bucketH = .85; % Height of salty water reservoir bucket (known volume)
    end
    
    
    methods 
        function set.StratificationStructure(comp,val)
            if ~isstruct(val)
                error('Input must be a structure with arguments for "Ta, S0, Sa, rho0, rhoa, N"')
            end
            comp.StratificationStructure = val;
            updateStratificationValues(comp)
        end
        function set.InputsStructure(comp,val)
            if ~isstruct(val)
                error('Input must be a structure with arguments for "level, SaMass, SaDye, FlowRate, IceAngle"')
            end
            comp.InputsStructure = val;
            updateInputsValues(comp)
        end
        function set.ParameterSpaceData(comp,val)
            if ~istable(val)
                error('Input must be a nx3 table of numeric data')
            end
            comp.ParameterSpaceData = val;
            updateTable(comp)
        end
    end
    methods (Access=private)
        function updateStratificationValues(comp)
            s = comp.StratificationStructure;
            comp.TaEditField.Value  = s.Ta;
            comp.S0EditField.Value  = s.S0;
            comp.SaditField.Value   = s.Sa;
            comp.rho0EditField.Value= s.rho0;
            comp.rhoaEditField.Value= s.rhoa;
            comp.NEditField.Value   = s.N;
        end
        function updateInputsValues(comp)
            s = comp.InputsStructure;
            comp.bucketLevelEditField.Value = s.level;
            comp.SaMassEditField.Value      = s.SaMass;
            comp.SaDyeEditField.Value       = s.SaMass;
            comp.FlowRateEditField.Value    = s.FlowRate;
            comp.IceSlopeEditField.Value    = s.IceAngle;
        end
        function updateTable(comp)
            comp.UITable.Data = table2array(comp.ParameterSpaceData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
%             set([comp.bucketLevelLabel comp.timeLabel],'Text','')
%             comp.UITable.Data = nan(1,3);
        end

        % Value changed function: NEditField, S0EditField, SaditField, 
        % ...and 3 other components
        function densityCalculation(comp, event)
            
            calcN   = @(rho0,rhoa) sqrt(9.81/rho0*(rhoa-rho0)/comp.H);
            Taef    = comp.TaEditField;
            S0ef    = comp.S0EditField;
            Saef    = comp.SaditField;
            rho0ef  = comp.rho0EditField;
            rhoaef  = comp.rhoaEditField;
            Nef     = comp.NEditField;
            
            switch event.Source     % affects
                case Taef           % rho0, rhoa, N
                    rho0ef.Value = getRho(S0ef.Value,Taef.Value);
                    rhoaef.Value = getRho(Saef.Value,Taef.Value);
                case S0ef           % rho0, N
                    rho0ef.Value = getRho(S0ef.Value,Taef.Value);
                case Saef           % rhoa, N
                    rhoaef.Value = getRho(Saef.Value,Taef.Value);
                case rho0ef         % S0, N

                case rhoaef         % Sa, N
                case Nef            % Sa, rhoa
                    DelRho = Nef.Value^2*comp.H*getRho(S0ef.Value,Taef.Value)/9.81;
                    rhoaef.Value = rho0ef.Value+DelRho;
                    s = linspace(0,50,1e3);
                    rhovalid=getRho(s,Taef.Value);
                    Saef.Value = round(s(find(rhovalid>rhoaef.Value,1)),2);
                    return
            end
            Nef.Value = round(real(calcN(getRho(S0ef.Value,Taef.Value),getRho(Saef.Value,Taef.Value))),2);     
            
            function rho = getRho(S,T)
                b0 =  8.24493e-1;
                b1 = -4.0899e-3;
                b2 =  7.6438e-5;
                b3 = -8.2467e-7;
                b4 =  5.3875e-9;
                c0 = -5.72466e-3;
                c1 = +1.0227e-4;
                c2 = -1.6546e-6;
                d0 = 4.8314e-4;
                rho = getSmow(T) + (b0 + (b1 + (b2 + (b3 + b4*T).*T).*T).*T).*S  ...
                    + (c0 + (c1 + c2*T).*T).*S.*sqrt(S) + d0*S.^2;
                rho = round(rho,2);
            end
            function rho = getSmow(T)
                a0 = 999.842594;
                a1 =   6.793952e-2;
                a2 =  -9.095290e-3;
                a3 =   1.001685e-4;
                a4 =  -1.120083e-6;
                a5 =   6.536332e-9;
                rho = a0 + (a1 + (a2 + (a3 + (a4 + a5*T).*T).*T).*T).*T;
            end
        end

        % Value changed function: FlowRateEditField, IceSlopeEditField, 
        % ...and 3 other components
        function inputsCallback(comp, event)
            switch event.Source
                case comp.bucketLevelEditField
                    comp.bucketLevelLabel.Text = sprintf('Fraction\n%.1f%s',comp.bucketLevelEditField.Value/comp.bucketH*1e2,'%');
                case comp.SaMassEditField
                case comp.SaDyeEditField
                case comp.FlowRateEditField

                    comp.timeLabel.Text = sprintf('Fill time\n%s',string(hours(comp.V*1e3/60/comp.FlowRateEditField.Value),'hh:mm:ss'));
                case comp.IceSlopeEditField
            end
        end

        % Button pushed function: acceptStratification
        function acceptCallback(comp, event)
            switch event.Source
                case comp.acceptStratification
                    comp.StratificationStructure.Ta     = comp.TaEditField.Value;
                    comp.StratificationStructure.S0     = comp.S0EditField.Value;
                    comp.StratificationStructure.Sa     = comp.SaEditField.Value;
                    comp.StratificationStructure.rho0   = comp.rho0EditField.Value;
                    comp.StratificationStructure.rhoa   = comp.rhoaEditField.Value;
                    comp.StratificationStructure.N      = comp.NEditField.Value;
                case comp.acceptInputs
                    comp.InputsStructure.level          = comp.bucketLevelEditField.Value;
                    comp.InputsStructure.SaMass         = comp.SaMassEditField.Value;
                    comp.InputsStructure.SaDye          = comp.SaDyeEditField.Value;
                    comp.InputsStructure.FlowRate       = comp.FlowRateEditField.Value;
                    comp.InputsStructure.IceAngle       = comp.IceSlopeEditField.Value;
                case comp.acceptParams
                    comp.ParameterSpaceData             = table( ...
                        comp.UITable.Data(:,1), ...
                        comp.UITable.Data(:,2), ...
                        comp.UITable.Data(:,3), ...
                        "VarirableNames",{'omega_radPs','A_mmPs','t_hrs'});
            end
        end

        % Double-clicked callback: UITable
        function doubleCallback(comp, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            if all([isempty(displayRow) isempty(displayColumn)])
                comp.UITable.Data = cat(1,comp.UITable.Data,nan(1,3));
            end
        end

        % Key press function: UITable
        function tableKeyPress(comp, event)
            key = event.Key;
            switch key
                case 'delete'
                    valid = comp.UITable.Selection;
                    if isempty(valid)
                        return
                    end
                    comp.UITable.Data(unique(valid(:,1)),:)=[];
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 555 507];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.StratificationsetupPanel = uipanel(comp.GridLayout);
            comp.StratificationsetupPanel.Title = 'Stratification setup';
            comp.GridLayout2 = uigridlayout(comp.StratificationsetupPanel);
            comp.TLabel = uilabel(comp.GridLayout2);
            comp.TLabel.Text = {'Tₐ '; '(°C)'};
            comp.SLabel = uilabel(comp.GridLayout2);
            comp.SLabel.Text = {'S₀ '; '(‰)'};
            comp.SLabel_3 = uilabel(comp.GridLayout2);
            comp.SLabel_3.Text = {'Sₐ '; '(‰)'};
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.Text = {'ρ₀ '; '(kg m⁻³)'};
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.Text = {'ρₐ '; '(kg m⁻³)'};
            comp.NradsLabel = uilabel(comp.GridLayout2);
            comp.NradsLabel.Text = {'N '; '(rad s⁻¹)'};
            comp.TaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.TaEditField.Limits = [0 Inf];
            comp.TaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.S0EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.S0EditField.Limits = [0 Inf];
            comp.S0EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.SaditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.SaditField.Limits = [0 Inf];
            comp.SaditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rho0EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.rho0EditField.Limits = [960 Inf];
            comp.rho0EditField.ValueDisplayFormat = '%.1f';
            comp.rho0EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rho0EditField.Editable = 'off';
            comp.rho0EditField.Value = 999.8426;
            comp.rhoaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.rhoaEditField.Limits = [960 Inf];
            comp.rhoaEditField.ValueDisplayFormat = '%.1f';
            comp.rhoaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rhoaEditField.Editable = 'off';
            comp.rhoaEditField.Value = 999.8426;
            comp.NEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.NEditField.Limits = [0 Inf];
            comp.NEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.acceptStratification = uibutton(comp.GridLayout2, 'push');
            comp.acceptStratification.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @acceptCallback, true);
            comp.acceptStratification.Text = 'Accept';
            comp.InputsPanel = uipanel(comp.GridLayout);
            comp.InputsPanel.Title = 'Inputs';
            comp.GridLayout2_2 = uigridlayout(comp.InputsPanel);
            comp.TLabel_4 = uilabel(comp.GridLayout2_2);
            comp.TLabel_4.Text = {'Bucket level '; '(m)'};
            comp.TLabel_3 = uilabel(comp.GridLayout2_2);
            comp.TLabel_3.Text = {'Sₐ mass'; '(kg)'};
            comp.TLabel_2 = uilabel(comp.GridLayout2_2);
            comp.TLabel_2.Text = {'Sₐ dye '; '(mL)'};
            comp.SLabel_2 = uilabel(comp.GridLayout2_2);
            comp.SLabel_2.Text = {'Flow rate '; '(L min⁻¹)'};
            comp.SLabel_5 = uilabel(comp.GridLayout2_2);
            comp.SLabel_5.Text = {'Ice angle '; '(° vert.)'};
            comp.bucketLevelEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.bucketLevelEditField.Limits = [0 0.9];
            comp.bucketLevelEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.bucketLevelEditField.Value = 0.85;
            comp.SaMassEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.SaMassEditField.Limits = [0 Inf];
            comp.SaMassEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.SaDyeEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.SaDyeEditField.Limits = [0 Inf];
            comp.SaDyeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.FlowRateEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.FlowRateEditField.Limits = [0 Inf];
            comp.FlowRateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.IceSlopeEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.IceSlopeEditField.Limits = [0 Inf];
            comp.IceSlopeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.bucketLevelLabel = uilabel(comp.GridLayout2_2);
            comp.bucketLevelLabel.Text = '100%';
            comp.timeLabel = uilabel(comp.GridLayout2_2);
            comp.timeLabel.Text = 'hh:mm:ss';
            comp.acceptInputs = uibutton(comp.GridLayout2_2, 'push');
            comp.acceptInputs.Text = 'Accept';
            comp.ParameterspacePanel = uipanel(comp.GridLayout);
            comp.ParameterspacePanel.Title = 'Parameter space';
            comp.GridLayout2_3 = uigridlayout(comp.ParameterspacePanel);
            comp.acceptParams = uibutton(comp.GridLayout2_3, 'push');
            comp.acceptParams.Text = 'Accept';
            comp.UITable = uitable(comp.GridLayout2_3);
            comp.UITable.ColumnName = {'ω (rad s⁻¹)'; 'A (mm s⁻¹)'; 't (hours)'};
            comp.UITable.RowName = {};
            comp.UITable.ColumnEditable = [true true true];
            comp.UITable.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @doubleCallback, true);
            comp.UITable.KeyPressFcn = matlab.apps.createCallbackFcn(comp, @tableKeyPress, true);
            % Execute the startup function
            startupFcn(comp)
        end
    end
end