classdef uiDrawMask < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        GridLayout2     matlab.ui.container.GridLayout
        SaveButton      matlab.ui.control.Button
        DrawmaskButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        MaskCreated
    end

    properties (Access = private)
        ic (1,1) logical = false; % Internal command
    end
    
    properties (Access = protected)
        Typr char = 'uiDrawMask';
    end
    
    properties (Access = public)
        imds % ImageDataStore
        index (1,1) double = 1;
        mask logical = false;
        mask_data struct
    end
    
    %% SET functions
    methods
        function set.index(comp,val)
            comp.index = val;
            comp.updateAxes
        end
        function set.mask_data(comp,val)
            comp.mask_data = comp.setmaskdata(val);
        end
        function set.imds(comp,val)
            comp.imds = val;
            comp.setimds
            comp.updateAxes
        end
    end
    %% SET-callbacks
    methods (Access = private)
        function setimds(comp)
            p   = findall(comp.UIAxes,'type','images');
            im  = findall(comp.UIAxes,'type','image');
            delete(p)
            delete(im)
            comp.index = 1;
        end
        function val = setmaskdata(comp,val)
            if isempty(val)
                comp.mask = false;
            end
            %%
            if comp.ic 
                return
            end

            p = @(pos) drawpolygon(comp.UIAxes,'FaceAlpha',.1,'LineWidth',1,'Color','r','Position',pos);
            try %#ok<TRYNC> 
                arrayfun(@(s_p) p(s_p.Position),val);
            end

        end
    end
    %% Internal functions
    methods (Access = private)
        function updateAxes(comp)
            imdata  = findall(comp.UIAxes,'type','image');
            im      = readimage(comp.imds,comp.index);
            if isempty(imdata)
                imagesc(comp.UIAxes,im)
            else
                set(imdata,CData = im,XData = [1 size(im,2)],YData = [1 size(im,1)])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Masking')
            colormap(comp.UIAxes,"gray")
            axis(comp.UIAxes,'tight')
        end

        % Button pushed function: DrawmaskButton
        function drawMaskCallback(comp, event)
            drawpolygon(comp.UIAxes,'FaceAlpha',.1,'LineWidth',1,'Color','r');
        end

        % Button pushed function: SaveButton
        function saveCallback(comp, event)
            p   = findall(comp.UIAxes,'type','images');
            im  = findall(comp.UIAxes,'type','image');
            if isempty(p)
                comp.mask = false;
                comp.mask_data = [];
                notify(comp, 'MaskCreated');
                return
            end
            try
                comp.ic = true;
                s_mask = cell2struct({p.Position},'Position');
                comp.mask_data  = s_mask;
                m = arrayfun(@(p) createMask(p,im.CData),p,'UniformOutput',false);
                comp.mask       = logical(sum(cat(3,m{:}),3));
                notify(comp, 'MaskCreated');
            catch
                comp.ic = false;
                return
            end
            comp.ic = false;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 544 472];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.DrawmaskButton = uibutton(comp.GridLayout2, 'push');
            comp.DrawmaskButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @drawMaskCallback, true);
            comp.DrawmaskButton.Text = 'Draw mask';
            comp.SaveButton = uibutton(comp.GridLayout2, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @saveCallback, true);
            comp.SaveButton.Text = 'Save';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end