classdef uiEdgeDetectionControls < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        IndexLabel                    matlab.ui.control.Label
        rot90L_2                      matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        PrevButton                    matlab.ui.control.Button
        IndexSlider                   matlab.ui.control.Slider
        AutoprocessButton             matlab.ui.control.StateButton
        ProcessButton                 matlab.ui.control.Button
        resetROI                      matlab.ui.control.Button
        setROI                        matlab.ui.control.Button
        rot90R                        matlab.ui.control.Button
        rot90L                        matlab.ui.control.Button
        GridLayout5                   matlab.ui.container.GridLayout
        OverlayButton                 matlab.ui.control.StateButton
        ConnectivityButton            matlab.ui.control.StateButton
        DilationButton                matlab.ui.control.StateButton
        EdgeButton                    matlab.ui.control.StateButton
        ImageButton                   matlab.ui.control.StateButton
        AllButton                     matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ImageTab                      matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        climMax                       matlab.ui.control.NumericEditField
        climMin                       matlab.ui.control.NumericEditField
        ClimitsLabel                  matlab.ui.control.Label
        imdiff                        matlab.ui.control.Spinner
        ImagedifferencestepSpinnerLabel  matlab.ui.control.Label
        blury                         uiSliderValuedLimits
        blurx                         uiSliderValuedLimits
        vignette                      uiSliderValuedLimits
        EdgeTab                       matlab.ui.container.Tab
        GridLayout2_2                 matlab.ui.container.GridLayout
        gradient                      uiSliderValuedLimits
        sigma                         uiSliderValuedLimits
        threshold                     uiSliderValuedLimits
        algorithm                     matlab.ui.control.ListBox
        AlgorithmListBoxLabel         matlab.ui.control.Label
        DilationTab                   matlab.ui.container.Tab
        GridLayout2_3                 matlab.ui.container.GridLayout
        reject                        uiSliderValuedLimits
        medfilt                       uiSliderValuedLimits
        arg2                          uiSliderValuedLimits
        arg1                          uiSliderValuedLimits
        shape                         matlab.ui.control.ListBox
        ShapeLabel                    matlab.ui.control.Label
        ConnectivityTab               matlab.ui.container.Tab
        GridLayout2_4                 matlab.ui.container.GridLayout
        ref                           matlab.ui.control.NumericEditField
        ReferenceimageEditFieldLabel  matlab.ui.control.Label
        smooth                        uiSliderValuedLimits
        direction                     matlab.ui.control.ListBox
        DirectionLabel                matlab.ui.control.Label
        connect                       uiSliderValuedLimits
        DatastoreTab                  matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        uiImageDataStoreSettings      uiImageDataStoreSettings
    end

    properties (Access = public)
        Axes % Handle for axes
        edi edIce % Edge Detection Controls superclass
    end
    
    %% SET functions
    methods 
        function set.edi(comp,val)
            comp.edi = val;
            setEDI(comp)
        end
    end
    %% SET-callbacks
    methods (Access = private)
        function setEDI(comp)
            setComponentValues(comp)
            setDataStore(comp)
            getSequenceStateValues(comp)
            comp.IndexSlider.Limits = [1 comp.edi.imds.maxIndex];
        end
    end

    %% Internal functions
    methods
        function processEDI(comp,resetAxes)
            if isempty(comp.edi)
                return
            end
            if nargin<2
                resetAxes = false;
            end
            ax = comp.Axes;
            if isempty(ax)
                ax = gca;
            end
            comp.edi.parse_sequence(comp.edi.index,comp.OverlayButton.Value,ax)
            if resetAxes
                axis(ax,'tight')
            end
        end
    end
    methods (Access = private)
        function setDataStore(comp)
            comp.uiImageDataStoreSettings.imds = comp.edi.imds;
        end
        function getSequenceStateValues(comp)
            s = comp.edi.sequence;
            comp.ImageButton.Value          = s.image;
            comp.EdgeButton.Value           = s.edge;
            comp.DilationButton.Value       = s.dilation;
            comp.ConnectivityButton.Value   = s.conn;
        end
        function setSequenceStateValues(comp)
            s           = comp.edi.sequence;
            s.image     = comp.ImageButton.Value;
            s.edge      = comp.EdgeButton.Value;
            s.dilation  = comp.DilationButton.Value;
            s.conn      = comp.ConnectivityButton.Value;
            comp.edi.sequence = s;
            comp.processEDI
        end
        
        function setEDIValues(comp,component) %#ok<INUSD> 
            fieldval  = sprintf('comp.edi.s_%s',component.Tag);
            value = component.Value;
            if isa(value,'double')
                eval(sprintf('%s=%g;',fieldval,value))
            else
                eval(sprintf('%s="%s";',fieldval,value))
            end
        end
        function setComponentValues(comp)
            % image
            setCVs('image',fieldnames(comp.edi.s_image))
            % special case "image.clim"
            set([comp.climMin comp.climMax],'Limits',comp.edi.get_default_ranges('image.clim'))
            clim = comp.edi.s_image.clim;
            if ~isempty(clim)
                try
                    comp.climMin.Value = min(clim);
                    comp.climMax.Value = max(clim);
                end
            else
                comp.climMin.Value = min(comp.climMin.Limits);
                comp.climMax.Value = max(comp.climMin.Limits);
            end

            % edge
            setCVs('edge',fieldnames(comp.edi.s_edge))
            % dilation
            setCVs('dilation',fieldnames(comp.edi.s_dilation))
            % connectivity
            setCVs('conn',fieldnames(comp.edi.s_conn))

            function setCVs(lbl,fn)
                cellfun(@(fn) CVloop(lbl,fn),fn)
                function CVloop(lbl,fn)
                    try %#ok<*TRYNC>
                        component = eval(sprintf('comp.%s;',fn)); %#ok<*NASGU> 
                        fieldval  = eval(sprintf('comp.edi.s_%s.%s;',lbl,fn));
                        fieldlim  = eval(sprintf('comp.edi.get_default_ranges("%s.%s");',lbl,fn));
                        if isa(fieldlim,'double')
                            if ~within(fieldval,fieldlim)
                                fieldlim(end) = fieldval;
                            end
                            set(component,'Limits',fieldlim,'Value',fieldval,'Tag',sprintf('%s.%s',lbl,fn))
                        else
                            set(component,'Items',fieldlim,'Value',fieldval,'Tag',sprintf('%s.%s',lbl,fn))
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Edge Detection Controls')
            try
                edib = evalin('base','edi;');
                comp.edi = edib;
            end
            if ~isempty(comp.edi)
                setComponentValues(comp)
            end
        end

        % Callback function: algorithm, arg1, arg2, blurx, blury, connect, 
        % ...and 11 other components
        function updateEDI(comp, event)
            setEDIValues(comp,event.Source)

            % special cases
            switch event.Source
                case comp.imdiff
                    lims = comp.edi.get_default_ranges('image.clim');
                    if comp.imdiff.Value
                        im = comp.edi.filter_image(comp.edi.loadimage(comp.edi.index));
                        lims = [min(im,[],'all','omitnan') max(im,[],'all','omitnan')];
                        pow  = 10.^ceil(log10(abs(lims)));
                        lims = ceil(abs(lims)./pow).*pow.*sign(lims);
                        lims(isnan(lims))=0;
                    else
                        comp.edi.s_image.clim = lims;
                    end
                    set([comp.climMin comp.climMax],'Limits',lims)
                    try
                        comp.climMin.Value = min(comp.edi.s_image.clim);
                        comp.climMax.Value = max(comp.edi.s_image.clim);

                        setEDIValues(comp,event.Source)
                    end
            end

            update(comp)
        end

        % Value changed function: AutoprocessButton
        function toggleAutoProcess(comp, event)
            value = comp.AutoprocessButton.Value;
            if value
                comp.processEDI
            end
        end

        % Button pushed function: ProcessButton
        function ProcessCallback(comp, event)
            comp.processEDI
        end

        % Callback function: IndexSlider, NextButton, PrevButton
        function indexCallback(comp, event)
            value = comp.IndexSlider.Value;
            switch event.Source
                case comp.NextButton
                    try
                        comp.IndexSlider.Value = comp.IndexSlider.Value+1;
                    end
                case comp.PrevButton
                    try
                        comp.IndexSlider.Value = comp.IndexSlider.Value-1;
                    end
            end
            comp.edi.index = round(comp.IndexSlider.Value);
            comp.IndexLabel.Text = sprintf('Index\n(%i)\n%s', ...
                comp.edi.index, ...
                string(comp.edi.time(comp.edi.index)));
            comp.processEDI
        end

        % Value changing function: IndexSlider
        function indexSliderChanging(comp, event)
            changingValue = round(event.Value);
            comp.IndexLabel.Text = sprintf('Index\n(%i)\n%s', ...
                changingValue,...
                string(comp.edi.time(changingValue)));
        end

        % Value changed function: ConnectivityButton, DilationButton, 
        % ...and 2 other components
        function toggleState(comp, event)
            setSequenceStateValues(comp)
        end

        % Button pushed function: AllButton
        function toggleAllState(comp, event)
            components = [
                comp.ImageButton
                comp.EdgeButton
                comp.DilationButton
                comp.ConnectivityButton];
            
            set(components,Value=true)
            setSequenceStateValues(comp)
        end

        % Value changed function: OverlayButton
        function toggleOverlay(comp, event)
            comp.processEDI
        end

        % Value changed function: climMax, climMin
        function updateEDISpecial(comp, event)
            val = [comp.climMin.Value comp.climMax.Value];
            comp.edi.s_image.clim = val;
            comp.processEDI
        end

        % Button pushed function: resetROI, rot90L, rot90R, setROI
        function imageTransformsCallback(comp, event)
            warning off
            rax = false;
            switch event.Source
                case comp.rot90L
                    comp.edi.rot = mod(comp.edi.rot-1,4);
                case comp.rot90R
                    comp.edi.rot = mod(comp.edi.rot+1,4);
                case comp.setROI
                    comp.edi.roi = 'get';
                    rax = true;
                case comp.resetROI
                    comp.edi.roi = 'reset';
                    rax = true;
            end
            comp.processEDI(rax)
        end

        % Button pushed function: rot90L_2
        function maskCallback(comp, event)
            comp.edi.mask = 'get';
        end

        % Callback function: uiImageDataStoreSettings
        function uiImageDataStoreSettingsApplyFilter(comp, event)
            edi0 = comp.edi;
            edi0.imds = comp.uiImageDataStoreSettings.imds;
            comp.edi = edi0;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            assignin('base','tmp',comp.edi)

            if comp.AutoprocessButton.Value
                comp.processEDI
            end
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 532 579];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.TabGroup = uitabgroup(comp.GridLayout);
            comp.ImageTab = uitab(comp.TabGroup);
            comp.ImageTab.Title = 'Image';
            comp.GridLayout2 = uigridlayout(comp.ImageTab);
            comp.vignette = uiSliderValuedLimits(comp.GridLayout2);
            comp.vignette.Text = 'Vignette';
            comp.vignette.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.blurx = uiSliderValuedLimits(comp.GridLayout2);
            comp.blurx.RoundFractionalValues = true;
            comp.blurx.Text = 'Blur (x)';
            comp.blurx.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.blury = uiSliderValuedLimits(comp.GridLayout2);
            comp.blury.RoundFractionalValues = true;
            comp.blury.Text = 'Blur (y)';
            comp.blury.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.ImagedifferencestepSpinnerLabel = uilabel(comp.GridLayout2);
            comp.ImagedifferencestepSpinnerLabel.Text = 'Image difference step';
            comp.imdiff = uispinner(comp.GridLayout2);
            comp.imdiff.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.ClimitsLabel = uilabel(comp.GridLayout2);
            comp.ClimitsLabel.Text = 'C-limits';
            comp.climMin = uieditfield(comp.GridLayout2, 'numeric');
            comp.climMin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDISpecial, true);
            comp.climMax = uieditfield(comp.GridLayout2, 'numeric');
            comp.climMax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDISpecial, true);
            comp.EdgeTab = uitab(comp.TabGroup);
            comp.EdgeTab.Title = 'Edge';
            comp.GridLayout2_2 = uigridlayout(comp.EdgeTab);
            comp.AlgorithmListBoxLabel = uilabel(comp.GridLayout2_2);
            comp.AlgorithmListBoxLabel.Text = 'Algorithm';
            comp.algorithm = uilistbox(comp.GridLayout2_2);
            comp.algorithm.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.threshold = uiSliderValuedLimits(comp.GridLayout2_2);
            comp.threshold.Text = 'Threshold';
            comp.threshold.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.sigma = uiSliderValuedLimits(comp.GridLayout2_2);
            comp.sigma.Text = 'Sigma';
            comp.sigma.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.gradient = uiSliderValuedLimits(comp.GridLayout2_2);
            comp.gradient.Text = 'Gradient';
            comp.gradient.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.DilationTab = uitab(comp.TabGroup);
            comp.DilationTab.Title = 'Dilation';
            comp.GridLayout2_3 = uigridlayout(comp.DilationTab);
            comp.ShapeLabel = uilabel(comp.GridLayout2_3);
            comp.ShapeLabel.Text = 'Shape';
            comp.shape = uilistbox(comp.GridLayout2_3);
            comp.shape.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.arg1 = uiSliderValuedLimits(comp.GridLayout2_3);
            comp.arg1.RoundFractionalValues = true;
            comp.arg1.Text = 'arg1';
            comp.arg1.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.arg2 = uiSliderValuedLimits(comp.GridLayout2_3);
            comp.arg2.RoundFractionalValues = true;
            comp.arg2.Text = 'arg2';
            comp.arg2.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.medfilt = uiSliderValuedLimits(comp.GridLayout2_3);
            comp.medfilt.RoundFractionalValues = true;
            comp.medfilt.Text = 'Median filtering';
            comp.medfilt.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.reject = uiSliderValuedLimits(comp.GridLayout2_3);
            comp.reject.Text = 'Pixel rejection';
            comp.reject.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.ConnectivityTab = uitab(comp.TabGroup);
            comp.ConnectivityTab.Title = 'Connectivity';
            comp.GridLayout2_4 = uigridlayout(comp.ConnectivityTab);
            comp.connect = uiSliderValuedLimits(comp.GridLayout2_4);
            comp.connect.RoundFractionalValues = true;
            comp.connect.Text = 'Connections';
            comp.connect.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.DirectionLabel = uilabel(comp.GridLayout2_4);
            comp.DirectionLabel.Text = 'Direction';
            comp.direction = uilistbox(comp.GridLayout2_4);
            comp.direction.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.smooth = uiSliderValuedLimits(comp.GridLayout2_4);
            comp.smooth.Text = 'Smoothing';
            comp.smooth.SliderValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.ReferenceimageEditFieldLabel = uilabel(comp.GridLayout2_4);
            comp.ReferenceimageEditFieldLabel.Text = 'Reference image';
            comp.ref = uieditfield(comp.GridLayout2_4, 'numeric');
            comp.ref.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateEDI, true);
            comp.DatastoreTab = uitab(comp.TabGroup);
            comp.DatastoreTab.Title = 'Datastore';
            comp.GridLayout6 = uigridlayout(comp.DatastoreTab);
            comp.uiImageDataStoreSettings = uiImageDataStoreSettings(comp.GridLayout6);
            comp.uiImageDataStoreSettings.ApplyFilterFcn = matlab.apps.createCallbackFcn(comp, @uiImageDataStoreSettingsApplyFilter, true);
            comp.GridLayout5 = uigridlayout(comp.GridLayout);
            comp.AllButton = uibutton(comp.GridLayout5, 'push');
            comp.AllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleAllState, true);
            comp.AllButton.Text = 'All';
            comp.ImageButton = uibutton(comp.GridLayout5, 'state');
            comp.ImageButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.ImageButton.Text = 'Image';
            comp.EdgeButton = uibutton(comp.GridLayout5, 'state');
            comp.EdgeButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.EdgeButton.Text = 'Edge';
            comp.DilationButton = uibutton(comp.GridLayout5, 'state');
            comp.DilationButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.DilationButton.Text = 'Dilation';
            comp.ConnectivityButton = uibutton(comp.GridLayout5, 'state');
            comp.ConnectivityButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.ConnectivityButton.Text = 'Connectivity';
            comp.OverlayButton = uibutton(comp.GridLayout5, 'state');
            comp.OverlayButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleOverlay, true);
            comp.OverlayButton.Text = 'Overlay';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.rot90L = uibutton(comp.GridLayout3, 'push');
            comp.rot90L.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageTransformsCallback, true);
            comp.rot90L.Tag = 'rot';
            comp.rot90L.Icon = 'mat-rotate-left.png';
            comp.rot90L.Tooltip = {'Rotate left 90° (0)'};
            comp.rot90L.Text = '';
            comp.rot90R = uibutton(comp.GridLayout3, 'push');
            comp.rot90R.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageTransformsCallback, true);
            comp.rot90R.Icon = 'mat-rotate-right.png';
            comp.rot90R.Tooltip = {'Rotate right 90° (0)'};
            comp.rot90R.Text = '';
            comp.setROI = uibutton(comp.GridLayout3, 'push');
            comp.setROI.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageTransformsCallback, true);
            comp.setROI.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-crop.png');
            comp.setROI.Tooltip = {'Define cropping ROI (0)'};
            comp.setROI.Text = '';
            comp.resetROI = uibutton(comp.GridLayout3, 'push');
            comp.resetROI.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageTransformsCallback, true);
            comp.resetROI.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-crop-free.png');
            comp.resetROI.Tooltip = {'Reset cropping ROI'};
            comp.resetROI.Text = '';
            comp.ProcessButton = uibutton(comp.GridLayout3, 'push');
            comp.ProcessButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ProcessCallback, true);
            comp.ProcessButton.Text = 'Process';
            comp.AutoprocessButton = uibutton(comp.GridLayout3, 'state');
            comp.AutoprocessButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleAutoProcess, true);
            comp.AutoprocessButton.Text = 'Auto-process';
            comp.AutoprocessButton.Value = true;
            comp.IndexSlider = uislider(comp.GridLayout3);
            comp.IndexSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @indexCallback, true);
            comp.IndexSlider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @indexSliderChanging, true);
            comp.PrevButton = uibutton(comp.GridLayout3, 'push');
            comp.PrevButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @indexCallback, true);
            comp.PrevButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronWestUI.svg');
            comp.PrevButton.Text = '';
            comp.NextButton = uibutton(comp.GridLayout3, 'push');
            comp.NextButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @indexCallback, true);
            comp.NextButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronEastUI.svg');
            comp.NextButton.Text = '';
            comp.rot90L_2 = uibutton(comp.GridLayout3, 'push');
            comp.rot90L_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @maskCallback, true);
            comp.rot90L_2.Tag = 'rot';
            comp.rot90L_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-select-all.png');
            comp.rot90L_2.Tooltip = {'Masking'};
            comp.rot90L_2.Text = '';
            comp.IndexLabel = uilabel(comp.GridLayout3);
            comp.IndexLabel.Text = {'Index'; ' '; ' '};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end