classdef uiExperimentSchedule < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        InformationPanel         matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        TimeFromNowLabel         matlab.ui.control.Label
        StarttimeEditField       matlab.ui.control.EditField
        StarttimeEditFieldLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    properties (Access = private)
        finishTime datetime = datetime('now');
        internalTimer timer
    end
    
    properties (Access = public)
        experimentData
        experimentSchedule table
        recordingActive logical = false;
        startTime datetime = datetime('now');
    end
    
    % SET functions
    methods
%         function set.recordingActive(comp,val)
%             comp.recordingActive = val;
%             showRecordingProgress(comp)
%         end
        function set.experimentData(comp,val)
            comp.experimentData = val;
            updateTable(comp)
        end
        function set.startTime(comp,val)
            comp.startTime = val;
            updateStartTime(comp)
        end
        function stopTimer(comp)
            stop(comp.internalTimer)
        end
        function restartTimer(comp)
            stop(comp.internalTimer)
            start(comp.internalTimer)
        end
    end
    methods (Access = private)
        function updateTable(comp)
            t = comp.UITable;
            d = comp.experimentData;
            elapsed = cumsum([0;d.t_hr],'omitnan');
            elapsed = comp.startTime+hours(elapsed);
            d=[array2table(string(elapsed(1:end-1)),'VariableNames',{'Schedule'}) d];

            t.Data = table2array(d);
            t.ColumnName = d.Properties.VariableNames;
        end
        function updateStartTime(comp)
            t = comp.UITable;
            d = comp.experimentData;
            % guard block changes
            if isempty(d)||comp.recordingActive
                return
            end
            elapsed = cumsum([0;d.t_hr],'omitnan');
            elapsed = comp.startTime+hours(elapsed);
            comp.finishTime = elapsed(end);
            t.Data(:,1) = string(elapsed(1:end-1));
            comp.experimentSchedule = array2table(t.Data,'VariableNames',t.ColumnName);
        end
    end
    methods (Access = private)
        %% TIMER 
        function updateSheduledTimeDisplay(comp)
            % Switch timer function if Recording is active
            if ~isvalid(comp.internalTimer)
                return
            end
            try
                TimerFcn(comp)
            catch ME
                assignin('base','ME',ME)
            end
        end
        function TimerFcn(comp)
            try %#ok<*TRYNC> 

                comp.UITable.Selection = [];

                if ~comp.recordingActive
                    comp.Label.Text = string(datetime('now'));

                    % check advanced start time
                    lbl = comp.TimeFromNowLabel;
                    lbl.Text = string(comp.startTime-datetime('now'));

                    if strcmp(comp.StarttimeEditField.Value,'now')
                        comp.startTime = datetime('now');
                        lbl.Text = '';
                    end
                    return
                end

                % RECORDING ACTIVE
                % check place in schedule
                d = comp.UITable.Data;
                s = 1:height(d);
                w = 1:width(d);
                dval = @(element) datetime(element,'InputFormat','dd-MMM-uuuu HH:mm:ss');
                d = dval(d(:,1));
                
                valid = (d<datetime('now'));
                S = reshape(repmat(s(valid),[numel(w) 1]),[],1);
                W = reshape(repmat(w,[numel(s(valid)) 1])',[],1);
                comp.UITable.Selection = [S W];

                last = string(comp.finishTime-datetime('now'));
                try
                    untilNext = string(dval(d(max(S)+1,1))-datetime('now'));
                catch
                    untilNext = last;
                end
                comp.Label.Text = sprintf(['' ...
                    '[Recording]\t%s' ...
                    '\n[Next]\t\t%s' ...
                    '\n[Finish]\t%s'], ...
                    string(datetime('now')), ...
                    untilNext, ...
                    last);
                
                if datetime('now')>comp.finishTime
                    comp.recordingActive = false;
                end
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'uiExperimentSchedule')
            % Build timer
            comp.internalTimer = timer(Period = 1, ...
                        ExecutionMode='fixedRate',...
                        TasksToExecute=inf,...
                        TimerFcn = @(varargin) updateSheduledTimeDisplay(comp), ...
                        Tag = 'ScheduleTimer', ...
                        UserData=false);
            start(comp.internalTimer)
            comp.TimeFromNowLabel.Text = '';
        end

        % Callback function: StarttimeEditField, StarttimeEditField
        function evaluateStartTime(comp, event)
            value = event.Value;
            valid = {'hr','h','min','m','sec','s','d','day'};
            state = '';
            
            try
                value = strrep(value,"'",'');
                value = strrep(value,'"','');
                value = eval(sprintf('datetime("%s");',value));
            catch
                numbers = join(regexp(value,'[\d.]+','match'),'');
                hasNumbers = ~isempty(numbers);
                if contains(value,'+')&&hasNumbers
                    value = extractAfter(value,'+');
                    [value,match]=split(value,[' ',valid]);
                    try
                        value = str2double(cell2mat(nonempty(arrayfun(@char,value,'UniformOutput',false))));
                    end
                    match = match(contains(match,valid));
                    switch match
                        case {'day','d'}
                            value = days(value);
                        case {'hr','h'}
                            value = hours(value);
                        case {'min','m'}
                            value = minutes(value);
                        case {'sec','s'}
                            value = seconds(value);
                        otherwise
                            value = hours(value);
                    end
                    value = datetime('now')+value;
                    state = '[From now]';
%                 elseif contains(value,{'am','pm'})&&hasNumbers
%                     [];
%                     state = '[Today]';
                elseif (length(char(value))==4)&&hasNumbers&&length(char(numbers))==4
                    value = char(value);
                    value = sum([hours(str2double(value(1:2))) minutes(str2double(value(3:4)))]);
                    value = datetime('today')+value;
                    state = '[Today; 24 time]';
                else
                    state = '[invalid format]';
                    value = datetime('now');
                end
            end

            try
                if isnan(value)
                    value = datetime('now');
                end
            end
            
            comp.TimeFromNowLabel.Text = '';
            if value>datetime('now')
                comp.TimeFromNowLabel.Text = string(value-datetime('now'));
            end
            
            switch event.EventName
                case 'ValueChanged'
                case 'ValueChanging'
                    comp.StarttimeEditFieldLabel.Text = sprintf('%s Start time',state);
            end

            try
                comp.startTime = value;
            catch
                comp.startTime = datetime('now');
            end

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 465 326];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = '';
            comp.UITable.RowName = {};
            comp.InformationPanel = uipanel(comp.GridLayout);
            comp.InformationPanel.Title = 'Information';
            comp.GridLayout2 = uigridlayout(comp.InformationPanel);
            comp.Label = uilabel(comp.GridLayout2);
            comp.StarttimeEditFieldLabel = uilabel(comp.GridLayout2);
            comp.StarttimeEditFieldLabel.Text = 'Start time';
            comp.StarttimeEditField = uieditfield(comp.GridLayout2, 'text');
            comp.StarttimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @evaluateStartTime, true);
            comp.StarttimeEditField.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @evaluateStartTime, true);
            comp.StarttimeEditField.Tooltip = {'Expessions include:'; '''now'', (datetime format)'; '''1200'' (today; 24hr time)'; '''+1 hr'' (from now; can be ''h/hr'', ''m/min'', ''d/day'', ''s/sec'')'};
            comp.StarttimeEditField.Value = 'now';
            comp.TimeFromNowLabel = uilabel(comp.GridLayout2);
            comp.TimeFromNowLabel.Text = 'TimeFromNowLabel';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end