classdef uiParameterSpace < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        GridLayout2                   matlab.ui.container.GridLayout
        YDropDown                     matlab.ui.control.DropDown
        ParameterspaceYDropDownLabel  matlab.ui.control.Label
        XDropDown                     matlab.ui.control.DropDown
        ParameterspaceXDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        data
    end
    
    properties (Access = public)
        experimentFiles string % Files with .mat extensions
    end
    
    % SET functions
    methods
        
        function set.experimentFiles(comp,val)
            comp.experimentFiles = val;     
            readFiles(comp)
        end

    end
    methods (Access = private)
        function readFiles(comp)
            d = arrayfun(@load,comp.experimentFiles);
            d = [d.le];
            d0 = d;
            try
                d   = ({d0.parameterspace});
                for i=1:numel(d)
                    v = d{i};
                    d{i} = v(~isnan(v.omegaN),:);
                end
                rep = cellfun(@height,d)';
                d   = nonempty(d);
                d   = vertcat(d{:});
            catch
                return
            end
            if isempty(d)
                return
            end
            % append table
            %s = cell2mat({d0.S}');
            t = cell2mat(arrayfun(@(x,y) repmat(x,[1 y]),[d0.T]',rep,'UniformOutput',false)')';
            n = cell2mat(arrayfun(@(x,y) repmat(x,[1 y]),[d0.N]',rep,'UniformOutput',false)')';
            e = cell2mat(arrayfun(@(x,y) repmat(x,[1 y]),[d0.eta]',rep,'UniformOutput',false)')';
            ta = array2table([t n e*1e3],'VariableNames',{'T_dC','N_rad_s','eta_mm'});

            comp.data = [d ta];
            getParameters(comp)

            % extend data 
            [~,fnames] = fileparts([d0.root]);
            fnames = arrayfun(@(x,y) repmat(x,[1 y]),fnames',rep,'UniformOutput',false)';
            fnames = [fnames{:}];
            comp.data = [array2table(fnames','VariableNames',{'Label'}) comp.data];
            generateTable(comp)
        end
        function getParameters(comp)
            ed = comp.data;
            if isempty(ed)
                return
            end
            vars = ed.Properties.VariableNames;
%             vars{end+1} = ' ';
%             vars = circshift(vars,1);
            comp.XDropDown.UserData = vars;
            comp.XDropDown.Items = vars;
            comp.YDropDown.Items = vars;
            try
                comp.YDropDown.Value = vars{2};
            end
            plotParameters(comp)
        end
        function toggleParameterDropDown(comp)
            vars = comp.XDropDown.UserData;
            comp.XDropDown.Items = vars;
            comp.YDropDown.Items = vars;
        end
        function generateTable(comp)
            comp.UITable.Data = table2array(comp.data);
            comp.UITable.ColumnName = comp.data.Properties.VariableNames;
        end
        function plotParameters(comp)
            x=eval(sprintf('comp.data.%s;',comp.XDropDown.Value));
            y=eval(sprintf('comp.data.%s;',comp.YDropDown.Value));
            plot(comp.UIAxes,x,y,'ok',x,y,'xk','MarkerSize',10)
            try
                xlim(comp.UIAxes,2*std(x).*[-1 1]+mean(x))
            catch
                xlim(comp.UIAxes,[-inf inf])
            end
            try
                ylim(comp.UIAxes,2*std(y).*[-1 1]+mean(y))
            catch
                ylim(comp.UIAxes,[-inf inf])
            end
            plotHighlight(comp)
        end
        function plotHighlight(comp)
            selection = comp.UITable.Selection; %#ok<NASGU> 
            if isempty(selection)
                return
            end
            delete(findall(comp.UIAxes,'Tag','selection'))
            x=eval(sprintf('comp.data.%s(selection(:,1));',comp.XDropDown.Value));
            y=eval(sprintf('comp.data.%s(selection(:,1));',comp.YDropDown.Value));
            hold(comp.UIAxes,'on')
            plot(comp.UIAxes,x,y,'--or',x,y,'--xr','Tag','selection','MarkerSize',10)
            hold(comp.UIAxes,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XDropDown, YDropDown
        function DropDownCallback(comp, event)
            plotParameters(comp)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(comp, event)
            plotHighlight(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 468 366];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.ParameterspaceXDropDownLabel = uilabel(comp.GridLayout2);
            comp.ParameterspaceXDropDownLabel.Text = 'Parameter space (X)';
            comp.XDropDown = uidropdown(comp.GridLayout2);
            comp.XDropDown.Items = {};
            comp.XDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownCallback, true);
            comp.XDropDown.Value = {};
            comp.ParameterspaceYDropDownLabel = uilabel(comp.GridLayout2);
            comp.ParameterspaceYDropDownLabel.Text = 'Parameter space (Y)';
            comp.YDropDown = uidropdown(comp.GridLayout2);
            comp.YDropDown.Items = {};
            comp.YDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownCallback, true);
            comp.YDropDown.Value = {};
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = '';
            comp.UITable.RowName = {};
            comp.UITable.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @UITableSelectionChanged, true);
        end
    end
end