classdef uiResults < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        SelectionListBox            matlab.ui.control.ListBox
        SelectionListBoxLabel       matlab.ui.control.Label
        PlottingDropDown            matlab.ui.control.DropDown
        PlottingDropDownLabel       matlab.ui.control.Label
        OpenwaveDetectionButton     matlab.ui.control.Button
        resultInfoLabel             matlab.ui.control.Label
        OpenmeltingDetectionButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ed labExperiment % Experiment data
        edi edIce % Edge detection data
        files_valid cell = {'experimentdata', 'ediData','iwdata'}; % Valid saved files
        iwd iwdata % Internal wave data
    end
    
    properties (Access = protected)
        has table % Logical table of what data is available
    end
    
    properties (Access = public)
        root {mustBeFolder(root)} = pwd; % Root directory
    end
    
    %% SET functions
    methods
        function set.root(comp,val)
            comp.root = val;
            comp.setroot
        end
    end
    %% SET-callbacks
    methods (Access = private)
        function setroot(comp)
            comp.checkForData
            comp.updateInfo

        end
    end

    %% Internal functions
    methods (Access = private)
        function checkForData(comp)
            % valid files 
            
            files = what(comp.root).mat;
            hasData = false;
            valid   = comp.files_valid;
            if ~isempty(files)
                hasData = cellfun(@(valid) any(contains(files,valid)),valid);
            end

            if all(~hasData)
                return
            end
            comp.has = array2table(hasData, ...
                "VariableNames",valid);
            fcn = @(file) string(fullfile(comp.root,files(contains(files,file))));
            if comp.has.experimentdata
                comp.ed = arrayfun(@(d) load(d).le,fcn('experimentdata'));
            end
            if comp.has.ediData
                comp.edi = arrayfun(@(d) load(d).edi,fcn('ediData'));
            end
            if comp.has.iwdata
                %comp.iwd = load(fcn('iwdata')).iw;
            end
        end
        function updateInfo(comp)
            comp.resultInfoLabel.Text = '';
            Has     = comp.has;
            if isempty(Has)
                return
            end
            valid   = comp.files_valid;
            lbls    = cellfun(@(valid) sprintf("No '%s' files found",valid),valid,'UniformOutput',false);
            match   = @(lbl) strcmp(valid,lbl);
            if Has.experimentdata
                lbls(match(valid{1})) = {replace(lbls{match(valid{1})},'No',sprintf('%i',numel(comp.ed)))};
            end
            if Has.ediData
                lbls(match(valid{2})) = {replace(lbls{match(valid{2})},'No',sprintf('%i',numel(comp.edi)))};
            end
%             if Has.iwdata
%                 lbls(match(valid{3})) = str;
%             end
            comp.resultInfoLabel.Text = cat(1,lbls{:});
        end
        function updateAxes(comp)
            data = comp.collectData;
            plot(comp.UIAxes,data.x,data.y)
        end
        function data = collectData(comp)
            data.x = [];
            data.y = [];
            e = comp.ed.probelog;
            item = comp.SelectionListBox.Value;
            switch comp.PlottingDropDown.Value
                case {'Filling density'}
                    
                    switch item
                        case 'Time'
                        case 'Depth' 
                        case 'N'
                    end
                case {'CT data'}
                    switch item
                        case 'S'
                        case 'T' 
                        case 'ρ' 
                        case 'N'
                    end
                case {'Melting rate'}
                    switch item
                        case 'z-t'
                        case 'int z-t'
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Results')
            comp.SelectionListBox.UserData = struct('fd','Time','ct','S','mr','z-t');
            PlottingDropDownValueChanged(comp)
        end

        % Button pushed function: OpenmeltingDetectionButton, 
        % ...and 1 other component
        function openAppCallback(comp, event)
            switch event.Source
                case comp.OpenmeltingDetectionButton
                    app = app_meltingDetection;
                case comp.OpenwaveDetectionButton
                    app = app_waveDetection;
            end
            app.root = comp.root;
        end

        % Value changed function: PlottingDropDown
        function PlottingDropDownValueChanged(comp, event)
            if nargin<2
                value = comp.PlottingDropDown.Value;
            else
                value = event.Value;
            end
            switch value
                case {'Filling density'}
                    items = {'Time','Depth','N'};
                case {'CT data'}
                    items = {'S','T','ρ','N'};
                case {'Melting rate'}
                    items = {'z-t','int z-t'};
            end
            comp.SelectionListBox.Items = items;
            updateAxes(comp)
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 709 517];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            comp.OpenmeltingDetectionButton = uibutton(comp.GridLayout, 'push');
            comp.OpenmeltingDetectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openAppCallback, true);
            comp.OpenmeltingDetectionButton.Text = 'Open meltingDetection';
            comp.resultInfoLabel = uilabel(comp.GridLayout);
            comp.resultInfoLabel.Text = 'resultInfo';
            comp.OpenwaveDetectionButton = uibutton(comp.GridLayout, 'push');
            comp.OpenwaveDetectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openAppCallback, true);
            comp.OpenwaveDetectionButton.Text = 'Open waveDetection';
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.Title = 'Panel';
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.PlottingDropDownLabel = uilabel(comp.GridLayout2);
            comp.PlottingDropDownLabel.Text = 'Plotting';
            comp.PlottingDropDown = uidropdown(comp.GridLayout2);
            comp.PlottingDropDown.Items = {'Filling density', 'CT data', 'Melting rate'};
            comp.PlottingDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PlottingDropDownValueChanged, true);
            comp.PlottingDropDown.Value = 'Filling density';
            comp.SelectionListBoxLabel = uilabel(comp.GridLayout2);
            comp.SelectionListBoxLabel.Text = 'Selection';
            comp.SelectionListBox = uilistbox(comp.GridLayout2);
            comp.SelectionListBox.Items = {};
            comp.SelectionListBox.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end