classdef app_experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceexperimentsUIFigure      matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        uiFileSystem                uiFileSystem
        experimentstatusLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        recordingStatusLabel        matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        NewexperimentButton         matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        saveStatusLabel             matlab.ui.control.Label
        autoSaveButton              matlab.ui.control.StateButton
        TabGroup                    matlab.ui.container.TabGroup
        DetailsTab                  matlab.ui.container.Tab
        GridLayout8                 matlab.ui.container.GridLayout
        uiDetails                   uiDetails
        DesignTab                   matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        uiDesigner                  uiDesigner
        StratificationTab           matlab.ui.container.Tab
        GridLayout5_4               matlab.ui.container.GridLayout
        uiStratification            uiStratificationCheckBox_2022b
        CameraTab                   matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        UsescheduleButton           matlab.ui.control.StateButton
        FilenameencodeButton        matlab.ui.control.StateButton
        CameraSubTabGroup           matlab.ui.container.TabGroup
        CameraTab_2                 matlab.ui.container.Tab
        GridLayout9                 matlab.ui.container.GridLayout
        uiCameraControl             uiCameraControl
        SchlierenTab_2              matlab.ui.container.Tab
        uiSchlierenPatternDesigner  uiSchlierenPatternDesigner
        DatastoreTab                matlab.ui.container.Tab
        GridLayout10                matlab.ui.container.GridLayout
        ImagecopierButton           matlab.ui.control.Button
        SchlierenbackgroundButton   matlab.ui.control.StateButton
        amplitudeListBox            matlab.ui.control.ListBox
        omegaLabel                  matlab.ui.control.Label
        omegaNListBox               matlab.ui.control.ListBox
        intervalSpinner             matlab.ui.control.Spinner
        intervalSpinnerLabel        matlab.ui.control.Label
        activeSpinner               matlab.ui.control.Spinner
        activeLabel                 matlab.ui.control.Label
        omegaNEditField             matlab.ui.control.NumericEditField
        NradsLabel                  matlab.ui.control.Label
        InternalWaveTab             matlab.ui.container.Tab
        GridLayout5_3               matlab.ui.container.GridLayout
        uiWaveTank                  uiWaveTank
        ResultsTab                  matlab.ui.container.Tab
        GridLayout5_2               matlab.ui.container.GridLayout
        uiResults                   uiResults
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        node
        selection
        files
        root {mustBeFolder(root)}=pwd;
        experiment
        defaultroot = '..\Data\ablation_images\'
        readOnly logical=false
    end
    properties (Access = private, Hidden)
        autosave {logical(autosave)}= false
        activeExperiment {logical(activeExperiment)}= false
    end
        
    methods (Access = public)
        %% Tab update
        function updateDetails(app)
            e = app.experiment;
            d = app.uiDetails;
            set(d, ...
                'N',e.N, ...
                'T',e.T,...
                'S',e.S, ...
                'rho',e.rho, ...
                'Date',e.dates, ...
                'Comments',e.comments, ...
                'eta',e.eta);
        end
        function updateResults(app)
            app.uiResults.root = app.root;
        end
        function updateDesigner(app)
            e = app.experiment;
            d = app.uiDesigner;
            ss = struct( ...
                'Ta',e.T, ...
                'S0',min(e.S), ...
                'Sa',max(e.S), ...
                'rho0',min(e.rho), ...
                'rhoa',max(e.rho), ...
                'N',e.N);
            set(d, ...
                'StratificationStructure',ss, ...
                'InputsStructure',e.inputs,...
                'ParameterSpaceData',e.parameterspace)
            
        end
        function updateStratification(app)
            e = app.experiment;
            set(app.uiStratification, ...
                "N",e.N,...
                'rho',e.rho, ...
                'Stratification',e.mixinglog,...
                'conductivityData',e.probelog, ...
                'dates',e.dates,...
                'LabelSearch',join(string(e.dates,'uuuu_MM_dd'),'-'))
            
            
            try
                % Mixing Tree
                e.probelog.fill = app.uiStratification.callMixingTree;
            end
            try
                % Conductivity Tree
                nodeC = app.uiStratification.callConductivityTree;
                e.probelog.ct_raw = dir(fullfile(nodeC.UserData,'*.txt'));
                e.probelog.ct_mat = what(nodeC.UserData);
            end

            try %#ok<*TRYNC> 
                i = e.inputs;
                set(app.uiStratification, ...
                    'FlowRate',i.FlowRate)
            end
            
        end
        function updateWaveTank(app)
            e = app.experiment;
            set(app.uiWaveTank, ...
                'ParameterSpaceData',e.parameterspace);
        end
        function updateCamera(app)
            e = app.experiment;
            updateCameraTabListBoxes(app)
            updateCameraTabSchedule(app)
            ccstruct = e.camera;
            cc = app.uiCameraControl;
            isClonePath = isequal(fileparts(e.driveSaveFile),folderOnPath(app.selection));
            if isempty(ccstruct)&&~isClonePath
                cc.ImagePath = e.root;
                return
            end
        end
        %% Update other tabs
        function updatedDesign(app)
            % affects uiDetails, uiWaveTank, uiStratification
            updateDetails(app)
            updateStratification(app)
            updateWaveTank(app)
        end
        function updatedStratification(app)
            % affects uiDetails, uiWaveTank, uiDesigner
            updateDetails(app)
            updateDesigner(app)
        end
        function updatedWaveTank(app)
            % affects uiDesign
            updateDesigner(app)
        end
        function updatedCamera(~)
            % has no dependents (yet?)
        end
        %% Experiment update
        function autoSaving(app)
            %% Manages autosaving functionality
            if app.autosave&&app.activeExperiment
                saveData(app)
            end
        end
        function saveData(app,type)
            %% Saves or updates a new/current data file
            if nargin<2
                type = 'update';
            end
            switch type
                case 'update'
                    if ~app.activeExperiment
                        app.clearMessageAfter(app.saveStatusLabel,'No active experiment found.')
                        return
                    end
                    app.experiment.saveExperiment;
                case 'create'
                    app.experiment.saveExperiment;
                    app.activeExperiment = true;
                    set([app.SaveButton app.autoSaveButton],Enable = 'on');
                    app.NewexperimentButton.Enable = 'off';
                    return
            end
            app.clearMessageAfter(app.saveStatusLabel,'File saved')
        end
        function loadData(app)
            %% Loads experiment data file and updates ui
            updateDetails(app)
            updateResults(app)
            updateCamera(app)
            updateDesigner(app)
            updateStratification(app)
            updateWaveTank(app)
        end
        function resetExperiment(app)
            %% Resets all fields to their default state
            app.experiment.dates = [datetime datetime];
            app.uiDetails.reset;
            app.uiDesigner.reset;
            app.experiment.root = app.uiFileSystem.SelectedPath;
            updateExperiment(app,struct('Source',app.uiStratification,'EventName','all'))
            updateExperiment(app,struct('Source',app.uiDesigner,'EventName','all'))
            updateExperiment(app,struct('Source',app.uiCameraControl))
        end
        function state = searchForData(app)
            % Searches for singular experimentdata files 
            state   = false;
            matches = dir(fullfile(app.selection,['*' app.experiment.savetag '.mat']));
            status  = 'No experiment files detected';
            
            app.activeExperiment            = false;
            app.uiCameraControl.ImagePath   = app.selection;
            app.readOnly                    = false;
            app.saveStatusLabel.Text        = '';

            % Delte parameter space tab
            delete(findall(app.TabGroup,'Title','Par. Space'))

            if ~isempty(matches)
                % experiment found
                status = matches(1).name;
                app.experiment=app.experiment.loadExperiment(matches(1));
                assignin('base',"experiment",app.experiment)

                if isequal(folderOnPath(app.selection),fileparts(app.experiment.driveSaveFile))
                    app.readOnly = true;
                end

                % import data
                loadData(app)   
                app.activeExperiment = true;
                app.saveStatusLabel.Text = '';

                if ~app.readOnly
                    set([app.SaveButton app.autoSaveButton],Enable = 'on');
                    app.NewexperimentButton.Enable = 'off';
                else
                    set([app.SaveButton ...
                        app.autoSaveButton ...
                        app.NewexperimentButton],Enable = 'off');
                    app.saveStatusLabel.Text = 'Read-only';
                end

                app.clearMessageAfter(app.saveStatusLabel,'File loaded',2)
                state = true;
            else
                % no experiment found - default experiment setup
                resetExperiment(app)
                set([app.SaveButton app.autoSaveButton],Enable = 'off');
                app.NewexperimentButton.Enable = 'on';
            end
            app.clearMessageAfter(app.experimentstatusLabel,status,1.5)
        end
        function searchForAllData(app)
            % Searches for experimentdata files in selected directory
            tab     = app.TabGroup;
            ps      = findall(tab,'Title','Par. Space');
            if isempty(app.selection)
                return
            end
            dirs = nonempty(split(genpath(app.selection),pathsep));
            if isempty(dirs)
                delete(ps)
                return
            end
            matches = nonempty(cellfun(@(x) dir(fullfile(x,['*' app.experiment.savetag '.mat'])),dirs,'UniformOutput',false));
            if isempty(matches)
                delete(ps)
                return
            end

            % create ParameterSpace Tab
            pstab   = uitab(tab,"Title",'Par. Space');
            gl      = uigridlayout(pstab,'ColumnWidth',{'1x'},'RowHeight',{'1x'},'Padding',[0,0,0,0]);
            psui    = uiParameterSpace(gl);
            matches = cell2mat(matches);
            matches = arrayfun(@(x) fullfile(x.folder,x.name),matches,'UniformOutput',false);
            psui.experimentFiles = matches;

            app.TabGroup.UserData = app.TabGroup.SelectedTab;
            app.TabGroup.SelectedTab = pstab;
        end
    end
    
    methods (Access = private,Hidden)
        %% PROGRAM functions
        function clearMessageAfter(~,component,message,time,lastmessage)
            if nargin<4
                time = 2;
            end
            if nargin<5
                lastmessage = '';
            end
            set(component,'Text',message)
            delayedFunction(@(~,~) set(component,'Text',lastmessage),time,message)
        end
        function toggleScheduleTab(app)
            % Creates a seperate tab in the Camera Tab subgroup for
            % information about the recording schedule
            g = app.CameraSubTabGroup;
            f = findall(g,'Title','Schedule');

            if isempty(f)
                app.CameraSubTabGroup.UserData = app.CameraSubTabGroup.SelectedTab;

                stab = uitab(g,"Title",'Schedule');
                app.CameraSubTabGroup.SelectedTab=stab;
                gl  = uigridlayout(stab,'ColumnWidth',{'1x'},'RowHeight',{'1x'},'Padding',[0,0,0,0]);
                uiExperimentSchedule(gl, ...
                    'experimentData',app.experiment.parameterspace, ...
                    'startTime',datetime('now'));
            else
                app.CameraSubTabGroup.SelectedTab=app.CameraSubTabGroup.UserData;
                findall(f,'Type','uiExperimentSchedule').stopTimer
                delete(f)
            end
        end
        function updateCameraTabListBoxes(app)
            % Checks for items in the experiment parameter space and loads
            % them into the relevant list boxes
            try
                newitems = app.experiment.parameterspace.omegaN;
                newitems = arrayfun(@(s) sprintf('%.2g',s),newitems(~isnan(newitems)),'UniformOutput',false);
                newitems{end+1} = 'none';
                app.omegaNListBox.Items = unique(newitems);

                newitems = app.experiment.parameterspace.A_mmPs;
                newitems = arrayfun(@(s) sprintf('%g',s),newitems(~isnan(newitems)),'UniformOutput',false);
                newitems{end+1} = 'none';
                app.amplitudeListBox.Items = unique(newitems);
                app.omegaNEditField.Value = eval(app.omegaNListBox.Items{1});

                period  = 2*pi/(app.omegaNEditField.Value*app.experiment.N);
                app.omegaLabel.Text = sprintf('%.3fs',period);
            end
        end
        function updateCameraTabSchedule(app)
            % Checks for items in the experiment parameter space and
            % enables the schedule recording function
            app.UsescheduleButton.Enable = 'off';
            app.UsescheduleButton.Value = false;
            try
                ps = app.experiment.parameterspace;
                if height(ps)
                    app.UsescheduleButton.Enable = 'on';
                end
            end

            % Update schedule 
            stab = findall(app.CameraSubTabGroup,'Title','Schedule');
            if isempty(stab)
                return
            end
            es = findall(stab,'Type','uiExperimentSchedule');
            es.experimentData = app.experiment.parameterspace;
            
        end
        function updateSchedule(app)
            % Updates or resets the parameter space schedule depending on
            % the wave-time (active/interval) properties
            state = app.UsescheduleButton.Value;
            ps = app.experiment.parameterspace;
            w  = ps.omegaN;
            if ~state
                % restores times in parameter space to NaN
                ps.t_hr(~or(isnan(w),w==0),:) = nan;
            else
                i = app.activeSpinner.Value;
                N = app.experiment.N;
                t = 2*pi./(w*N)/60^2*i;
                ps.t_hr(~or(isnan(w),w==0),:) = t(~or(isnan(w),w==0));
            end
            app.experiment.parameterspace = ps;
            updateDesigner(app)
            updateWaveTank(app)
        end
        function updateSchlierenSubTab(app)
            % Attempts to read metadata in root folder containing
            % information about the schlieren background used in the image
            % sequence
            try
                app.uiSchlierenPatternDesigner.savepath = app.experiment.savepath;
            end
        end
        function updateSchlierenBaseTime(app)
            if ~app.SchlierenbackgroundButton.Value
                return
            end
            bt  = 2*pi/(app.omegaNEditField.Value*app.experiment.N);
            bt(isinf(bt)) = 1;
            app.uiSchlierenPatternDesigner.BaseTime = bt;
            
        end
        function updateFilenameTag(app)
            % Encodes image filenames with information about the
            % selected wave forcing frequency and amplitude
            iw = iwdata;
            omegaNval = app.omegaNEditField.Value;
            ampval    = app.amplitudeListBox.Value;
            app.uiCameraControl.FilenameTag = iw.file_encodeInfo(omegaNval,ampval);
        end
        %% RECORDING functions
        function appendMetadata(app)
            %% Appends the metadata file within an image repository
            % (called when recording starts)
            % Base file provides interval,duration,exposure,gammagain
            % Appends base file with omega/N,omega,amplitude,h0
            cc = app.uiCameraControl;
            mdp = cc.metadataPath;
            if isempty(mdp)
                return
            end
            for i=1:numel(mdp)
                md  = readstruct(mdp(i));
                e   = app.experiment;
                md.omega    = app.omegaNListBox.Value;
                md.ampltide = app.amplitudeListBox.Value;
                md.N        = e.N;
                md.fpT      = app.intervalSpinner.Value;
                writestruct(md,mdp,"FileType","xml")
            end
        end
        function toggleSchlierenBackground(app)
            %% Generates and opens the defined schlieren background video
            % (called when recording starts)
            lbl = app.SchlierenbackgroundButton;
            lbl0 = lbl.Text;
            if ~app.SchlierenbackgroundButton.Value
                return
            end 
            lbl.Text = 'Generating';
            pause(1e-3)
            app.uiSchlierenPatternDesigner.autoComplete;
            lbl.Text = lbl0;
        end
        function notification = checkSchedule(app)
            %% Checks for a schedule
            if ~app.UsescheduleButton.Value
                notification = '';
                return
            end
            app.UsescheduleButton.Enable = 'off';
            notification = '[Using scheduled recording session]';
            stab = findall(app.CameraSubTabGroup,'Type','uiExperimentSchedule');
           
            % TODO
            % make a schedule file for synchronous use with SmartMotors
            % (needs FileSystemWatcher)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            home
            app.uiStratification.ActivateFileWatcher = true;
            set([ ...
                app.experimentstatusLabel ...
                app.saveStatusLabel], ...
                'Text','')
            app.experiment = labExperiment;
            assignin('base',"experiment",app.experiment)
            if isfolder(app.defaultroot)
                app.root = app.defaultroot;
            end
            app.uiFileSystem.root = app.root;
            %app.uiFileSystem.buildTree;
            app.uiFileSystem.HiddenTreeVisible = false;
        end

        % Callback function: uiFileSystem
        function changeDir(app, event)
            % update
            app.root        = app.uiFileSystem.root;
            app.selection   = app.uiFileSystem.SelectedPath;
            app.node        = app.uiFileSystem.SelectedNode;
            app.files       = app.uiFileSystem.files;

            % search for singular experimentdata files in selected directory
            if searchForData(app)
                if app.TabGroup.SelectedTab==app.InternalWaveTab
                    try
                        app.uiWaveTank.configurationChanged;
                    end
                end
%                 try
%                     % Reinstate last open tab
%                     app.TabGroup.SelectedTab = app.TabGroup.UserData;
%                 end

                return
            end

            % search for grouped experimentdata files in selected directory
            searchForAllData(app)

            
        end

        % Selection change function: TabGroup
        function tabSelection(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.DetailsTab
%                     updateDetails(app)
                case app.ResultsTab
                    updateResults(app)
                case app.DesignTab
%                     updateDesign(app)
                case app.InternalWaveTab
                    app.uiWaveTank.startProgram;
                case app.CameraTab
                    updateCameraTabListBoxes(app)
                    updateSchlierenSubTab(app)
            end
        end

        % Value changed function: autoSaveButton
        function autoSaveCallback(app, event)
            app.autosave = app.autoSaveButton.Value;
            switch app.autosave
                case 0
                    app.autoSaveButton.Tooltip = 'Autosave (off)';
                case 1
                    app.autoSaveButton.Tooltip = 'Autosave (on)';
            end
            app.clearMessageAfter(app.saveStatusLabel,app.autoSaveButton.Tooltip,.5)
        end

        % Button pushed function: NewexperimentButton, SaveButton
        function saveCallback(app, event)
            saveData(app,event.Source.Tag)
        end

        % Callback function: uiCameraControl, uiDesigner, uiDesigner, 
        % ...and 3 other components
        function updateExperiment(app, event)
            e   = app.experiment;
            src = event.Source;

            % Handle different tab source callbacks
            switch event.Source
                case app.uiDetails
                    %% UIDETAILS (obsolete)
                    e.N     = src.N;
                    e.S     = src.S;
                    e.T     = src.T;
                    e.rho   = src.rho;
                    e.dates = src.Date;
                    e.comments = src.Comments;
                case app.uiDesigner
                    %% UIDESIGNER 
                    % Activates when "Accept" buttons are pressed
                    ss = src.StratificationStructure;
                    is = src.InputsStructure;
                    ps = src.ParameterSpaceData;
                    switch event.EventName
                        case {'StratificationChanged','InputsChanged'}
                            e.N     = ss.N;
                            e.S     = [ss.S0 ss.Sa];
                            e.rho   = [ss.rho0 ss.rhoa];
                            e.T     = ss.Ta;
                            try
                            e.eta   = ss.eta;
                            end
                            e.inputs= is;
                        case 'ParameterSpaceChanged'
                            e.parameterspace = ps;
                            updateCameraTabSchedule(app)
                        case 'all'
                            e.N     = ss.N;
                            e.S     = [ss.S0 ss.Sa];
                            e.rho   = [ss.rho0 ss.rhoa];
                            e.T     = ss.Ta;
                            e.eta   = 0;
                            e.inputs= is;
                            e.parameterspace = ps;
                    end
                    updatedDesign(app)
                case app.uiStratification
                    %% UISTRATIFICATION
                    switch event.EventName
                        case 'ProfileGenerated'
                            % Updates when "Generate Profile" button is pressed
                            e.N     = src.N;
                            e.rho   = src.rho;
                            e.inputs.FlowRate = src.FlowRate;
                        case 'ProfileLogged'
                            e.mixinglog = src.Stratification;
                        case 'ConductivityProfileLogged'
                            e.probelog = src.conductivityData;
                        case 'all'
                            e.mixinglog = struct;
                            e.probelog  = struct;
                            return
                    end
                    updatedStratification(app)
                case app.uiWaveTank
                    %% UIWAVETANK
                    e.parameterspace=src.ParameterSpaceData;
                    updatedWaveTank(app)
                case app.uiCameraControl
                    %% UICAMERACONTROL
                    cc          = app.uiCameraControl;
                    cameras     = '';
                    ds          = nan;
                    imagepaths  = '';

                    if ~isempty(cc.labCamera)
                        cameras     = get([cc.labCamera.camera],'Name');
                        ds          = [cc.labCamera.ds];
                        imagepaths  = [cc.labCamera.savepath];
                    end

                    ccstruct = struct( ...
                        'TimeActive',cc.TimeActive, ...
                        'TimeInterval',cc.TimeInterval, ...
                        'TimeStart',cc.TimeStart, ...
                        'Cameras',cameras,...
                        'Resolution',ds, ...
                        'ImagePaths',imagepaths);
                    e.camera = ccstruct;
                    updatedCamera(app)
            end
            autoSaving(app)
        end

        % Callback function: uiCameraControl, uiCameraControl, 
        % ...and 3 other components
        function recordingCallback(app, event)
            name = strrep(event.EventName,'Recording','');
            release = false;
            switch name
                case 'Started'
                    notif = checkSchedule(app);
                    appendMetadata(app)
                    toggleSchlierenBackground(app)
                    txt=sprintf('Recording is active since %s %s',string(datetime),notif);
                case 'Stopped'
                    txt=sprintf('Recording has been stopped since %s',string(datetime));
                    app.UsescheduleButton.Enable = 'on';
                    release = true;
                case 'Paused'
                    txt=sprintf('Recording has been paused since %s',string(datetime));
                case 'Restarted'
                    txt=sprintf('Recording has been restarted since %s',string(datetime));
                case 'Finished'
                    txt=sprintf('Recording finished at %s',string(datetime));
                    release = true;
            end

            % Toggle folder tree functionality
            enable = 'off';
            if release; enable = 'on';end
            app.uiFileSystem.Enable = enable;
            app.recordingStatusLabel.Text=txt;
        end

        % Value changed function: activeSpinner, intervalSpinner, 
        % ...and 1 other component
        function changeCameraTimesCallback(app, event)
            if event.Source==app.omegaNListBox
                val = str2double(event.Value);
                val(isnan(val)) = 0;
                app.omegaNEditField.Value = val;
            end
            
            omegaN  = app.omegaNEditField.Value;
            Wpa     = app.activeSpinner.Value;
            Wpi     = app.intervalSpinner.Value;

            period  = 2*pi/(omegaN*app.experiment.N);
            %app.experiment.parameterspace
            app.omegaLabel.Text = sprintf('%.3fs',period);
            % set camera times
            if ~isinf(period)
                app.uiCameraControl.TimeActive   = seconds(period)*Wpa;
            end
            if Wpi
                app.uiCameraControl.TimeInterval = seconds(period)/Wpi;
            end
            updateSchedule(app)

        end

        % Value changed function: amplitudeListBox, omegaNListBox
        function ListBoxValueChanged(app, event)
            app.FilenameencodeButton.Enable = 'off';
            app.FilenameencodeButton.Enable = 'on';
            switch event.Source
                case app.omegaNListBox
                    changeCameraTimesCallback(app,event)
                case app.amplitudeListBox
            end
            encodeFilename(app)
            updateSchlierenBaseTime(app)
        end

        % Value changed function: FilenameencodeButton
        function encodeFilename(app, event)
            value = app.FilenameencodeButton.Value;
            if value
                % append filename label
                updateFilenameTag(app)
                return
            end
            app.uiCameraControl.FilenameTag = [];
        end

        % Value changed function: SchlierenbackgroundButton
        function updateSchlierenCallback(app, event)
            app.uiSchlierenPatternDesigner.readMetadata;
            updateSchlierenBaseTime(app)
        end

        % Button pushed function: ImagecopierButton
        function ImageCopierCallback(app, event)
            ic = app_copyImagesByTime;
            try
                ic.root = app.experiment.root;
            end
        end

        % Value changed function: UsescheduleButton
        function updateScheduleCallback(app, event)
            toggleScheduleTab(app)
            updateSchedule(app)
        end

        % Close request function: IceexperimentsUIFigure
        function IceexperimentsUIFigureCloseRequest(app, event)
            stop(timerfindall("Tag",'ScheduleTimer'))
            delete(timerfindall("Tag",'ScheduleTimer'))
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IceexperimentsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {671, 671};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IceexperimentsUIFigure = uifigure('Visible', 'off');
            app.IceexperimentsUIFigure.Name = 'Ice experiments';
            app.IceexperimentsUIFigure.Icon = fullfile(pathToMLAPP, 'labExperiment_logo-01.png');
            app.IceexperimentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @IceexperimentsUIFigureCloseRequest, true);
            app.IceexperimentsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IceexperimentsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.experimentstatusLabel = uilabel(app.GridLayout2);
            app.experimentstatusLabel.Text = 'experimentstatusLabel';
            app.uiFileSystem = uiFileSystem(app.GridLayout2);
            app.uiFileSystem.DateFilters = [datetime([2023 10 25]) datetime([2023 10 25])];
            app.uiFileSystem.HiddenTreeVisible = true;
            app.uiFileSystem.SelectionChangedFcn = createCallbackFcn(app, @changeDir, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabSelection, true);
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.GridLayout8 = uigridlayout(app.DetailsTab);
            app.uiDetails = uiDetails(app.GridLayout8);
            app.uiDetails.ValueChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.GridLayout5 = uigridlayout(app.DesignTab);
            app.uiDesigner = uiDesigner(app.GridLayout5);
            app.uiDesigner.InputsChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiDesigner.ParameterSpaceChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiDesigner.StratificationChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.StratificationTab = uitab(app.TabGroup);
            app.StratificationTab.Title = 'Stratification';
            app.GridLayout5_4 = uigridlayout(app.StratificationTab);
            app.uiStratification = uiStratificationCheckBox_2022b(app.GridLayout5_4);
            app.uiStratification.dates = [datetime([2024 3 4]) datetime([2024 3 5])];
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.GridLayout6 = uigridlayout(app.CameraTab);
            app.NradsLabel = uilabel(app.GridLayout6);
            app.NradsLabel.Text = 'ω/N';
            app.omegaNEditField = uieditfield(app.GridLayout6, 'numeric');
            app.omegaNEditField.Limits = [0 Inf];
            app.omegaNEditField.ValueChangedFcn = createCallbackFcn(app, @changeCameraTimesCallback, true);
            app.omegaNEditField.Tooltip = {'Synchronise interval time to forcing frequency'};
            app.activeLabel = uilabel(app.GridLayout6);
            app.activeLabel.Text = '2π/ω (active)';
            app.activeSpinner = uispinner(app.GridLayout6);
            app.activeSpinner.ValueChangedFcn = createCallbackFcn(app, @changeCameraTimesCallback, true);
            app.activeSpinner.Tooltip = {'Number of wave periods until timeout.'};
            app.intervalSpinnerLabel = uilabel(app.GridLayout6);
            app.intervalSpinnerLabel.Text = '2π/ω (interval)';
            app.intervalSpinner = uispinner(app.GridLayout6);
            app.intervalSpinner.Limits = [0 Inf];
            app.intervalSpinner.ValueChangedFcn = createCallbackFcn(app, @changeCameraTimesCallback, true);
            app.intervalSpinner.Tooltip = {'Number of wave periods in an interval.'};
            app.omegaNListBox = uilistbox(app.GridLayout6);
            app.omegaNListBox.Items = {};
            app.omegaNListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.omegaNListBox.Tooltip = {'Saved configurations'; '(ω/N)'};
            app.omegaNListBox.Value = {};
            app.omegaLabel = uilabel(app.GridLayout6);
            app.omegaLabel.Text = '';
            app.amplitudeListBox = uilistbox(app.GridLayout6);
            app.amplitudeListBox.Items = {};
            app.amplitudeListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.amplitudeListBox.Tooltip = {'Saved configurations (amplitude)'};
            app.amplitudeListBox.Value = {};
            app.SchlierenbackgroundButton = uibutton(app.GridLayout6, 'state');
            app.SchlierenbackgroundButton.ValueChangedFcn = createCallbackFcn(app, @updateSchlierenCallback, true);
            app.SchlierenbackgroundButton.Tooltip = {'Toggles the generation and opening of a schlieren background when recording starts.'};
            app.SchlierenbackgroundButton.Text = 'Schlieren background';
            app.CameraSubTabGroup = uitabgroup(app.GridLayout6);
            app.CameraTab_2 = uitab(app.CameraSubTabGroup);
            app.CameraTab_2.Title = 'Camera';
            app.GridLayout9 = uigridlayout(app.CameraTab_2);
            app.uiCameraControl = uiCameraControl(app.GridLayout9);
            app.uiCameraControl.TimeStart = datetime([2023 10 10]);
            app.uiCameraControl.AcquisitionSettingsChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiCameraControl.RecordingFinishedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingPausedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingRestartedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingStartedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingStoppedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.SchlierenTab_2 = uitab(app.CameraSubTabGroup);
            app.SchlierenTab_2.Title = 'Schlieren';
            app.uiSchlierenPatternDesigner = uiSchlierenPatternDesigner(app.SchlierenTab_2);
            app.DatastoreTab = uitab(app.CameraSubTabGroup);
            app.DatastoreTab.Title = 'Datastore';
            app.GridLayout10 = uigridlayout(app.DatastoreTab);
            app.ImagecopierButton = uibutton(app.GridLayout10, 'push');
            app.ImagecopierButton.ButtonPushedFcn = createCallbackFcn(app, @ImageCopierCallback, true);
            app.ImagecopierButton.Text = 'Image copier';
            app.FilenameencodeButton = uibutton(app.GridLayout6, 'state');
            app.FilenameencodeButton.ValueChangedFcn = createCallbackFcn(app, @encodeFilename, true);
            app.FilenameencodeButton.Enable = 'off';
            app.FilenameencodeButton.Text = 'Filename encode';
            app.UsescheduleButton = uibutton(app.GridLayout6, 'state');
            app.UsescheduleButton.ValueChangedFcn = createCallbackFcn(app, @updateScheduleCallback, true);
            app.UsescheduleButton.Enable = 'off';
            app.UsescheduleButton.Tooltip = {'Starting a recording will follow a schedule that has been defined (Design & Internal Wave tabs). Properties such as active wave time, wave interval, and file encoding will carry over.'};
            app.UsescheduleButton.Text = 'Use schedule';
            app.InternalWaveTab = uitab(app.TabGroup);
            app.InternalWaveTab.Title = 'Internal Wave';
            app.GridLayout5_3 = uigridlayout(app.InternalWaveTab);
            app.uiWaveTank = uiWaveTank(app.GridLayout5_3);
            app.uiWaveTank.hasStarted = false;
            app.uiWaveTank.k0 = [];
            app.uiWaveTank.omega = 0;
            app.uiWaveTank.rayceil = [];
            app.uiWaveTank.rayfloor = [];
            app.uiWaveTank.ParameterSpaceChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout5_2 = uigridlayout(app.ResultsTab);
            app.uiResults = uiResults(app.GridLayout5_2);
            app.uiResults.root = 'D:\PhD\MATLAB_Drive\0Experiment\components';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.autoSaveButton = uibutton(app.GridLayout7, 'state');
            app.autoSaveButton.ValueChangedFcn = createCallbackFcn(app, @autoSaveCallback, true);
            app.autoSaveButton.Enable = 'off';
            app.autoSaveButton.Tooltip = {'Auto-save (off)'};
            app.autoSaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'mat-autorenew.png');
            app.autoSaveButton.Text = '';
            app.saveStatusLabel = uilabel(app.GridLayout7);
            app.saveStatusLabel.Text = 'saveStatusLabel';
            app.SaveButton = uibutton(app.GridLayout7, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.SaveButton.Tag = 'update';
            app.SaveButton.Icon = 'mat-save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.NewexperimentButton = uibutton(app.GridLayout7, 'push');
            app.NewexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.NewexperimentButton.Tag = 'create';
            app.NewexperimentButton.Icon = fullfile(pathToMLAPP, 'icons', 'mat-create-new-folder.png');
            app.NewexperimentButton.Text = 'New experiment';
            app.recordingStatusLabel = uilabel(app.GridLayout3);
            app.recordingStatusLabel.Text = '';
            % Show the figure after all components are created
            app.IceexperimentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_experiment

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IceexperimentsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IceexperimentsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceexperimentsUIFigure)
        end
    end
end