classdef app_experiment_updates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceexperimentsUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        uiFileSystem            uiFileSystem
        experimentstatusLabel   matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        recordingStatusLabel    matlab.ui.control.Label
        GridLayout7             matlab.ui.container.GridLayout
        NewexperimentButton     matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        saveStatusLabel         matlab.ui.control.Label
        autoSaveButton          matlab.ui.control.StateButton
        TabGroup                matlab.ui.container.TabGroup
        DetailsTab              matlab.ui.container.Tab
        GridLayout8             matlab.ui.container.GridLayout
        uiDetails               uiDetails
        DesignTab               matlab.ui.container.Tab
        GridLayout5             matlab.ui.container.GridLayout
        uiDesigner              uiDesigner
        StratificationTab       matlab.ui.container.Tab
        GridLayout5_4           matlab.ui.container.GridLayout
        uiStratification        uiStratificationCheckBox_2022b
        CameraTab               matlab.ui.container.Tab
        GridLayout6             matlab.ui.container.GridLayout
        uiCameraControl         uiCameraControl
        InternalWaveTab         matlab.ui.container.Tab
        GridLayout5_3           matlab.ui.container.GridLayout
        uiWaveTank              uiWaveTank
        ResultsTab              matlab.ui.container.Tab
        GridLayout5_2           matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        node
        selection
        files
        root {mustBeFolder(root)}=pwd;
        experiment
        defaultroot = '..\Data\ablation_images\'
        readOnly logical=false
    end
    properties (Access = private, Hidden)
        autosave {logical(autosave)}= false
        activeExperiment {logical(activeExperiment)}= false
    end
        
    methods (Access = public)
        %% Tab update
        function updateDetails(app)
            e = app.experiment;
            d = app.uiDetails;
            set(d, ...
                'N',e.N, ...
                'T',e.T,...
                'S',e.S, ...
                'rho',e.rho, ...
                'Date',e.dates, ...
                'Comments',e.comments, ...
                'eta',e.eta);
        end
        function updateResults(~)
            
        end
        function updateDesigner(app)
            e = app.experiment;
            d = app.uiDesigner;
            ss = struct( ...
                'Ta',e.T, ...
                'S0',min(e.S), ...
                'Sa',max(e.S), ...
                'rho0',min(e.rho), ...
                'rhoa',max(e.rho), ...
                'N',e.N);
            set(d, ...
                'StratificationStructure',ss, ...
                'InputsStructure',e.inputs,...
                'ParameterSpaceData',e.parameterspace)
            
        end
        function updateStratification(app)
            e = app.experiment;
            set(app.uiStratification, ...
                "N",e.N,...
                'rho',e.rho, ...
                'Stratification',e.mixinglog,...
                'conductivityData',e.probelog)
            try %#ok<*TRYNC> 
                i = e.inputs;
                set(app.uiStratification, ...
                    'FlowRate',i.FlowRate)
            end
            
        end
        function updateWaveTank(app)
            e = app.experiment;
            set(app.uiWaveTank, ...
                'ParameterSpaceData',e.parameterspace);
        end
        function updateCamera(app)
            e = app.experiment;
            ccstruct = e.camera;
            cc = app.uiCameraControl;
            isClonePath = isequal(fileparts(e.driveSaveFile),folderOnPath(app.selection));
            if isempty(ccstruct)&&~isClonePath
                cc.ImagePath = e.root;
                return
            end
        end
        %% Update other tabs
        function updatedDetails(app)
            % affects uiDesign, uiWaveTank
            e   = app.experiment;
        end
        function updatedDesign(app)
            % affects uiDetails, uiWaveTank, uiStratification
            updateDetails(app)
            updateStratification(app)
            updateWaveTank(app)
        end
        function updatedStratification(app)
            % affects uiDetails, uiWaveTank, uiDesigner
            updateDetails(app)
            updateDesigner(app)
        end
        function updatedWaveTank(app)
            % affects uiDesign
            updateDesigner(app)
        end
        function updatedCamera(~)
            % has no dependents (yet?)
        end
        %% Experiment update
        function autoSaving(app)
            if app.autosave&&app.activeExperiment
                saveData(app)
            end
        end
        function saveData(app,type)
            if nargin<2
                type = 'update';
            end
            switch type
                case 'update'
                    if ~app.activeExperiment
                        app.clearMessageAfter(app.saveStatusLabel,'No active experiment found.')
                        return
                    end
                    app.experiment.saveExperiment;
                case 'create'
                    app.experiment.saveExperiment;
                    return
            end
            app.clearMessageAfter(app.saveStatusLabel,'File saved')
        end
        function toggleSaveState(app)
            
        end
        function loadData(app)
            updateDetails(app)
            updateResults(app)
            updateCamera(app)
            updateDesigner(app)
            updateStratification(app)
            updateWaveTank(app)
        end
        function resetExperiment(app)
            app.experiment.dates = [datetime datetime];
            app.uiDetails.reset;
            app.uiDesigner.reset;
            app.experiment.root = app.uiFileSystem.SelectedPath;
            updateExperiment(app,struct('Source',app.uiStratification,'EventName','all'))
            updateExperiment(app,struct('Source',app.uiDesigner,'EventName','all'))
            updateExperiment(app,struct('Source',app.uiCameraControl))
        end
    end
    
    methods (Access = private,Hidden)
        
        function clearMessageAfter(~,component,message,time,lastmessage)
            if nargin<4
                time = 2;
            end
            if nargin<5
                lastmessage = '';
            end
            set(component,'Text',message)
            delayedFunction(@(~,~) set(component,'Text',lastmessage),time,message)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            home
            app.uiStratification.ActivateFileWatcher = true;
            set([ ...
                app.experimentstatusLabel ...
                app.saveStatusLabel], ...
                'Text','')
            app.experiment = labExperiment;
            assignin('base',"experiment",app.experiment)
            if isfolder(app.defaultroot)
                app.root = app.defaultroot;
            end
            app.uiFileSystem.root = app.root;
            app.uiFileSystem.buildTree;
            app.uiFileSystem.HiddenTreeVisible = false;
        end

        % Callback function: uiFileSystem
        function changeDir(app, event)
            % update
            app.root        = app.uiFileSystem.root;
            app.selection   = app.uiFileSystem.SelectedPath;
            app.node        = app.uiFileSystem.SelectedNode;
            app.files       = app.uiFileSystem.files;

            % search for experimentdata files
            matches = dir(fullfile(app.selection,['*' app.experiment.savetag '.mat']));
            status  = 'No experiment files detected';
            app.activeExperiment            = false;
            app.uiCameraControl.ImagePath   = app.selection;
            app.readOnly = false;
            app.saveStatusLabel.Text = '';
            if ~isempty(matches)
                status = matches(1).name;
                app.experiment=app.experiment.loadExperiment(matches(1));
                assignin('base',"experiment",app.experiment)
                if isequal(folderOnPath(app.selection),fileparts(app.experiment.driveSaveFile))
                    app.readOnly = true;
                end
                loadData(app)   
                app.activeExperiment = true;
                app.saveStatusLabel.Text = '';
                if ~app.readOnly
                    set([app.SaveButton app.autoSaveButton],Enable = 'on');
                    app.NewexperimentButton.Enable = 'off';
                else
                    set([app.SaveButton ...
                        app.autoSaveButton ...
                        app.NewexperimentButton],Enable = 'off');
                    app.saveStatusLabel.Text = 'Read-only';
                end
                app.clearMessageAfter(app.saveStatusLabel,'File loaded',2)
            else
                resetExperiment(app)
                set([app.SaveButton app.autoSaveButton],Enable = 'off');
                app.NewexperimentButton.Enable = 'on';
            end
            app.clearMessageAfter(app.experimentstatusLabel,status,1.5)
        end

        % Selection change function: TabGroup
        function tabSelection(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.DetailsTab
%                     updateDetails(app)
                case app.ResultsTab
                    updateResults(app)
                case app.DesignTab
%                     updateDesign(app)
                case app.InternalWaveTab
                    app.uiWaveTank.startProgram;
            end
        end

        % Value changed function: autoSaveButton
        function autoSaveCallback(app, event)
            app.autosave = app.autoSaveButton.Value;
            switch app.autosave
                case 0
                    app.autoSaveButton.Tooltip = 'Autosave (off)';
                case 1
                    app.autoSaveButton.Tooltip = 'Autosave (on)';
            end
            app.clearMessageAfter(app.saveStatusLabel,app.autoSaveButton.Tooltip,.5)
        end

        % Button pushed function: NewexperimentButton, SaveButton
        function saveCallback(app, event)
            saveData(app,event.Source.Tag)
        end

        % Callback function: uiCameraControl, uiDesigner, uiDesigner, 
        % ...and 6 other components
        function updateExperiment(app, event)
            e   = app.experiment;
            src = event.Source;

            % Handle different tab source callbacks
            switch event.Source
                case app.uiDetails
                    %% UIDETAILS (obsolete)
                    e.N     = src.N;
                    e.S     = src.S;
                    e.T     = src.T;
                    e.rho   = src.rho;
                    e.dates = src.Date;
                    e.comments = src.Comments;
                    updatedDetails(app)
                case app.uiDesigner
                    %% UIDESIGNER 
                    % Activates when "Accept" buttons are pressed
                    ss = src.StratificationStructure;
                    is = src.InputsStructure;
                    ps = src.ParameterSpaceData;
                    switch event.EventName
                        case {'StratificationChanged','InputsChanged'}
                            e.N     = ss.N;
                            e.S     = [ss.S0 ss.Sa];
                            e.rho   = [ss.rho0 ss.rhoa];
                            e.T     = ss.Ta;
                            e.eta   = ss.eta;
                            e.inputs= is;
                        case 'ParameterSpaceChanged'
                            e.parameterspace = ps;
                        case 'all'
                            e.N     = ss.N;
                            e.S     = [ss.S0 ss.Sa];
                            e.rho   = [ss.rho0 ss.rhoa];
                            e.T     = ss.Ta;
                            e.eta   = 0;
                            e.inputs= is;
                            e.parameterspace = ps;
                    end
                    updatedDesign(app)
                case app.uiStratification
                    %% UISTRATIFICATION
                    switch event.EventName
                        case 'ProfileGenerated'
                            % Updates when "Generate Profile" button is pressed
                            e.N     = src.N;
                            e.rho   = src.rho;
                            e.inputs.FlowRate = src.FlowRate;
                        case 'ProfileLogged'
                            e.mixinglog = src.Stratification;
                        case 'ConductivityProfileLogged'
                            e.probelog = src.conductivityData;
                        case 'all'
                            e.mixinglog = struct;
                            e.probelog  = struct;
                            return
                    end
                    updatedStratification(app)
                case app.uiWaveTank
                    %% UIWAVETANK
                    e.parameterspace=src.ParameterSpaceData;
                    updatedWaveTank(app)
                case app.uiCameraControl
                    %% UICAMERACONTROL
                    cc          = app.uiCameraControl;
                    cameras     = '';
                    ds          = nan;
                    imagepaths  = '';

                    if ~isempty(cc.labCamera)
                        cameras     = get([cc.labCamera.camera],'Name');
                        ds          = [cc.labCamera.ds];
                        imagepaths  = [cc.labCamera.savepath];
                    end

                    ccstruct = struct( ...
                        'TimeActive',cc.TimeActive, ...
                        'TimeInterval',cc.TimeInterval, ...
                        'TimeStart',cc.TimeStart, ...
                        'Cameras',cameras,...
                        'Resolution',ds, ...
                        'ImagePaths',imagepaths);
                    e.camera = ccstruct;
                    updatedCamera(app)
            end
            autoSaving(app)
        end

        % Callback function: uiCameraControl, uiCameraControl, 
        % ...and 2 other components
        function recordingCallback(app, event)
             name = strrep(event.EventName,'Recording','');
            switch name
                case 'Started'
                    txt=sprintf('Recording is active since %s',string(datetime));
                case 'Stopped'
                    txt=sprintf('Recording has been stopped since %s',string(datetime));
                case 'Paused'
                    txt=sprintf('Recording has been paused since %s',string(datetime));
                case 'Restarted'
                    txt=sprintf('Recording has been restarted since %s',string(datetime));
            end
            app.recordingStatusLabel.Text=txt;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IceexperimentsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IceexperimentsUIFigure = uifigure('Visible', 'off');
            app.IceexperimentsUIFigure.Name = 'Ice experiments';
            app.IceexperimentsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IceexperimentsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.experimentstatusLabel = uilabel(app.GridLayout2);
            app.experimentstatusLabel.Text = 'experimentstatusLabel';
            app.uiFileSystem = uiFileSystem(app.GridLayout2);
            app.uiFileSystem.DateFilters = [datetime([2023 10 25]) datetime([2023 10 25])];
            app.uiFileSystem.SelectionChangedFcn = createCallbackFcn(app, @changeDir, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabSelection, true);
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.GridLayout8 = uigridlayout(app.DetailsTab);
            app.uiDetails = uiDetails(app.GridLayout8);
            app.uiDetails.ValueChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.GridLayout5 = uigridlayout(app.DesignTab);
            app.uiDesigner = uiDesigner(app.GridLayout5);
            app.uiDesigner.InputsChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiDesigner.ParameterSpaceChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiDesigner.StratificationChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.StratificationTab = uitab(app.TabGroup);
            app.StratificationTab.Title = 'Stratification';
            app.GridLayout5_4 = uigridlayout(app.StratificationTab);
            app.uiStratification = uiStratificationCheckBox_2022b(app.GridLayout5_4);
            app.uiStratification.ConductivityProfileLoggedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiStratification.ProfileGeneratedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiStratification.ProfileLoggedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.GridLayout6 = uigridlayout(app.CameraTab);
            app.uiCameraControl = uiCameraControl(app.GridLayout6);
            app.uiCameraControl.TimeStart = datetime([2023 10 10]);
            app.uiCameraControl.AcquisitionSettingsChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.uiCameraControl.RecordingPausedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingRestartedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingStartedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.uiCameraControl.RecordingStoppedFcn = createCallbackFcn(app, @recordingCallback, true);
            app.InternalWaveTab = uitab(app.TabGroup);
            app.InternalWaveTab.Title = 'Internal Wave';
            app.GridLayout5_3 = uigridlayout(app.InternalWaveTab);
            app.uiWaveTank = uiWaveTank(app.GridLayout5_3);
            app.uiWaveTank.ParameterSpaceChangedFcn = createCallbackFcn(app, @updateExperiment, true);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout5_2 = uigridlayout(app.ResultsTab);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.autoSaveButton = uibutton(app.GridLayout7, 'state');
            app.autoSaveButton.ValueChangedFcn = createCallbackFcn(app, @autoSaveCallback, true);
            app.autoSaveButton.Enable = 'off';
            app.autoSaveButton.Tooltip = {'Auto-save (off)'};
            app.autoSaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'mat-autorenew.png');
            app.autoSaveButton.Text = '';
            app.saveStatusLabel = uilabel(app.GridLayout7);
            app.saveStatusLabel.Text = 'saveStatusLabel';
            app.SaveButton = uibutton(app.GridLayout7, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.SaveButton.Tag = 'update';
            app.SaveButton.Icon = 'mat-save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.NewexperimentButton = uibutton(app.GridLayout7, 'push');
            app.NewexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.NewexperimentButton.Tag = 'create';
            app.NewexperimentButton.Icon = fullfile(pathToMLAPP, 'icons', 'mat-create-new-folder.png');
            app.NewexperimentButton.Text = 'New experiment';
            app.recordingStatusLabel = uilabel(app.GridLayout3);
            app.recordingStatusLabel.Text = '';
            % Show the figure after all components are created
            app.IceexperimentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_experiment_updates

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IceexperimentsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IceexperimentsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceexperimentsUIFigure)
        end
    end
end