classdef app_meltingDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceMeltingDetectionUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        FolderTree                   uiFileSystem
        notification                 matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        SaveButton                   matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        uiEdgeDetectionControls      uiEdgeDetectionControls
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        root    {mustBeFolder(root)} = pwd
        edi     (1,1) edIce
        index   (1,1) double
    end

    properties (Access = private)
        photopath
        resultspath
    end

    properties (Access = private, Hidden)
        processingTime duration = seconds(nan)
    end

    %% Set
    methods
        function set.root(app,val)
            app.root = val;
            updateRoot(app)
        end
    end
    methods (Access=private)
        function updateRoot(app)
            app.FolderTree.root = app.root;
        end
    end
    
    %% Imaging
    methods (Access = public)
        function parseImage(app)
            
            app.notification.Text = 'Processing';
            pause(1e-4)
            errorstr = '';

            tic
            try
                app.uiEdgeDetectionControls.processEDI
            catch ME
                assignin("base",'ME',ME)
                errorstr = ' [Error]';
                app.notification.Tooltip = ME.message;
            end
            t0  = toc;
            t   = t0;
            warning on

            % computation time
            computationcalls    = sprintf('/operation %.2gs total',t0);
            
            clearMessageAfter(app,[], ...
                sprintf('Processing%s (%.2gs%s)',errorstr,t,computationcalls), ...
                60)
        end
    end
    %% Datastore
    methods (Access = private)
        function state = checkForSavedFile(app,fpath)
            state = false;
            [r,f] = fileparts(fpath);

            FILES = dir(fullfile(r,sprintf('ediData_%s.mat',f)));
            FILES = cat(1,FILES,dir(fullfile(fpath,('ediData_*+*.mat'))));
            if isempty(FILES)
                clearMessageAfter(app,[],'No saved files detected')
                return
            end
            file2load = fullfile(FILES.folder,FILES.name);
            app.edi = load(file2load).edi;
            clearMessageAfter(app,[],sprintf('%s loaded',char(FILES.name)))
            state = true;
        end
        function checkForSubFolders(app,fpath)
            folders = {DIR(fpath).name};
            if ~isempty(folders)
                app.notification.Text = strcat(app.notification.Text,' (filtering: "cam2")');
                pause(1e-4)
                ds = imageDatastore(fullfile(fpath,folders));
                filter = true;
            else
                ds = imageDatastore(fpath);
                filter = false;
            end

            t  = datetime({cellfun(@dir,ds.Files).date});
            [t,map]  = sort(hours(t-t(1)));
            ds.Files = ds.Files(map);
            [~,keep] = unique(t);
            ds.Files = ds.Files(keep);
            if filter
                ds.Files(~contains(ds.Files,'cam2'))=[];
            end
            app.edi  = edIce(ds);

            %app.edi = edIce(string(s));
        end
    end
    
    %% App functionality
    methods (Access = private)
        function configureLimits(app)
            uniqueFiles    = app.edi.imds.Files;
            if isa(app.edi.imds,'imMerge')
                uniqueFiles = 1:app.edi.imds.maxIndex;
            else
                uniqueFiles = 1:numel(uniqueFiles);
            end

            % Adjust image index slider
            ticks           = 0:round(numel(uniqueFiles)/5):numel(uniqueFiles);
            ticks(1)        = 1;
            ticks(end+1)    = numel(uniqueFiles);

            app.ImageIndex.Enable       = 'on';
            app.ImageIndex.Visible      = 'on';
            app.ImageIndex.Limits       = [1 numel(uniqueFiles)];
            app.ImageIndex.MajorTicks   = ticks;
            app.ImageIndex.MinorTicksMode  = 'auto';
            app.ImageIndex.MajorTickLabels = cellstr(num2str(ticks'))';

            app.ScrollwheelstepEditField.UserData = false;
            getScrollWheelStep(app)
        end
        function step = getScrollWheelStep(app)
            if ~app.ScrollwheelstepEditField.UserData
                step = round(diff(app.ImageIndex.Limits)/100);
                step(step<1)=1;
                app.ScrollwheelstepEditField.Value = step;
            else
                step = app.ScrollwheelstepEditField.Value;
            end

            if ~nargout
                clear step
            end
        end
        function clearMessageAfter(app,component,message,time,lastmessage)
            if nargin<4
                time = 2;
            end
            if nargin<5
                lastmessage = '';
            end
            if isempty(component)
                component = app.notification;
            end
            set(component,'Text',message)
            try delayedFunction(@(~,~) set(component,'Text',lastmessage),time)
            catch
                set(component,'Text',lastmessage)
            end
        end
        function state = toggleSwitch(~,component)
            switch component.Value
                case 'On'
                    state = true;
                case 'Off'
                    state = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % open root folder
            ROOT = 'D:\PhD\Data\ablation_images';
            if ~isfolder(ROOT)
                try ROOT = '..\Desktop\PhD\Data\ablation_images';
                catch
                    ROOT = pwd;
                end
            end
            app.FolderTree.root = ROOT;
            app.FolderTree.HiddenTreeVisible = false;
            app.uiEdgeDetectionControls.Axes = app.UIAxes;
            colormap(app.UIAxes,'gray')
            axis(app.UIAxes,'tight')
        end

        % Callback function: FolderTree
        function TreeSelectionChanged(app, event)
            %% CHECK NODES
            sn = app.FolderTree.SelectedNode;
            s = sn.UserData;
            
            %% CHECK FILES IN FOLDER

            app.SaveButton.Enable = 'off';
            app.notification.Text = 'Reading datastore';
            pause(1e-3)
            if ~checkForSavedFile(app,s)
                try
                    checkForSubFolders(app,s)
                catch ME
                    assignin('base',"ME",ME)
                    return
                end
            end
            app.uiEdgeDetectionControls.edi = app.edi;
            % send 'edi' to workspace
            assignin('base','edi',app.edi)

            app.SaveButton.Enable = 'on';
            parseImage(app)
            axis(app.UIAxes,'tight')
        end

        % Button pushed function: SaveButton
        function saveCallback(app, event)
            app.edi.saveData;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IceMeltingDetectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {589, 589};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IceMeltingDetectionUIFigure = uifigure('Visible', 'off');
            app.IceMeltingDetectionUIFigure.Name = 'Ice Melting Detection';
            app.IceMeltingDetectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IceMeltingDetectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveCallback, true);
            app.SaveButton.Tag = 'save';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Pushes the ''icedata'' superclass to a workspace file'};
            app.SaveButton.Text = 'Save';
            app.notification = uilabel(app.GridLayout2);
            app.notification.Text = '';
            app.FolderTree = uiFileSystem(app.GridLayout2);
            app.FolderTree.DateFilters = [datetime([2024 3 27]) datetime([2024 3 27])];
            app.FolderTree.HiddenTreeVisible = true;
            app.FolderTree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            app.uiEdgeDetectionControls = uiEdgeDetectionControls(app.GridLayout5);
            % Show the figure after all components are created
            app.IceMeltingDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_meltingDetection

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IceMeltingDetectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IceMeltingDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceMeltingDetectionUIFigure)
        end
    end
end