classdef ICE_analysis_211006 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisUIFigure           matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        FolderPanel                matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        resultsfolder              matlab.ui.control.ListBox
        TabGroup                   matlab.ui.container.TabGroup
        TimeseriesTab              matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ControlsPanel              matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        PixelcutoffcmSlider        matlab.ui.control.Slider
        PixelcutoffcmSliderLabel   matlab.ui.control.Label
        PixelrejectionSlider       matlab.ui.control.Slider
        PixelrejectionSliderLabel  matlab.ui.control.Label
        SmoothingTSlider           matlab.ui.control.Slider
        SmoothingTSliderLabel      matlab.ui.control.Label
        SmoothingZSlider           matlab.ui.control.Slider
        SmoothingZSliderLabel      matlab.ui.control.Label
        HeightmEditField           matlab.ui.control.NumericEditField
        HeightmEditFieldLabel      matlab.ui.control.Label
        EdgebufferSpinner          matlab.ui.control.Spinner
        EdgebufferLabel            matlab.ui.control.Label
        RangeLabel                 matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        loop_end                   matlab.ui.control.NumericEditField
        loop_step                  matlab.ui.control.NumericEditField
        loop_start                 matlab.ui.control.NumericEditField
        OutputsPanel               matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        TypeDropDown               matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        CaxisDropDown              matlab.ui.control.DropDown
        CaxisDropDownLabel         matlab.ui.control.Label
        TimeDropDown               matlab.ui.control.DropDown
        TimeDropDownLabel          matlab.ui.control.Label
        MeltTab                    matlab.ui.container.Tab
        GridLayout10               matlab.ui.container.GridLayout
        Panel_4                    matlab.ui.container.Panel
        GridLayout11_3             matlab.ui.container.GridLayout
        Panel_3                    matlab.ui.container.Panel
        GridLayout11_2             matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout11               matlab.ui.container.GridLayout
        TimeDropDown_2             matlab.ui.control.DropDown
        TimeDropDown_2Label        matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        TextArea                   matlab.ui.control.TextArea
        Panel                      matlab.ui.container.Panel
        GridLayout2_2              matlab.ui.container.GridLayout
        ColormapDropDown           matlab.ui.control.DropDown
        ColormapDropDownLabel      matlab.ui.control.Label
        ReprocessButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        root,path,file,loadedfile,state,args,analysis,melt,layers,reprocess
    end

    methods (Access = private)
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end

            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end

            % Enable new callbacks now that the prompt has been modified
            inProgress = [];

        end  % setPromptFcn
        
        function autosave(app)
            switch app.state
                case 'analysis'
                    analysis = app.analysis;
            end
            
            if isfile(app.root)
                save(app.root,app.state,'-append')
            else
                save(app.root,app.state)
            end
        end
        
        function updateFolderList(app)
            app.path=fullfile(cd,'results');

            files = dir(app.path);
            files = files(3:end);

            for i=1:length(files)
                app.resultsfolder.Items{i} = files(i).name;
            end
        end
    end
    
    methods (Access = public)
        
        function gatherNewData(app,input)
                app.state   = 'analysis';
                app.args    = input;
                updateFolderList(app)
                app.resultsfolder.Value = strcat(input.expt,'.mat');
                updateAxes(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputfile)
            addpath(genpath('functions'))
            
            app.ColormapDropDown.Items = colormapclasses;
            app.ColormapDropDown.Value = 'parula';
            %% Populate results folder list
            updateFolderList(app)

            %% Optional input argument handling
            if nargin==1
                inputfile = [];
            end

            if isempty(inputfile)
                app.ReprocessButton.Enable = 'off';
            else
                app.state = 'analysis';
                app.args = inputfile;
                updateAxes(app)
            end

            %% Command window display
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {401, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CaxisDropDown, ColormapDropDown, 
        % EdgebufferSpinner, HeightmEditField, PixelcutoffcmSlider, 
        % PixelcutoffcmSlider, PixelrejectionSlider, 
        % PixelrejectionSlider, SmoothingTSlider, SmoothingTSlider, 
        % SmoothingZSlider, SmoothingZSlider, TimeDropDown, 
        % TypeDropDown, loop_end, loop_start, loop_step
        function updateAxes(app, event)
            clc
            switch app.state
                case 'analysis'                  
                    
                    if app.loop_end.Value==Inf || app.loop_end.Value>length(app.args.files)
                        app.loop_end.Value = length(app.args.files);
                    end
                    
                    if isempty(app.reprocess)
                        app.reprocess = '';
                    end
                    
                    %% Time-series generator
                    app.analysis = EdgeAnalysis(app.args,...
                        app.TypeDropDown.Value,...
                        app.CaxisDropDown.Value,...
                        lower(app.TimeDropDown.Value),...
                        app.reprocess,...
                        'loop',app.loop_start.Value:app.loop_step.Value:app.loop_end.Value,...
                        'step',app.loop_step.Value,...
                        'Zspan',app.HeightmEditField.Value,...
                        'buffer',app.EdgebufferSpinner.Value,...
                        'cam',app.UIAxes.View);
                    
                    app.reprocess = '';
                    
                    %% Pixel rejection
                    map = app.analysis.H*app.analysis.ds>app.PixelcutoffcmSlider.Value*1e-2;
                    app.analysis.H = app.analysis.H.*bwareaopen(map,round(app.PixelrejectionSlider.Value));
                    
                    
                    %% Smoothing
                    app.SmoothingZSlider.Value = round(app.SmoothingZSlider.Value);
                    app.SmoothingTSlider.Value = round(app.SmoothingTSlider.Value);
                    if (app.SmoothingZSlider.Value>0)&&(app.SmoothingTSlider.Value>0)
                        app.analysis.H = smooth2a(app.analysis.H,app.SmoothingZSlider.Value,app.SmoothingTSlider.Value);
                    elseif (app.SmoothingZSlider.Value==0)
                        app.analysis.H = smooth2a(app.analysis.H,1,app.SmoothingTSlider.Value);
                    elseif (app.SmoothingTSlider.Value==0)
                        app.analysis.H = smooth2a(app.analysis.H,app.SmoothingZSlider.Value,1);
                    end
                    
                    %% Saving
                    app.args.root       = app.analysis.root;
                    app.args.savepath   = app.analysis.savepath;
                    assignin('base','analysis',app.analysis);
                    
                    %% Plotting
                    appPlotting(app.UIAxes,'analysis',app.analysis,app.ColormapDropDown.Value)
            end
            
            %% Automatic saving
            autosave(app)

            
            %% Print statements
            app.loadedfile = load(app.root);
            fprintf('File saved. Contents:\n')
            disp(app.loadedfile)
        end

        % Button pushed function: ReprocessButton
        function analysisCallback(app, event)
            app.state       = 'analysis';
            app.reprocess   = 'rerun';
            updateAxes(app)
        end

        % Callback function
        function folderCallback(app, event)
            [FILE,path]  = uigetfile(fullfile(cd,'results')); %cd must be experiment directory
            app.file = fullfile(path,FILE);
            
            if ~FILE
                disp('No file selected')
                return
            else
                app.loadedfile = load(app.file);
            end

            items = fieldnames(app.loadedfile);

            for i=1:length(items)
                switch items{i}
                    case 'args'
                        app.args = app.loadedfile.args;
                        analysisCallback(app)
                end
            end

            app.ReprocessButton.Enable = 'on';
        end

        % Value changed function: resultsfolder
        function folderlistCallback(app, event)
            FILE = app.resultsfolder.Value;
            app.file = fullfile(cd,'results',FILE);
            if ~app.file
                disp('No file selected')
                return
            else
                app.loadedfile = load(app.file);
            end
            app.root = app.file;
            items = fieldnames(app.loadedfile);

            for i=1:length(items)
                switch items{i}
                    case 'args'
                        app.args = app.loadedfile.args;
                        analysisCallback(app)
                end
            end
            app.ReprocessButton.Enable = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisUIFigure = uifigure('Visible', 'off');
            app.AnalysisUIFigure.Name = 'Analysis';
            app.AnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.TimeseriesTab = uitab(app.TabGroup);
            app.TimeseriesTab.Title = 'Time-series';
            app.GridLayout2 = uigridlayout(app.TimeseriesTab);
            app.OutputsPanel = uipanel(app.GridLayout2);
            app.OutputsPanel.Title = 'Outputs';
            app.GridLayout3 = uigridlayout(app.OutputsPanel);
            app.TimeDropDownLabel = uilabel(app.GridLayout3);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.GridLayout3);
            app.TimeDropDown.Items = {'Seconds', 'Minutes', 'Hours', 'Days'};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.TimeDropDown.Value = 'Hours';
            app.CaxisDropDownLabel = uilabel(app.GridLayout3);
            app.CaxisDropDownLabel.Text = 'C-axis';
            app.CaxisDropDown = uidropdown(app.GridLayout3);
            app.CaxisDropDown.Items = {'Thickness', 'Gradient'};
            app.CaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.CaxisDropDown.Value = 'Thickness';
            app.TypeDropDownLabel = uilabel(app.GridLayout3);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout3);
            app.TypeDropDown.Items = {'Surf', 'Waterfall', 'Contoured'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.TypeDropDown.Value = 'Surf';
            app.ControlsPanel = uipanel(app.GridLayout2);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout4 = uigridlayout(app.ControlsPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.loop_start = uieditfield(app.GridLayout5, 'numeric');
            app.loop_start.Limits = [1 Inf];
            app.loop_start.RoundFractionalValues = 'on';
            app.loop_start.ValueDisplayFormat = '%.0f';
            app.loop_start.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.loop_start.Value = 1;
            app.loop_step = uieditfield(app.GridLayout5, 'numeric');
            app.loop_step.Limits = [1 Inf];
            app.loop_step.RoundFractionalValues = 'on';
            app.loop_step.ValueDisplayFormat = '%.0f';
            app.loop_step.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.loop_step.Value = 1;
            app.loop_end = uieditfield(app.GridLayout5, 'numeric');
            app.loop_end.Limits = [1 Inf];
            app.loop_end.ValueDisplayFormat = '%.0f';
            app.loop_end.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.loop_end.Value = Inf;
            app.RangeLabel = uilabel(app.GridLayout4);
            app.RangeLabel.Text = 'Range';
            app.EdgebufferLabel = uilabel(app.GridLayout4);
            app.EdgebufferLabel.Text = {'Edge'; 'buffer'};
            app.EdgebufferSpinner = uispinner(app.GridLayout4);
            app.EdgebufferSpinner.Limits = [0 Inf];
            app.EdgebufferSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.EdgebufferSpinner.Value = 5;
            app.HeightmEditFieldLabel = uilabel(app.GridLayout4);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.HeightmEditField.Value = 1.1;
            app.SmoothingZSliderLabel = uilabel(app.GridLayout4);
            app.SmoothingZSliderLabel.Text = {'Smoothing'; '(Z)'};
            app.SmoothingZSlider = uislider(app.GridLayout4);
            app.SmoothingZSlider.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.SmoothingZSlider.ValueChangingFcn = createCallbackFcn(app, @updateAxes, true);
            app.SmoothingTSliderLabel = uilabel(app.GridLayout4);
            app.SmoothingTSliderLabel.Text = {'Smoothing'; '(T)'};
            app.SmoothingTSlider = uislider(app.GridLayout4);
            app.SmoothingTSlider.Limits = [0 10];
            app.SmoothingTSlider.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.SmoothingTSlider.ValueChangingFcn = createCallbackFcn(app, @updateAxes, true);
            app.PixelrejectionSliderLabel = uilabel(app.GridLayout4);
            app.PixelrejectionSliderLabel.Text = {'Pixel'; 'rejection'};
            app.PixelrejectionSlider = uislider(app.GridLayout4);
            app.PixelrejectionSlider.Limits = [0 1000];
            app.PixelrejectionSlider.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.PixelrejectionSlider.ValueChangingFcn = createCallbackFcn(app, @updateAxes, true);
            app.PixelcutoffcmSliderLabel = uilabel(app.GridLayout4);
            app.PixelcutoffcmSliderLabel.Text = {'Pixel cutoff'; '(cm)'};
            app.PixelcutoffcmSlider = uislider(app.GridLayout4);
            app.PixelcutoffcmSlider.Limits = [0 1];
            app.PixelcutoffcmSlider.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.PixelcutoffcmSlider.ValueChangingFcn = createCallbackFcn(app, @updateAxes, true);
            app.MeltTab = uitab(app.TabGroup);
            app.MeltTab.Title = 'Melt';
            app.GridLayout10 = uigridlayout(app.MeltTab);
            app.Panel_2 = uipanel(app.GridLayout10);
            app.Panel_2.Title = 'Panel';
            app.GridLayout11 = uigridlayout(app.Panel_2);
            app.TimeDropDown_2Label = uilabel(app.GridLayout11);
            app.TimeDropDown_2Label.Text = 'Time';
            app.TimeDropDown_2 = uidropdown(app.GridLayout11);
            app.TimeDropDown_2.Items = {'Seconds', 'Minutes', 'Hours', 'Days'};
            app.TimeDropDown_2.Value = 'Hours';
            app.Panel_3 = uipanel(app.GridLayout10);
            app.Panel_3.Title = 'Panel';
            app.GridLayout11_2 = uigridlayout(app.Panel_3);
            app.Panel_4 = uipanel(app.GridLayout10);
            app.Panel_4.Title = 'Panel';
            app.GridLayout11_3 = uigridlayout(app.Panel_4);
            app.FolderPanel = uipanel(app.GridLayout8);
            app.FolderPanel.Title = 'Folder';
            app.GridLayout9 = uigridlayout(app.FolderPanel);
            app.resultsfolder = uilistbox(app.GridLayout9);
            app.resultsfolder.Items = {};
            app.resultsfolder.ValueChangedFcn = createCallbackFcn(app, @folderlistCallback, true);
            app.resultsfolder.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Panel = uipanel(app.GridLayout7);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.ReprocessButton = uibutton(app.GridLayout2_2, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @analysisCallback, true);
            app.ReprocessButton.Text = 'Reprocess';
            app.ColormapDropDownLabel = uilabel(app.GridLayout2_2);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout2_2);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes, true);
            app.ColormapDropDown.Value = {};
            app.TextArea = uitextarea(app.GridLayout7);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.AnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICE_analysis_211006(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisUIFigure)
        end
    end
end