classdef imagepv < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        GridLayout2                 matlab.ui.container.GridLayout
        RefreshratesEditField       matlab.ui.control.NumericEditField
        RefreshratesEditFieldLabel  matlab.ui.control.Label
        PauseButton                 matlab.ui.control.StateButton
        GridLayout4                 matlab.ui.container.GridLayout
        openButton                  matlab.ui.control.Button
        closeButton                 matlab.ui.control.Button
        ColorbarsettingsPanel       matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        SymmetricButton             matlab.ui.control.StateButton
        negT                        matlab.ui.control.Label
        posT                        matlab.ui.control.Label
        colormapChoice              matlab.ui.control.DropDown
        maxT                        matlab.ui.control.NumericEditField
        MaximumCEditFieldLabel      matlab.ui.control.Label
        minT                        matlab.ui.control.NumericEditField
        MinimumCEditFieldLabel      matlab.ui.control.Label
        negSlider                   matlab.ui.control.Slider
        posSlider                   matlab.ui.control.Slider
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        PauseButtonPushed
        RefreshRateChanged
    end

    properties (Access = public)
        CData
        XData
        YData
        images double % 2D time-series data
    end
    
    properties (Access = public, Constant)
        Axes
    end
    
    
    methods (Access = public)
        function stream(comp,image,x,y)
            if nargin<2
                x = 1:size(image,2);
            end
            if nargin<3
                y = 1:size(image,1);
            end
            if isempty(comp.CData)
                imdata = imagesc(comp.UIAxes,x,y,image);
                comp.CData = imdata.CData;
                comp.XData = imdata.XData;
                comp.YData = imdata.YData;
                return
            end
            set(findall(app.UIAxes,'Type','Image'),'CData',image)
        end
        function updateAxes(comp)
            val     = sort([comp.minT.Value comp.maxT.Value]);
            clim    = mean(val)+[comp.negSlider.UserData comp.posSlider.UserData];
            if comp.SymmetricButton.Value
                
            end

            try %#ok<TRYNC> 
                set(comp.UIAxes, ...
                    'CLim',clim)
            end
        end
    end
    
    methods (Access = private)
        
        function setSliders(comp)
            val     = sort([comp.minT.Value comp.maxT.Value]);
            newlim  = diff(val)/2;
            comp.negSlider.Limits = [-newlim 0];
            comp.posSlider.Limits = [0 newlim];
            comp.negSlider.Value(comp.negSlider.Value<-newlim)=-newlim;
            comp.posSlider.Value(comp.posSlider.Value>newlim)=newlim;
            comp.posT.Text=sprintf('+%.2f °C',comp.posSlider.Value);
            comp.negT.Text=sprintf('%.2f °C',comp.negSlider.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            % minimize colorbar tab
            comp.GridLayout.ColumnWidth={'1x',20,0};
            % omit labels
            comp.posT.Text=sprintf('+%.2f °C',comp.posSlider.Value);
            comp.negT.Text=sprintf('%.2f °C',comp.negSlider.Value);

            % build colormap labels
            builtinmaps = {'parula','jet','hsv','hot','cool','spring',...
                'spring','summer','autumn','winter',...
                'gray','bone','copper','pink','lines',...
                'colorcube','prism','flag','white'};
            comp.colormapChoice.Items = builtinmaps;

            colorbar(comp.UIAxes,"eastoutside")

            comp.negSlider.UserData = comp.negSlider.Value;
            comp.posSlider.UserData = comp.posSlider.Value;
            updateAxes(comp)
        end

        % Button pushed function: closeButton, openButton
        function toggleSettings(comp, event)
            switch event.Source
                case comp.openButton
                    comp.GridLayout.ColumnWidth={'1x',20,150};
                case comp.closeButton
                    comp.GridLayout.ColumnWidth={'1x',20,0};
            end
        end

        % Value changed function: maxT, minT
        function colorbarLimits(comp, event)
            % check limits
            val = sort([comp.minT.Value comp.maxT.Value]);
            comp.minT.Value = min(val);
            comp.maxT.Value = max(val);

            % set sliders
            setSliders(comp)


            updateAxes(comp)
        end

        % Value changing function: negSlider, posSlider
        function colorbarReadout(comp, event)
            changingValue = event.Value;
            sliders     = [comp.posSlider comp.negSlider];
            slidertext  = [comp.posT comp.negT];
            sgn         = {'','+'};
            valid       = event.Source~=sliders;
            if comp.SymmetricButton.Value
                set(sliders(valid), ...
                    'Value',-changingValue, ...
                    'UserData',-changingValue)
                set(slidertext(valid), ...
                    'Text',sprintf('%s%.2f °C',sgn{~valid},-changingValue))
            end
            sliders(event.Source==sliders).UserData = changingValue;
            switch event.Source
                case comp.posSlider
                    comp.posT.Text=sprintf('%s%.2f °C',sgn{valid},changingValue);
                case comp.negSlider
                    comp.negT.Text=sprintf('%s%.2f °C',sgn{valid},changingValue);
            end
            updateAxes(comp)
        end

        % Value changed function: colormapChoice
        function colormapChanged(comp, event)
            value = comp.colormapChoice.Value;
            colormap(comp.UIAxes,value)
        end

        % Value changed function: SymmetricButton
        function updateCallback(comp, event)
            updateAxes(comp)
        end

        % Value changed function: PauseButton
        function playback(comp, event)
            switch event.Value
                case false
                    event.Source.Text = 'Pause';
                case true
                    event.Source.Text = 'Play';
            end
            notify(comp,'PauseButtonPushed')
        end

        % Value changed function: RefreshratesEditField
        function refreshRateChanged(comp, event)
            notify(comp,'RefreshRateChanged')
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 582 487];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ColorbarsettingsPanel = uipanel(comp.GridLayout);
            comp.ColorbarsettingsPanel.Title = 'Colorbar settings';
            comp.GridLayout3 = uigridlayout(comp.ColorbarsettingsPanel);
            comp.posSlider = uislider(comp.GridLayout3);
            comp.posSlider.Limits = [0 5];
            comp.posSlider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @colorbarReadout, true);
            comp.posSlider.Value = 5;
            comp.negSlider = uislider(comp.GridLayout3);
            comp.negSlider.Limits = [-5 0];
            comp.negSlider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @colorbarReadout, true);
            comp.negSlider.Value = -5;
            comp.MinimumCEditFieldLabel = uilabel(comp.GridLayout3);
            comp.MinimumCEditFieldLabel.Text = 'Minimum (°C)';
            comp.minT = uieditfield(comp.GridLayout3, 'numeric');
            comp.minT.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @colorbarLimits, true);
            comp.minT.Value = 20;
            comp.MaximumCEditFieldLabel = uilabel(comp.GridLayout3);
            comp.MaximumCEditFieldLabel.Text = 'Maximum (°C)';
            comp.maxT = uieditfield(comp.GridLayout3, 'numeric');
            comp.maxT.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @colorbarLimits, true);
            comp.maxT.Value = 30;
            comp.colormapChoice = uidropdown(comp.GridLayout3);
            comp.colormapChoice.Items = {'colormaps'};
            comp.colormapChoice.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @colormapChanged, true);
            comp.colormapChoice.Value = 'colormaps';
            comp.posT = uilabel(comp.GridLayout3);
            comp.posT.Text = '+ve';
            comp.negT = uilabel(comp.GridLayout3);
            comp.negT.Text = '-ve';
            comp.SymmetricButton = uibutton(comp.GridLayout3, 'state');
            comp.SymmetricButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateCallback, true);
            comp.SymmetricButton.Text = 'Symmetric';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.closeButton = uibutton(comp.GridLayout4, 'push');
            comp.closeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleSettings, true);
            comp.closeButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronEastUI.svg');
            comp.closeButton.Tooltip = {'Close the colorbar tab'};
            comp.closeButton.Text = '';
            comp.openButton = uibutton(comp.GridLayout4, 'push');
            comp.openButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleSettings, true);
            comp.openButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronWestUI.svg');
            comp.openButton.Tooltip = {'Open the colorbar tab'};
            comp.openButton.Text = '';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.PauseButton = uibutton(comp.GridLayout2, 'state');
            comp.PauseButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @playback, true);
            comp.PauseButton.Text = 'Pause';
            comp.RefreshratesEditFieldLabel = uilabel(comp.GridLayout2);
            comp.RefreshratesEditFieldLabel.Text = 'Refresh rate (s)';
            comp.RefreshratesEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.RefreshratesEditField.Limits = [0 Inf];
            comp.RefreshratesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refreshRateChanged, true);
            comp.Panel = uipanel(comp.GridLayout);
            comp.GridLayout5 = uigridlayout(comp.Panel);
            comp.UIAxes = uiaxes(comp.GridLayout5);
            % Execute the startup function
            startupFcn(comp)
        end
    end
end