classdef uiCameraControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        RecordingPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ReadoutsLabel                  matlab.ui.control.TextArea
        openFolderButton               matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        BeginButton                    matlab.ui.control.Button
        AcquisitionsettingsPanel       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        FoldersettingsPanel            matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        assigntoListBox                matlab.ui.control.ListBox
        appendUniqueSubfolderButton    matlab.ui.control.Button
        uniqueSubfolderButton          matlab.ui.control.Button
        filename_previewLabel          matlab.ui.control.Label
        FilenamelabelEditField         matlab.ui.control.EditField
        FilenamelabelEditFieldLabel    matlab.ui.control.Label
        NotifyMultiselect              matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        ImagerepositoryEditField       matlab.ui.control.EditField
        ImagerepositoryEditFieldLabel  matlab.ui.control.Label
        timeSheduleLabel               matlab.ui.control.Label
        timeSheduleLabel_2             matlab.ui.control.Label
        ScheduleDate                   matlab.ui.control.DatePicker
        SetfromDropDown                matlab.ui.control.DropDown
        SetfromDropDownLabel           matlab.ui.control.Label
        GL_Start                       matlab.ui.container.GridLayout
        set_ss                         matlab.ui.control.NumericEditField
        set_mm                         matlab.ui.control.NumericEditField
        set_hh                         matlab.ui.control.NumericEditField
        set_dd                         matlab.ui.control.NumericEditField
        StarttimeddhhmmssLabel         matlab.ui.control.Label
        GL_Interval                    matlab.ui.container.GridLayout
        interval_SSS                   matlab.ui.control.NumericEditField
        interval_ss                    matlab.ui.control.NumericEditField
        interval_mm                    matlab.ui.control.NumericEditField
        interval_hh                    matlab.ui.control.NumericEditField
        IntervaltimehhmmssSSSLabel     matlab.ui.control.Label
        GL_Active                      matlab.ui.container.GridLayout
        duration_SSS                   matlab.ui.control.NumericEditField
        duration_ss                    matlab.ui.control.NumericEditField
        duration_mm                    matlab.ui.control.NumericEditField
        duration_hh                    matlab.ui.control.NumericEditField
        ActivetimehhmmssSSSLabel       matlab.ui.control.Label
        DeviceselectionPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        debugButton                    matlab.ui.control.StateButton
        timerLabel                     matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        ChangesettingsButton           matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        NotificationsLabel             matlab.ui.control.Label
        DeviceIDListBox                matlab.ui.control.ListBox
        DeviceIDLabel                  matlab.ui.control.Label
        AdaptorListBox                 matlab.ui.control.ListBox
        AdaptorLabel                   matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        AcquisitionSettingsChanged
        RecordingFinished
        RecordingPaused
        RecordingRestarted
        RecordingSettingsChanged
        RecordingStarted
        RecordingStopped
    end

    properties (Access = private)
        adaptor
        cameraSelectionChange logical = false;
        debugMode logical = false; % Toggles debug mode
        device
        internalTimeChange logical = false;
        pauseTime duration = seconds(0);
        scheduleTimer timer
    end
    
    properties (Access = public)
        FilenameTag string % Optional filename tag
        ImagePath string {mustBeFolder(ImagePath)}
        IsRecording logical = false;
        TimeActive duration = seconds(nan); % Active camera time
        TimeInterval duration = seconds(nan); % Interval time between images for camera
        TimeStart datetime = datetime('now'); % Start time for camera acquisition
        camTimer timer = timer;
        camera
        isRecording logical = false;
        labCamera
        metadataPath string % Filename of current image metadata path(s)
        recordingState char = 'off';
    end
    
    %% SET functions
    methods
        function set.isRecording(comp,val)
            comp.isRecording = val;
            toggleRecording(comp)
        end
        function set.TimeActive(comp,val)
            if ~isduration(val)
                error('Value must be a duration')
            end
            comp.TimeActive = val;
            updateTimeActive(comp)
        end
        function set.TimeInterval(comp,val)
            if ~isduration(val)
                error('Value must be a duration')
            end
            comp.TimeInterval = val;
            updateTimeInterval(comp)
        end
        function set.TimeStart(comp,val)
            if ~isdatetime(val)
                error('Value must be a datetime')
            end
            comp.TimeStart = val;
            updateTimeStart(comp)
        end
        function set.ImagePath(comp,val)
            comp.ImagePath = val;
            updateImagePath(comp)
        end
        function set.FilenameTag(comp,val)
            comp.FilenameTag = val;
            updateFilename(comp)
        end

        %% SET related functions
        function toggleRecording(comp)
            event = struct;
            switch comp.isRecording
                case true
                    event.Source = comp.BeginButton;
                case false
                    event.Source = comp.StopButton;
            end
            recordingCallback(app,event)
        end
        function setCameraTime(comp,event)
            camCond = ~comp.cameraSelectionChange;
            notify(comp, 'AcquisitionSettingsChanged');
            vc = validcamera(comp);
            if isempty(vc)
                %warning off backtrace
                %warning('Cannot set times as no cameras are connected')
                return
            end

            if nargin<2
                event = 'all';
            else
                event = event.Source.Tag;
            end
            
            switch event
                case 'starttime'
                    st=sum([days(comp.set_dd.Value) ...
                    hours(comp.set_hh.Value)...
                    minutes(comp.set_mm.Value) ...
                    seconds(comp.set_ss.Value)]);

                    if camCond;arrayfun(@(cam)set(cam, ...
                            starttime=comp.TimeStart+seconds(1), ...
                            timeset=struct( ...
                            'Time',datetime, ...
                            'From',comp.SetfromDropDown.Value, ...
                            'Value',st)), ...
                            vc)
                    end
                case 'interval'
                    if isnan(comp.TimeInterval);error('Input time interval is not valid');end
                    if camCond;arrayfun(@(cam)set(cam,interval=comp.TimeInterval),vc);end
                case 'duration'
                    if camCond;arrayfun(@(cam)set(cam,duration=comp.TimeActive),vc);end
                case 'all'
                    if camCond
                        arrayfun(@(cam)set(cam, ...
                            starttime=comp.TimeStart, ...
                            duration=comp.TimeActive, ...
                            interval=comp.TimeInterval),vc)
                    end
            end
            notify(comp, 'AcquisitionSettingsChanged');
        end
    end
    %% UPDATE functions
    methods (Access= private)
        function updateTimes(comp)
            vc=validcamera(comp);
            
            if numel(vc)>1
                vc = vc(1);
            end
            comp.cameraSelectionChange = true;
            switch vc.timeset.From
                case 'Now'
                    val  = datetime+vc.timeset.Value+seconds(1);
                    if isempty(val)
                        comp.TimeStart = datetime;
                    else
                        comp.TimeStart = val;
                    end
                case 'From'
                    comp.TimeStart  = vc.timeset.Time;
            end
            comp.TimeStart(comp.TimeStart<datetime)=datetime;
            comp.TimeInterval   = vc.interval;
            comp.TimeActive     = vc.duration;
            comp.cameraSelectionChange = false;
        end
        function updateTimeActive(comp)
            if comp.internalTimeChange
                return
            end
            val = splitTime(comp,comp.TimeActive);
            arrayfun(@(val,component) set(component,Value=val),val',[ ...
                comp.duration_hh comp.duration_mm comp.duration_ss comp.duration_SSS])
            timeReadOut(comp)
            setCameraTime(comp)
        end
        function updateTimeInterval(comp)
            if comp.internalTimeChange
                return
            end
            val = splitTime(comp,comp.TimeInterval);
            arrayfun(@(val,component) set(component,Value=val),val',[ ...
                comp.interval_hh comp.interval_mm comp.interval_ss comp.interval_SSS])
            timeReadOut(comp)
            setCameraTime(comp)
        end
        function updateTimeStart(comp)
            if comp.internalTimeChange
                return
            end
            val = splitTime(comp,comp.TimeStart,'uuuu:MM:dd:hh:mm:ss');

            if datetime('tomorrow')-comp.TimeStart<0
                set(comp.ScheduleDate,Value = comp.TimeStart,Enable='on')
                comp.SetfromDropDown.Value = 'Date';
                val = val(4:end);
                toggleClock = false;
            else
                set(comp.ScheduleDate,Value = comp.TimeStart,Enable='off')
                comp.SetfromDropDown.Value = 'Now';
                val = splitTime(comp,comp.TimeStart-datetime,'hh:mm:ss');
                toggleClock = true;
                
            end
            if any(val<0)
                val = val*0;
            end
            arrayfun(@(val,component) set(component,Value=val),val',[ ...
                comp.set_hh comp.set_mm comp.set_ss])
            setCameraTime(comp)
            timeReadOut(comp)
            switch toggleClock
                case true
                    if strcmp(comp.scheduleTimer,'off')
                        ScheduleTimer(comp,'start')
                    end
                case false
                    ScheduleTimer(comp,'stop')
            end
        end
        function updateImagePath(comp)
            comp.ImagerepositoryEditField.Value = comp.ImagePath;
            vc = validcamera(comp);
            if ~isempty(vc)
                set(vc,savepath=comp.ImagePath)
            end
            checkForUniqueSubfolders(comp);
        end
        
    end
    %% PROGRAM functions
    methods (Access = private)
        %% Quick functions
        function names = checkForUniqueSubfolders(comp)
            names = {};
            if ~isempty(comp.assigntoListBox.Items)
                previousValue = comp.assigntoListBox.Value;
            end
            comp.assigntoListBox.Items = {};
            comp.appendUniqueSubfolderButton.Enable = "off";
            if isempty(comp.ImagePath)
                return
            end
            % seach for folders labelled "stream" 
            f = dir(fullfile(comp.ImagePath,'*stream*'));
            f = f([f.isdir]);
            
            if isempty(f)||isempty(comp.camera)
                return
            end
            % build assignment listbox
            if length(comp.camera)==1
                return
            end
            if mod(numel({f.name}),2)
                return
            end

            names = reshape({f.name},2,[]);
            comp.assigntoListBox.Items = join(names',',');
            try
                comp.assigntoListBox.Value = previousValue;
            end
            comp.appendUniqueSubfolderButton.Enable = "on";
            if ~nargout
                clear names
            end
        end
        function toggleElements(~,tgt,state)
            valid = arrayfun(@(tgt) tgt.Enable,tgt,'ErrorHandler',@(~,~) nan,'UniformOutput',false);
            valid = ~cellfun(@isnan,valid);
            tgt   = tgt(valid);
            set(tgt,Enable=state)
        end
        function val=splitTime(~,val,fmt)
            %% Formats datetime strings into numeric arrays
            if nargin<3
                fmt = 'hh:mm:ss.SSS';
            end
            val=str2double(split(strrep(string(val,fmt),'.',':'),':'));
        end
        function timeReadOut(comp)
            %% Formats and prints strings to read-out label in the Acquisition Tab
            fmt     = 'uuuu-MMM-dd HH:mm:ss';
            settime = comp.TimeStart;
            endtime = comp.TimeActive+settime;
            
            vc = validcamera(comp);
            if isnat(endtime)
                endtime = 'NaT';
                imagecount = nan;
            else
                endtime = string(endtime,fmt);
                if numel(vc)>1
                    imagecount = sum(floor([vc.duration]./[vc.interval]));
                    comp.timeSheduleLabel_2.Text{3} = '(total) Image count:';
                else
                    imagecount = floor((endtime-settime)/comp.TimeInterval);
                    comp.timeSheduleLabel_2.Text{3} = 'Image count:';
                end
            end
            
            str = sprintf( ...
                '%s\n%s\n%i', ...
                string(settime,fmt), ...
                endtime, ...
                imagecount);
            comp.timeSheduleLabel.Text = str;
        end
        function validCam = validcamera(comp)
            %% Gathers all valid camera objects
            if isempty(comp.labCamera)
                validCam = [];
                return
            end
            ids         = str2double(comp.DeviceIDListBox.Value);
            validids    = arrayfun(@(lc) lc.camera.DeviceID,comp.labCamera);
            validCam    = comp.labCamera(validids==ids);
        end
        function connect(comp)
            %% Connects to camera(s) video inputs
            try
                comp.NotificationsLabel.Text = 'Attempting to connect to camera...';
                comp.camera = videoinput(comp.adaptor,comp.device);
                comp.NotificationsLabel.Text = 'Camera is active';
            catch
                comp.NotificationsLabel.Text = 'Failed to connect to camera';
            end
        end
        function createMetadata(comp)
            %% Toggles the creation of metadata within image repositories
            % Is only initiated when recording begins.
            fname = '0image_metadata.xml';
            vc      = validcamera(comp);
            ffile = fullfile([vc.savepath],fname);
            for i=1:numel(vc)
                md = struct;
                c = vc(i);
                md.interval     = c.interval;
                md.duration     = c.duration;
                md.exposuretime = c.settings.ExposureTime;
                md.gamma        = c.settings.Gamma;
                md.gain         = c.settings.Gain;
                writestruct(md,ffile{i},"FileType","xml");
            end
        end
    end
    %% TIMER callbacks
    methods (Access = private)
        %% Build functions
        function buildTimer(comp,timername)
            switch timername
                case 'Schedule'
                    comp.scheduleTimer = timer(Period = 1, ...
                        ExecutionMode='fixedRate',...
                        TasksToExecute=inf,...
                        TimerFcn = @(varargin) updateSheduledTimeDisplay(comp), ...
                        Tag = 'uiCameraLinkTimer', ...
                        UserData=false);
                case 'Recording'
                    comp.camTimer = timer(Period = 1, ...
                        ExecutionMode='fixedRate',...
                        TasksToExecute=inf,...
                        TimerFcn = @(varargin) updateRecordingTimerDisplay(comp), ...
                        Tag = 'uiCameraLinkTimer', ...
                        UserData=false);
            end
        end

        %% Schedule timer functions
        function ScheduleTimer(comp,event)
            try
                switch event
                    case 'start'
                        start(comp.scheduleTimer)
                    case 'stop'
                        stop(comp.scheduleTimer)
                end
            catch ME
                buildTimer(comp,'Schedule')
                ScheduleTimer(comp,event)
                comp.timerLabel.Text = '[Error]';
                assignin('base','ME',ME)
            end
        end
        function updateSheduledTimeDisplay(comp,~)
            % Switch timer function if Recording is active
            if ~isvalid(comp.scheduleTimer)
                return
            end
            comp.timerLabel.Text = '';
            try
                ScheduleTimerFcn(comp)
            catch ME
                comp.timerLabel.Text = '[Error]';
                assignin('base','ME',ME)
            end
        end
        %% Recording timer functions
        function RecordingTimer(comp,event)
            try
                switch event
                    case 'start'
                        start(comp.camTimer)
                    case 'stop'
                        stop(comp.camTimer)
                end
            catch ME
                comp.ReadoutsLabel.Value = cat(1,comp.ReadoutsLabel.Value,'[Error]');
                assignin('base','ME',ME)
                buildTimer(comp,'Recording')
                RecordingTimer(comp,event)
            end
        end
        function updateRecordingTimerDisplay(comp,~)
            try
                comp.ReadoutsLabel.Value = recordingReadOut(comp);
            catch ME
                comp.ReadoutsLabel.Value = cat(1,comp.ReadoutsLabel.Value,'[Error]');
                assignin('base','ME',ME)
            end
        end
    end
    %% RECORDING functions
    methods (Access = private)
        function str = recordingReadOut(comp)
            %% Formats and prints strings to read-out label in the Recording Tab
            vc       = validcamera(comp);
            
            imcount  = sum([validcamera(comp).count]);

            timestarted = comp.TimeStart;
            telapsed = string(datetime-timestarted-comp.pauseTime,'dd:hh:mm:ss');
            tfinish  = string((timestarted+comp.TimeActive)-datetime,'dd:hh:mm:ss');

            if comp.pauseTime>0
                comp.pauseTime
            end

            strL    = { sprintf('Elapsed time:\t\t'), ...
                sprintf('Images acquired:\t'), ...
                sprintf('Time remaining:\t')};
            strR    = {char(telapsed), ...
                num2str(imcount), ...
                char(tfinish)};
            str = join([strL' strR']);
            switch comp.recordingState
                case 'on'
%                     str = arrayfun(@(vc) time2acq(vc),vc,'UniformOutput',false);
                    if seconds((comp.TimeStart+comp.TimeActive)-datetime)<=0
                        comp.recordingState='finished';
                    end
                case 'off'
                    str{1} = sprintf('%s\t\t[Stopped at %s]',str{1},string(datetime));
                case 'pause'
                    str{1}          = sprintf('%s [Paused at %s]',str{1},string(datetime));
                    comp.pauseTime  = telapsed;
                case 'restart'
                case 'finished'
                    str = sprintf('Recording finished at %s',string(datetime));
                    notify(comp, 'RecordingFinished');
                    acpanel = findall(comp.AcquisitionsettingsPanel,'-not','Type',class(comp.FoldersettingsPanel));
                    dpanel  = findall(comp.DeviceselectionPanel,'-not','Type',class(comp.FoldersettingsPanel));
                    panels  = [acpanel;dpanel];
                    comp.toggleElements(panels,'on')
                    set([comp.PauseButton comp.RestartButton comp.StopButton],'Enable','off')
                    comp.BeginButton.Enable = 'on';
                    RecordingTimer(comp,'stop')
            end

            %% Formatting functions
            function str = time2acq(vc)
                val = vc.starttime-datetime;
                str = '';
                if val>0
                str = sprintf('%s time to acquisition: %s',vc.camera.Name,string(val,'hh:mm:ss'));
                end
            end
%             function str = recordingstr(vc)
%                 te = string(datetime-vc.starttime,'dd:hh:mm:ss');
%                 tf = string((vc.starttime+vc.duration)-datetime,'dd:hh:mm:ss');
%             end

        end
        function ScheduleTimerFcn(comp)
            %% Primary timer function
            st=sum([days(comp.set_dd.Value) ...
                hours(comp.set_hh.Value)...
                minutes(comp.set_mm.Value) ...
                seconds(comp.set_ss.Value)]);
            comp.internalTimeChange = true;
            comp.TimeStart = string(datetime('now')+st);
            comp.internalTimeChange = false;

            % updateTimeStart on cameras if set time is "Now"
            vc = validcamera(comp);
            if isempty(vc)
                timeReadOut(comp)
                return
            end

            ts=[vc.timeset];
            val = [ts.Value];
            arrayfun(@(vc,val) set(vc,starttime=datetime+val),vc,val+seconds(1))
            timeReadOut(comp)
        end
        function RecordingTimerFcn(comp)
            comp.ReadoutsLabel.Value = recordingReadOut(comp);
        end
    end
    %% Callbacks

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            %% STARTUPFCN
            % Called without parent?
            if ~calledWithoutParent(comp,'Camera Controller', ...
                    'Icon',fullfile(fileparts(which(mfilename)),'resources/icons/cameraTools.png'))
                comp.debugButton.Visible = 'off';
            end
            try addlistener(ancestor(comp,'Figure'),'ObjectBeingDestroyed',@closePreviews);end %#ok<*TRYNC> 

            % gather installed camera adaptors
            warning off
            imaqreset
            info = imaqhwinfo;
            
            comp.AdaptorListBox.Items    = info.InstalledAdaptors;
            comp.ScheduleDate.Value = datetime('now');
            
            % remove placeholder strings
            set([comp.NotificationsLabel ...
                comp.timeSheduleLabel ...
                comp.NotifyMultiselect ...
                comp.filename_previewLabel],...
                'Text','')

            % initialise components
            comp.ConnectButton.UserData = false;
            try 
                comp.ImagePath = '..\Data\ablation_images\';
            catch
                comp.ImagePath = pwd;
            end
            
            delete(timerfindall('Tag','uiCameraLinkTimer'))
            warning on
            
            
            buildTimer(comp,'Schedule')
            buildTimer(comp,'Recording')
           
            %% Figure close listener function
            function closePreviews(~,~)
                stop(timerfindall('Tag','uiCameraLinkTimer'))
                delete(timerfindall('Tag','uiCameraLinkTimer'))
            end
        end

        % Value changed function: duration_SSS, duration_hh, duration_mm, 
        % ...and 1 other component
        function activeTimeChanged(comp, event)
            %% Update Active Time
            comp.internalTimeChange = true;
            comp.TimeActive=sum([hours(comp.duration_hh.Value)...
                minutes(comp.duration_mm.Value) ...
                seconds(comp.duration_ss.Value) ...
                milliseconds(comp.duration_SSS.Value)]);
            comp.internalTimeChange = false;
            setCameraTime(comp,event)
            timeReadOut(comp)
        end

        % Value changed function: interval_SSS, interval_hh, interval_mm, 
        % ...and 1 other component
        function intervalTimeChanged(comp, event)
            %% Update Interval Time
            comp.internalTimeChange = true;
            comp.TimeInterval=sum([hours(comp.interval_hh.Value)...
                minutes(comp.interval_mm.Value) ...
                seconds(comp.interval_ss.Value) ...
                milliseconds(comp.interval_SSS.Value)]);   
            comp.internalTimeChange = false;
            setCameraTime(comp,event)
            timeReadOut(comp)
        end

        % Value changed function: ScheduleDate, SetfromDropDown, set_dd, 
        % ...and 3 other components
        function setTimeChanged(comp, event)
            %% Update Start Time
            comp.internalTimeChange = true; 
            st=sum([days(comp.set_dd.Value) ...
                 hours(comp.set_hh.Value)...
                minutes(comp.set_mm.Value) ...
                seconds(comp.set_ss.Value)]);  
            switch comp.SetfromDropDown.Value
                case 'Now'
                    comp.ScheduleDate.Enable = "off";
                    comp.TimeStart=datetime('now')+st;
                    if strcmp(comp.scheduleTimer.Running,'off')
                        ScheduleTimer(comp,'start')
                    end
                case 'Date'
                    ScheduleTimer(comp,'stop')
                    comp.ScheduleDate.Enable = "on";
                    comp.TimeStart=comp.ScheduleDate.Value+st;
            end
            comp.internalTimeChange = false;
            setCameraTime(comp,event)
            timeReadOut(comp);
        end

        % Value changed function: AdaptorListBox, DeviceIDListBox
        function changeAttribute(comp, event)
            %% Handle Device Selection requests 
            set([comp.ConnectButton comp.ChangesettingsButton comp.PreviewButton],'Enable','off')

            if comp.AdaptorListBox==event.Source
                if ~any(strcmp(comp.AdaptorListBox.Value,event.PreviousValue))||isempty(comp.DeviceIDListBox.Items)
                    % update device id list
                    warning off
                    info = imaqhwinfo(event.Value);
                    comp.DeviceIDListBox.Items = {};
                    warning on
                    if isempty(info)
                        return
                    end
                    if contains(lastwarn,'No devices were detected for the')&&isempty(info.DeviceIDs)
                        comp.NotificationsLabel.Text = 'No devices were detected';
                        return
                    end
                    comp.DeviceIDListBox.Items = arrayfun(@(x) sprintf('%i',x),1:numel(info.DeviceIDs),'UniformOutput',false);
                    comp.NotificationsLabel.Text = '';
                end
                comp.adaptor = comp.AdaptorListBox.Value;
                return
            end

            % update public properties
            comp.adaptor = comp.AdaptorListBox.Value;
            comp.device  = comp.DeviceIDListBox.Value;

            % handle device/camera requests
            set(comp.ConnectButton,'Enable','on');
            if numel(comp.device)>1
                % multi-select on
%                 set(comp.ChangesettingsButton,'Enable','off');
                comp.NotifyMultiselect.Text = 'Repository for all devices';
            else
                comp.NotifyMultiselect.Text = sprintf('Repository for device %s',cell2mat(comp.device));
            end

            % update elements with camera information
            if ~isempty(comp.labCamera)
                comp.ImagerepositoryEditField.Value = validcamera(comp).savepath;
                updateFilename(comp,struct('Value',comp.FilenamelabelEditField.Value))
                updateTimes(comp)
            end
        end

        % Callback function: Button, ImagerepositoryEditField
        function changeDir(comp, event)
            %% Handle filepath requests
            switch event.Source
                case comp.Button
                    val = uigetdir(comp.ImagerepositoryEditField.Value);
                    if ~val
                        return
                    end
                    comp.ImagerepositoryEditField.Value = val;
                case comp.ImagerepositoryEditField
                    comp.NotifyMultiselect.Text = '';
                    if ~isfolder(event.Value)
                        comp.NotifyMultiselect.Text = 'Folder does not exist and will be created on execution';
                    end
            end
            comp.ImagePath = comp.ImagerepositoryEditField.Value;
            set(validcamera(comp),savepath=comp.ImagePath);
            updateFilename(comp,struct('Value',comp.FilenamelabelEditField.Value))
            notify(comp, 'RecordingSettingsChanged');
        end

        % Button pushed function: ConnectButton
        function connectCallback(comp, event)
            %% Handle connection requests 
            singleselectbuttons     = [comp.PreviewButton comp.ChangesettingsButton];
            multiselectbutttons     = comp.uniqueSubfolderButton;
            allbuttons              = [singleselectbuttons multiselectbutttons];

            switch comp.ConnectButton.UserData
                case true
                    warning off;imaqreset,warning on
                    comp.labCamera              = [];
                    comp.ConnectButton.Text     = 'Connect';
                    comp.ConnectButton.UserData = false;
                    comp.AdaptorListBox.Enable  = 'on';
                    comp.DeviceIDListBox.Items  = comp.DeviceIDListBox.UserData;
                    ScheduleTimer(comp,'stop')
                    set(allbuttons,'Enable','off');
                otherwise
                    set(singleselectbuttons,'Enable','on');
                    comp.ConnectButton.Text         = 'Disconnect';
                    comp.ConnectButton.UserData     = true;
                    comp.DeviceIDListBox.UserData   = comp.DeviceIDListBox.Items;
                    comp.DeviceIDListBox.Items      = comp.DeviceIDListBox.Value;
                    warning off
                    try
                        cameras         = cellfun(@(ids)videoinput(comp.adaptor,ids),comp.device,'UniformOutput',false);
                    catch
                        comp.NotificationsLabel.Text = 'Error in connecting to cameras';
                        return
                    end
                    comp.camera     = [cameras{:}];
                    if size(comp.camera,2)>1
                        set(multiselectbutttons,'Enable','on');
                        checkForUniqueSubfolders(comp);
                    end
                    comp.labCamera  = arrayfun(@(idx) labCamera(comp.camera(idx)),1:size(comp.camera,2)); %#ok<CPROPLC> 
                    warning on
                    comp.AdaptorListBox.Enable = 'off';
                    updateFilename(comp)
                    ScheduleTimer(comp,'start')
            end
        end

        % Button pushed function: BeginButton, PauseButton, RestartButton, 
        % ...and 1 other component
        function recordingCallback(comp, event)
            %% Handle recording requests
            acpanel = findall(comp.AcquisitionsettingsPanel,'-not','Type',class(comp.FoldersettingsPanel));
            dpanel  = findall(comp.DeviceselectionPanel,'-not','Type',class(comp.FoldersettingsPanel));
            panels  = [acpanel;dpanel];
            vc      = validcamera(comp);
            state   = 'normal';
            recordingButtons = [comp.PauseButton comp.RestartButton comp.StopButton];

            switch event.Source
                case comp.BeginButton
                    % notify callback
                    notify(comp, 'RecordingStarted');
                    
                    % check if cameras are open (improves latency)
                    arrayfun(@(vc) vc.openCamera(true),vc)

                    % enable pause/restart/stop buttons
                    set(recordingButtons,'Enable','on')

                    % update pause button state
                    togglePausing(comp,'play',false);

                    % set recording state and disable Acquisition and
                    % Device panels
                    comp.recordingState = 'on';
                    comp.toggleElements(panels,'off')

                    % toggle Schedule and Recording timers
                    ScheduleTimer(comp,'stop')
                    comp.pauseTime = seconds(0);
                    RecordingTimer(comp,'start')

                    % generate metadata file within repository
                    createMetadata(comp)

                    if ~comp.debugMode
                        % begin recording
                        arrayfun(@(vc) vc.timelapse(false),vc)
                    end

                    % disable recording button
                    comp.BeginButton.Enable = 'off';
                    return
                case comp.RestartButton
                    comp.pauseTime = seconds(0);
                    % notify callback and update recording state
                    notify(comp, 'RecordingRestarted');
                    comp.recordingState = 'restart';
                    state = 'restart';
                    comp.BeginButton.Enable = 'off';
                case comp.PauseButton
                    % toggle pause button apperance and recording state
                    % ("labCamera")
                    state = togglePausing(comp,comp.PauseButton.Tag);
                    % notify callback and update recording state (timerFcn)
                    notify(comp, 'RecordingPaused');
                    comp.recordingState = 'pause';

                    % toggle recording timer
                    switch state
                        case 'pause'
                        RecordingTimer(comp,'stop')
                        RecordingTimerFcn(comp)
                        case 'normal'
                        comp.recordingState = 'on';
                        RecordingTimer(comp,'start')
                    end
                    comp.BeginButton.Enable = 'off';
                    if strcmp(state,'normal')
                        return
                    end
                case comp.StopButton
                    % notify callback and update recording state
                    notify(comp, 'RecordingStopped');
                    comp.recordingState = 'off';

                    % re-enable panels and disable pause/restart/stop
                    % buttons
                    comp.toggleElements(panels,'on')
                    set(recordingButtons,'Enable','off')

                    % toggle recording and schedule timers
                    RecordingTimer(comp,'stop')
                    ScheduleTimer(comp,'start')

                    % enable recording button
                    comp.BeginButton.Enable = 'on';

                    % update recording readout
                    RecordingTimerFcn(comp)
            end

            % update "labCamera" timer state
            set(vc,'camTimerState',state)

            try
                arrayfun(@(vc) stop(vc.camtimer),vc)
            catch ME
                if ~strcmp(ME.identifier,'MATLAB:timer:invalid')
                    rethrow(ME)
                end
                set(recordingButtons,'Enable','off')
            end
            
            %% Nested functions
            function state = togglePausing(comp,state,hasStarted)
                if nargin<3
                    hasStarted = true;
                end
                switch state
                    case 'pause'
                        % timer has been paused
                        Tag     = 'play';
                        icon    = 'mat-play-arrow.png';
                        ttip    = 'Continue recording';
                        state   = 'pause';
                    case 'play'
                        % timer has been resumed
                        Tag     = 'pause';
                        icon    = 'mat-pause-circle-filled.png';
                        state   = 'normal';
                        ttip    = 'Pause recording';
                        if hasStarted
                            set(vc,'camTimerState',state)
                            arrayfun(@(vc) start(vc.camtimer),vc)
                        end
                        
                end
                set(comp.PauseButton,Icon=icon,Tooltip=ttip,Tag=Tag);
            end
        end

        % Value changing function: FilenamelabelEditField
        function updateFilename(comp, event)
            %% Handle filename requests
            eventdata = false;
            try 
                value = event.Value;
                eventdata = true;
            catch
                % assume no event data
                value = comp.FilenamelabelEditField.Value;
                if isempty(value)&&~isempty(comp.FilenameTag)
                    value = comp.FilenameTag;
                    eventdata = true;
                end
            end
            if isempty(comp.labCamera)
                return
            end
            cams = validcamera(comp);
            if numel(cams)>1
                deviceID = nan;
            else
                deviceID = cams.camera.DeviceID;
            end
            set(cams,label = value);
            fmt = cams(1).fnamefmt;

            if eventdata
                tmp = struct('camera',struct('DeviceID',deviceID),'label',value,'count',1,'elapsed',seconds(0));
                comp.filename_previewLabel.Text = fmt(tmp);
            else
                formatting = extractBetween(func2str(fmt),"'","'");
                formatting = formatting{1};
                formatting = strrep(formatting,'cam%i','cam<id>');
                formatting = strrep(formatting,'%sC','<label>C');
                formatting = strrep(formatting,'%i','<imagecount>');
                formatting = strrep(formatting,'T%s','T<hh_mm_ss>');
                comp.filename_previewLabel.Text = formatting;
            end
            
            notify(comp, 'RecordingSettingsChanged');
        end

        % Button pushed function: PreviewButton
        function previewCallback(comp, event)
            vc = validcamera(comp);
            arrayfun(@(vc) vc.seeLiveView,vc);
        end

        % Button pushed function: appendUniqueSubfolderButton, 
        % ...and 1 other component
        function createDirs(comp, event)
            vc      = validcamera(comp);
            root    = comp.ImagerepositoryEditField.Value;
            count   = 1:numel(vc);
            base    = 'stream';
            names   = checkForUniqueSubfolders(comp);
            switch event.Source
                case comp.uniqueSubfolderButton
                    if isempty(names)
                        lbls = arrayfun(@(count) sprintf('%s%i',base,count),count,'UniformOutput',false);
                    else
                        if isempty(comp.assigntoListBox.Value)
                            comp.assigntoListBox.Value = comp.assigntoListBox.Items{1};
                        end
                        lbls = split(comp.assigntoListBox.Value,',')';
                    end
                case comp.appendUniqueSubfolderButton
                    lbls    = arrayfun(@(count) sprintf('%s%i',base,count),numel(names)+1:numel(names)+2,'UniformOutput',false);
                    comp.assigntoListBox.Items(end+1)   = join(lbls,',');
                    comp.assigntoListBox.Value          = comp.assigntoListBox.Items{end};
            end
            fnames  = fullfile(root,lbls);
            valid   = ~isfolder(fnames);
            cellfun(@(f) mkdir(f),fnames(valid))
            arrayfun(@(vc,f) set(vc,'savepath',f),vc,fnames)
            checkForUniqueSubfolders(comp);
        end

        % Button pushed function: ChangesettingsButton
        function openCameraSettings(comp, event)
            fig = findall(0,'Type','Figure','Name','Camera settings');
            if isempty(fig)
                fig = uifigure('HandleVisibility','callback','Name','Camera settings');
                gl  = uigridlayout(fig,'ColumnWidth',{'1x'},'RowHeight',{'1x'});
                uiCameraSettings(gl)
                fig.Position = fig.Position.*[1 1 0 0]+[0 0 280 420];
            end
            set(0,'CurrentFigure',fig)
        end

        % Button pushed function: openFolderButton
        function openDatastore(comp, event)
            try arrayfun(@winopen,[validcamera(comp).savepath]),end 
        end

        % Button pushed function: Button_2
        function timerRestart(comp, event)
            ScheduleTimer(comp,'stop')
            ScheduleTimer(comp,'start')
        end

        % Value changed function: debugButton
        function toggleDebug(comp, event)
            comp.debugMode = comp.debugButton.Value;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            if seconds(comp.TimeActive)==0||isnan(comp.TimeActive)||isnan(comp.TimeInterval)||comp.TimeInterval==seconds(0)
                set([comp.BeginButton comp.PauseButton comp.RestartButton comp.StopButton],'Enable','off')
            else
                set([comp.BeginButton],'Enable','on')
            end
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 582 530];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.DeviceselectionPanel = uipanel(comp.GridLayout);
            comp.DeviceselectionPanel.Title = 'Device selection';
            comp.GridLayout2 = uigridlayout(comp.DeviceselectionPanel);
            comp.AdaptorLabel = uilabel(comp.GridLayout2);
            comp.AdaptorLabel.Text = 'Adaptor';
            comp.AdaptorListBox = uilistbox(comp.GridLayout2);
            comp.AdaptorListBox.Items = {};
            comp.AdaptorListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeAttribute, true);
            comp.AdaptorListBox.Value = {};
            comp.DeviceIDLabel = uilabel(comp.GridLayout2);
            comp.DeviceIDLabel.Text = 'Device ID';
            comp.DeviceIDListBox = uilistbox(comp.GridLayout2);
            comp.DeviceIDListBox.Items = {};
            comp.DeviceIDListBox.Multiselect = 'on';
            comp.DeviceIDListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeAttribute, true);
            comp.DeviceIDListBox.Value = {};
            comp.NotificationsLabel = uilabel(comp.GridLayout2);
            comp.ConnectButton = uibutton(comp.GridLayout2, 'push');
            comp.ConnectButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @connectCallback, true);
            comp.ConnectButton.Enable = 'off';
            comp.ConnectButton.Text = 'Connect';
            comp.ChangesettingsButton = uibutton(comp.GridLayout2, 'push');
            comp.ChangesettingsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openCameraSettings, true);
            comp.ChangesettingsButton.Enable = 'off';
            comp.ChangesettingsButton.Text = 'Settings';
            comp.PreviewButton = uibutton(comp.GridLayout2, 'push');
            comp.PreviewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @previewCallback, true);
            comp.PreviewButton.Enable = 'off';
            comp.PreviewButton.Text = 'Preview';
            comp.Button_2 = uibutton(comp.GridLayout2, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @timerRestart, true);
            comp.Button_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'mat-timer.png');
            comp.Button_2.Tooltip = {'Restart timer'};
            comp.Button_2.Text = '';
            comp.timerLabel = uilabel(comp.GridLayout2);
            comp.timerLabel.Text = '';
            comp.debugButton = uibutton(comp.GridLayout2, 'state');
            comp.debugButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDebug, true);
            comp.debugButton.Text = 'debug';
            comp.AcquisitionsettingsPanel = uipanel(comp.GridLayout);
            comp.AcquisitionsettingsPanel.Title = 'Acquisition settings';
            comp.GridLayout3 = uigridlayout(comp.AcquisitionsettingsPanel);
            comp.ActivetimehhmmssSSSLabel = uilabel(comp.GridLayout3);
            comp.ActivetimehhmmssSSSLabel.Text = 'Active time (hh:mm:ss.SSS)';
            comp.GL_Active = uigridlayout(comp.GridLayout3);
            comp.duration_hh = uieditfield(comp.GL_Active, 'numeric');
            comp.duration_hh.Limits = [0 Inf];
            comp.duration_hh.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @activeTimeChanged, true);
            comp.duration_hh.Tag = 'duration';
            comp.duration_mm = uieditfield(comp.GL_Active, 'numeric');
            comp.duration_mm.Limits = [0 Inf];
            comp.duration_mm.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @activeTimeChanged, true);
            comp.duration_mm.Tag = 'duration';
            comp.duration_ss = uieditfield(comp.GL_Active, 'numeric');
            comp.duration_ss.Limits = [0 Inf];
            comp.duration_ss.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @activeTimeChanged, true);
            comp.duration_ss.Tag = 'duration';
            comp.duration_SSS = uieditfield(comp.GL_Active, 'numeric');
            comp.duration_SSS.Limits = [0 Inf];
            comp.duration_SSS.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @activeTimeChanged, true);
            comp.duration_SSS.Tag = 'duration';
            comp.IntervaltimehhmmssSSSLabel = uilabel(comp.GridLayout3);
            comp.IntervaltimehhmmssSSSLabel.Text = 'Interval time (hh:mm:ss.SSS)';
            comp.GL_Interval = uigridlayout(comp.GridLayout3);
            comp.interval_hh = uieditfield(comp.GL_Interval, 'numeric');
            comp.interval_hh.Limits = [0 Inf];
            comp.interval_hh.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @intervalTimeChanged, true);
            comp.interval_hh.Tag = 'interval';
            comp.interval_mm = uieditfield(comp.GL_Interval, 'numeric');
            comp.interval_mm.Limits = [0 Inf];
            comp.interval_mm.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @intervalTimeChanged, true);
            comp.interval_mm.Tag = 'interval';
            comp.interval_ss = uieditfield(comp.GL_Interval, 'numeric');
            comp.interval_ss.Limits = [0 Inf];
            comp.interval_ss.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @intervalTimeChanged, true);
            comp.interval_ss.Tag = 'interval';
            comp.interval_SSS = uieditfield(comp.GL_Interval, 'numeric');
            comp.interval_SSS.Limits = [0 Inf];
            comp.interval_SSS.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @intervalTimeChanged, true);
            comp.interval_SSS.Tag = 'interval';
            comp.StarttimeddhhmmssLabel = uilabel(comp.GridLayout3);
            comp.StarttimeddhhmmssLabel.Text = 'Start time (dd:hh:mm:ss)';
            comp.GL_Start = uigridlayout(comp.GridLayout3);
            comp.set_dd = uieditfield(comp.GL_Start, 'numeric');
            comp.set_dd.Limits = [0 Inf];
            comp.set_dd.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.set_dd.Tag = 'starttime';
            comp.set_hh = uieditfield(comp.GL_Start, 'numeric');
            comp.set_hh.Limits = [0 Inf];
            comp.set_hh.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.set_hh.Tag = 'starttime';
            comp.set_mm = uieditfield(comp.GL_Start, 'numeric');
            comp.set_mm.Limits = [0 Inf];
            comp.set_mm.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.set_mm.Tag = 'starttime';
            comp.set_ss = uieditfield(comp.GL_Start, 'numeric');
            comp.set_ss.Limits = [0 Inf];
            comp.set_ss.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.set_ss.Tag = 'starttime';
            comp.SetfromDropDownLabel = uilabel(comp.GridLayout3);
            comp.SetfromDropDownLabel.Text = 'Set from';
            comp.SetfromDropDown = uidropdown(comp.GridLayout3);
            comp.SetfromDropDown.Items = {'Now', 'Date'};
            comp.SetfromDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.SetfromDropDown.Value = 'Now';
            comp.ScheduleDate = uidatepicker(comp.GridLayout3);
            comp.ScheduleDate.DisplayFormat = 'dd/MMM/uuuu';
            comp.ScheduleDate.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @setTimeChanged, true);
            comp.ScheduleDate.Enable = 'off';
            comp.timeSheduleLabel_2 = uilabel(comp.GridLayout3);
            comp.timeSheduleLabel_2.Text = {'Set for:'; 'Ends at:'; 'Image count:'};
            comp.timeSheduleLabel = uilabel(comp.GridLayout3);
            comp.timeSheduleLabel.Text = 'shedule';
            comp.FoldersettingsPanel = uipanel(comp.GridLayout3);
            comp.FoldersettingsPanel.Title = 'Folder settings';
            comp.GridLayout6 = uigridlayout(comp.FoldersettingsPanel);
            comp.ImagerepositoryEditFieldLabel = uilabel(comp.GridLayout6);
            comp.ImagerepositoryEditFieldLabel.Text = 'Image repository';
            comp.ImagerepositoryEditField = uieditfield(comp.GridLayout6, 'text');
            comp.ImagerepositoryEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.Button = uibutton(comp.GridLayout6, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.Button.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'find_folder.svg');
            comp.Button.Tooltip = {'Choose path'};
            comp.Button.Text = '';
            comp.NotifyMultiselect = uilabel(comp.GridLayout6);
            comp.NotifyMultiselect.Text = 'multiselect warning';
            comp.FilenamelabelEditFieldLabel = uilabel(comp.GridLayout6);
            comp.FilenamelabelEditFieldLabel.Text = 'Filename label';
            comp.FilenamelabelEditField = uieditfield(comp.GridLayout6, 'text');
            comp.FilenamelabelEditField.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @updateFilename, true);
            comp.filename_previewLabel = uilabel(comp.GridLayout6);
            comp.filename_previewLabel.Text = 'filename_preview';
            comp.uniqueSubfolderButton = uibutton(comp.GridLayout6, 'push');
            comp.uniqueSubfolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @createDirs, true);
            comp.uniqueSubfolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folders.svg');
            comp.uniqueSubfolderButton.Enable = 'off';
            comp.uniqueSubfolderButton.Tooltip = {'Assign unique subfolders for each device (create if empty)'};
            comp.uniqueSubfolderButton.Text = '';
            comp.appendUniqueSubfolderButton = uibutton(comp.GridLayout6, 'push');
            comp.appendUniqueSubfolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @createDirs, true);
            comp.appendUniqueSubfolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folders_new.svg');
            comp.appendUniqueSubfolderButton.Enable = 'off';
            comp.appendUniqueSubfolderButton.Tooltip = {'Create new unique subfolders for each device (and assign)'};
            comp.appendUniqueSubfolderButton.Text = '';
            comp.assigntoListBox = uilistbox(comp.GridLayout6);
            comp.assigntoListBox.Items = {};
            comp.assigntoListBox.Tooltip = {'Assign to'};
            comp.assigntoListBox.Value = {};
            comp.RecordingPanel = uipanel(comp.GridLayout);
            comp.RecordingPanel.Title = 'Recording';
            comp.GridLayout5 = uigridlayout(comp.RecordingPanel);
            comp.BeginButton = uibutton(comp.GridLayout5, 'push');
            comp.BeginButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @recordingCallback, true);
            comp.BeginButton.Icon = 'mat-fiber-manual-record.png';
            comp.BeginButton.Enable = 'off';
            comp.BeginButton.Tooltip = {'Start recording'};
            comp.BeginButton.Text = '';
            comp.PauseButton = uibutton(comp.GridLayout5, 'push');
            comp.PauseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @recordingCallback, true);
            comp.PauseButton.Tag = 'pause';
            comp.PauseButton.Icon = 'mat-pause-circle-filled.png';
            comp.PauseButton.Enable = 'off';
            comp.PauseButton.Tooltip = {'Pause recording'};
            comp.PauseButton.Text = '';
            comp.RestartButton = uibutton(comp.GridLayout5, 'push');
            comp.RestartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @recordingCallback, true);
            comp.RestartButton.Icon = 'mat-replay.png';
            comp.RestartButton.Enable = 'off';
            comp.RestartButton.Tooltip = {'Restart recording'};
            comp.RestartButton.Text = '';
            comp.StopButton = uibutton(comp.GridLayout5, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @recordingCallback, true);
            comp.StopButton.Icon = 'icon-fa-stop-circle.png';
            comp.StopButton.Enable = 'off';
            comp.StopButton.Tooltip = {'Stop recording'};
            comp.StopButton.Text = '';
            comp.openFolderButton = uibutton(comp.GridLayout5, 'push');
            comp.openFolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDatastore, true);
            comp.openFolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_database.svg');
            comp.openFolderButton.Tooltip = {'Open image datastore directory(s)'};
            comp.openFolderButton.Text = '';
            comp.ReadoutsLabel = uitextarea(comp.GridLayout5);
            % Execute the startup function
            startupFcn(comp)
        end
    end
end