classdef uiCameraResolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraResolutionUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        SettingsPanel             matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        AcceptButton              matlab.ui.control.Button
        NewmeasurementButton      matlab.ui.control.Button
        RefreshimageButton        matlab.ui.control.Button
        AverageNaNmpxLabel        matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        ShowgridButton            matlab.ui.control.StateButton
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ActiveCamera
        ds double = nan
    end
    
    methods (Access = private)
        function applyDs(app)
            ud = app.ActiveCamera.UserData;
            if isempty(ud)
                return
            end
            try app.ds=ud.ds;catch,return;end
            if isempty(app.ds)
                return
            end
            if isnan(app.ds)
                return
            end
            addMeasure(app,app.ds)
        end
        function calculateAverageResolution(app)
            m       = app.UITable.Data(:,3);
            px      = app.UITable.Data(:,2);
            app.ds  = mean(m./px,'omitnan');

            scale = floor(log10(app.ds));
            symbol = '';
            switch ceil(scale/3)
                case 0
                    symbol = '';
                case -1
                    symbol = 'm';
                case -2
                    symbol = 'μ';
            end
            fac = 1/10^(scale);
            [y,x]=size(findall(app.UIAxes,'Type','Image').CData);
            app.AverageNaNmpxLabel.Text = sprintf('Average: %.2f %sm/px (%.1fmx%.1fm)',app.ds*fac,symbol,y*app.ds,x*app.ds);
        end
        function [x,y,im] = cameraImage(app)
            ud = app.ActiveCamera.UserData;
            if isempty(ud)
                im    = getsnapshot(app.ActiveCamera);
            else
                im      = ud.command(app.ActiveCamera,ud);
                if isempty(ud.ds)
                    ud.ds = nan;
                end
                app.ds  = ud.ds;
            end
            [Y,X] = size(im);
            x = 0:X-1;
            y = 0:Y-1;
        end
        %% Line functions
        function movingFcn(app,src)
            d = app.UITable.Data;
            app.UITable.Data(find(d(:,1)==src.UserData),2)=hypot(diff(src.Position(:,1)),diff(src.Position(:,2)));
            calculateAverageResolution(app)
        end
        function clickedFcn(app,src)
            pgons = findall(app.UIAxes,'Tag','measurement');
            set(pgons,'Color','b')
            src.Color = 'r';
            app.UITable.Selection = [src.UserData 1];
        end
        function deletedFcn(app,src)
            d = app.UITable.Data;
            app.UITable.Data(find(d(:,1)==src.UserData),:)=[];
            pgons = findall(app.UIAxes,'Tag','measurement');
            neworder = [pgons.UserData]-1;
            neworder(neworder<1)=1;
            arrayfun(@(p,o) set(p,UserData=o),pgons,neworder')
            d=app.UITable.Data(:,1)-1;
            d(d<1)=1;
            app.UITable.Data(:,1)=d;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ActiveCamera)
            if nargin<2
                warning('No active videoinput detected as an input argument')
                delete(app.CameraResolutionUIFigure)
            end
            app.ActiveCamera = ActiveCamera;
            refreshAxes(app)
            applyDs(app)

        end

        % Button pushed function: RefreshimageButton
        function refreshAxes(app, event)
            [x,y,im] = cameraImage(app);
            imdata = findall(app.UIAxes,'Type','Image');
            if isempty(imdata)
                imagesc(app.UIAxes,x,y,im);
                colormap(app.UIAxes,"gray")
                axis(app.UIAxes,'image')
                return
            end
            set(imdata,CData=im)
        end

        % Button pushed function: NewmeasurementButton
        function addMeasure(app, event)
            hasDs = false;
            if isa(event,'double')
                hasDs   = true;
                [y,x] = size(findall(app.UIAxes,'Type','Image').CData);
                dis = max([y x])/10;
                pos = [x/2 y/2+dis/2;x/2 y/2-dis/2];
            end
            count = numel(findall(app.UIAxes,'Tag','measurement'))+1;
            if ~hasDs
                pgon=drawline("Color",'r', ...
                    'Parent',app.UIAxes, ...
                    'Tag','measurement', ...
                    'UserData',count);
            else
                 pgon=drawline("Color",'r', ...
                    'Parent',app.UIAxes, ...
                    'Position',pos,...
                    'Tag','measurement', ...
                    'UserData',count, ...
                    'Label',sprintf('%.3fm',dis*app.ds));
            end
            addlistener(pgon,'MovingROI',@(varargin) movingFcn(app,pgon));
            addlistener(pgon,'ROIClicked',@(varargin) clickedFcn(app,pgon));
            addlistener(pgon,'DeletingROI',@(varargin) deletedFcn(app,pgon));
            d = app.UITable.Data;
            val = [count round(hypot(diff(pgon.Position(:,1)),diff(pgon.Position(:,2))),1) nan];
            if isempty(d)
                app.UITable.Data = val;
            else
                app.UITable.Data(end+1,:) = val;
            end
            if hasDs
                app.UITable.Data(1,end) = dis*app.ds;
                calculateAverageResolution(app)
            end
        end

        % Cell edit callback: UITable
        function changeDs(app, event)
            if ~isa(event.NewData,'char')
                try app.UITable.Data(event.Indices(1),event.Indices(2))=eval(event.EditData);end %#ok<*TRYNC> 
            end
            calculateAverageResolution(app)
            
        end

        % Selection changed function: UITable
        function ChangeActiveLine(app, event)
            selection = app.UITable.Selection;
            pgons = findall(app.UIAxes,'Tag','measurement');
            set(pgons,'Color','b')
            set(pgons(([pgons.UserData]==selection(1))),'Color','r')
        end

        % Key press function: UITable
        function keyFcn(app, event)
            key = event.Key;
            if strcmp(key,'delete')
                src = findall(app.UIAxes,'Tag','measurement','UserData',app.UITable.Selection(1));
                deletedFcn(app,src)
                delete(src)
                calculateAverageResolution(app)
            end

        end

        % Button pushed function: AcceptButton
        function acceptChanges(app, event)
            ud = app.ActiveCamera.UserData;
            if isempty(ud)
                app.ActiveCamera.UserData = struct( ...
                    'Rot',0, ...
                    'ROI',[1 1 app.ActiveCamera.VideoResolution], ...
                    'ds',app.ds, ...
                    'command',@(camera_handle,userdata)rot90(imcrop(getsnapshot(camera_handle),userdata.ROI),userdata.Rot));
                return
            end
            ud.ds = app.ds;
            app.ActiveCamera.UserData = ud;
            try %#ok<TRYNC> 
                fname = fullfile("0Experiment\classes\camera_profiles\",...
                sprintf('%s_userdata.xml',app.ActiveCamera.Name));
                ud.command = func2str(ud.command);
                writestruct(ud,fname,'FileType','xml')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraResolutionUIFigure = uifigure('Visible', 'off');
            app.CameraResolutionUIFigure.Name = 'Camera Resolution';
            app.GridLayout = uigridlayout(app.CameraResolutionUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsPanel);
            app.ShowgridButton = uibutton(app.GridLayout2, 'state');
            app.ShowgridButton.Text = 'Show grid';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Count'; 'Pixels'; 'Measurement (m)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @changeDs, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @ChangeActiveLine, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @keyFcn, true);
            app.AverageNaNmpxLabel = uilabel(app.GridLayout2);
            app.AverageNaNmpxLabel.Text = 'Average: NaN m/px';
            app.RefreshimageButton = uibutton(app.GridLayout2, 'push');
            app.RefreshimageButton.ButtonPushedFcn = createCallbackFcn(app, @refreshAxes, true);
            app.RefreshimageButton.Text = 'Refresh image';
            app.NewmeasurementButton = uibutton(app.GridLayout2, 'push');
            app.NewmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @addMeasure, true);
            app.NewmeasurementButton.Text = 'New measurement';
            app.AcceptButton = uibutton(app.GridLayout2, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @acceptChanges, true);
            app.AcceptButton.Text = 'Accept';
            % Show the figure after all components are created
            app.CameraResolutionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiCameraResolution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CameraResolutionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraResolutionUIFigure)
        end
    end
end