classdef uiCameraSettings < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl                          matlab.ui.container.GridLayout
        glax                        matlab.ui.container.GridLayout
        axfft                       matlab.ui.control.UIAxes
        axhist                      matlab.ui.control.UIAxes
        CamerasettingsPanel         matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ProgramsettingsPanel        matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        DrawgridButton              matlab.ui.control.StateButton
        RestartButton               matlab.ui.control.Button
        lockButton                  matlab.ui.control.StateButton
        PreviewPanel                matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        OrderEditField              matlab.ui.control.EditField
        CameraorderLabel            matlab.ui.control.Label
        imageQuality                matlab.ui.control.NumericEditField
        HistogramButton             matlab.ui.control.StateButton
        ImageButton                 matlab.ui.control.Button
        FFTButton                   matlab.ui.control.Button
        ImagesettingsPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        getResolution               matlab.ui.control.Button
        resetROI                    matlab.ui.control.Button
        setROI                      matlab.ui.control.Button
        flipUD                      matlab.ui.control.StateButton
        flipLR                      matlab.ui.control.StateButton
        rot90R                      matlab.ui.control.Button
        rot90L                      matlab.ui.control.Button
        digitalshiftef              matlab.ui.control.NumericEditField
        DigitalShiftEditFieldLabel  matlab.ui.control.Label
        gammaed                     matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        autoGain                    matlab.ui.control.StateButton
        gainef                      matlab.ui.control.NumericEditField
        GainEditFieldLabel          matlab.ui.control.Label
        autoExposure                matlab.ui.control.StateButton
        exposureef                  matlab.ui.control.NumericEditField
        ExposuremsEditFieldLabel    matlab.ui.control.Label
        CameraListBox               matlab.ui.control.ListBox
        CameraListBoxLabel          matlab.ui.control.Label
    end

    properties (Access = private)
        LockedCameras
        VideoData
        hasOpened logical = false;
        imageData
        multiStream struct = struct('count',0,'ref',[],'return',false);
        previewState string = 'Image';
        validEnable
    end
    
    properties (Access = public)
        ActiveCamera {mustBeA(ActiveCamera, 'videoinput')} = imaqfind;
        CameraList {mustBeA(CameraList, 'videoinput')} = imaqfind;
        Settings
    end
    
    
    methods (Access = private)
        function validCam = validcamera(comp)
            %% Gathers all valid camera objects
            if isempty(comp.CameraList)
               error('No videoinputs available anymore')
            end
            ids         = comp.CameraListBox.Value;
            validids    = strcmp(ids',comp.CameraList.Name);
            validCam    = comp.CameraList(validids);
        end
        function updateSettings(comp)
            set(comp.validEnable,'Enable','on')
            set(comp.lockButton,'Enable','off')
            if size(comp.ActiveCamera,2)>1
                set(comp.validEnable,'Enable','off')

                set(findall(comp.PreviewPanel,'Tag','preview'),'Enable','on')
                set(comp.lockButton,'Enable','on')
                return
            end
            src = getselectedsource(comp.ActiveCamera);
            comp.Settings           = src;
            comp.exposureef.Value   = src.ExposureTime*1e-3;
            comp.gainef.Value       = src.Gain;
            comp.gammaed.Value      = src.Gamma;
            comp.digitalshiftef.Value = double(src.DigitalShift);

            comp.flipLR.Value = eval(lower(src.ReverseX));
            comp.flipUD.Value = eval(lower(src.ReverseY));


            val = NaN;
            ud  = comp.ActiveCamera.UserData;
            if ~isempty(ud)
                try val = ud.ds;end
                comp.getResolution.Tooltip = sprintf('Measure pixel resolution (%.2f mm/px)',val*1e3);
            else
                % look for camera userdata
                udpath = fullfile('0Experiment\classes\camera_profiles\',[comp.ActiveCamera.Name '_userdata.xml']);
                if isfile(udpath)
                    ud = readstruct(udpath);
                    try ud.command=str2func(ud.command);end
                    comp.getResolution.Tooltip = sprintf('Measure pixel resolution (%.2f mm/px)',val*1e3);
                end
            end
            switch src.ExposureAuto
                case {'Once','Continuous'}
                    comp.autoExposure.Value = true;
                otherwise
                    comp.autoExposure.Value = false;
            end
            switch src.GainAuto
                case {'Once','Continuous'}
                    comp.autoGain.Value = true;
                otherwise
                    comp.autoGain.Value = false;
            end
        end
        
        %% Live-view
        function openCamera(comp,activeCam)
            %% Opening fcn
            if nargin<2
                activeCam = comp.ActiveCamera;
            end
            cam     = activeCam;
            if size(cam,2)>1
                for i=1:size(cam,2)
                    openCamera(comp,cam(i))
                end
                return
            end
            vidRes  = cam.VideoResolution;
            nBands  = cam.NumberOfBands;
            tag     = sprintf('LiveView_%s_source',cam.Name);
            camfig  = findall(0,'Type','Figure','Tag',tag);

            lb = comp.CameraListBox;
            if isempty(lb.UserData)
                lb.UserData = false(size(lb.Items));
            end
            valid       = ismember(lb.Items,lb.Value);

            % logical tests to prevent unnecessary re-opening of background preview window
            previewOpened   = ~isempty(camfig);
            hasStreamed     = ~isempty(comp.VideoData);
            multiStreams    = size(comp.ActiveCamera,2)>1;
            hasMultiStreamed= size(comp.imageData,3)>1;
            MultiStreaming  = multiStreams||hasMultiStreamed;
            wasOpened       = all(lb.UserData(valid));
            if hasStreamed&&previewOpened
                if ~MultiStreaming
                    return
                end
                if wasOpened
                    return
                end
            end
            
            lb.UserData = logical(lb.UserData+valid);

            comp.getResolution.Enable   = 'on';

            if isempty(camfig)
                camfig  = figure('Name', 'LiveView: Preview', ...
                    'Visible','off', ...
                    'CloseRequestFcn',@closeFig,...
                    'Tag',tag, ...
                    'HandleVisibility','callback');
                axes(camfig)
            end
            hImage  = image(zeros(vidRes(2), vidRes(1), nBands), ...
                'Parent',findall(camfig,'Type','Axes'));
            comp.VideoData = hImage;
            preview(cam, hImage);
            set(camfig,'Visible','off')

            ax      = comp.axfft;
            ax.Toolbar.Visible='off';
            ud = cam.UserData;
            if isempty(ud)
                im0    = getsnapshot(cam);
            else
                try
                    im0     = ud.command(cam,ud);
                catch
                    im0    = getsnapshot(cam);
                end

            end
            comp.imageData = im0;
            if size(comp.ActiveCamera,2)>1
                if size(comp.imageData,3)==1
                    try comp.imageData = cat(3,comp.imageData,im0);end
                end
            end
            imagesc(ax,im0,'Tag','imdata');
            caxis(ax,[0 255])
            colormap(ax,'gray')
            axis(ax,'image')
            setappdata(hImage,'UpdatePreviewWindowFcn',@liveView); % livestream loop
            
            function closeFig(~,~)
                closepreview(cam)
                try comp.VideoData = [];end
                delete(camfig)
            end
            %% Live-view fcn
            function liveView(~,~,~)
                try %#ok<*TRYNC>
                    liveViewFcn(comp,cam)
                end
            end
        end
        function liveViewFcn(comp,camera)
            ax = comp.axfft;
            imdata  = findall(ax,'Type','Image','Tag','imdata');
            [im,isMultStreaming,cameralist] = getImage(comp,camera);
            if isMultStreaming
                im = getMultiStreamImage(comp,cameralist);
            end

            if ~all(comp.ActiveCamera==camera)&&~isMultStreaming
                return
            end
            im      = imresize(im,comp.imageQuality.Value);
            imref   = im;
            [y,x]   = size(im);
            
            switch comp.previewState
                case 'Image'
                    caxis(comp.axfft,[0 255])
                    kxvec = 0:x;
                    kyvec = 0:y;
                case 'FFT'
                    kxvec = fftshift(kvec(x));
                    kyvec = fftshift(kvec(y));

                    [Fk,~]  = getcarrierWave(comp,im);
                    im  = log10(fftshift(abs(Fk)));
                    caxis(ax,[0 5])
            end
            set(imdata,CData = im,XData = kxvec,YData = kyvec)
            
            if comp.multiStream.return
                ref = comp.multiStream.ref;
                xlim(ax,ref(1,:)*x);
                ylim(ax,ref(1,:)*y);
                comp.multiStream.return = false;
            end

            if ~comp.HistogramButton.UserData
                return
            end

            [counts,bins] = imhist(imref,256/2);

            if isMultStreaming
                cts = squeeze(zeros([size(counts) size(cameralist,2)]));
                for k=1:size(cameralist,2)
                    [c,~] = imhist(imresize(comp.imageData(:,:,k),.35),256/2);
                    cts(:,k)=c;
                end
                counts = cts;
            end

            bar(comp.axhist,bins,counts/max(counts,[],'all'),'EdgeColor','none','BarWidth',2)

            xlim(comp.axhist,[0 255])
            ylim(comp.axhist,[1e-5 1])
        end
        function [im,multipleStreams,cameras] = getImage(comp,camera)
            ud              = camera.UserData;
            cameras         = comp.ActiveCamera;

            multipleCameras = size(cameras,2)>1;
            lockedCameras   = comp.lockButton.Value;
            multipleStreams = false;
            if lockedCameras
                cameras = comp.LockedCameras;
                multipleStreams = true;
            elseif multipleCameras
                multipleStreams = true;
            end

            if isempty(ud)
                im    = getsnapshot(camera);
            else
                im    = ud.command(camera,ud);
            end
        end
        
        function im = getMultiStreamImage(comp,cameralist)
            % prevent unnecessary n-duplicate calls
                if comp.multiStream.count==size(comp.imageData,3)
                    % prevent unnecessary n-duplicate calls
                    comp.multiStream.count  = 0;
                    return
                end
                comp.multiStream.count  = comp.multiStream.count+1;
                imref   = zeros(size(comp.imageData,[1 2]));
                for k=1:size(cameralist,2)
                    udi = cameralist(k).UserData;
                    if isempty(udi)
                        im    = getsnapshot(cameralist(k));
                    else
                        im    = udi.command(cameralist(k),udi);
                    end
                    comp.imageData(:,:,k)=im;
                    if k==1
                        imref=im;
                    else
                        imref = cat(2,imref,im);
                    end
                end
                
                im = evaluateOrder(comp);
        end
        function [image,order] = evaluateOrder(comp)
            value   = comp.OrderEditField.Value;
            im      = comp.imageData;
            order   = 1;
            try
                order = eval(['[' value ']']);
                im(:,:,order) = im; 
            end
            image = cat(2,im(:,:,1),im(:,:,2));
        end
        
        function flashRectangle(comp)
            idxC = ismember(comp.LockedCameras.Name,comp.ActiveCamera.Name);
            [~,idxO] = evaluateOrder(comp);
            im = size(imresize(comp.imageData(:,:,1),comp.imageQuality.Value));
            c  = size(findall(comp.axfft,'Type','Image').CData);
            dim = im./c==1;
            y0 = [0 im(dim)];
            x0 = im(~dim)*([idxO(idxC)-1 idxO(idxC)]);
            drawrectangle(comp.axfft,'Position', ...
                [x0(1) y0(1) x0(end) y0(end)], ...
                'Color','r', ...
                'InteractionsAllowed','none', ...
                'FaceAlpha',0.1, ...
                'Tag','flash');
            delayedFunction(@(varagin) delete(findall(comp.axfft,'Tag','flash')),.3,'pgon')
        end
        function [Fk,c] = getcarrierWave(~,im)
            Fk      = fft2(im);
            c       = [];
            try
                [kr, ku] = findorthcarrierpks(Iref, 4*pi/min(size(Iref)), Inf,thresh,usehamming);
            catch
                return
            end
            % extract carrier signals from reference image and store them for later use
            krad    = sqrt(sum((kr-ku).^2))/2;%2;
            cr      = getcarrier(fIref, kr, krad);
            cu      = getcarrier(fIref, ku, krad);
            c       = [cr;cu];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            comp.CameraList=imaqfind;
            
            calledWithoutParent(comp,'Camera settings');
            addlistener(ancestor(comp,'Figure'),'ObjectBeingDestroyed',@closePreviews);

            if ~isvalid(comp.CameraList)
                warning('No videoinputs have been loaded into the workspace')
            else
                % populate camera list box
                names = comp.CameraList.Name;
                if ~iscell(names)
                    names = {names};
                end
                comp.CameraListBox.Items = unique(names);
            end
            % hide axes
            set(comp.glax,RowHeight={0,0},ColumnWidth={0});
            set(comp.gl,ColumnWidth={'fit',0})
            if isa(comp.Parent,'matlab.ui.Figure')
                comp.Position(3) = 265;
                comp.Parent.Position(3)=265;
            end
            comp.validEnable = findall(comp.gl,'Enable','off');
            comp.getResolution.Enable = 'off';

            function closePreviews(~,~)
                figs = findall(0,'Type','Figure');
                close(figs(contains({figs.Tag},'LiveView')),'force')
            end
        end

        % Callback function: digitalshiftef, exposureef, flipLR, flipUD, 
        % ...and 4 other components
        function valueChanged(comp, event)
            warning off
            switch event.Source
                case comp.digitalshiftef
                    comp.Settings.DigitalShift = uint32(event.Value);
                case comp.exposureef
                    comp.Settings.ExposureTime = event.Value*1e3;
                case {comp.flipLR,comp.flipUD}
                    val = 'False';
                    if event.Value
                        val = 'True';
                    end
                    command = sprintf('comp.Settings.%s="%s";',event.Source.Tag,val);
                    eval(command)
                otherwise
                    eval(sprintf('comp.Settings.%s=event.Value;',event.Source.Tag))

            end
            warning on
        end

        % Value changed function: autoExposure, autoGain
        function toggleAuto(comp, event)
            switch event.Value
                case true 
                    val = 'Continuous';
                case false
                    val = 'Off';
            end
            src = getselectedsource(comp.ActiveCamera);
            switch event.Source
                case comp.autoExposure
                    comp.Settings.ExposureAuto = val;
                    comp.exposureef.Value = src.ExposureTime*1e-3;
                case comp.autoGain
                    comp.Settings.GainAuto = val;
                    comp.gainef.Value = src.Gain;
            end
            
        end

        % Button pushed function: rot90L, rot90R
        function imageSettingsChanged(comp, event)
            udstruct = struct('Rot',0,'ROI',[1 1 comp.ActiveCamera.VideoResolution],'ds',nan,'command', ...
                @(camera_handle,userdata) ...
                rot90(imcrop( ...
                getsnapshot(camera_handle),userdata.ROI),userdata.Rot) ...
                );
            ac = comp.ActiveCamera;
            ud = ac.UserData;
            hasAllFields = true;
            if ~isempty(ud)
                AllFields       = setxor(fieldnames(ud),fieldnames(udstruct));
                hasAllFields    = isempty(AllFields);
            end
            if isempty(ud)||~hasAllFields
                if ~hasAllFields
                    commands = cellfun(@(field) ...
                        sprintf('ud.%s=udstruct.%s;',field,field), ...
                        AllFields, ...
                        'UniformOutput',false);
                    cellfun(@(commands) evalin('caller',commands),commands)
                    comp.ActiveCamera.UserData = ud;
                else
                    comp.ActiveCamera.UserData = udstruct;
                end
                ud = udstruct;
            end
            switch event.Source
                case comp.rot90L
                    ud.Rot = mod(ud.Rot-1,4);
                case comp.rot90R
                    ud.Rot = mod(ud.Rot+1,4);
                case comp.setROI
                    [];
            end
            val     = sprintf('(%s)',char(extractBetween(comp.rot90L.Tooltip,'(',')')));
            newval  = sprintf('(%i)',ud.Rot);
            comp.rot90L.Tooltip=strrep(comp.rot90L.Tooltip,val,newval);
            comp.rot90R.Tooltip=strrep(comp.rot90R.Tooltip,val,newval);
            comp.ActiveCamera.UserData = ud;

        end

        % Callback function: FFTButton, HistogramButton, ImageButton
        function previewActive(comp, event)
            
            comp.gl.ColumnWidth = {'fit','1x'};
            
            fig = ancestor(comp,'Figure');
            if strcmp(fig.WindowState,'normal')&&~comp.hasOpened
                comp.hasOpened = true;
                fig.Position = fig.Position.*[1 1 0 0]+[0 0 786,420];
                set(comp.glax,RowHeight = {'1x',0},ColumnWidth={'1x'})
                comp.axhist.Visible='off';
            end
            openCamera(comp)

            ax = comp.axfft;
            switch event.Source
                case comp.ImageButton
                    xlabel(comp.axfft,'x')
                    ylabel(comp.axfft,'y')
                    comp.previewState = 'Image';
                    comp.axfft.Visible='on';
                    xlim(ax,[-inf inf])
                    ylim(ax,[-inf inf])
                case comp.FFTButton
                    xlabel(comp.axfft,'k')
                    ylabel(comp.axfft,'l')
                    comp.previewState = 'FFT';
                    comp.axfft.Visible='on';

                    xlim(ax,[-inf inf])
                    ylim(ax,[-inf inf])
                case comp.HistogramButton
                    comp.axhist.YScale='log';
                    switch comp.HistogramButton.Value
                        case true
                            set(comp.glax,RowHeight = {'4x',150})
                            comp.axhist.Visible='on';
                            comp.HistogramButton.UserData = true;
                        case false
                            set(comp.glax,RowHeight = {'1x',0})
                            comp.axhist.Visible='off';
                            comp.HistogramButton.UserData = false;
                    end
            end
            
        end

        % Button pushed function: getResolution
        function measureResolution(comp, event)
            uiCameraResolution(comp.ActiveCamera)
        end

        % Value changed function: CameraListBox
        function updateActiveCamera(comp, event)
            set(comp.validEnable,'Enable','off')
            if ~isempty(event.Value)
                set(comp.validEnable,'Enable','on')
                if isempty(comp.VideoData)
                    comp.getResolution.Enable = 'off';
                else
                    comp.getResolution.Enable = 'on';
                end
            end
            comp.ActiveCamera = validcamera(comp);
            set(comp.lockButton,'Enable','off')
            if ~isempty(comp.VideoData)
                openCamera(comp)
            end
            updateSettings(comp)

            if length(comp.LockedCameras)>1
                flashRectangle(comp)
            end
        end

        % Value changed function: lockButton
        function toggleLock(comp, event)
            switch event.Value
                case true
                    icon = 'functions/appdesigner/components/resources/icons/icon-fa-lock.png';
                    comp.LockedCameras = comp.ActiveCamera;
                    set(comp.OrderEditField,'Visible','on','Enable','on')
                    comp.CameraorderLabel.Visible = 'on';
                    comp.OrderEditField.Value = char(join(arrayfun(@(idx) sprintf('%i',idx),1:length(comp.LockedCameras),'UniformOutput',false),','));
                case false
                    icon = 'functions/appdesigner/components/resources/icons/icon-fa-unlock-alt.png';
                    comp.LockedCameras = [];
                    set(comp.OrderEditField,'Visible','off','Enable','off')
                    comp.CameraorderLabel.Visible = 'off';
            end
            set(event.Source,'Icon',icon)
            
        end

        % Value changed function: imageQuality
        function qualityChanged(comp, event)
            comp.multiStream.return = true;
            imdata  = findall(comp.axfft,'Type','Image','Tag','imdata');
            [Y,X] = size(imdata.CData);
            XL = xlim(comp.axfft);
            YL = ylim(comp.axfft);
            comp.multiStream.ref = round([XL/X; YL/Y],3); 
        end

        % Button pushed function: RestartButton
        function restartCallback(comp, event)
            startupFcn(comp)
        end

        % Value changed function: DrawgridButton
        function drawGridCallback(comp, event)
             ax = comp.axfft;
             imdata = findall(ax,'Type','Image');
             
             if strcmp(comp.previewState,'Image')&&event.Value
                [y,x] = size(imdata.CData); 
                X = xlim(ax);
                Y = ylim(ax);
                arrayfun(@(x) line(ax,xlim(ax),Y(1)+diff(Y)*x/10.*[1 1],'LineStyle','--','Color','k'),1:10);
                arrayfun(@(x) line(ax,X(1)+diff(X)*x/10.*[1 1],ylim(ax),'LineStyle','--','Color','k'),1:10);
                return
             end
             delete(findall(ax,'Type','Line'))


        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 786 659];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.gl = uigridlayout(comp);
            comp.CamerasettingsPanel = uipanel(comp.gl);
            comp.CamerasettingsPanel.Title = 'Camera settings';
            comp.GridLayout2 = uigridlayout(comp.CamerasettingsPanel);
            comp.CameraListBoxLabel = uilabel(comp.GridLayout2);
            comp.CameraListBoxLabel.Text = 'Camera';
            comp.CameraListBox = uilistbox(comp.GridLayout2);
            comp.CameraListBox.Items = {};
            comp.CameraListBox.Multiselect = 'on';
            comp.CameraListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateActiveCamera, true);
            comp.CameraListBox.Value = {};
            comp.ExposuremsEditFieldLabel = uilabel(comp.GridLayout2);
            comp.ExposuremsEditFieldLabel.Text = 'Exposure  (ms)';
            comp.exposureef = uieditfield(comp.GridLayout2, 'numeric');
            comp.exposureef.Limits = [0 Inf];
            comp.exposureef.RoundFractionalValues = 'on';
            comp.exposureef.ValueDisplayFormat = '%.0f';
            comp.exposureef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.exposureef.Tag = 'Exposure';
            comp.exposureef.Enable = 'off';
            comp.exposureef.Value = 100;
            comp.autoExposure = uibutton(comp.GridLayout2, 'state');
            comp.autoExposure.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleAuto, true);
            comp.autoExposure.Enable = 'off';
            comp.autoExposure.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-brightness-auto.png');
            comp.autoExposure.Text = '';
            comp.GainEditFieldLabel = uilabel(comp.GridLayout2);
            comp.GainEditFieldLabel.Text = 'Gain';
            comp.gainef = uieditfield(comp.GridLayout2, 'numeric');
            comp.gainef.Limits = [0 Inf];
            comp.gainef.RoundFractionalValues = 'on';
            comp.gainef.ValueDisplayFormat = '%.0f';
            comp.gainef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.gainef.Tag = 'Gain';
            comp.gainef.Enable = 'off';
            comp.autoGain = uibutton(comp.GridLayout2, 'state');
            comp.autoGain.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleAuto, true);
            comp.autoGain.Enable = 'off';
            comp.autoGain.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-brightness-auto.png');
            comp.autoGain.Text = '';
            comp.GammaEditFieldLabel = uilabel(comp.GridLayout2);
            comp.GammaEditFieldLabel.Text = 'Gamma';
            comp.gammaed = uieditfield(comp.GridLayout2, 'numeric');
            comp.gammaed.Limits = [0 Inf];
            comp.gammaed.ValueDisplayFormat = '%.2f';
            comp.gammaed.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.gammaed.Tag = 'Gamma';
            comp.gammaed.Enable = 'off';
            comp.DigitalShiftEditFieldLabel = uilabel(comp.GridLayout2);
            comp.DigitalShiftEditFieldLabel.Text = 'Digital Shift';
            comp.digitalshiftef = uieditfield(comp.GridLayout2, 'numeric');
            comp.digitalshiftef.Limits = [0 Inf];
            comp.digitalshiftef.RoundFractionalValues = 'on';
            comp.digitalshiftef.ValueDisplayFormat = '%.0f';
            comp.digitalshiftef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.digitalshiftef.Tag = 'Digital Shift';
            comp.digitalshiftef.Enable = 'off';
            comp.ImagesettingsPanel = uipanel(comp.GridLayout2);
            comp.ImagesettingsPanel.Title = 'Image settings';
            comp.GridLayout3 = uigridlayout(comp.ImagesettingsPanel);
            comp.rot90L = uibutton(comp.GridLayout3, 'push');
            comp.rot90L.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageSettingsChanged, true);
            comp.rot90L.Icon = 'mat-rotate-left.png';
            comp.rot90L.Enable = 'off';
            comp.rot90L.Tooltip = {'Rotate left 90° (0)'};
            comp.rot90L.Text = '';
            comp.rot90R = uibutton(comp.GridLayout3, 'push');
            comp.rot90R.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @imageSettingsChanged, true);
            comp.rot90R.Icon = 'mat-rotate-right.png';
            comp.rot90R.Enable = 'off';
            comp.rot90R.Tooltip = {'Rotate right 90° (0)'};
            comp.rot90R.Text = '';
            comp.flipLR = uibutton(comp.GridLayout3, 'state');
            comp.flipLR.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.flipLR.Tag = 'ReverseX';
            comp.flipLR.Enable = 'off';
            comp.flipLR.Tooltip = {'Flip horizontally'};
            comp.flipLR.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flip.png');
            comp.flipLR.Text = '';
            comp.flipUD = uibutton(comp.GridLayout3, 'state');
            comp.flipUD.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.flipUD.Tag = 'ReverseY';
            comp.flipUD.Enable = 'off';
            comp.flipUD.Tooltip = {'Flip vertically'};
            comp.flipUD.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flipud.png');
            comp.flipUD.Text = '';
            comp.setROI = uibutton(comp.GridLayout3, 'push');
            comp.setROI.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.setROI.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-crop.png');
            comp.setROI.Enable = 'off';
            comp.setROI.Tooltip = {'Define cropping ROI (0)'};
            comp.setROI.Text = '';
            comp.resetROI = uibutton(comp.GridLayout3, 'push');
            comp.resetROI.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @valueChanged, true);
            comp.resetROI.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-crop-free.png');
            comp.resetROI.Enable = 'off';
            comp.resetROI.Tooltip = {'Reset cropping ROI'};
            comp.resetROI.Text = '';
            comp.getResolution = uibutton(comp.GridLayout3, 'push');
            comp.getResolution.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @measureResolution, true);
            comp.getResolution.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-distance.png');
            comp.getResolution.Enable = 'off';
            comp.getResolution.Tooltip = {'Measure pixel resolution (0)'};
            comp.getResolution.Text = '';
            comp.PreviewPanel = uipanel(comp.GridLayout2);
            comp.PreviewPanel.Title = 'Preview';
            comp.GridLayout5 = uigridlayout(comp.PreviewPanel);
            comp.FFTButton = uibutton(comp.GridLayout5, 'push');
            comp.FFTButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @previewActive, true);
            comp.FFTButton.Tag = 'preview';
            comp.FFTButton.Enable = 'off';
            comp.FFTButton.Text = 'FFT';
            comp.ImageButton = uibutton(comp.GridLayout5, 'push');
            comp.ImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @previewActive, true);
            comp.ImageButton.Tag = 'preview';
            comp.ImageButton.Enable = 'off';
            comp.ImageButton.Text = 'Image';
            comp.HistogramButton = uibutton(comp.GridLayout5, 'state');
            comp.HistogramButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @previewActive, true);
            comp.HistogramButton.Tag = 'preview';
            comp.HistogramButton.Enable = 'off';
            comp.HistogramButton.Text = 'Histogram';
            comp.imageQuality = uieditfield(comp.GridLayout5, 'numeric');
            comp.imageQuality.Limits = [0 1];
            comp.imageQuality.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @qualityChanged, true);
            comp.imageQuality.Tag = 'preview';
            comp.imageQuality.Enable = 'off';
            comp.imageQuality.Tooltip = {'Image quality (x100%)'};
            comp.imageQuality.Value = 0.35;
            comp.CameraorderLabel = uilabel(comp.GridLayout5);
            comp.CameraorderLabel.Visible = 'off';
            comp.CameraorderLabel.Text = 'Order';
            comp.OrderEditField = uieditfield(comp.GridLayout5, 'text');
            comp.OrderEditField.Visible = 'off';
            comp.OrderEditField.Value = '1';
            comp.lockButton = uibutton(comp.GridLayout2, 'state');
            comp.lockButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleLock, true);
            comp.lockButton.Enable = 'off';
            comp.lockButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-fa-unlock-alt.png');
            comp.lockButton.Text = '';
            comp.ProgramsettingsPanel = uipanel(comp.GridLayout2);
            comp.ProgramsettingsPanel.Title = 'Program settings';
            comp.GridLayout6 = uigridlayout(comp.ProgramsettingsPanel);
            comp.RestartButton = uibutton(comp.GridLayout6, 'push');
            comp.RestartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @restartCallback, true);
            comp.RestartButton.Text = 'Restart';
            comp.DrawgridButton = uibutton(comp.GridLayout6, 'state');
            comp.DrawgridButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @drawGridCallback, true);
            comp.DrawgridButton.Text = 'Draw grid';
            comp.glax = uigridlayout(comp.gl);
            comp.axhist = uiaxes(comp.glax);
            xlabel(comp.axhist, 'Gray-value')
            ylabel(comp.axhist, 'Normalized frequency')
            zlabel(comp.axhist, 'Z')
            comp.axfft = uiaxes(comp.glax);
            xlabel(comp.axfft, 'X')
            ylabel(comp.axfft, 'Y')
            zlabel(comp.axfft, 'Z')
            % Execute the startup function
            startupFcn(comp)
        end
    end
end