classdef uiDesigner < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        ParameterspaceSchedulerPanel  matlab.ui.container.Panel
        GridLayout2_3                 matlab.ui.container.GridLayout
        MovedownButton                matlab.ui.control.Button
        MoveupButton                  matlab.ui.control.Button
        openFolderButton              matlab.ui.control.Button
        GeneratefilesButton           matlab.ui.control.Button
        RemoveconfigurationButton     matlab.ui.control.Button
        AddconfigurationButton        matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        acceptParams                  matlab.ui.control.Button
        InputsPanel                   matlab.ui.container.Panel
        GridLayout2_2                 matlab.ui.container.GridLayout
        wavemakerLabel                matlab.ui.control.Label
        wavemakerEditField            matlab.ui.control.NumericEditField
        SLabel_6                      matlab.ui.control.Label
        acceptInputs                  matlab.ui.control.Button
        timeLabel                     matlab.ui.control.Label
        bucketLevelLabel              matlab.ui.control.Label
        IceSlopeEditField             matlab.ui.control.NumericEditField
        FlowRateEditField             matlab.ui.control.NumericEditField
        SaDyeEditField                matlab.ui.control.NumericEditField
        SaMassEditField               matlab.ui.control.NumericEditField
        bucketLevelEditField          matlab.ui.control.NumericEditField
        SLabel_5                      matlab.ui.control.Label
        SLabel_2                      matlab.ui.control.Label
        TLabel_2                      matlab.ui.control.Label
        TLabel_3                      matlab.ui.control.Label
        TLabel_4                      matlab.ui.control.Label
        StratificationsetupPanel      matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        acceptStratification          matlab.ui.control.Button
        NEditField                    matlab.ui.control.NumericEditField
        rhoaEditField                 matlab.ui.control.NumericEditField
        rho0EditField                 matlab.ui.control.NumericEditField
        SaEditField                   matlab.ui.control.NumericEditField
        S0EditField                   matlab.ui.control.NumericEditField
        TaEditField                   matlab.ui.control.NumericEditField
        NradsLabel                    matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SLabel_3                      matlab.ui.control.Label
        SLabel                        matlab.ui.control.Label
        TLabel                        matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        InputsChanged
        ParameterSpaceChanged
        StratificationChanged
    end

    properties (Access = private)
        eta double = 0;
        internalProcess logical = false;
    end
    
    properties (Access = public)
        H = 1.1; % Tank depth
        InputsStructure struct = struct('level',0.85,'SaMass',0,'SaDye',0,'FlowRate',0,'IceAngle',0,'m0',1/8.5e-2);
        ParameterSpaceData table
        StratificationStructure struct = struct('Ta',0,'S0',0,'Sa',0,'rho0',999.8,'rhoa',999.8,'N',0,'eta',0);
        V = 0.33; % Volume of tank (m³)
        bucketH = .85; % Height of salty water reservoir bucket (known volume)
        wavemakerprofilesPath string {mustBeFolder(wavemakerprofilesPath)} = pwd; % Directory containing wavemaker output files
    end
    
    %% SET functions
    methods 
        function set.wavemakerprofilesPath(comp,val)
            comp.wavemakerprofilesPath = val;
        end
        function set.StratificationStructure(comp,val)
            if ~isstruct(val)
                error('Input must be a structure with arguments for "Ta, S0, Sa, rho0, rhoa, N"')
            end
            comp.StratificationStructure = val;
            updateStratificationValues(comp)
        end
        function set.InputsStructure(comp,val)
            if ~isstruct(val)
                error('Input must be a structure with arguments for "level, SaMass, SaDye, FlowRate, IceAngle"')
            end
            comp.InputsStructure = val;
            updateInputsValues(comp)
        end
        function set.ParameterSpaceData(comp,val)
            if ~istable(val)
                error('Input must be a nx5 table of numeric data')
            end
            comp.ParameterSpaceData = val;
            updateTable(comp)
        end
        function reset(comp)
            set(comp, ...
                StratificationStructure=struct('Ta',0,'S0',0,'Sa',0,'rho0',999.8,'rhoa',999.8,'N',0), ...
                InputsStructure=struct('level',0.85,'SaMass',0,'SaDye',0,'FlowRate',0,'IceAngle',0,'m0',1/8.5e-2), ...
                ParameterSpaceData=[])
            comp.GeneratefilesButton.Enable = 'off';
        end
    end
    %%
    methods (Access=private)
        function updateStratificationValues(comp)
            if comp.internalProcess
                return
            end
            s = comp.StratificationStructure;
            comp.TaEditField.Value  = s.Ta;
            comp.S0EditField.Value  = s.S0;
            comp.SaEditField.Value  = s.Sa;
            comp.rho0EditField.Value= s.rho0;
            comp.rhoaEditField.Value= s.rhoa;
            comp.NEditField.Value   = s.N;
        end
        function updateInputsValues(comp)
            if comp.internalProcess
                return
            end
            s = comp.InputsStructure;
            try
            comp.bucketLevelEditField.Value = s.level;
            comp.SaMassEditField.Value      = s.SaMass;
            comp.SaDyeEditField.Value       = s.SaMass;
            comp.FlowRateEditField.Value    = s.FlowRate;
            comp.IceSlopeEditField.Value    = s.IceAngle;
            end
        end
        function updateTable(comp)
            comp.UITable.Data = table2array(comp.ParameterSpaceData);
            if ~isempty(comp.UITable.Data)
                comp.GeneratefilesButton.Enable = 'on';
            end
        end
        function buildStructures(comp,type)
            comp.internalProcess=true;
            switch type
                case 'stratification'
                    comp.StratificationStructure.Ta     = comp.TaEditField.Value;
                    comp.StratificationStructure.S0     = comp.S0EditField.Value;
                    comp.StratificationStructure.Sa     = comp.SaEditField.Value;
                    comp.StratificationStructure.rho0   = comp.rho0EditField.Value;
                    comp.StratificationStructure.rhoa   = comp.rhoaEditField.Value;
                    comp.StratificationStructure.N      = comp.NEditField.Value;
                    comp.StratificationStructure.eta    = comp.eta;
                case 'inputs'
                    comp.InputsStructure.level          = comp.bucketLevelEditField.Value;
                    comp.InputsStructure.SaMass         = comp.SaMassEditField.Value;
                    comp.InputsStructure.SaDye          = comp.SaDyeEditField.Value;
                    comp.InputsStructure.FlowRate       = comp.FlowRateEditField.Value;
                    comp.InputsStructure.IceAngle       = comp.IceSlopeEditField.Value;
                    comp.InputsStructure.m0             = comp.wavemakerEditField.Value;
            end
            comp.internalProcess=false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            calledWithoutParent(comp,'Experiment Parameters Designer')
            set([comp.bucketLevelLabel comp.timeLabel],'Text','')
            comp.UITable.Data = nan(1,numel(comp.UITable.ColumnName));
            try
                comp.wavemakerprofilesPath = 'IWs\wavemaker_profiles\';
            end
            
            try %#ok<*TRYNC> 
                comp.UITable.DoubleClickedFcn = @(src,event) tableClickCallback(src,event);
            end
            function tableClickCallback(src, event)
%                 displayRow = event.InteractionInformation.DisplayRow;
%                 displayColumn = event.InteractionInformation.DisplayColumn;
%                 if all([isempty(displayRow) isempty(displayColumn)])
%                     src.Data = cat(1,src.Data,nan(1,3));
%                 end
            end
        end

        % Value changed function: NEditField, S0EditField, SaEditField, 
        % ...and 3 other components
        function densityCalculation(comp, event)
            
            calcN   = @(rho0,rhoa) sqrt(9.81/rho0*(rhoa-rho0)/comp.H);
            Taef    = comp.TaEditField;
            S0ef    = comp.S0EditField;
            Saef    = comp.SaEditField;
            rho0ef  = comp.rho0EditField;
            rhoaef  = comp.rhoaEditField;
            Nef     = comp.NEditField;
            Ta      = comp.TaEditField.Value;
            Ti      = liquidus('T',Ta).*(1-tanh(0.15*Ta));
            geteta  = @() 0.66*...
                abs(mean([rho0ef.Value rhoaef.Value])- ...
                getRho(mean([S0ef.Value Saef.Value]),Ti))/...
                (Nef.Value*rho0ef.Value/9.81);
            getSaMass = @() set(comp.SaMassEditField,Value=comp.V/2*comp.bucketLevelEditField.Value*Saef.Value);

            switch event.Source     % affects
                case Taef           % rho0, rhoa, N
                    rho0ef.Value = getRho(S0ef.Value,Taef.Value);
                    rhoaef.Value = getRho(Saef.Value,Taef.Value);
                case S0ef           % rho0, N
                    rho0ef.Value = getRho(S0ef.Value,Taef.Value);
                case Saef           % rhoa, N
                    rhoaef.Value = getRho(Saef.Value,Taef.Value);
                    getSaMass()
                case rho0ef         % S0, N
                    S0ef.Value = getS(rho0ef.Value,Taef.Value);
                case rhoaef         % Sa, N
                    Saef.Value = getS(rhoaef.Value,Taef.Value);
                    getSaMass()
                case Nef            % Sa, rhoa
                    DelRho = Nef.Value^2*comp.H*getRho(S0ef.Value,Taef.Value)/9.81;
                    rhoaef.Value = rho0ef.Value+DelRho;
                    s = linspace(0,100,1e3);
                    rhovalid=getRho(s,Taef.Value);
                    try
                        Saef.Value = round(s(find(rhovalid>rhoaef.Value,1)),2);
                    end
                    comp.eta = geteta();
                    getSaMass()
                    return
            end
            Nef.Value = round(real(calcN(getRho(S0ef.Value,Taef.Value),getRho(Saef.Value,Taef.Value))),2);     
            comp.eta = geteta();

            function s = getS(rho,T)
                s = linspace(0,100,1e4);
                RHO = getRho(s,T);
                s=s(find(RHO>rho,1,'first'));
            end
            function rho = getRho(S,T)
                b0 =  8.24493e-1;
                b1 = -4.0899e-3;
                b2 =  7.6438e-5;
                b3 = -8.2467e-7;
                b4 =  5.3875e-9;
                c0 = -5.72466e-3;
                c1 = +1.0227e-4;
                c2 = -1.6546e-6;
                d0 = 4.8314e-4;
                rho = getSmow(T) + (b0 + (b1 + (b2 + (b3 + b4*T).*T).*T).*T).*S  ...
                    + (c0 + (c1 + c2*T).*T).*S.*sqrt(S) + d0*S.^2;
                rho = round(rho,2);
            end
            function rho = getSmow(T)
                a0 = 999.842594;
                a1 =   6.793952e-2;
                a2 =  -9.095290e-3;
                a3 =   1.001685e-4;
                a4 =  -1.120083e-6;
                a5 =   6.536332e-9;
                rho = a0 + (a1 + (a2 + (a3 + (a4 + a5*T).*T).*T).*T).*T;
            end
        end

        % Value changed function: FlowRateEditField, IceSlopeEditField, 
        % ...and 4 other components
        function inputsCallback(comp, event)
            switch event.Source
                case comp.bucketLevelEditField
                    comp.bucketLevelLabel.Text = sprintf('Fraction\n%.1f%s',comp.bucketLevelEditField.Value/comp.bucketH*1e2,'%');
                case comp.FlowRateEditField
                    comp.timeLabel.Text = sprintf('Fill time\n%s',string(hours(comp.V*1e3/60/comp.FlowRateEditField.Value),'hh:mm:ss'));
                case comp.wavemakerEditField
                    comp.wavemakerLabel.Text = sprintf('%s=%.1f(cm)',extractBefore(comp.wavemakerLabel.Text,'='),1/comp.wavemakerEditField.Value*1e2);
            end
        end

        % Button pushed function: acceptInputs, acceptParams, 
        % ...and 1 other component
        function acceptCallback(comp, event)
            switch event.Source
                case comp.acceptStratification
                    buildStructures(comp,'stratification')
                    notify(comp, 'StratificationChanged');
                case comp.acceptInputs
                    buildStructures(comp,'inputs')
                    notify(comp, 'InputsChanged');
                case comp.acceptParams
                    d = comp.UITable.Data;
                    comp.ParameterSpaceData = table;
                    comp.GeneratefilesButton.Enable = 'off';
                    if ~isempty(d)
                        comp.ParameterSpaceData             = array2table(...
                            d,...
                            'VariableNames', ...
                            {'omegaN','A_mmPs','t_hr','h_m','m0_1Pm'});
                        comp.GeneratefilesButton.Enable = 'on';
                    end
                    notify(comp, 'ParameterSpaceChanged');
            end
            
        end

        % Key press function: UITable
        function tableKeyPress(comp, event)
            key = event.Key;
            switch key
                case 'delete'
                    valid = comp.UITable.Selection;
                    if isempty(valid)
                        return
                    end
                    comp.UITable.Data(unique(valid(:,1)),:)=[];
            end
        end

        % Button pushed function: AddconfigurationButton
        function addConfigCallback(comp, event)
            d = comp.UITable.Data;
            defaults = nan(1,5);
            defaults(end) = comp.wavemakerEditField.Value;
            if isempty(d)
                comp.UITable.Data = defaults;
                return
            end
            comp.UITable.Data = cat(1,d,defaults);
        end

        % Button pushed function: RemoveconfigurationButton
        function removeConfigCallback(comp, event)
            s = comp.UITable.Selection;
            if isempty(s)
                return
            end
            comp.UITable.Data(s(:,1),:)=[];
        end

        % Button pushed function: GeneratefilesButton
        function generateFiles(comp, event)
            d = comp.UITable.Data;
            if isempty(d)
                return
            end
            % omit non-wave generating setups
            d = d(~or(isnan(d(:,1)),d(:,1)==0),:);
            for i=1:height(d)
                wavemaker(comp.StratificationStructure.N, ...
                    'amplitude',d(i,2), ...
                    'omega',d(i,1)*comp.StratificationStructure.N, ...
                    'm',d(i,5),...
                    'label',comp.wavemakerprofilesPath,...
                    'export','silent')
            end
        end

        % Button pushed function: openFolderButton
        function openFolder(comp, event)
            winopen(comp.wavemakerprofilesPath)
        end

        % Button pushed function: MovedownButton, MoveupButton
        function moveParameterCallback(comp, event)
            s = unique(comp.UITable.Selection(:,1));
            d = comp.UITable.Data;
            S = 1:height(d);
            valid = ismember(S,s);

            switch event.Source
                case comp.MoveupButton
                    dir = -1;
                case comp.MovedownButton
                    dir = 1;
            end

            SS              = S;
            newvalid        = circshift(valid,dir);
            SS(newvalid)    = S(valid);
            SS(~newvalid)   = S(~valid);
            comp.UITable.Data = d(SS,:);

            s = 1:height(d);
            w = 1:width(d);
            
            valid = newvalid;
            S = reshape(repmat(s(valid),[numel(w) 1]),[],1);
            W = reshape(repmat(w,[numel(s(valid)) 1])',[],1);
            comp.UITable.Selection = [S W];
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 555 518];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.StratificationsetupPanel = uipanel(comp.GridLayout);
            comp.StratificationsetupPanel.Title = 'Stratification setup';
            comp.GridLayout2 = uigridlayout(comp.StratificationsetupPanel);
            comp.TLabel = uilabel(comp.GridLayout2);
            comp.TLabel.Text = {'Tₐ '; '(°C)'};
            comp.SLabel = uilabel(comp.GridLayout2);
            comp.SLabel.Text = {'S₀ '; '(‰)'};
            comp.SLabel_3 = uilabel(comp.GridLayout2);
            comp.SLabel_3.Text = {'Sₐ '; '(‰)'};
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.Text = {'ρ₀ '; '(kg m⁻³)'};
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.Text = {'ρₐ '; '(kg m⁻³)'};
            comp.NradsLabel = uilabel(comp.GridLayout2);
            comp.NradsLabel.Text = {'N '; '(rad s⁻¹)'};
            comp.TaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.TaEditField.Limits = [0 Inf];
            comp.TaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.S0EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.S0EditField.Limits = [0 Inf];
            comp.S0EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.SaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.SaEditField.Limits = [0 Inf];
            comp.SaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rho0EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.rho0EditField.Limits = [960 Inf];
            comp.rho0EditField.ValueDisplayFormat = '%.1f';
            comp.rho0EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rho0EditField.Value = 999.8426;
            comp.rhoaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.rhoaEditField.Limits = [960 Inf];
            comp.rhoaEditField.ValueDisplayFormat = '%.1f';
            comp.rhoaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.rhoaEditField.Value = 999.8426;
            comp.NEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.NEditField.Limits = [0 Inf];
            comp.NEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @densityCalculation, true);
            comp.acceptStratification = uibutton(comp.GridLayout2, 'push');
            comp.acceptStratification.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @acceptCallback, true);
            comp.acceptStratification.Text = 'Accept';
            comp.InputsPanel = uipanel(comp.GridLayout);
            comp.InputsPanel.Title = 'Inputs';
            comp.GridLayout2_2 = uigridlayout(comp.InputsPanel);
            comp.TLabel_4 = uilabel(comp.GridLayout2_2);
            comp.TLabel_4.Text = {'Bucket level '; '(m)'};
            comp.TLabel_3 = uilabel(comp.GridLayout2_2);
            comp.TLabel_3.Text = {'Sₐ mass'; '(kg)'};
            comp.TLabel_2 = uilabel(comp.GridLayout2_2);
            comp.TLabel_2.Text = {'Sₐ dye '; '(mL)'};
            comp.SLabel_2 = uilabel(comp.GridLayout2_2);
            comp.SLabel_2.Text = {'Flow rate '; '(L min⁻¹)'};
            comp.SLabel_5 = uilabel(comp.GridLayout2_2);
            comp.SLabel_5.Text = {'Ice angle '; '(° vert.)'};
            comp.bucketLevelEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.bucketLevelEditField.Limits = [0 0.9];
            comp.bucketLevelEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.bucketLevelEditField.Value = 0.85;
            comp.SaMassEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.SaMassEditField.Limits = [0 Inf];
            comp.SaMassEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.SaDyeEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.SaDyeEditField.Limits = [0 Inf];
            comp.SaDyeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.FlowRateEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.FlowRateEditField.Limits = [0 Inf];
            comp.FlowRateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.IceSlopeEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.IceSlopeEditField.Limits = [0 Inf];
            comp.IceSlopeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.bucketLevelLabel = uilabel(comp.GridLayout2_2);
            comp.bucketLevelLabel.Text = '100%';
            comp.timeLabel = uilabel(comp.GridLayout2_2);
            comp.timeLabel.Text = 'hh:mm:ss';
            comp.acceptInputs = uibutton(comp.GridLayout2_2, 'push');
            comp.acceptInputs.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @acceptCallback, true);
            comp.acceptInputs.Text = 'Accept';
            comp.SLabel_6 = uilabel(comp.GridLayout2_2);
            comp.SLabel_6.Text = {'Wavemaker'; 'm₀  (m⁻¹)'};
            comp.wavemakerEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.wavemakerEditField.Limits = [0 Inf];
            comp.wavemakerEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inputsCallback, true);
            comp.wavemakerEditField.Value = 11.7647;
            comp.wavemakerLabel = uilabel(comp.GridLayout2_2);
            comp.wavemakerLabel.Text = '1/m₀=8.5 (cm)';
            comp.ParameterspaceSchedulerPanel = uipanel(comp.GridLayout);
            comp.ParameterspaceSchedulerPanel.Title = 'Parameter space & Scheduler';
            comp.GridLayout2_3 = uigridlayout(comp.ParameterspaceSchedulerPanel);
            comp.acceptParams = uibutton(comp.GridLayout2_3, 'push');
            comp.acceptParams.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @acceptCallback, true);
            comp.acceptParams.Text = 'Accept';
            comp.UITable = uitable(comp.GridLayout2_3);
            comp.UITable.ColumnName = {'ω/N'; 'A (mm s⁻¹)'; 't (hours)'; 'h (m)'; 'm₀ (m⁻¹)'};
            comp.UITable.RowName = {};
            comp.UITable.ColumnEditable = [true true true true true];
            comp.UITable.KeyPressFcn = matlab.apps.createCallbackFcn(comp, @tableKeyPress, true);
            comp.AddconfigurationButton = uibutton(comp.GridLayout2_3, 'push');
            comp.AddconfigurationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addConfigCallback, true);
            comp.AddconfigurationButton.Text = 'Add configuration';
            comp.RemoveconfigurationButton = uibutton(comp.GridLayout2_3, 'push');
            comp.RemoveconfigurationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeConfigCallback, true);
            comp.RemoveconfigurationButton.Text = 'Remove configuration';
            comp.GeneratefilesButton = uibutton(comp.GridLayout2_3, 'push');
            comp.GeneratefilesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @generateFiles, true);
            comp.GeneratefilesButton.Enable = 'off';
            comp.GeneratefilesButton.Text = 'Generate files';
            comp.openFolderButton = uibutton(comp.GridLayout2_3, 'push');
            comp.openFolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openFolder, true);
            comp.openFolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_database.svg');
            comp.openFolderButton.Tooltip = {'Open image datastore directory(s)'};
            comp.openFolderButton.Text = '';
            comp.MoveupButton = uibutton(comp.GridLayout2_3, 'push');
            comp.MoveupButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @moveParameterCallback, true);
            comp.MoveupButton.Text = 'Move up';
            comp.MovedownButton = uibutton(comp.GridLayout2_3, 'push');
            comp.MovedownButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @moveParameterCallback, true);
            comp.MovedownButton.Text = 'Move down';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end