classdef uiDetails < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout4              matlab.ui.container.GridLayout
        CommentsTextArea         matlab.ui.control.TextArea
        CommentsTextAreaLabel    matlab.ui.control.Label
        Ta                       matlab.ui.control.NumericEditField
        kgmLabel_3               matlab.ui.control.Label
        Nef                      matlab.ui.control.NumericEditField
        kgmLabel_2               matlab.ui.control.Label
        etaef                    matlab.ui.control.NumericEditField
        kgmLabel_4               matlab.ui.control.Label
        rhoav_label              matlab.ui.control.Label
        rhomax                   matlab.ui.control.NumericEditField
        rhomin                   matlab.ui.control.NumericEditField
        kgmLabel                 matlab.ui.control.Label
        sav_label                matlab.ui.control.Label
        smax                     matlab.ui.control.NumericEditField
        smin                     matlab.ui.control.NumericEditField
        SLabel                   matlab.ui.control.Label
        AverageValueLabel        matlab.ui.control.Label
        MaximumLabel             matlab.ui.control.Label
        MinimumLabel             matlab.ui.control.Label
        DatetoDatePicker         matlab.ui.control.DatePicker
        DatetoDatePickerLabel    matlab.ui.control.Label
        DatefromDatePicker       matlab.ui.control.DatePicker
        DatefromDatePickerLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = private)
        internalChange logical = 0;
    end
    
    properties (Access = public)
        Comments string
        Date datetime
        N (1,1) double
        S (1,2) double
        T (1,1) double
        eta (1,1) double = 0;
        rho (1,2) double
    end
    
    %% SET functions
    methods         
        function set.Date(comp,val)
            if ~isdatetime(val)
                error('Value must be a datetime')
            end
            comp.Date=val;
            updateComponent(comp,'date')
        end
        function set.N(comp,val)
            if ~isa(val,'double')
                error('Value must be a double')
            end
            comp.N=val;
            updateComponent(comp,'N')
        end
        function set.T(comp,val)
            if ~isa(val,'double')
                error('Value must be a double')
            end
            comp.T=val;
            updateComponent(comp,'T')
        end
        function set.S(comp,val)
            if ~isa(val,'double')
                error('Value must be a double')
            end
            if numel(val)~=2
                error('Value must have two elements')
            end
            comp.S=val;
            updateComponent(comp,'S')
        end
        function set.rho(comp,val)
            if ~isa(val,'double')
                error('Value must be a double')
            end
            if numel(val)~=2
                error('Value must have two elements')
            end
            comp.rho=val;
            updateComponent(comp,'rho')
        end
        function set.Comments(comp,val)
            if ~isa(val,'string')
                error('Value must be a string')
            end
            comp.Comments = val;
            updateComponent(comp,'comment')
        end
        function set.eta(comp,val)
             if ~isa(val,'double')
                error('Value must be a double')
            end
            comp.eta=val;
            updateComponent(comp,'eta')
        end
        function reset(comp)
            set(comp,S=0,T=0,eta=0,rho=999.8,N=0,Comments='',Date=NaT)
        end
    end
    
    methods (Access = private)
        function updateAverages(comp)
            comp.sav_label.Text = sprintf('%.2f',mean(comp.S));
            comp.rhoav_label.Text = sprintf('%.2f',mean(comp.rho));
            

        end
        function updateComponent(comp,type)
            if ~comp.internalChange
                switch type
                    case 'date'
                        if isempty(comp.Date)
                            return
                        end
                        comp.DatefromDatePicker.Value   = min(comp.Date);
                        comp.DatetoDatePicker.Value     = max(comp.Date);
                    case 'S'
                        comp.smin.Value = min(comp.S);
                        comp.smax.Value = max(comp.S);
                        updateAverages(comp)
                    case 'N'
                        comp.Nef.Value = comp.N;
                    case 'rho'
                        comp.rhomin.Value = min(comp.rho);
                        comp.rhomax.Value = max(comp.rho);
                        updateAverages(comp)
                    case 'comment'
                        val = comp.Comments;
                        if isempty(val)
                            val = '';
                        end
                        comp.CommentsTextArea.Value = val;
                    case 'T'
                        comp.Ta.Value = comp.T;
                    case 'eta'
                        comp.etaef.Value = comp.eta;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CommentsTextArea, DatefromDatePicker, 
        % ...and 7 other components
        function valueChange(comp, event)
            comp.internalChange = true;
            switch event.Source.Tag
                case 'S'
                    comp.S = [comp.smin.Value comp.smax.Value];
                    comp.sav_label.Text = sprintf('%.2f',mean(comp.S));
                case 'rho'
                    comp.rho = [comp.rhomin.Value comp.rhomax.Value];
                    comp.rhoav_label.Text = sprintf('%.2f',mean(comp.rho));
                case 'Date'
                    comp.Date = [comp.DatefromDatePicker.Value comp.DatetoDatePicker.Value];
                otherwise
                    eval(sprintf('comp.%s=event.Value;',event.Source.Tag))
            end
            comp.internalChange = false;
            notify(comp, 'ValueChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 579 518];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout4 = uigridlayout(comp);
            comp.DatefromDatePickerLabel = uilabel(comp.GridLayout4);
            comp.DatefromDatePickerLabel.Text = 'Date (from)';
            comp.DatefromDatePicker = uidatepicker(comp.GridLayout4);
            comp.DatefromDatePicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.DatefromDatePicker.Tag = 'Date';
            comp.DatetoDatePickerLabel = uilabel(comp.GridLayout4);
            comp.DatetoDatePickerLabel.Text = 'Date (to)';
            comp.DatetoDatePicker = uidatepicker(comp.GridLayout4);
            comp.DatetoDatePicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.DatetoDatePicker.Tag = 'Date';
            comp.MinimumLabel = uilabel(comp.GridLayout4);
            comp.MinimumLabel.Text = 'Minimum';
            comp.MaximumLabel = uilabel(comp.GridLayout4);
            comp.MaximumLabel.Text = 'Maximum';
            comp.AverageValueLabel = uilabel(comp.GridLayout4);
            comp.AverageValueLabel.Text = 'Average / Value';
            comp.SLabel = uilabel(comp.GridLayout4);
            comp.SLabel.Text = 'Sₐ (‰)';
            comp.smin = uieditfield(comp.GridLayout4, 'numeric');
            comp.smin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.smin.Tag = 'S';
            comp.smin.Editable = 'off';
            comp.smax = uieditfield(comp.GridLayout4, 'numeric');
            comp.smax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.smax.Tag = 'S';
            comp.smax.Editable = 'off';
            comp.sav_label = uilabel(comp.GridLayout4);
            comp.sav_label.Text = '';
            comp.kgmLabel = uilabel(comp.GridLayout4);
            comp.kgmLabel.Text = 'ρₐ (kg m⁻³)';
            comp.rhomin = uieditfield(comp.GridLayout4, 'numeric');
            comp.rhomin.Limits = [960 1100];
            comp.rhomin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.rhomin.Tag = 'rho';
            comp.rhomin.Editable = 'off';
            comp.rhomin.Value = 999.8426;
            comp.rhomax = uieditfield(comp.GridLayout4, 'numeric');
            comp.rhomax.Limits = [960 1100];
            comp.rhomax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.rhomax.Tag = 'rho';
            comp.rhomax.Editable = 'off';
            comp.rhomax.Value = 999.8426;
            comp.rhoav_label = uilabel(comp.GridLayout4);
            comp.rhoav_label.Text = '';
            comp.kgmLabel_4 = uilabel(comp.GridLayout4);
            comp.kgmLabel_4.Text = 'η (m)';
            comp.etaef = uieditfield(comp.GridLayout4, 'numeric');
            comp.etaef.Limits = [0 Inf];
            comp.etaef.Tag = 'N';
            comp.etaef.Editable = 'off';
            comp.kgmLabel_2 = uilabel(comp.GridLayout4);
            comp.kgmLabel_2.Text = 'N (rad s⁻¹)';
            comp.Nef = uieditfield(comp.GridLayout4, 'numeric');
            comp.Nef.Limits = [0 Inf];
            comp.Nef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.Nef.Tag = 'N';
            comp.Nef.Editable = 'off';
            comp.kgmLabel_3 = uilabel(comp.GridLayout4);
            comp.kgmLabel_3.Text = 'Tₐ (°C)';
            comp.Ta = uieditfield(comp.GridLayout4, 'numeric');
            comp.Ta.Limits = [0 Inf];
            comp.Ta.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.Ta.Tag = 'T';
            comp.Ta.Editable = 'off';
            comp.CommentsTextAreaLabel = uilabel(comp.GridLayout4);
            comp.CommentsTextAreaLabel.Text = 'Comments';
            comp.CommentsTextArea = uitextarea(comp.GridLayout4);
            comp.CommentsTextArea.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChange, true);
            comp.CommentsTextArea.Tag = 'Comments';
        end
    end
end