classdef uiDuration < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        millisecondsEditField   matlab.ui.control.NumericEditField
        secondsEditField        matlab.ui.control.NumericEditField
        minutesEditField        matlab.ui.control.NumericEditField
        hoursEditField          matlab.ui.control.NumericEditField
        DurationhhmmssSSSLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        SelectionChanged
    end

    properties (Access = private)
        DefaultSumTime = seconds(nan);
    end
    
    properties (Access = public)
        HoursValue = 0;
        MilliSecondsValue = 0;
        MinutesValue = 0;
        SecondsValue = 0;
        SumTime = seconds(0);
        Text = 'Duration (hh:mm:ss)';
    end
    
    
    methods
        function set.SumTime(comp,val)
           if ~isduration(val) 
               error('Value must be a duration class argument')
           end
           comp.SumTime=val;
        end
    end
    
    
    methods (Access = private)
        
        function assignTime(comp)
            val=str2double(split( ...
                strrep(string(duration(comp.SumTime,'Format','hh:mm:ss.SSS')),'.',':'),':'));
            comp.hoursEditField.Value    = val(1);
            comp.minutesEditField.Value  = val(2);
            comp.secondsEditField.Value  = val(3);
            comp.millisecondsEditField.Value=val(4);
        end
        function getSumEditField(comp)
            comp.SumTime=sum([ ...
                hours(comp.hoursEditField.Value) ...
                minutes(comp.minutesEditField.Value) ...
                seconds(comp.secondsEditField.Value) ...
                milliseconds(comp.millisecondsEditField.Value)]); 
        end
        function getSumProperties(comp)
            st=sum([ ...
                milliseconds(comp.MilliSecondsValue) ...
                seconds(comp.SecondsValue) ...
                minutes(comp.MinutesValue) ...
                hours(comp.HoursValue)]); 
            if st==comp.DefaultSumTime
                return
            end
            comp.SumTime=st;
            assignTime(comp)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.DefaultSumTime=sum([ ...
                milliseconds(comp.MilliSecondsValue) ...
                seconds(comp.SecondsValue) ...
                minutes(comp.MinutesValue) ...
                hours(comp.HoursValue)]);
        end

        % Value changed function: hoursEditField, millisecondsEditField, 
        % ...and 2 other components
        function timeChange(comp, event)
            getSumEditField(comp)
            notify(comp,'SelectionChanged')
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.DurationhhmmssSSSLabel.Text=comp.Text;
            getSumProperties(comp)
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 414 43];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.DurationhhmmssSSSLabel = uilabel(comp.GridLayout);
            comp.DurationhhmmssSSSLabel.Text = 'Duration (hh:mm:ss.SSS)';
            comp.hoursEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.hoursEditField.Limits = [0 Inf];
            comp.hoursEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeChange, true);
            comp.hoursEditField.Tooltip = {'Hours'};
            comp.minutesEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.minutesEditField.Limits = [0 Inf];
            comp.minutesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeChange, true);
            comp.minutesEditField.Tooltip = {'Minutes'};
            comp.secondsEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.secondsEditField.Limits = [0 Inf];
            comp.secondsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeChange, true);
            comp.secondsEditField.Tooltip = {'Seconds'};
            comp.millisecondsEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.millisecondsEditField.Limits = [0 Inf];
            comp.millisecondsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeChange, true);
            comp.millisecondsEditField.Tooltip = {'Milliseconds'};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end