classdef uiEdgeDetection < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        ConnectivityButton              matlab.ui.control.StateButton
        DilationButton                  matlab.ui.control.StateButton
        EDButton                        matlab.ui.control.StateButton
        ImageButton                     matlab.ui.control.StateButton
        TabGroup3                       matlab.ui.container.TabGroup
        ROITab_2                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Rotate90degreesSpinner          matlab.ui.control.Spinner
        Rotate90degreesSpinnerLabel     matlab.ui.control.Label
        FlipUDButton                    matlab.ui.control.StateButton
        FlipLRButton                    matlab.ui.control.StateButton
        ResetROIButton                  matlab.ui.control.Button
        SetROIButton                    matlab.ui.control.Button
        ImageTab                        matlab.ui.container.Tab
        GridLayout3_7                   matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.NumericEditField
        BrightnessLabel                 matlab.ui.control.Label
        BlurYSlider                     matlab.ui.control.Slider
        BlurYSliderLabel                matlab.ui.control.Label
        BlurXSlider                     matlab.ui.control.Slider
        BlurXSliderLabel                matlab.ui.control.Label
        VignetteCorrectionSlider        matlab.ui.control.Slider
        VignetteCorrectionSliderLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        EDTab                           matlab.ui.container.Tab
        GridLayout3_6                   matlab.ui.container.GridLayout
        GradientcutoffSlider            matlab.ui.control.Slider
        GradientcutoffSliderLabel       matlab.ui.control.Label
        SigmaSlider                     matlab.ui.control.Slider
        SigmaSliderLabel                matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        AlgorithmListBox                matlab.ui.control.ListBox
        AlgorithmListBoxLabel           matlab.ui.control.Label
        DilationTab                     matlab.ui.container.Tab
        GridLayout14_3                  matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ShapeTab                        matlab.ui.container.Tab
        GridLayout3_8                   matlab.ui.container.GridLayout
        dil_arg2Slider                  matlab.ui.control.Slider
        dil_arg2SliderLabel             matlab.ui.control.Label
        dil_arg1Slider                  matlab.ui.control.Slider
        dil_arg1SliderLabel             matlab.ui.control.Label
        ShapeListBox                    matlab.ui.control.ListBox
        ShapeListBoxLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FiltersTab                      matlab.ui.container.Tab
        GridLayout3_9                   matlab.ui.container.GridLayout
        Pixelrejectionx1000Slider       matlab.ui.control.Slider
        Pixelrejectionx1000SliderLabel  matlab.ui.control.Label
        MedianfilteringSlider           matlab.ui.control.Slider
        MedianfilteringSliderLabel      matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        Smoothingx100Slider             matlab.ui.control.Slider
        Smoothingx100SliderLabel        matlab.ui.control.Label
        ConnectionsSlider               matlab.ui.control.Slider
        ConnectionsSliderLabel          matlab.ui.control.Label
        GlobalsettingsPanel             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ToggleButtonAll_2               matlab.ui.control.StateButton
        ToggleButtonAll                 matlab.ui.control.StateButton
        SetreferenceimageButton         matlab.ui.control.Button
    end

    properties (Access = public)
        Algorithm string = 'Sobel';
        Blur (1,2) double = [0 0];
        Brightness (1,2) double = [0 255];
        Dilation (1,2) double = [0 0];
        Gradient double = 0;
        ROI (1,4) double
        Shape string = 'None';
        Sigma double = 0;
        Threshold double = 0;
        Transient double = 0;
        Vignette (1,1) double = 0;
        states (1,4) logical = [true false false false]; % Logical array of the filters toggled
    end
    
    %% Set functions and subroutines
    methods 
        function set.Vignette(comp,val)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            warning off
            [~,parent] = calledWithoutParent(comp,'Ice Edge Detection Control Window');
            scrollfcn = parent.WindowScrollWheelFcn;
            if isempty(scrollfcn)
                parent.WindowScrollWheelFcn = @(e,s) scrollwheelFcn;
            end
            
            function scrollwheelFcn(e,s)
                

            end
        end

        % Value changed function: ConnectivityButton, DilationButton, 
        % ...and 2 other components
        function toggleState(comp, event)
            but = [comp.ImageButton comp.EDButton comp.DilationButton comp.ConnectivityButton];
            comp.states = cell2mat(get(but,'Value'));
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 405 584];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GlobalsettingsPanel = uipanel(comp.GridLayout);
            comp.GlobalsettingsPanel.Title = 'Global settings';
            comp.GridLayout5 = uigridlayout(comp.GlobalsettingsPanel);
            comp.SetreferenceimageButton = uibutton(comp.GridLayout5, 'push');
            comp.SetreferenceimageButton.Tag = 'im0';
            comp.SetreferenceimageButton.Text = 'Set reference image';
            comp.ToggleButtonAll = uibutton(comp.GridLayout5, 'state');
            comp.ToggleButtonAll.Tag = 'toggleAll';
            comp.ToggleButtonAll.Tooltip = {'Applies all filters to algorithm'};
            comp.ToggleButtonAll.Text = 'Toggle all';
            comp.ToggleButtonAll_2 = uibutton(comp.GridLayout5, 'state');
            comp.ToggleButtonAll_2.Tag = 'overlay';
            comp.ToggleButtonAll_2.Tooltip = {'Overlays algorithm results (binary) to unfiltered image'};
            comp.ToggleButtonAll_2.Text = 'Overlay';
            comp.TabGroup3 = uitabgroup(comp.GridLayout);
            comp.ROITab_2 = uitab(comp.TabGroup3);
            comp.ROITab_2.Title = 'ROI';
            comp.GridLayout11 = uigridlayout(comp.ROITab_2);
            comp.SetROIButton = uibutton(comp.GridLayout11, 'push');
            comp.SetROIButton.Tag = 'setROI';
            comp.SetROIButton.Text = 'Set ROI';
            comp.ResetROIButton = uibutton(comp.GridLayout11, 'push');
            comp.ResetROIButton.Tag = 'resetROI';
            comp.ResetROIButton.Text = 'Reset ROI';
            comp.FlipLRButton = uibutton(comp.GridLayout11, 'state');
            comp.FlipLRButton.Tag = 'flipLR';
            comp.FlipLRButton.Text = 'Flip LR';
            comp.FlipUDButton = uibutton(comp.GridLayout11, 'state');
            comp.FlipUDButton.Tag = 'flipUD';
            comp.FlipUDButton.Text = 'Flip UD';
            comp.Rotate90degreesSpinnerLabel = uilabel(comp.GridLayout11);
            comp.Rotate90degreesSpinnerLabel.Text = {'Rotate'; '(90 degrees)'};
            comp.Rotate90degreesSpinner = uispinner(comp.GridLayout11);
            comp.Rotate90degreesSpinner.Limits = [0 3];
            comp.Rotate90degreesSpinner.Tag = 'rotation';
            comp.ImageTab = uitab(comp.TabGroup3);
            comp.ImageTab.Title = 'Image';
            comp.GridLayout3_7 = uigridlayout(comp.ImageTab);
            comp.UIAxes2 = uiaxes(comp.GridLayout3_7);
            comp.UIAxes2.XLim = [0 255];
            comp.VignetteCorrectionSliderLabel = uilabel(comp.GridLayout3_7);
            comp.VignetteCorrectionSliderLabel.Text = 'Vignette Correction (%)';
            comp.VignetteCorrectionSlider = uislider(comp.GridLayout3_7);
            comp.VignetteCorrectionSlider.Tag = 'slider';
            comp.VignetteCorrectionSlider.Tooltip = {''};
            comp.BlurXSliderLabel = uilabel(comp.GridLayout3_7);
            comp.BlurXSliderLabel.Text = 'Blur (X)';
            comp.BlurXSlider = uislider(comp.GridLayout3_7);
            comp.BlurXSlider.Limits = [0 20];
            comp.BlurXSlider.Tag = 'round-0';
            comp.BlurXSlider.Tooltip = {''};
            comp.BlurYSliderLabel = uilabel(comp.GridLayout3_7);
            comp.BlurYSliderLabel.Text = 'Blur (Y)';
            comp.BlurYSlider = uislider(comp.GridLayout3_7);
            comp.BlurYSlider.Limits = [0 20];
            comp.BlurYSlider.Tag = 'round-0';
            comp.BlurYSlider.Tooltip = {''};
            comp.BrightnessLabel = uilabel(comp.GridLayout3_7);
            comp.BrightnessLabel.Text = 'Brightness';
            comp.EditField = uieditfield(comp.GridLayout3_7, 'numeric');
            comp.EditField.Limits = [0 100];
            comp.EDTab = uitab(comp.TabGroup3);
            comp.EDTab.Title = 'ED';
            comp.GridLayout3_6 = uigridlayout(comp.EDTab);
            comp.AlgorithmListBoxLabel = uilabel(comp.GridLayout3_6);
            comp.AlgorithmListBoxLabel.Text = 'Algorithm';
            comp.AlgorithmListBox = uilistbox(comp.GridLayout3_6);
            comp.AlgorithmListBox.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'Canny'};
            comp.AlgorithmListBox.Tag = 'list';
            comp.AlgorithmListBox.Tooltip = {''};
            comp.AlgorithmListBox.Value = 'Sobel';
            comp.ThresholdSliderLabel = uilabel(comp.GridLayout3_6);
            comp.ThresholdSliderLabel.Text = 'Threshold';
            comp.ThresholdSlider = uislider(comp.GridLayout3_6);
            comp.ThresholdSlider.Limits = [0 10];
            comp.ThresholdSlider.Tag = 'omit-0';
            comp.ThresholdSlider.Tooltip = {''};
            comp.SigmaSliderLabel = uilabel(comp.GridLayout3_6);
            comp.SigmaSliderLabel.Text = 'Sigma';
            comp.SigmaSlider = uislider(comp.GridLayout3_6);
            comp.SigmaSlider.Limits = [0 30];
            comp.SigmaSlider.Tag = 'omit-0';
            comp.SigmaSlider.Enable = 'off';
            comp.SigmaSlider.Tooltip = {''};
            comp.GradientcutoffSliderLabel = uilabel(comp.GridLayout3_6);
            comp.GradientcutoffSliderLabel.Text = 'Gradient cutoff (%)';
            comp.GradientcutoffSlider = uislider(comp.GridLayout3_6);
            comp.GradientcutoffSlider.Limits = [0 20];
            comp.GradientcutoffSlider.Tag = 'slider';
            comp.GradientcutoffSlider.Tooltip = {''};
            comp.DilationTab = uitab(comp.TabGroup3);
            comp.DilationTab.Title = 'Dilation';
            comp.GridLayout14_3 = uigridlayout(comp.DilationTab);
            comp.TabGroup2 = uitabgroup(comp.GridLayout14_3);
            comp.ShapeTab = uitab(comp.TabGroup2);
            comp.ShapeTab.Title = 'Shape';
            comp.GridLayout3_8 = uigridlayout(comp.ShapeTab);
            comp.UIAxes = uiaxes(comp.GridLayout3_8);
            comp.UIAxes.XTick = [];
            comp.UIAxes.YTick = [];
            comp.ShapeListBoxLabel = uilabel(comp.GridLayout3_8);
            comp.ShapeListBoxLabel.Text = 'Shape';
            comp.ShapeListBox = uilistbox(comp.GridLayout3_8);
            comp.ShapeListBox.Items = {'None', 'Line', 'Cross', 'Star', 'Rectangle'};
            comp.ShapeListBox.Tag = 'list';
            comp.ShapeListBox.Tooltip = {'dilation.shape'};
            comp.ShapeListBox.Value = 'Line';
            comp.dil_arg1SliderLabel = uilabel(comp.GridLayout3_8);
            comp.dil_arg1SliderLabel.Text = 'dil_arg1';
            comp.dil_arg1Slider = uislider(comp.GridLayout3_8);
            comp.dil_arg1Slider.Limits = [0 10];
            comp.dil_arg1Slider.Tag = 'round-0';
            comp.dil_arg1Slider.Tooltip = {'dilation.arg1'};
            comp.dil_arg2SliderLabel = uilabel(comp.GridLayout3_8);
            comp.dil_arg2SliderLabel.Text = 'dil_arg2';
            comp.dil_arg2Slider = uislider(comp.GridLayout3_8);
            comp.dil_arg2Slider.Limits = [0 10];
            comp.dil_arg2Slider.Tag = 'round-0';
            comp.dil_arg2Slider.Tooltip = {'dilation.arg2'};
            comp.FiltersTab = uitab(comp.TabGroup2);
            comp.FiltersTab.Title = 'Filters';
            comp.GridLayout3_9 = uigridlayout(comp.FiltersTab);
            comp.MedianfilteringSliderLabel = uilabel(comp.GridLayout3_9);
            comp.MedianfilteringSliderLabel.Text = 'Median-filtering';
            comp.MedianfilteringSlider = uislider(comp.GridLayout3_9);
            comp.MedianfilteringSlider.Limits = [0 10];
            comp.MedianfilteringSlider.Tag = 'omit-0;round-0';
            comp.MedianfilteringSlider.Tooltip = {''};
            comp.Pixelrejectionx1000SliderLabel = uilabel(comp.GridLayout3_9);
            comp.Pixelrejectionx1000SliderLabel.Text = 'Pixel rejection (x1000)';
            comp.Pixelrejectionx1000Slider = uislider(comp.GridLayout3_9);
            comp.Pixelrejectionx1000Slider.Limits = [0 10];
            comp.Pixelrejectionx1000Slider.Tag = 'mult-1000';
            comp.Pixelrejectionx1000Slider.Tooltip = {''};
            comp.ConnectivityTab = uitab(comp.TabGroup3);
            comp.ConnectivityTab.Title = 'Connectivity';
            comp.GridLayout13 = uigridlayout(comp.ConnectivityTab);
            comp.ConnectionsSliderLabel = uilabel(comp.GridLayout13);
            comp.ConnectionsSliderLabel.Text = 'Connections';
            comp.ConnectionsSlider = uislider(comp.GridLayout13);
            comp.ConnectionsSlider.Limits = [0 10];
            comp.ConnectionsSlider.Tag = 'round-0';
            comp.ConnectionsSlider.Tooltip = {''};
            comp.Smoothingx100SliderLabel = uilabel(comp.GridLayout13);
            comp.Smoothingx100SliderLabel.Text = 'Smoothing (x100)';
            comp.Smoothingx100Slider = uislider(comp.GridLayout13);
            comp.Smoothingx100Slider.Limits = [0 5];
            comp.Smoothingx100Slider.Tag = 'omit-0;mult-100';
            comp.Smoothingx100Slider.Tooltip = {''};
            comp.GridLayout14 = uigridlayout(comp.GridLayout);
            comp.ImageButton = uibutton(comp.GridLayout14, 'state');
            comp.ImageButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.ImageButton.Text = 'Image';
            comp.EDButton = uibutton(comp.GridLayout14, 'state');
            comp.EDButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.EDButton.Text = 'ED';
            comp.DilationButton = uibutton(comp.GridLayout14, 'state');
            comp.DilationButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.DilationButton.Text = 'Dilation';
            comp.ConnectivityButton = uibutton(comp.GridLayout14, 'state');
            comp.ConnectivityButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleState, true);
            comp.ConnectivityButton.Text = 'Connectivity';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end