classdef uiFFTMaskFilter < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        FindcarrierpeaksButton  matlab.ui.control.Button
        LogicDropDown           matlab.ui.control.DropDown
        LogicDropDownLabel      matlab.ui.control.Label
        ShapeDropDown           matlab.ui.control.DropDown
        ShapeDropDownLabel      matlab.ui.control.Label
        NotButton               matlab.ui.control.StateButton
        DrawmaskButton          matlab.ui.control.Button
        axFFT                   matlab.ui.control.UIAxes
        axOutput                matlab.ui.control.UIAxes
        axInput                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Fk
        lastpgon
    end
    
    properties (Access = public)
        image double
        lastShape
    end
    
    
    methods
        function set.image(comp,val)
            comp.image = val;
            updateAxes(comp)
        end
    end
    methods (Access = private)
        function updateAxes(comp)
            imagesc(comp.axInput,comp.image)
            comp.Fk = fft2(comp.image);
            imagesc(comp.axFFT,fftshow(comp.Fk))
            updateOutput(comp)
            ax = [comp.axInput comp.axFFT comp.axOutput];
            axis(ax,'tight')
            xlabel(ax,'')
            ylabel(ax,'')
            title(ax,'')
            set(ax,'Colormap',gray)
        end
        function updateOutput(comp)
            imdata = findall(comp.axFFT,'Type','Image');
            if isempty(imdata)
                return
            end
            

            mask                = getmasks(comp);
            if ~mask
                return
            end
%             imdata.AlphaData    = mask;
            mask                = fftshift(mask);
            fk                  = comp.Fk;
            fk(mask)            = 0;
            imagesc(comp.axOutput,real(ifft2(fk)))
            imagesc(comp.axInput,real(ifft2(fk))-ifft2(comp.Fk))
            
        end
          function mask = getmasks(comp)
            masks = findall(comp.axFFT,'Type','Images');
            if ~isempty(masks)
                maskarray = arrayfun(@createMask,masks,'UniformOutput',false);
                logic = {masks.UserData};
            else 
                mask = false;
                return
            end
            ref = size(cell2mat(maskarray(1)));
            [logic,map] = sort(logic);
            maskarray = maskarray(map);
            if numel(maskarray)>1
                mask = zeros(ref);
                for i=1:numel(maskarray)
                    mask = double(mask);
                    nextmask = maskarray{i};
                    command = logic{i};
                    if contains(command,'~')
                        nextmask = ~maskarray{i};
                        command = extractAfter(command,'~');
                    end
                    switch lower(command)
                        case 'add'
                            mask = mask+nextmask;
                        case 'subtract'
                            mask = mask-nextmask;
                        case 'or'
                            mask = or(mask,nextmask);
                        case 'xor'
                            mask = xor(mask,nextmask);
                        case 'and'
                            mask = and(mask,nextmask);
                    end
                    mask = logical(mask);
                end
                mask = logical(mask);
            else
                mask = cell2mat(maskarray);
            end
          end

          function addShape(comp,event,eventData)
              if nargin<2
                  event = 'callback';
              end
              switch event
                  case 'callback'
                      shape       = lower(comp.ShapeDropDown.Value);
                      invert      = '';
                      if comp.NotButton.Value
                          invert = '~';
                      end
                      logic       = join([invert,comp.LogicDropDown.Value]);
                      command     = "pgon=draw%s(comp.axFFT);";
                  case 'set'
                      shape       = extractAfter(eventData.type,'roi.');
                      logic       = eventData.inverted;
                      position    = eventData.position; %#ok<NASGU>
                      command     = "pgon=draw%s(comp.axFFT,'Position',position);";
              end
              exec            = sprintf(command,shape);
              eval(exec)
              pgon.UserData   = logic;
              comp.lastpgon    = pgon;
              pgon.Label      = logic;
              addlistener(pgon,'ROIClicked',@(varargin) roiClicked(comp,pgon));
              addlistener(pgon,'ROIMoved',@(varargin) roiMoved(comp,pgon));
              addlistener(pgon,'DeletingROI',@(varargin) roiDeleted(comp,pgon));
          end
    end
    methods (Access = private,Hidden)
        function roiDeleted(comp,pgon)
            ROIS = findall(comp.axFFT,'Type','Images');
            delete(ROIS(ROIS==pgon))
            roiMoved(comp)
        end
        function roiMoved(comp,~)
            updateOutput(comp)
        end
        function roiClicked(comp,pgon)
            masks = findall(comp.axFFT,'Type','Images');
            set(masks,'LabelVisible','off')
            pgon.LabelVisible = 'on';
            comp.lastpgon = pgon;
            comp.NotButton.Value = false;
            if contains(pgon.Label,'~')
                comp.NotButton.Value = true;
            end
            comp.lastShape = comp.ShapeDropDown.Value;
            comp.ShapeDropDown.Value = extractAfter(class(pgon),'images.roi.');
            comp.LogicDropDown.Value = strrep((pgon.UserData),'~','');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'FFT Mask')
        end

        % Button pushed function: DrawmaskButton
        function drawShape(comp, event)
            imdata = findall(comp.axFFT,'Type','Image');
            if isempty(imdata)
                return
            end
            addShape(comp)
            updateOutput(comp)
        end

        % Value changed function: LogicDropDown, NotButton
        function logicCallback(comp, event)
            value = comp.LogicDropDown.Value;
            if comp.NotButton.Value
                value = sprintf('~%s',value);
            end
            comp.lastpgon.UserData = value;
            comp.lastpgon.Label = value;

            updateOutput(comp)
        end

        % Button pushed function: FindcarrierpeaksButton
        function carrierCallback(comp, event)
            findorthcarrierpks(comp.image,4*pi/min(size(comp.image)),Inf)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 558 359];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.axInput = uiaxes(comp.GridLayout);
            title(comp.axInput, 'Title')
            xlabel(comp.axInput, 'X')
            ylabel(comp.axInput, 'Y')
            zlabel(comp.axInput, 'Z')
            comp.axOutput = uiaxes(comp.GridLayout);
            title(comp.axOutput, 'Title')
            xlabel(comp.axOutput, 'X')
            ylabel(comp.axOutput, 'Y')
            zlabel(comp.axOutput, 'Z')
            comp.axFFT = uiaxes(comp.GridLayout);
            title(comp.axFFT, 'Title')
            xlabel(comp.axFFT, 'X')
            ylabel(comp.axFFT, 'Y')
            zlabel(comp.axFFT, 'Z')
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.Title = 'Panel';
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.DrawmaskButton = uibutton(comp.GridLayout2, 'push');
            comp.DrawmaskButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @drawShape, true);
            comp.DrawmaskButton.Text = 'Draw mask';
            comp.NotButton = uibutton(comp.GridLayout2, 'state');
            comp.NotButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @logicCallback, true);
            comp.NotButton.Text = 'Not';
            comp.ShapeDropDownLabel = uilabel(comp.GridLayout2);
            comp.ShapeDropDownLabel.Text = 'Shape';
            comp.ShapeDropDown = uidropdown(comp.GridLayout2);
            comp.ShapeDropDown.Items = {'Rectangle', 'Polygon', 'Circle'};
            comp.ShapeDropDown.Value = 'Rectangle';
            comp.LogicDropDownLabel = uilabel(comp.GridLayout2);
            comp.LogicDropDownLabel.Text = 'Logic';
            comp.LogicDropDown = uidropdown(comp.GridLayout2);
            comp.LogicDropDown.Items = {'Add', 'Subtract', 'Xor', 'Or', 'And'};
            comp.LogicDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @logicCallback, true);
            comp.LogicDropDown.Value = 'Add';
            comp.FindcarrierpeaksButton = uibutton(comp.GridLayout2, 'push');
            comp.FindcarrierpeaksButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @carrierCallback, true);
            comp.FindcarrierpeaksButton.Visible = 'off';
            comp.FindcarrierpeaksButton.Text = 'Find carrier peaks';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end