classdef uiFileSystem < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl0                            matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        FolderTreeGridLayout           matlab.ui.container.GridLayout
        FolderTreeHidden               matlab.ui.container.Tree
        FolderTree                     matlab.ui.container.Tree
        SettingsPanel                  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SearchdepthSpinner             matlab.ui.control.Spinner
        SearchdepthSpinnerLabel        matlab.ui.control.Label
        ContainsCheckBox               matlab.ui.control.CheckBox
        ExactCheckBox                  matlab.ui.control.CheckBox
        DatefiltertoDatePicker         matlab.ui.control.DatePicker
        DatefiltertoDatePickerLabel    matlab.ui.control.Label
        DatefilterfromDatePicker       matlab.ui.control.DatePicker
        DatefilterfromDatePickerLabel  matlab.ui.control.Label
        LabelfilterEditField           matlab.ui.control.EditField
        LabelfilterEditFieldLabel      matlab.ui.control.Label
        glUpper                        matlab.ui.container.GridLayout
        toggleFilesButton              matlab.ui.control.StateButton
        GoBackButton                   matlab.ui.control.Button
        GoUpButton                     matlab.ui.control.Button
        Button_2                       matlab.ui.control.StateButton
        Button                         matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        FileWatcherEvent
        SelectionChanged
    end

    properties (Access = private)
        FileSystemWatcher System.IO.FileSystemWatcher = System.IO.FileSystemWatcher(pwd);
        internalEvent (1,1) logical = false;
    end
    
    properties (Access = private, Hidden)
        lastroot string {mustBeFolder(lastroot)} = pwd;
        searchResults
    end
    
    properties (Access = public)
        ActiveTree
        DateFilters (1,2) datetime = [datetime datetime];
        Enable logical = true;
        HiddenTree
        HiddenTreeVisible (1,1) logical = false;
        LastFileWatcherEvent
        SearchBarVisible logical {mustBeNumericOrLogical(SearchBarVisible)} = true;
        SelectedNode
        SelectedPath
        Tree
        files % Files found in the root directory
        root string {mustBeFolder(root)} % Root directory
        toggleFiles (1,1) logical = false; % Toggle whether files are visible
    end
    
    %% Set functions
    methods
        function set.Enable(comp,val)
            comp.Enable = val;
            toggleEnable(comp)
        end
        function set.DateFilters(comp,val)
            comp.DateFilters = val;
            updateDateFilters(comp)
        end
        function set.root(comp,val)
            if isempty(val)
                return
            end
            if ~isfolder(val)
                error('Root directory must be a valid folder')
            end
            comp.root=val;
            updateWatcher(comp)
            buildTree(comp)
        end
        function set.SearchBarVisible(comp,val)
            comp.SearchBarVisible=val;
            toggleSearchBar(comp)
        end
        function set.HiddenTreeVisible(comp,val)
            comp.HiddenTreeVisible = val;
            toggleHiddenTree(comp)
        end
        function set.toggleFiles(comp,val)
            comp.toggleFiles = val;
            toggleFilesFcn(comp)
        end
    end
    %% Public functions
    methods (Access = public)
        function buildTree(comp,selectedNode,isUpdate)
            if isempty(comp.root)
                return
            end
            if nargin<2
                selectedNode = [];
            end
            if nargin<3
                isUpdate = false;
            end
            if isUpdate
                %compareFolders
                pause(1e-2)
                delete(comp.FolderTree.Children)
                pause(1e-2)
                makeAll(comp)
                return
            end
            if isempty(selectedNode)
                % Build entire tree at root level
                pause(1e-2)
                delete(comp.FolderTree.Children)
                pause(1e-2)
                makeAll(comp)
                return
            end

            makeChildren(comp,selectedNode);

            %% Sub-functions
            function makeAll(comp)
                comp.Label.Text = 'Processing';
                pause(1e-2)
                [~,list] = listFoldersInDirectory(comp);
                cellfun(@(label) makeNode(comp,comp.FolderTree,label,'folder'), ...
                    list)
                if comp.toggleFiles
                    [~,list] = listFilesInDirectory(comp);
                    cellfun(@(label) makeNode(comp,comp.FolderTree,label,'file'), ...
                        list)
                end
                comp.Label.Text = comp.root;
                sortNodes(comp)
            end
            function makeChildren(comp,node)
                delete(findall(node,'Tag','omit'))
                [~,list] = listFoldersInDirectory(comp,node.UserData);
                cellfun(@(label) makeNode(comp,node,label,'folder'), ...
                    list)
                comp.sortNodes(node)
                if comp.toggleFiles
                    [~,list] = listFilesInDirectory(comp,node.UserData);
                    cellfun(@(label) makeNode(comp,node,label,'file'), ...
                        list)
                    %comp.sortNodes
                end
            end
        end
        function toggleSearchBar(comp,state)
            if nargin<2
                state = comp.SearchBarVisible;
            end
            switch state
                case {'on',true}
                    comp.gl0.RowHeight = {35,200,'1x',20};
                case {'off',false}
                    comp.gl0.RowHeight = {35,0,'1x',20};
            end
        end
        function toggleHiddenTree(comp,state)
            if nargin<2
                state = comp.HiddenTreeVisible;
            end
            switch state
                case {'on',true}
                    comp.FolderTreeGridLayout.ColumnWidth = {0,'1x'};
                case {'off',false}
                    comp.FolderTreeGridLayout.ColumnWidth = {'1x',0};
            end
        end
        function updateDateFilters(comp)
            comp.HiddenTreeVisible=true;
            comp.DatefilterfromDatePicker.Value = min(comp.DateFilters);
            comp.DatefiltertoDatePicker.Value   = max(comp.DateFilters);
            try refineList(comp);end
        end
        function toggleFilesFcn(comp)
             switch comp.toggleFiles
                case true
                    buildTree(comp)
                case false
                    delete(findall(comp.FolderTree,'Tag','file'))
                    delete(findall(comp.FolderTreeHidden,'Tag','file'))
            end
        end
    end
    %% Private functions
    methods (Access = private)
        function toggleEnable(comp)
            enable = 'off';
            if comp.Enable; enable = 'on';end
            elements = [comp.glUpper.Children
                comp.FolderTreeGridLayout.Children];
            set(elements,'Enable',enable)
        end
        function node = defaultNode(comp,parent,label,type)
            label = char(label);
            switch type
                case 'folder'
                    icon = fullfile('icons/folder_win10.png');
                case 'file'
                    icon = fullfile('icons/icon-19.png');
            end
            [~,filename,ext] = fileparts(label);
            if isempty(filename)
                node = [];
                return
            end
            if strcmp(type,'file')
                filename = strcat(filename,ext);
            end
            node = uitreenode( ...
                'Parent',parent, ...
                'Text',filename,...
                'UserData',label,...
                'NodeData',comp.dir2(label),...
                'Icon',icon, ...
                'Tag',type);
        end
        function node = makeNode(comp,parent,label,type)
            node = defaultNode(comp,parent,label,type);
            if isempty(node)
                if ~nargout
                    clear node
                end
                return
            end

            % Sub-level search
            if strcmp(type,'folder')
                checkNodeChildren(comp,node,label)
            end

            comp.SetTreeContextMenu(node,type)
            if ~nargout
                clear node
            end
        end
        function node = findNode(comp,label)
            allnodes    = findall(comp.FolderTree,'Type','uitreenode');
            [~,label]   = filepartsn(label,1,'keep');
            [~,list]    = filepartsn({allnodes.UserData}',1,'keep');
            if ~isa(list,'cell')
                list = {list};
            end
            node = allnodes(ismember(list,label));
        end
        function checkNodeChildren(comp,parent,label,event)
            if nargin<4
                event = [];
            end

            try
                [~,list] = listFoldersInDirectory(comp,label);
            end
            if comp.toggleFiles
                [~,listfile] = listFilesInDirectory(comp,label);
                if ~isempty(listfile)
                    list = cat(1,list,listfile);
                end
            end
            if ~isempty(event)
                if isempty(list)
                    if strcmp(parent.Tag,'folder')
                        delete(parent.Children)
                    end
                    return
                end
                if strcmp(parent.Tag,'folder')&&~isfile(label)
                    parseNode(parent,list,label);
                end
            end
            if ~isempty(list)
                if strcmp(parent.Tag,'folder')&&~isfile(label)
                    parseNode(parent,list,label);
                end
            else
                delete(parent.Children)
            end

            function parseNode(parent,list,label)
                %checkFolderChildren(list,label);
                uitreenode( ...
                        'Parent',parent,...
                        'Text','',...
                        'Tag','omit', ...
                        'UserData','');
            end
            function state = checkFolderChildren(list,label)
                state = false;
                parent = findNode(comp,label);
                if isempty(parent.Children)
                    return
                end
                valid  = ismember(list,{parent.Children.UserData}');
                if all(valid)
                    return
                end
                defaultNode(comp,parent,list(~valid),'folder')
                state = true;
            end
        end
        function sortNodes(comp,handle)
            if nargin<2
                handle = comp.ActiveTree;
            end
            if isempty(handle)
                return
            end
            if isempty(handle.Children)
                handle = comp.FolderTree;
                if isempty(handle.Children)
                    return
                end
                sortNodes(comp,handle)
                return
            end
            warning off
            tab = table({handle.Children.Tag}', ...
                {handle.Children.Text}', ...
                'VariableNames',{'type','name'});
            folders = strcmp(tab.type,'folder');
            [tab,map]=sortrows( ...
                tab, ...
                [1 2],{'descend','ascend'});

            % sort again for filenames of the form "<string><integer>"
            nums = cellfun(@(x) str2double(regexp(x,'[\d.]+','match')),tab.name(folders),'UniformOutput',false);
            if ~isempty(nums)
                valid           = ~cellfun(@isempty,nums);
                nums(~valid)    = {nan};
                [val,mapf]      = sort(cell2mat(nums));
                mapf            = circshift(mapf,sum(isnan(val)));
                try
                    map(folders)    = mapf;
                end
            end

            handle.Children = handle.Children(map);
            warning on
        end
        function SetTreeContextMenu(comp,parent,type)
            if nargin<3
                type = 'folder';
            end
            figparent = ancestor(parent,'Figure');
            if isempty(figparent)
                return
            end
            NodeContextMenu     = uicontextmenu(figparent);

            uimenu(NodeContextMenu,...
                MenuSelectedFcn = @CMCallback,...
                Text = 'Open in explorer',...
                Tag  = 'command-explorer');
            uimenu(NodeContextMenu,...
                MenuSelectedFcn = @CMCallback,...
                Text = 'New folder',...
                Tag = 'command-mkdir');
            uimenu(NodeContextMenu,...
                MenuSelectedFcn = @CMCallback,...
                Text = 'Refresh',...
                Tag = 'command-refresh',Separator='on');
            uimenu(NodeContextMenu,...
                MenuSelectedFcn = @CMCallback,...
                Text = 'Delete',...
                Tag = 'command-delete',Separator='on');

            switch type
                case 'folder'
                case 'file'
                    delete(findall(NodeContextMenu.Children,'Tag','command-mkdir'));
            end
            parent.ContextMenu = NodeContextMenu;

            %% Nested functions
            function CMCallback(~,event)
                tag = event.Source.Tag;
                command = extractAfter(tag,'-');
                filename = comp.root;

                if isa(parent,'matlab.ui.container.TreeNode')
                    explore = parent.UserData;
                else
                    explore = filename;
                end
                switch command
                    case 'explorer'
                        if ~isfolder(explore)
                            explore = fileparts(explore);
                        end
                        winopen(explore)
                    case 'mkdir'
                        fname = fullfile(explore,'New folder');
                        if isfolder(fname)
                            i = 1;
                            while true
                                name = sprintf('%s (%i)',fname,i);
                                i = i+1;
                                if ~isfolder(name)
                                    fname = name;
                                    break
                                end
                            end
                        end
                        mkdir(fname)
                        checkNodeChildren(comp,comp.ActiveTree,explore)
                    case 'refresh'
                        comp.buildTree([],true)
                    case 'delete'
                        answer =questdlg( ...
                            sprintf('Are you sure that you want to permanently delete this folder?\n%s', ...
                            explore), ...
                            'Delete folder', ...
                            'Yes','No','No');
                        if strcmp(answer,"Yes")
                            if isfolder(explore)
                                rmdir(explore)
                            else
                                delete(explore)
                            end
                        end
                end
            end
        end
        
    end
    %% Internal functions
    methods (Access = private)
        function dirinfo=dir2(~,path)
            %DIR is identical to dir but it has no files or directory tags ('.','..')
            dirinfo = dir(path);
            dirinfo(~[dirinfo.isdir]) = [];  %remove non-directories
            tf = ismember( {dirinfo.name}, {'.', '..'});
            dirinfo(tf) = [];  %remove current and parent directory.
        end
        function list=searchDir(comp,depth,newpath)
            isLevel = false;
            if nargin<3
                comp.Label.Text = 'Searching';
                pause(1e-2)
                newpath = comp.root;
                isLevel = true;
            end
            [~,listpaths]=listFoldersInDirectory(comp,newpath);
            if ~depth
                list = listpaths;
                return;
            end
            list = cellfun(@(newpath) searchDir(comp,depth-1,newpath),listpaths,'UniformOutput',false);
            list = cat(1,listpaths,cat(1,list{:}));
            if isLevel
                list = list(~cellfun(@isempty,list));
                comp.Label.Text = comp.root;
            end
        end
        function [list,listpath,info]=listFoldersInDirectory(comp,searchPath)
            if nargin<2
                searchPath = comp.root;
            end
            info=dir2(comp,searchPath);
            list={info.name}';
            list = list(~contains(list,'.MATLABDriveTag'));
            listpath = {};
            if numel(list)>0
                listpath = fullfile(info(1).folder,list);
            end
        end
        function [list,listpath,info]=listFilesInDirectory(comp,searchPath)
            if nargin<2
                searchPath = comp.root;
            end
            info=dir(searchPath);
            info=info(~[info.isdir]);
            list={info.name}';
            listpath = {};
            if numel(list)>0
                listpath = fullfile(info(1).folder,list);
            end
        end

    end
    %% FileSystemWatcher functions
    methods (Access = private)
        function setupWatcher(comp)
            warning off backtrace
            %comp.FileSystemWatcher.Filter = '*.csv';
            comp.FileSystemWatcher.EnableRaisingEvents = true;
            ValidEvents = events(comp.FileSystemWatcher);
            cellfun(@(event) addlistener(comp.FileSystemWatcher,event,@fsEvent),ValidEvents);
            warning on
            function fsEvent(~,event)
                if ~isvalid(comp)
                    return
                end
                if ~isfolder(comp.root)
                    return
                end
                FileSystemWatcherEvent(comp,event)
            end
        end
        function updateWatcher(comp)
            try comp.FileSystemWatcher.Path = comp.root;end
            FileSystemWatcherEvent(comp)
        end
        function FileSystemWatcherEvent(comp,event)
            if comp.internalEvent||nargin<2
                return
            end
            nodes       = comp.FolderTree.Children;
            fname       = char(event.FullPath);
            [~,~,ext]   = fileparts(fname);
            try 
                validnames  = {nodes.UserData}';
                validnames  = validnames(~cellfun(@isempty,validnames));
            catch
                validnames = {''};
            end
            valid       = ismember(validnames,{fname});
            parent      = comp.FolderTree;
            if any(valid)
                parent = nodes(valid);
            end
            if ~isvalid(comp.FileSystemWatcher)
                return
            end
            switch string(event.ChangeType)
                case 'Created'
                    if isempty(ext)
                        makeNode(comp,parent,fname,'folder')
                    elseif comp.toggleFiles
                        makeNode(comp,parent,fname,'file')
                    end
                    sortNodes(comp)
                case 'Renamed'
                    valid       = ismember(validnames,{char(event.OldFullPath)});
                    set(nodes(valid), ...
                        Text = char(event.Name), ...
                        UserData = fname);
                case 'Deleted'
                    if any(valid)
                        % check for additional deletions
                        list = listFoldersInDirectory(comp);
                        delete(nodes(ismember(validnames,list)))
                        delete(parent)
                    end
                case 'Changed'
                    % Activity in subfolders
                    checkNodeChildren(comp,parent,fname,event)
%                     delete(findall(parent.Children,'Text',''))
            end

            delete(findall(comp.ActiveTree.Children,'Text','','-not','UserData','nested'))
            comp.LastFileWatcherEvent = event;
            notify(comp, 'FileWatcherEvent');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            calledWithoutParent(comp,'uiFileSystem')

            % hide search bar
            toggleSearchBar(comp,'off')
            % create context menu for uitree
            comp.SetTreeContextMenu(comp.FolderTree)
            comp.root = pwd;
            % create listeners for FileSystemWatcher
            setupWatcher(comp)

            comp.ActiveTree = comp.FolderTree;
            comp.Tree       = comp.FolderTree;
            comp.HiddenTree = comp.FolderTreeHidden;
            
            % re-evaluate defaults
            comp.toggleFiles        = false;
            comp.HiddenTreeVisible  = false;
            comp.SearchBarVisible   = false;

        end

        % Selection changed function: FolderTree, FolderTreeHidden
        function changeFolder(comp, event)
            comp.SelectedNode = event.Source.SelectedNodes;
            comp.SelectedPath = comp.SelectedNode.UserData;
            comp.FolderTree.UserData = comp.root;
            notify(comp, 'SelectionChanged');
        end

        % Callback function: Button, FolderTree, GoBackButton, GoUpButton
        function changeDir(comp, event)
            switch event.EventName
                case 'ButtonPushed'
                    switch event.Source.Tag
                        case 'uigetdir'
                            if isempty(comp.root)
                                comp.root = pwd;
                            end
                            newroot   = uigetdir(comp.root);
                            if ~newroot
                                return
                            end
                        case 'up'
                            try
                                newroot = fileparts(comp.root);
                                comp.lastroot = comp.root;
                            catch
                                newroot = '';
                            end
                        case 'back'
                            if isempty(comp.lastroot)
                                comp.lastroot=comp.root;
                            end
                            newroot = comp.lastroot;
                    end
                case 'DoubleClicked'
                    if strcmp(event.Source.SelectedNodes.Tag,'file')
                        return
                    end
                    newroot = event.Source.SelectedNodes.UserData;
            end
            comp.root = newroot;
            comp.FileSystemWatcher.Path = string(newroot);
            notify(comp,'SelectionChanged')
        end

        % Node expanded function: FolderTree, FolderTreeHidden
        function seeContents(comp, event)
            comp.buildTree(event.Node)
            if event.Source~=comp.FolderTreeHidden
                return
            end
            nodes = findall(comp.FolderTreeHidden,'Type','uitreenode');
            labels = {nodes.UserData};
            labels = labels(~cellfun(@isempty,labels));
            keep   = setxor({comp.FolderTreeHidden.Children.UserData},comp.searchResults);
            delete(nodes(~cellfun(@(labels) contains(labels,keep),labels)))

        end

        % Node collapsed function: FolderTree, FolderTreeHidden
        function hideContents(comp, event)
            try %#ok<*TRYNC>
                delete(event.Node.Children);
                uitreenode( ...
                    'Parent',event.Node,...
                    'Tag','omit');
            end
        end

        % Node text changed function: FolderTree
        function renameFolder(comp, event)
            node = event.Node;
            from = node.UserData;
            to   = fullfile(fileparts(node.UserData),node.Text);
            movefile(from,to)
            node.UserData = to;

        end

        % Callback function: ContainsCheckBox, DatefilterfromDatePicker, 
        % ...and 5 other components
        function refineList(comp, event)
            labels      = comp.LabelfilterEditField.Value;
            dates       = [comp.DatefilterfromDatePicker.Value comp.DatefiltertoDatePicker.Value];
            isExact     = comp.ExactCheckBox.Value;
            isContains  = comp.ContainsCheckBox.Value;
            searchDepth = comp.SearchdepthSpinner.Value;

            if isempty(labels)&&all(isnat(dates))
                comp.FolderTreeGridLayout.ColumnWidth = {'1x',0};
                comp.ActiveTree = comp.FolderTree;
                return
            else
                comp.FolderTreeGridLayout.ColumnWidth = {0,'1x'};
                comp.ActiveTree = comp.FolderTreeHidden;
            end

            searchresults = beginSearch(comp);
            updateNodes(comp,searchresults);
            

            %% Nested functions
            function updateNodes(comp,searchresults)
                nodes = comp.FolderTree.Children;
                if isempty(nodes)
                    return
                end
                if isempty(searchresults)
                    return
                end            
                omit = true(size(searchresults));
                delete(comp.FolderTreeHidden.Children)
                if ~isempty(comp.FolderTreeHidden.Children)
                    % find same search results already in hidden tree
                    inTree      = folderOnPath({comp.FolderTreeHidden.Children.UserData});
                    inSearch    = folderOnPath({searchresults.UserData});
                    
                    omit        = ~ismember(inSearch,inTree);
                    % delete
                    searchresults(~omit)
                end
                valid = ismember({comp.FolderTree.Children.UserData},{searchresults.UserData});
                newnodes = nodes(valid);
                newnodes = newnodes(omit);
                copyobj(newnodes,comp.HiddenTree)
                if isempty(comp.HiddenTree.Children)
                    return
                end
                sortNodes(comp,comp.HiddenTree)


                if searchDepth==0
                    valid = (~ismember({searchresults.Text},{comp.HiddenTree.Children.Text}));
                    copyobj(searchresults(valid),comp.FolderTreeHidden)
                else
                    % Open depth-0 nodes
                    search      = search0(any(search,2));
                    allfiles    = {searchresults.UserData};
                    searchresults   = searchresults(~cellfun(@isempty,allfiles));
                    allfiles    = allfiles(~cellfun(@isempty,allfiles));
                    results     = cell2mat(cellfun(@(x) contains(allfiles,x),unique(fileparts(search)),'UniformOutput',false));
                    results     = any(results);
                    open        = searchresults(results);
                    copyobj(open,comp.FolderTreeHidden)


                    uniquePaths=cellfun(@(x) contains(search,x),search,'UniformOutput',false);
                    uniquePaths = logical(cat(2,uniquePaths{:})-eye(numel(uniquePaths)));
                    comp.searchResults = search(~any(uniquePaths,2));
                end
            end
            function childfrom = beginSearch(comp)
                childfrom = findall(comp.FolderTree,'Type','uitreenode');
                if searchDepth==0
                    search0 = {childfrom.Text}';
                else
                    search0 = searchDir(comp,searchDepth);
                end
                if ~isempty(labels)
                    search1 = zeros(size(search0));
                    search2 = search1;
                    search3 = search1;
                    if isContains
                        search1 = (contains(search0,labels));
                    end
                    if isExact
                        search2 = (strcmp(search0,labels));
                    else
                        search3 = (strcmpi(search0,labels));
                    end

                    search = [search1 search2 search3];
                    if isempty(search)
                        return
                    end

                    if searchDepth==0
                        childfrom=childfrom(any(search,2));
                    end
                else
                    [];
                end
                
                if any(~isnat(dates))
                    if isnat(dates(2))
                        dates(2)=datetime('today');
                    end
                    valid = ~cellfun(@isempty,{childfrom.UserData});
                    childfrom = childfrom(valid);
                    allfiles = arrayfun(@(x) dir(x.UserData),childfrom, ...
                        'UniformOutput',false);
                    allfiles = allfiles(~cellfun(@isempty,allfiles));
                    allfiles = cellfun(@(x) x(1).date,allfiles,'UniformOutput',false);
                    alldates = datetime(allfiles,'Format','dd-MMM-uuuu');
                    if searchDepth==0
                        childfrom = childfrom(alldates<=(dates(2)+day(1)-seconds(1))&alldates>=dates(1));
                    end
                end
            end
        end

        % Value changed function: Button_2
        function filtersDropDownCallback(comp, event)
            if comp.Button_2.Value
                toggleSearchBar(comp,'on')
            else
                toggleSearchBar(comp,'off')
                if isempty(comp.FolderTreeHidden.Children)
                    comp.FolderTreeGridLayout.ColumnWidth = {'1x',0};
                end
            end
        end

        % Value changed function: toggleFilesButton
        function toggleFilesCallback(comp, event)
            comp.toggleFiles = event.Value;
            toggleFilesFcn(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
%             home,disp('update')
%             pos = comp.Position;
%             rh = {'fit'};
%             if pos(3)<210&&pos(3)>=200
%                 comp.SearchButton.Text = '';
%                 comp.toggleFilesButton.Layout.Row    = 1;
%                 comp.toggleFilesButton.Layout.Column = 5;
%                 comp.SearchButton.Layout.Row    = 1;
%                 comp.SearchButton.Layout.Column = 6;
%             elseif pos(3)<200
%                 rh = {'fit','fit'};
%                 comp.SearchButton.Text = '';
%                 comp.toggleFilesButton.Layout.Row    = 2;
%                 comp.toggleFilesButton.Layout.Column = 1;
%                 comp.SearchButton.Layout.Row    = 2;
%                 comp.SearchButton.Layout.Column = 2;
%             else
%                 comp.toggleFilesButton.Layout.Row    = 1;
%                 comp.toggleFilesButton.Layout.Column = 5;
%                 comp.SearchButton.Layout.Row    = 1;
%                 comp.SearchButton.Layout.Column = 6;
%                 comp.SearchButton.Text = 'Search';
%             end
%             comp.glUpper.RowHeight = rh;
%             val = 0;
%             if comp.SearchBarVisible
%                 val = 200;
%             end
%             comp.gl0.RowHeight = {'fit',val,'1x',20};

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 327 486];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.gl0 = uigridlayout(comp);
            comp.glUpper = uigridlayout(comp.gl0);
            comp.Button = uibutton(comp.glUpper, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.Button.Tag = 'uigetdir';
            comp.Button.Icon = 'find_folder.svg';
            comp.Button.Tooltip = {'Change directory'};
            comp.Button.Text = '';
            comp.Button_2 = uibutton(comp.glUpper, 'state');
            comp.Button_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @filtersDropDownCallback, true);
            comp.Button_2.Tooltip = {'Toggle file search'};
            comp.Button_2.Icon = 'find.svg';
            comp.Button_2.Text = '';
            comp.GoUpButton = uibutton(comp.glUpper, 'push');
            comp.GoUpButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.GoUpButton.Tag = 'up';
            comp.GoUpButton.Icon = 'chevronNorthUI.svg';
            comp.GoUpButton.Tooltip = {'Go up a directory'};
            comp.GoUpButton.Text = '';
            comp.GoBackButton = uibutton(comp.glUpper, 'push');
            comp.GoBackButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.GoBackButton.Tag = 'back';
            comp.GoBackButton.Icon = 'chevronWestUI.svg';
            comp.GoBackButton.Tooltip = {'Go to last directory'};
            comp.GoBackButton.Text = '';
            comp.toggleFilesButton = uibutton(comp.glUpper, 'state');
            comp.toggleFilesButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleFilesCallback, true);
            comp.toggleFilesButton.Tooltip = {'Toggle files'};
            comp.toggleFilesButton.Icon = 'folder_details.svg';
            comp.toggleFilesButton.Text = '';
            comp.SettingsPanel = uipanel(comp.gl0);
            comp.SettingsPanel.Title = 'Settings';
            comp.GridLayout3 = uigridlayout(comp.SettingsPanel);
            comp.LabelfilterEditFieldLabel = uilabel(comp.GridLayout3);
            comp.LabelfilterEditFieldLabel.Text = 'Label filter';
            comp.LabelfilterEditField = uieditfield(comp.GridLayout3, 'text');
            comp.LabelfilterEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.LabelfilterEditField.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.DatefilterfromDatePickerLabel = uilabel(comp.GridLayout3);
            comp.DatefilterfromDatePickerLabel.Text = 'Date filter (from)';
            comp.DatefilterfromDatePicker = uidatepicker(comp.GridLayout3);
            comp.DatefilterfromDatePicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.DatefiltertoDatePickerLabel = uilabel(comp.GridLayout3);
            comp.DatefiltertoDatePickerLabel.Text = 'Date filter (to)';
            comp.DatefiltertoDatePicker = uidatepicker(comp.GridLayout3);
            comp.DatefiltertoDatePicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.ExactCheckBox = uicheckbox(comp.GridLayout3);
            comp.ExactCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.ExactCheckBox.Text = 'Exact';
            comp.ContainsCheckBox = uicheckbox(comp.GridLayout3);
            comp.ContainsCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.ContainsCheckBox.Text = 'Contains';
            comp.SearchdepthSpinnerLabel = uilabel(comp.GridLayout3);
            comp.SearchdepthSpinnerLabel.Text = 'Search depth';
            comp.SearchdepthSpinner = uispinner(comp.GridLayout3);
            comp.SearchdepthSpinner.Limits = [0 Inf];
            comp.SearchdepthSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @refineList, true);
            comp.FolderTreeGridLayout = uigridlayout(comp.gl0);
            comp.FolderTree = uitree(comp.FolderTreeGridLayout);
            comp.FolderTree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @changeFolder, true);
            comp.FolderTree.NodeExpandedFcn = matlab.apps.createCallbackFcn(comp, @seeContents, true);
            comp.FolderTree.NodeCollapsedFcn = matlab.apps.createCallbackFcn(comp, @hideContents, true);
            comp.FolderTree.NodeTextChangedFcn = matlab.apps.createCallbackFcn(comp, @renameFolder, true);
            comp.FolderTree.Tag = 'folder';
            comp.FolderTree.Editable = 'on';
            comp.FolderTree.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @changeDir, true);
            comp.FolderTreeHidden = uitree(comp.FolderTreeGridLayout);
            comp.FolderTreeHidden.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @changeFolder, true);
            comp.FolderTreeHidden.NodeExpandedFcn = matlab.apps.createCallbackFcn(comp, @seeContents, true);
            comp.FolderTreeHidden.NodeCollapsedFcn = matlab.apps.createCallbackFcn(comp, @hideContents, true);
            comp.FolderTreeHidden.Tag = 'folder';
            comp.FolderTreeHidden.Editable = 'on';
            comp.Label = uilabel(comp.gl0);
            comp.Label.Text = '';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end