classdef uiImageDataStoreSettings < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        TimeTab                  matlab.ui.container.Tab
        GridLayout5              matlab.ui.container.GridLayout
        resetTime                matlab.ui.control.Button
        ApplyButton              matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        TimestepEditField        matlab.ui.control.NumericEditField
        TimestepEditFieldLabel   matlab.ui.control.Label
        AxesTime                 matlab.ui.control.UIAxes
        ImageTab                 matlab.ui.container.Tab
        GridLayout4_2            matlab.ui.container.GridLayout
        GridLayout2_2            matlab.ui.container.GridLayout
        resetGroup               matlab.ui.control.Button
        SelectButton             matlab.ui.control.Button
        ImagegroupListBox        matlab.ui.control.ListBox
        ImagegroupListBoxLabel   matlab.ui.control.Label
        ScanButton               matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        MethodDropDown           matlab.ui.control.DropDown
        MethodDropDownLabel      matlab.ui.control.Label
        StepEditField            matlab.ui.control.NumericEditField
        StepEditFieldLabel       matlab.ui.control.Label
        AxesGroups               matlab.ui.control.UIAxes
        AxesImage                matlab.ui.control.UIAxes
        FilenameTab              matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        ApplyButton_2            matlab.ui.control.Button
        AddButton                matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        InformationPanel         matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        infoLabel                matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ApplyFilter
    end

    properties (Access = public)
        imds (1,1) imDataStore % Image data store
    end
    
    %% Set functions
    methods        
        function set.imds(comp,val)
            comp.imds = val;
            comp.setimds
        end
    end
    %% Set-callbacks
    methods (Access = private)
        function setimds(comp)
            comp.resetComponents
            val = 10^ceil(log10(comp.imds.maxIndex))/100;
            val(val<1) = 1;
            comp.StepEditField.Value = val;
            comp.updateInfo
        end
    end
    %% Internal functions
    methods (Access = private)
        function resetComponents(comp,imgroups)
            comp.ImagegroupListBox.Items = {};
            comp.ImagegroupListBoxLabel.Text = 'Image group';
            arrayfun(@cla,[comp.AxesImage comp.AxesGroups])
            axis(comp.AxesGroups,'square')
            if nargin<2
                comp.UITable.Data = [];
                return
            end
            if imgroups
                comp.UITable.Data(contains(comp.UITable.Data.logic,'im'),:)=[];
            end
        end
        function updateInfo(comp)
            ids = comp.imds;
            txt = {};
            fcn = @(txt,lbl) cat(2,txt,lbl);
            txt = fcn(txt,sprintf('Files in original datastore: %i',numel(ids.ds0.Files)));
            txt = fcn(txt,sprintf('Files in filtered datastore: %i',numel(ids.ds.Files)));
            comp.infoLabel.Text = string(txt);
            comp.updateTimeAxes
        end
        function [i,x,t] = filterTime(comp)
            t   = comp.imds.time;
            x   = 1:numel(t);
            ts  = comp.TimestepEditField.Value;
            i   = [];
            if ts
                i = roundto( ...
                    eval(sprintf('%s(t)',comp.ListBox.Value)), ...
                    ts);
            end
        end
        function filterDataStore(comp)
            comp.imds.filters = comp.UITable.Data;
            comp.updateInfo
            notify(comp, 'ApplyFilter');
        end
        function updateImageGroups(comp)
            s = 1:numel(comp.imds.imgroups);
            if isempty(s)
                comp.ImagegroupListBoxLabel.Text = 'Image group';
                comp.ImagegroupListBox.Items = [];
                return
            end
            comp.ImagegroupListBox.Items = arrayfun(@(s) sprintf('%i',s),s,'UniformOutput',false);
            comp.ImagegroupListBox.Value = comp.ImagegroupListBox.Items{1};
            comp.ImagegroupListBoxLabel.Text = sprintf('Image group\n(%i)',numel(comp.imds.imgroups{1}));
            
            cla(comp.AxesGroups)
            hold(comp.AxesGroups,'on')
            range = cumsum([cellfun(@numel,comp.imds.imgroups)])';
            range = [circshift(range,-1)+1;range];
            range(1) = 1;
            range = arrayfun(@(i) range(1,i):range(2,i),1:width(range),'UniformOutput',false);
            cellfun(@(i) plot(comp.AxesGroups,comp.imds.imgroup_h(i)),range)
            hold(comp.AxesGroups,'off')
            comp.updateImageAxes
        end
        function updateImageAxes(comp)
            val = str2double(comp.ImagegroupListBox.Value);
            comp.ImagegroupListBoxLabel.Text = sprintf('Image group\n(%i)',numel(comp.imds.imgroups{val}));
            im  = comp.imds.loadimage(comp.imds.imgroups{val}(1),true,true,false);
            imagesc(comp.AxesImage,im)
            axis(comp.AxesImage,'image')
            colormap(comp.AxesImage,gray)
        end
        function updateTimeAxes(comp)
            [i,x,t] = filterTime(comp);
            plot(comp.AxesTime,x,t,x(i),t(i),'o')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'ImageDataStore filter')
            try %#ok<*TRYNC> 
                comp.imds = evalin('base','edi.imds');
                comp.UITable.Data = comp.imds.filters;
                comp.updateImageGroups
            end
            set(comp.infoLabel,'Text','')
        end

        % Button pushed function: AddButton
        function addCallback(comp, event)
            t = comp.UITable;
            d = t.Data;
            defaults = [{''},{'and'}];
            if isempty(d)
                t.Data = defaults;
                return
            end
            t.Data(end+1,:) = defaults;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(comp, event)
            t   = comp.UITable;
            s   = t.Selection;
            d   = t.Data;
            keep = ~contains(d.logic,'im');
            if all(s(:,2)==2)
                valid = {'or','not','and','xor'};
                if ~contains(event.NewData,valid)
                    d(event.Indices(1),event.Indices(2)) = {event.PreviousData};
                else
                    d(s(:,1),2) = {event.NewData};
                end
            end
            
            t.Data(keep,:) = d(keep,:);
            if contains(event.PreviousData,'im')
                t.Data(event.Indices(1),event.Indices(2)) = {event.PreviousData};
            end
        end

        % Key press function: UITable
        function UITableKeyPress(comp, event)
            key = event.Key;
            t   = comp.UITable;
            s   = t.Selection;
            if strcmp(key,'delete')
                t.Data(s(:,1),:) = [];
                comp.filterDataStore
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(comp, event)
            comp.ScanButton.Text = 'Scanning';pause(1e-3)
            try
                comp.imds.image_identify( ...
                    comp.StepEditField.Value, ...
                    comp.MethodDropDown.Value, ...
                    comp.ThresholdEditField.Value,...
                    true)
                comp.ScanButton.Text = 'Scan';
            catch ME
                assignin("base",'ME',ME)
                comp.ScanButton.Text = 'Error';
                rethrow(ME)
            end
            
            comp.updateImageGroups
        end

        % Value changed function: ImagegroupListBox
        function ImagegroupListBoxValueChanged(comp, event)
            comp.updateImageAxes
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(comp, event)
            f0 = comp.imds.filters;
            val = str2double(comp.ImagegroupListBox.Value);
            index = comp.imds.imgroups{val};
            f   = [{index},{'imgroup'}];
            tbl = array2table(f,'VariableNames',{'string','logic'});
            try
                f0(contains(f0.logic,'imgroup'),:)=[];
            end
            try
                tbl = cat(1,f0,tbl);
            end
            comp.UITable.Data = tbl;
            comp.filterDataStore
        end

        % Button pushed function: ApplyButton_2
        function applyFilter(comp, event)
            comp.filterDataStore
        end

        % Value changed function: ListBox, TimestepEditField
        function timeStepUpdate(comp, event)
            comp.updateTimeAxes            
        end

        % Button pushed function: ApplyButton
        function applyTimeFilter(comp, event)
            f0 = comp.imds.filters;
            f   = [{comp.filterTime},{'imtime'}];
            tbl = array2table(f,'VariableNames',{'string','logic'});
            try
                f0(contains(f0.logic,'imtime'),:)=[];
            end
            try
                tbl = cat(1,f0,tbl);
            end
            comp.UITable.Data = tbl;
            comp.filterDataStore
        end

        % Button pushed function: resetGroup, resetTime
        function ResetCallback(comp, event)
            switch event.Source
                case comp.resetTime
                    comp.imds.resetFilter('imtime')
                case comp.resetGroup
                    comp.imds.resetFilter('imgroup')
            end
            comp.resetComponents(true)
            comp.updateInfo
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 574 565];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.InformationPanel = uipanel(comp.GridLayout);
            comp.InformationPanel.Title = 'Information';
            comp.GridLayout3 = uigridlayout(comp.InformationPanel);
            comp.infoLabel = uilabel(comp.GridLayout3);
            comp.infoLabel.Text = 'infoLabel';
            comp.TabGroup = uitabgroup(comp.GridLayout);
            comp.TimeTab = uitab(comp.TabGroup);
            comp.TimeTab.Title = 'Time';
            comp.GridLayout5 = uigridlayout(comp.TimeTab);
            comp.AxesTime = uiaxes(comp.GridLayout5);
            xlabel(comp.AxesTime, 'Image index')
            ylabel(comp.AxesTime, 'Time')
            comp.TimestepEditFieldLabel = uilabel(comp.GridLayout5);
            comp.TimestepEditFieldLabel.Text = 'Time step';
            comp.TimestepEditField = uieditfield(comp.GridLayout5, 'numeric');
            comp.TimestepEditField.Limits = [0 Inf];
            comp.TimestepEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeStepUpdate, true);
            comp.TimestepEditField.Value = 1;
            comp.ListBox = uilistbox(comp.GridLayout5);
            comp.ListBox.Items = {'minutes', 'hours', 'days'};
            comp.ListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @timeStepUpdate, true);
            comp.ListBox.Value = 'hours';
            comp.ApplyButton = uibutton(comp.GridLayout5, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @applyTimeFilter, true);
            comp.ApplyButton.Text = 'Apply';
            comp.resetTime = uibutton(comp.GridLayout5, 'push');
            comp.resetTime.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetCallback, true);
            comp.resetTime.Text = 'Reset';
            comp.ImageTab = uitab(comp.TabGroup);
            comp.ImageTab.Title = 'Image';
            comp.GridLayout4_2 = uigridlayout(comp.ImageTab);
            comp.GridLayout2_2 = uigridlayout(comp.GridLayout4_2);
            comp.AxesImage = uiaxes(comp.GridLayout2_2);
            comp.AxesGroups = uiaxes(comp.GridLayout2_2);
            comp.StepEditFieldLabel = uilabel(comp.GridLayout2_2);
            comp.StepEditFieldLabel.Text = 'Step';
            comp.StepEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.StepEditField.Limits = [1 Inf];
            comp.StepEditField.RoundFractionalValues = 'on';
            comp.StepEditField.Value = 10;
            comp.MethodDropDownLabel = uilabel(comp.GridLayout2_2);
            comp.MethodDropDownLabel.Text = 'Method';
            comp.MethodDropDown = uidropdown(comp.GridLayout2_2);
            comp.MethodDropDown.Items = {'hist', 'sum', 'fft'};
            comp.MethodDropDown.Value = 'hist';
            comp.ThresholdEditFieldLabel = uilabel(comp.GridLayout2_2);
            comp.ThresholdEditFieldLabel.Text = 'Threshold';
            comp.ThresholdEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.ThresholdEditField.Limits = [0 1];
            comp.ThresholdEditField.Value = 0.2;
            comp.ScanButton = uibutton(comp.GridLayout2_2, 'push');
            comp.ScanButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ScanButtonPushed, true);
            comp.ScanButton.Text = 'Scan';
            comp.ImagegroupListBoxLabel = uilabel(comp.GridLayout2_2);
            comp.ImagegroupListBoxLabel.Text = 'Image group';
            comp.ImagegroupListBox = uilistbox(comp.GridLayout2_2);
            comp.ImagegroupListBox.Items = {};
            comp.ImagegroupListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ImagegroupListBoxValueChanged, true);
            comp.ImagegroupListBox.Value = {};
            comp.SelectButton = uibutton(comp.GridLayout2_2, 'push');
            comp.SelectButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectButtonPushed, true);
            comp.SelectButton.Text = 'Select';
            comp.resetGroup = uibutton(comp.GridLayout2_2, 'push');
            comp.resetGroup.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetCallback, true);
            comp.resetGroup.Text = 'Reset';
            comp.FilenameTab = uitab(comp.TabGroup);
            comp.FilenameTab.Title = 'Filename';
            comp.GridLayout4 = uigridlayout(comp.FilenameTab);
            comp.GridLayout2 = uigridlayout(comp.GridLayout4);
            comp.UITable = uitable(comp.GridLayout2);
            comp.UITable.ColumnName = {'String'; 'Logic'};
            comp.UITable.RowName = {};
            comp.UITable.ColumnEditable = [true true];
            comp.UITable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @UITableCellEdit, true);
            comp.UITable.KeyPressFcn = matlab.apps.createCallbackFcn(comp, @UITableKeyPress, true);
            comp.AddButton = uibutton(comp.GridLayout2, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addCallback, true);
            comp.AddButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'mat-add.png');
            comp.AddButton.Text = '';
            comp.ApplyButton_2 = uibutton(comp.GridLayout2, 'push');
            comp.ApplyButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @applyFilter, true);
            comp.ApplyButton_2.Text = 'Apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end