classdef uiImageMergeControls < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        ImagemergingsettingsPanel  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        swapImagesButton           matlab.ui.control.Button
        ConcatanatedirectionButtonGroup  matlab.ui.container.ButtonGroup
        YwiseButton                matlab.ui.control.RadioButton
        XwiseButton                matlab.ui.control.RadioButton
        RightimagePanel            matlab.ui.container.Panel
        glRight                    matlab.ui.container.GridLayout
        ShiftYpxSpinner_2          matlab.ui.control.Spinner
        ShiftYpxSpinner_2Label     matlab.ui.control.Label
        ShiftXpxSpinner_2          matlab.ui.control.Spinner
        ShiftXpxSpinner_2Label     matlab.ui.control.Label
        flipUD_2                   matlab.ui.control.StateButton
        flipLR_2                   matlab.ui.control.StateButton
        rot90R_2                   matlab.ui.control.Button
        rot90L_2                   matlab.ui.control.Button
        LeftimagePanel             matlab.ui.container.Panel
        glLeft                     matlab.ui.container.GridLayout
        ShiftYpxSpinner            matlab.ui.control.Spinner
        ShiftYpxSpinnerLabel       matlab.ui.control.Label
        ShiftXpxSpinner            matlab.ui.control.Spinner
        ShiftXpxSpinnerLabel       matlab.ui.control.Label
        flipUD                     matlab.ui.control.StateButton
        flipLR                     matlab.ui.control.StateButton
        rot90R                     matlab.ui.control.Button
        rot90L                     matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = private)
        Axes
        Figure
        Tiles
        eventStructure struct = struct('Rot',0,'ShiftX',0,'ShiftY',0,'FlipLR',false,'FlipUD',false);;
        internalCommand logical = false;
        isMergeEvent (1,1) logical = false;
        leftSide struct
        rightSide struct
    end
    
    properties (Access = public)
        ConcatanateDirection (1,1) double = 1;
        Image1 (:,:) double = [];
        Image2 (:,:) double = [];
        Operation (1,1) struct = struct('leftSide',[],'rightSide',[],'catDirection',1);
    end
    
    %% Set functions
    methods
        function set.Image1(comp,val)
            comp.Image1=im2double(val);
            toggleButtons(comp,'left',~isempty(val))
            updateTiles(comp)
        end
        function set.Image2(comp,val)
            comp.Image2=im2double(val);
            toggleButtons(comp,'right',~isempty(val))
            updateTiles(comp)
        end
        function set.Operation(comp,val)
            fields = fieldnames(val);
            if isempty(fields)
                return
            end
            if ~any(ismember(fields,{'leftSide','rightSide','catDirection'}))
                error('Missing argument for merging structure')
            end
            comp.Operation=val;
            updateOperation(comp)
        end
    end
    %% Internal functions
    methods (Access = private)
        function toggleButtons(comp,side,state)
            switch side
                case 'left'
                    objs = findall(comp.glLeft,'-not','Type','uigridlayout');
                case 'right'
                    objs = findall(comp.glRight,'-not','Type','uigridlayout');
            end
            set(objs,'Enable',state)
        end
        function updateOperation(comp)
            if comp.internalCommand
                return
            end
            comp.leftSide   = comp.Operation.leftSide;
            comp.rightSide  = comp.Operation.rightSide;
            comp.ConcatanateDirection = comp.Operation.catDirection;
            switch comp.ConcatanateDirection
                case 1
                    comp.XwiseButton.Value = true;
                    comp.YwiseButton.Value = false;
                case 2
                    comp.XwiseButton.Value = false;
                    comp.YwiseButton.Value = true;
            end
            comp.updateRotationTooltip(comp.rot90L,comp.rot90R,comp.leftSide)
            comp.updateRotationTooltip(comp.rot90L_2,comp.rot90R_2,comp.rightSide)
            updateButtons(comp,'left')
            updateButtons(comp,'right')
            updateTiles(comp)

            function updateButtons(comp,side)
                switch side
                    case 'left'
                        ud = comp.leftSide;
                        objs = findall(comp.glLeft,'-not','Type','uigridlayout');
                    case 'right'
                        ud = comp.rightSide;
                        objs = findall(comp.glRight,'-not','Type','uigridlayout');
                end
                if isempty(ud)
                    return
                end
                getobj = @(tag) findall(objs,'Tag',tag);
                set(getobj('ReverseX'),'Value',ud.FlipLR)
                set(getobj('ReverseY'),'Value',ud.FlipUD)
                set(getobj('ShiftX'),'Value',ud.ShiftX)
                set(getobj('ShiftY'),'Value',ud.ShiftY)

            end
        end
        function updateTiles(comp)
            hasIm1 = ~isempty(comp.Image1);
            hasIm2 = ~isempty(comp.Image1);
            comp.swapImagesButton.Enable = 'off';


            set([comp.ShiftXpxSpinner comp.ShiftXpxSpinner_2 ...
                comp.ShiftYpxSpinner comp.ShiftYpxSpinner_2],'Enable','off')
            if all([hasIm1 hasIm2])
                comp.swapImagesButton.Enable = 'on';
                set([comp.ShiftXpxSpinner comp.ShiftXpxSpinner_2 ...
                    comp.ShiftYpxSpinner comp.ShiftYpxSpinner_2],'Enable','on')
            end

            if ~comp.isMergeEvent
                im1 = comp.transformImage(comp.Image1,'left');
                im2 = comp.transformImage(comp.Image2,'right');
                if hasIm1
                    imagesc(comp.Tiles(1),im1)
                end
                if hasIm2
                    imagesc(comp.Tiles(2),im2)
                end
            end

            setTight = true;
            try %#ok<*TRYNC> 
                im = cat(comp.ConcatanateDirection,...
                    comp.transformImage(comp.Image1,'left',true), ...
                    comp.transformImage(comp.Image2,'right',true));
                imdata = findall(comp.Tiles(3),'Type','Image');
                if isempty(imdata)
                    imagesc(comp.Tiles(3),im)
                else
                    setTight = false;
                    imdata.CData = im;
                end
            end

            notify(comp,'ValueChanged')
            arrayfun(@(ax) set(ax,'Colormap',gray),comp.Tiles)
            if setTight
                arrayfun(@(ax) axis(ax,'tight'),comp.Tiles)
            end
        end
        function image = transformImage(comp,image,side,isMerge)
            if nargin<4
                isMerge = false;
            end
            switch side
                case 'left'
                    ud = comp.leftSide;
                case 'right'
                    ud = comp.rightSide;
            end
            if isempty(ud)
                return
            end
            if isempty(image)
                return
            end

            if isMerge
                if ud.ShiftX
                    image = circshift(image,ud.ShiftX,1);
                end
                if ud.ShiftY
                    image = circshift(image,ud.ShiftY,2);
                end
            end
            image = rot90(image,ud.Rot);
            if ud.FlipLR
                image = fliplr(image);
            end
            if ud.FlipUD
                image = flipud(image);
            end
        end
        function updateRotationTooltip(~,rotLButton,rotRButton,operationStruct)
            val     = sprintf('(%s)',char(extractBetween(rotLButton.Tooltip,'(',')')));
            newval  = sprintf('(%i)',operationStruct.Rot);
            rotLButton.Tooltip=strrep(rotLButton.Tooltip,val,newval);
            rotRButton.Tooltip=strrep(rotRButton.Tooltip,val,newval);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            delete(findall(0,'Type','Figure','Name','Image Merging Settings Window'))
            calledWithoutParent(comp,'Image Merging Settings Window')
            comp.Figure = findall(0,'Type','Figure','Tag','ImageMergeSettings');
            if isempty(comp.Figure)
                comp.Figure = figure('Tag','ImageMergeSettings', ...
                    'HandleVisibility','callback', ...
                    'Name','Image Merger Preview');
            end
            clf(comp.Figure)
            set(0,'CurrentFigure',comp.Figure)
            shg;
            comp.Axes = tiledlayout(2,2,'Parent',comp.Figure, ...
                'TileSpacing','tight');
            comp.Tiles=nexttile(comp.Axes);
            comp.Tiles(2)=nexttile(comp.Axes);
            comp.Tiles(3)=nexttile(comp.Axes,[1 2]);
            updateTiles(comp)

            comp.leftSide  = comp.eventStructure;
            comp.rightSide = comp.eventStructure;
            
            comp.internalCommand = true;
            comp.Operation.leftSide  = comp.eventStructure;
            comp.Operation.rightSide = comp.eventStructure;
            comp.internalCommand = false;
        end

        % Button pushed function: swapImagesButton
        function swapImages(comp, event)
            im1             = comp.Image1;
            im2             = comp.Image2;
            comp.Image1     = im2;
            comp.Image2     = im1;

            ls              = comp.leftSide;
            rs              = comp.rightSide;
            comp.leftSide   = rs;
            comp.rightSide  = ls;
            comp.Operation.leftSide  = comp.leftSide;
            comp.Operation.rightSide = comp.rightSide;
        end

        % Callback function: ShiftXpxSpinner, ShiftXpxSpinner, 
        % ...and 14 other components
        function panelObjChanged(comp, event)
            switch ancestor(event.Source,'uiPanel')
                case comp.LeftimagePanel
                    ud = comp.leftSide;
                case comp.RightimagePanel
                    ud = comp.rightSide;
            end
            comp.isMergeEvent = false;
            switch event.Source.Tag
                case 'rotL'
                    ud.Rot = mod(ud.Rot-1,4);
                case 'rotR'
                    ud.Rot = mod(ud.Rot+1,4);
                case 'ReverseX'
                    ud.FlipLR = ~ud.FlipLR;
                case 'ReverseY'
                    ud.FlipUD = ~ud.FlipUD;
                case 'ShiftX'
                    comp.isMergeEvent = true;
                    ud.ShiftX = event.Value;
                case 'ShiftY'
                    comp.isMergeEvent = true;
                    ud.ShiftY = event.Value;
            end

            switch ancestor(event.Source,'uiPanel')
                case comp.LeftimagePanel
                    comp.leftSide = ud;
                    comp.Operation.leftSide  = comp.leftSide;
                    comp.updateRotationTooltip(comp.rot90L,comp.rot90R,ud)
                case comp.RightimagePanel
                    comp.rightSide = ud;
                    comp.Operation.rightSide = comp.rightSide;
                    comp.updateRotationTooltip(comp.rot90L_2,comp.rot90R_2,ud)
            end
            updateTiles(comp)            
        end

        % Selection changed function: ConcatanatedirectionButtonGroup
        function changeCatDirection(comp, event)
            selectedButton = comp.ConcatanatedirectionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'X-wise'
                    comp.ConcatanateDirection = 1;
                case 'Y-wise'
                    comp.ConcatanateDirection = 2;
            end
            comp.Operation.catDirection = comp.ConcatanateDirection;
            updateTiles(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 360 245];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ImagemergingsettingsPanel = uipanel(comp.GridLayout);
            comp.ImagemergingsettingsPanel.Title = 'Image merging settings';
            comp.GridLayout2 = uigridlayout(comp.ImagemergingsettingsPanel);
            comp.LeftimagePanel = uipanel(comp.GridLayout2);
            comp.LeftimagePanel.Title = 'Left image';
            comp.glLeft = uigridlayout(comp.LeftimagePanel);
            comp.rot90L = uibutton(comp.glLeft, 'push');
            comp.rot90L.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.rot90L.Tag = 'rotL';
            comp.rot90L.Icon = 'mat-rotate-left.png';
            comp.rot90L.Enable = 'off';
            comp.rot90L.Tooltip = {'Rotate left 90° (0)'};
            comp.rot90L.Text = '';
            comp.rot90R = uibutton(comp.glLeft, 'push');
            comp.rot90R.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.rot90R.Tag = 'rotR';
            comp.rot90R.Icon = 'mat-rotate-right.png';
            comp.rot90R.Enable = 'off';
            comp.rot90R.Tooltip = {'Rotate right 90° (0)'};
            comp.rot90R.Text = '';
            comp.flipLR = uibutton(comp.glLeft, 'state');
            comp.flipLR.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.flipLR.Tag = 'ReverseX';
            comp.flipLR.Enable = 'off';
            comp.flipLR.Tooltip = {'Flip horizontally'};
            comp.flipLR.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flip.png');
            comp.flipLR.Text = '';
            comp.flipUD = uibutton(comp.glLeft, 'state');
            comp.flipUD.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.flipUD.Tag = 'ReverseY';
            comp.flipUD.Enable = 'off';
            comp.flipUD.Tooltip = {'Flip vertically'};
            comp.flipUD.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flipud.png');
            comp.flipUD.Text = '';
            comp.ShiftXpxSpinnerLabel = uilabel(comp.glLeft);
            comp.ShiftXpxSpinnerLabel.Enable = 'off';
            comp.ShiftXpxSpinnerLabel.Text = 'Shift X (px)';
            comp.ShiftXpxSpinner = uispinner(comp.glLeft);
            comp.ShiftXpxSpinner.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftXpxSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftXpxSpinner.Tag = 'ShiftX';
            comp.ShiftXpxSpinner.Enable = 'off';
            comp.ShiftXpxSpinner.Tooltip = {''};
            comp.ShiftYpxSpinnerLabel = uilabel(comp.glLeft);
            comp.ShiftYpxSpinnerLabel.Enable = 'off';
            comp.ShiftYpxSpinnerLabel.Text = 'Shift Y (px)';
            comp.ShiftYpxSpinner = uispinner(comp.glLeft);
            comp.ShiftYpxSpinner.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftYpxSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftYpxSpinner.Tag = 'ShiftY';
            comp.ShiftYpxSpinner.Enable = 'off';
            comp.RightimagePanel = uipanel(comp.GridLayout2);
            comp.RightimagePanel.Title = 'Right image';
            comp.glRight = uigridlayout(comp.RightimagePanel);
            comp.rot90L_2 = uibutton(comp.glRight, 'push');
            comp.rot90L_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.rot90L_2.Tag = 'rotL';
            comp.rot90L_2.Icon = 'mat-rotate-left.png';
            comp.rot90L_2.Enable = 'off';
            comp.rot90L_2.Tooltip = {'Rotate left 90° (0)'};
            comp.rot90L_2.Text = '';
            comp.rot90R_2 = uibutton(comp.glRight, 'push');
            comp.rot90R_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.rot90R_2.Tag = 'rotR';
            comp.rot90R_2.Icon = 'mat-rotate-right.png';
            comp.rot90R_2.Enable = 'off';
            comp.rot90R_2.Tooltip = {'Rotate right 90° (0)'};
            comp.rot90R_2.Text = '';
            comp.flipLR_2 = uibutton(comp.glRight, 'state');
            comp.flipLR_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.flipLR_2.Tag = 'ReverseX';
            comp.flipLR_2.Enable = 'off';
            comp.flipLR_2.Tooltip = {'Flip horizontally'};
            comp.flipLR_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flip.png');
            comp.flipLR_2.Text = '';
            comp.flipUD_2 = uibutton(comp.glRight, 'state');
            comp.flipUD_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.flipUD_2.Tag = 'ReverseY';
            comp.flipUD_2.Enable = 'off';
            comp.flipUD_2.Tooltip = {'Flip vertically'};
            comp.flipUD_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-flipud.png');
            comp.flipUD_2.Text = '';
            comp.ShiftXpxSpinner_2Label = uilabel(comp.glRight);
            comp.ShiftXpxSpinner_2Label.Enable = 'off';
            comp.ShiftXpxSpinner_2Label.Text = 'Shift X (px)';
            comp.ShiftXpxSpinner_2 = uispinner(comp.glRight);
            comp.ShiftXpxSpinner_2.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftXpxSpinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftXpxSpinner_2.Tag = 'ShiftX';
            comp.ShiftXpxSpinner_2.Enable = 'off';
            comp.ShiftXpxSpinner_2.Tooltip = {''};
            comp.ShiftYpxSpinner_2Label = uilabel(comp.glRight);
            comp.ShiftYpxSpinner_2Label.Enable = 'off';
            comp.ShiftYpxSpinner_2Label.Text = 'Shift Y (px)';
            comp.ShiftYpxSpinner_2 = uispinner(comp.glRight);
            comp.ShiftYpxSpinner_2.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftYpxSpinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @panelObjChanged, true);
            comp.ShiftYpxSpinner_2.Tag = 'ShiftY';
            comp.ShiftYpxSpinner_2.Enable = 'off';
            comp.ConcatanatedirectionButtonGroup = uibuttongroup(comp.GridLayout2);
            comp.ConcatanatedirectionButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @changeCatDirection, true);
            comp.ConcatanatedirectionButtonGroup.Title = 'Concatanate direction';
            comp.XwiseButton = uiradiobutton(comp.ConcatanatedirectionButtonGroup);
            comp.XwiseButton.Text = 'X-wise';
            comp.XwiseButton.Value = true;
            comp.YwiseButton = uiradiobutton(comp.ConcatanatedirectionButtonGroup);
            comp.YwiseButton.Text = 'Y-wise';
            comp.swapImagesButton = uibutton(comp.GridLayout2, 'push');
            comp.swapImagesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @swapImages, true);
            comp.swapImagesButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-mat-swap-horiz.png');
            comp.swapImagesButton.Enable = 'off';
            comp.swapImagesButton.Tooltip = {'Switch images'};
            comp.swapImagesButton.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end