classdef uiMovieCreation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        SortbyDropDown       matlab.ui.control.DropDown
        SortbyDropDownLabel  matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        RefreshButton        matlab.ui.control.Button
        UITable              matlab.ui.control.Table
    end

    properties (Access = private)
        vars
    end
    
    
    methods (Access = private)
        
        function updateTable(comp)
            comp.vars = evalin('base','whos');
            sizes = cellfun(@(v) sprintf('[%s]',num2str(v)),{comp.vars.size}','UniformOutput',false);
            comp.UITable.Data = [{comp.vars.name}' {comp.vars.class}' sizes];
        end
        function sortTable(comp)
            data = array2table(comp.UITable.Data,'VariableNames',comp.UITable.ColumnName);
            switch comp.SortbyDropDown.Value
                case 'Size product'
                    product = cellfun(@numel,({comp.vars.size}'));
                    [~,map] = sort(product);
                case 'Name'
                    [~,map] = sortrows(data,'Variable');
                otherwise
                    [~,map] = sortrows(data,comp.SortbyDropDown.Value);
            end
            data=data(map,:);
            comp.UITable.Data = table2array(data);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Movie creation')
            updateTable(comp)
            sortTable(comp)
        end

        % Value changed function: SortbyDropDown
        function sortTableCallback(comp, event)
            sortTable(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 640 467];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Variable'; 'Class'; 'Size'};
            comp.UITable.RowName = {};
            comp.RefreshButton = uibutton(comp.GridLayout, 'push');
            comp.RefreshButton.Text = 'Refresh';
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.Title = 'Panel';
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.SortbyDropDownLabel = uilabel(comp.GridLayout);
            comp.SortbyDropDownLabel.Text = 'Sort by';
            comp.SortbyDropDown = uidropdown(comp.GridLayout);
            comp.SortbyDropDown.Items = {'Name', 'Class', 'Size product'};
            comp.SortbyDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sortTableCallback, true);
            comp.SortbyDropDown.Value = 'Name';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end