classdef uiNetcdfDesigner < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        ncinfoLabel                   matlab.ui.control.TextArea
        VariablesListBox              matlab.ui.control.ListBox
        VariablesListBoxLabel         matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        SearchDirButton               matlab.ui.control.Button
        FilerepositoryEditField       matlab.ui.control.EditField
        FilerepositoryEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        VariableStructure struct % Structure containing all variables for netCDF file
        filename string = 'nctest.nc';
        info
        root string {mustBeFolder(root)} = pwd;
    end
    
    %% Set functions and subroutines
    methods         
        function set.filename(comp,val)
            comp.filename = val;
            updateFilename(comp)
            if isfile(val)
                setAllAttributes(comp,val)
            end
        end
        function set.root(comp,val)
            comp.root = val;
            updateRoot(comp)
        end
        function set.VariableStructure(comp,val)
            comp.VariableStructure = val;
            updateVariableList(comp)
        end
    end
    methods 
        function setState(comp,state)
            set([comp.FilenameEditField ...
                comp.FilerepositoryEditField ...
                comp.VariablesListBox],'Enable',state)
        end
        function resetAll(comp)
            setState(comp,'off')
        end
        function setAllAttributes(comp,filename)
            [comp.root,fname,ext] = fileparts(filename);
            comp.filename = strcat(fname,ext);
            ni = ncinfo(filename);
            comp.info = ni;
            comp.VariablesListBox.Items = {ni.Variables.Name};
            setState(comp,'on')
        end
        function updateFilename(comp)
            comp.FilenameEditField.Value = comp.filename;
        end
        function updateRoot(comp)
            comp.FilerepositoryEditField.Value = comp.root;
        end
        function updateVariableList(comp)
            vs = comp.VariableStructure;
            fields = fieldnames(vs);
            if isempty(fields)
                return
            end
            comp.VariablesListBox.Items = fields;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'NetCDF file creation')
            set([comp.ncinfoLabel],'Value','')
            updateFilename(comp)
            updateRoot(comp)
            updateVariableList(comp)
            resetAll(comp)
        end

        % Clicked callback: VariablesListBox
        function updateInfo(comp, event)
            try
                pause(1e-2)
                ni   = comp.info.Variables(strcmp(event.Source.Value,event.Source.Items));
            catch
                return
            end
            str00 = fieldnames(ni);
            str0 = [str00(1:2); fieldnames(ni.Dimensions);str00(3:end)];
            
            %%
            str1 = cell(size(str0));
            str  = struct2cell(ni);
            j = 1;
            for i=1:numel(str)
                switch class(str{i})
                    case 'struct'
                        switch str00{i}
                            case 'Attributes'
                                str1(j) = join(cellfun(@num2str,{str{i}.Name str{i}.Value},'UniformOutput',false));
                                j = j+1;
                            case 'Dimensions'
                                str1(j) = {''};
                                j=j+1;
                                tmp = cellfun(@num2str,([{str{i}.Name}
                                    {str{i}.Length}
                                    {str{i}.Unlimited}]),'UniformOutput',false);
                                str1(j:j+2)= join(pad(tmp));
                                j = j+3;
                        end
                        continue
                    case {'double','logical'}
                        tmp = num2str(str{i});
                    otherwise
                        tmp = str{i};
                end
                str1{j}=tmp;
                j = j+1;
            end
            str = (cellfun(@(x,y) ...
                sprintf('%s:\t%s\n',x,y),pad(str0),str1,'UniformOutput',false));
%             str(3:5)=cellfun(@(x) sprintf(' %s',x),str(3:5),'UniformOutput',false);
            comp.ncinfoLabel.Value = join(str,'');

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 745 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.FilerepositoryEditFieldLabel = uilabel(comp.GridLayout);
            comp.FilerepositoryEditFieldLabel.Text = 'File repository';
            comp.FilerepositoryEditField = uieditfield(comp.GridLayout, 'text');
            comp.SearchDirButton = uibutton(comp.GridLayout, 'push');
            comp.SearchDirButton.Icon = 'find_files_icon.png';
            comp.SearchDirButton.Text = '';
            comp.FilenameEditFieldLabel = uilabel(comp.GridLayout);
            comp.FilenameEditFieldLabel.Text = 'Filename';
            comp.FilenameEditField = uieditfield(comp.GridLayout, 'text');
            comp.VariablesListBoxLabel = uilabel(comp.GridLayout);
            comp.VariablesListBoxLabel.Text = 'Variables';
            comp.VariablesListBox = uilistbox(comp.GridLayout);
            comp.VariablesListBox.Items = {};
            comp.VariablesListBox.Multiselect = 'on';
            comp.VariablesListBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @updateInfo, true);
            comp.VariablesListBox.Value = {};
            comp.ncinfoLabel = uitextarea(comp.GridLayout);
            comp.ncinfoLabel.Editable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end