classdef uiOpticalFlow < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl                            matlab.ui.container.GridLayout
        Panel_2                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        statusLabel                   matlab.ui.control.Label
        FiltersizeEditField           matlab.ui.control.NumericEditField
        FiltersizeEditFieldLabel      matlab.ui.control.Label
        derivativesMethod             matlab.ui.control.DropDown
        DerivativesmethodLabel        matlab.ui.control.Label
        AutoprocessButton             matlab.ui.control.StateButton
        ProcessButton                 matlab.ui.control.Button
        AlphaEditFieldLabel           matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        methodtab                     matlab.ui.container.TabGroup
        HornSchunckTab                matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        IterationsEditFieldLabel      matlab.ui.control.Label
        IterationsEditField           matlab.ui.control.NumericEditField
        LiuShenTab                    matlab.ui.container.Tab
        GridLayout6_2                 matlab.ui.container.GridLayout
        IlluminationEditField         matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel    matlab.ui.control.Label
        LambdaEditFieldLabel          matlab.ui.control.Label
        LambdaEditField               matlab.ui.control.NumericEditField
        OverlayDropDown               matlab.ui.control.DropDown
        OverlayDropDownLabel          matlab.ui.control.Label
        ViewDropDown                  matlab.ui.control.DropDown
        ViewDropDownLabel             matlab.ui.control.Label
        ResizeEditField               matlab.ui.control.NumericEditField
        ResizeEditFieldLabel          matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout2_2                 matlab.ui.container.GridLayout
        statusLabel_2                 matlab.ui.control.Label
        FiltersizeEditField_2         matlab.ui.control.NumericEditField
        FiltersizeEditField_2Label    matlab.ui.control.Label
        derivativesMethod_2           matlab.ui.control.DropDown
        DerivativesmethodLabel_2      matlab.ui.control.Label
        AutoprocessButton_2           matlab.ui.control.StateButton
        ProcessButton_2               matlab.ui.control.Button
        AlphaEditField_2Label         matlab.ui.control.Label
        AlphaEditField_2              matlab.ui.control.NumericEditField
        methodtab_2                   matlab.ui.container.TabGroup
        HornSchunckTab_2              matlab.ui.container.Tab
        GridLayout6_3                 matlab.ui.container.GridLayout
        IterationsEditField_2Label    matlab.ui.control.Label
        IterationsEditField_2         matlab.ui.control.NumericEditField
        LiuShenTab_2                  matlab.ui.container.Tab
        GridLayout6_4                 matlab.ui.container.GridLayout
        IlluminationEditField_2       matlab.ui.control.NumericEditField
        IlluminationEditField_2Label  matlab.ui.control.Label
        LambdaEditField_2Label        matlab.ui.control.Label
        LambdaEditField_2             matlab.ui.control.NumericEditField
        OverlayDropDown_2             matlab.ui.control.DropDown
        OverlayDropDown_2Label        matlab.ui.control.Label
        ViewDropDown_2                matlab.ui.control.DropDown
        ViewDropDown_2Label           matlab.ui.control.Label
        ResizeEditField_2             matlab.ui.control.NumericEditField
        ResizeEditField_2Label        matlab.ui.control.Label
        MethodDropDown_2              matlab.ui.control.DropDown
        MethodDropDown_2Label         matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ComputationCalled
    end

    properties (Access = private)
        autoProcess logical = false;
    end
    
    properties (Access = public)
        AxesHandle
        Style string = 'vertical'; % Layout of the UI
        imdef
        imref
        operations
        output
        usesApp logical = false;
    end
    
    methods
        function set.imdef(comp,val)
            comp.imdef = val;
%             calculateVelocity(comp)
%             updateAxes(comp)
        end
        
        function set.Style(comp,val)
            if ~any(strcmp(val,{'vertical','horizontal'}))
                return
            end
            comp.Style = val;
            changeStyle(comp)
        end
        function changeStyle(comp)
            
            switch comp.Style
                case 'vertical'
                    comp.gl.ColumnWidth = {'fit',0};
                    comp.Position = [1 1 1054 432];
                case 'horizontal'
                    comp.gl.ColumnWidth = {0,'fit'};
                    comp.Position = [1 1 1054 221];
            end
        end

    end
    methods (Access = private)
        
        function calculateVelocity(comp)
            if isempty(comp.imref)
                return
            end

            notify(comp, 'ComputationCalled');
            if comp.usesApp
                return
            end

            OPS = comp.operations;
            
            im0 = comp.imref;
            im  = comp.imdef;

            rz = OPS.resize;
            if rz<1
                im0 = imresize(im0,rz);
                im = imresize(im,rz);
            end

            filt = OPS.filter;

            if filt>0
                im0 = imgaussfilt(im0,filt);
                im = imgaussfilt(im,filt);
            end

            comp.statusLabel.Text = 'Processing';
            pause(1e-2)
            comp.output = velocity_field(...
                im0,im,...
                OPS.method,...
                OPS.alpha,...
                OPS.iterations,...
                OPS.lambda,...
                OPS.illumination,...
                OPS.filter,...
                OPS.diffmethod);
            assignin('base','output',comp.output)
            comp.statusLabel.Text = '';
        end
        function updateAxes(comp)
            if comp.usesApp
                return
            end
            in = comp.output;
            if isempty(in)
                return
            end
            
            view = comp.ViewDropDown.Value;

            ux = in.u;
            uy = in.v;
            switch view
                case 'Warp'
                    image = WarpImage(in.im1,ux*5e4,uy*5e4);
                    adata = ones(size(im));
                    cmap = 'gray';
                case 'Image'
                    image = in.im2;
                    adata = ones(size(image));
                    cmap = 'gray';
                case 'uv'
                    image = (imfuse(abs(uy),abs(ux)));
                    cmap = 'imfuse';
                case 'u'
                    image = ux;
                case 'v'
                    image = uy;
                case 'U'
                    image = in.U;
                    cmap = 'gray';
                case 'Vorticity'
                    image = vor;
                case 'Continuity'
                    image = (gradient(ux)+gradient(uy')')./hypot(ux,uy);
                    image(abs(image)>1)=nan;
                case 'Streamfunction'
                    image = flowfun(ux,uy);                    
            end

            ax = comp.AxesHandle;
            if isempty(ax)
                ax = gca;
            end
            imdata = findall(ax,'Type','Image');
            if isempty(imdata)
                imagesc(ax,image)
            else
                imdata.CData = image;
                imdata.XData = [0 size(image,2)];
                imdata.YData = [0 size(image,1)];
                caxis([-1 1]*std(image,[],'all'))
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Optical Flow')
            set([comp.statusLabel comp.statusLabel_2],Text = '');
            comp.operations= struct( ...
                'method','fast', ...
                'alpha',comp.AlphaEditField.Value, ...
                'iterations',comp.IterationsEditField.Value, ...
                'resize',comp.ResizeEditField.Value,...
                'lambda',comp.LambdaEditField.Value, ...
                'illumination',comp.IlluminationEditField.Value, ...
                'filter',comp.FiltersizeEditField.Value,...
                'field','raw',...
                'diffmethod','hs');
             changeStyle(comp)
            
        end

        % Callback function: AlphaEditField, AlphaEditField_2, 
        % ...and 22 other components
        function valueChangedCallback(comp, event)
            tag = event.Source.Tag;
            command = split(tag,'-');
            command = command{end};
            iscalc  = false;
            switch command
                case {'dt','alpha','iterations','lambda','illumination','filter','resize','window'}
                    task = sprintf('comp.operations.%s=event.Source.Value;',command);
                    eval(task)
                    iscalc = true;                    
                case 'method'
                    switch event.Source.Value
                        case 'Horn-Schunck (fast)'
                            comp.methodtab.SelectedTab = comp.HornSchunckTab;
                            comp.operations.method = 'fast';
                        case 'Liu-Shen (slow)'
                            comp.methodtab.SelectedTab = comp.LiuShenTab;
                            comp.operations.method = 'slow';
                    end
                    iscalc = true;
                case 'diff'
                    switch event.Source.Value
                        case 'Horn-Shunck'
                            val = 'hs';
                        case 'Difference'
                            val = 'diff';
                        case 'Barron'
                            val = 'barron';
                        case 'Liu-Shen'
                            val = 'ls';
                    end
                    comp.operations.diffmethod = val;
                    iscalc = true;
                case 'auto'
                    comp.autoProcess = event.Source.Value;
            end

            if ~strcmp(command,'force')||~comp.autoProcess
                iscalc = false;
            end
            if strcmp(command,'force')
                iscalc = true;
            end

            if iscalc
                calculateVelocity(comp)
            end
            updateAxes(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 968 470];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.gl = uigridlayout(comp);
            comp.Panel = uipanel(comp.gl);
            comp.GridLayout2_2 = uigridlayout(comp.Panel);
            comp.MethodDropDown_2Label = uilabel(comp.GridLayout2_2);
            comp.MethodDropDown_2Label.Text = 'Method';
            comp.MethodDropDown_2 = uidropdown(comp.GridLayout2_2);
            comp.MethodDropDown_2.Items = {'Horn-Schunck (fast)', 'Liu-Shen (slow)'};
            comp.MethodDropDown_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.MethodDropDown_2.Tag = 'set-method';
            comp.MethodDropDown_2.Tooltip = {'Optical flow velocity field calculation method'};
            comp.MethodDropDown_2.Value = 'Horn-Schunck (fast)';
            comp.ResizeEditField_2Label = uilabel(comp.GridLayout2_2);
            comp.ResizeEditField_2Label.Text = 'Resize';
            comp.ResizeEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.ResizeEditField_2.Limits = [0 1];
            comp.ResizeEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ResizeEditField_2.Tag = 'set-resize';
            comp.ResizeEditField_2.Tooltip = {'Image scale resizing (x100%)'};
            comp.ResizeEditField_2.Value = 1;
            comp.ViewDropDown_2Label = uilabel(comp.GridLayout2_2);
            comp.ViewDropDown_2Label.Text = 'View';
            comp.ViewDropDown_2 = uidropdown(comp.GridLayout2_2);
            comp.ViewDropDown_2.Items = {'Image', 'Warp', 'uv', 'u', 'v', 'U', 'Vorticity', 'Continuity', 'Streamfunction'};
            comp.ViewDropDown_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ViewDropDown_2.Tag = 'set-view';
            comp.ViewDropDown_2.Tooltip = {'Output viewing modes'};
            comp.ViewDropDown_2.Value = 'Image';
            comp.OverlayDropDown_2Label = uilabel(comp.GridLayout2_2);
            comp.OverlayDropDown_2Label.Text = 'Overlay';
            comp.OverlayDropDown_2 = uidropdown(comp.GridLayout2_2);
            comp.OverlayDropDown_2.Items = {'None', 'Vectors', 'Streamlines'};
            comp.OverlayDropDown_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.OverlayDropDown_2.Tag = 'set-overlay';
            comp.OverlayDropDown_2.Value = 'None';
            comp.methodtab_2 = uitabgroup(comp.GridLayout2_2);
            comp.HornSchunckTab_2 = uitab(comp.methodtab_2);
            comp.HornSchunckTab_2.Tooltip = {'Solves the optical flow equation'};
            comp.HornSchunckTab_2.Title = 'Horn-Schunck';
            comp.GridLayout6_3 = uigridlayout(comp.HornSchunckTab_2);
            comp.IterationsEditField_2 = uieditfield(comp.GridLayout6_3, 'numeric');
            comp.IterationsEditField_2.Limits = [1 Inf];
            comp.IterationsEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.IterationsEditField_2.Tag = 'set-iterations';
            comp.IterationsEditField_2.Tooltip = {'Number of iterations for velocity field convergence'};
            comp.IterationsEditField_2.Value = 200;
            comp.IterationsEditField_2Label = uilabel(comp.GridLayout6_3);
            comp.IterationsEditField_2Label.Text = 'Iterations';
            comp.LiuShenTab_2 = uitab(comp.methodtab_2);
            comp.LiuShenTab_2.Tooltip = {'Solves the optical flow equation and applies a refinement to preserve fluid continuity'};
            comp.LiuShenTab_2.Title = 'Liu-Shen';
            comp.GridLayout6_4 = uigridlayout(comp.LiuShenTab_2);
            comp.LambdaEditField_2 = uieditfield(comp.GridLayout6_4, 'numeric');
            comp.LambdaEditField_2.Limits = [1 Inf];
            comp.LambdaEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.LambdaEditField_2.Tag = 'set-lambda';
            comp.LambdaEditField_2.Tooltip = {'Liu-Shen estimator for refined estimation'};
            comp.LambdaEditField_2.Value = 200;
            comp.LambdaEditField_2Label = uilabel(comp.GridLayout6_4);
            comp.LambdaEditField_2Label.Text = 'Lambda';
            comp.IlluminationEditField_2Label = uilabel(comp.GridLayout6_4);
            comp.IlluminationEditField_2Label.Text = 'Illumination';
            comp.IlluminationEditField_2 = uieditfield(comp.GridLayout6_4, 'numeric');
            comp.IlluminationEditField_2.Limits = [0 Inf];
            comp.IlluminationEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.IlluminationEditField_2.Tag = 'set-illumination';
            comp.IlluminationEditField_2.Tooltip = {'Local illumination intensity adjustment, To bypass it, set to 0'};
            comp.AlphaEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.AlphaEditField_2.Limits = [1 Inf];
            comp.AlphaEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.AlphaEditField_2.Tag = 'set-alpha';
            comp.AlphaEditField_2.Tooltip = {'Smoothing velocity field'};
            comp.AlphaEditField_2.Value = 100;
            comp.AlphaEditField_2Label = uilabel(comp.GridLayout2_2);
            comp.AlphaEditField_2Label.Text = 'Alpha';
            comp.ProcessButton_2 = uibutton(comp.GridLayout2_2, 'push');
            comp.ProcessButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ProcessButton_2.Tag = 'force';
            comp.ProcessButton_2.Tooltip = {'Process the current frame'};
            comp.ProcessButton_2.Text = 'Process';
            comp.AutoprocessButton_2 = uibutton(comp.GridLayout2_2, 'state');
            comp.AutoprocessButton_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.AutoprocessButton_2.Tag = 'auto';
            comp.AutoprocessButton_2.Tooltip = {'Toggle automatic processing of Optical Flow'};
            comp.AutoprocessButton_2.Text = 'Auto-process';
            comp.DerivativesmethodLabel_2 = uilabel(comp.GridLayout2_2);
            comp.DerivativesmethodLabel_2.Text = {'Derivatives'; 'method'};
            comp.derivativesMethod_2 = uidropdown(comp.GridLayout2_2);
            comp.derivativesMethod_2.Items = {'Horn-Shunck', 'Difference', 'Barron', 'Liu-Shen'};
            comp.derivativesMethod_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.derivativesMethod_2.Tag = 'set-diff';
            comp.derivativesMethod_2.Tooltip = {'Optical flow velocity field derivative computation method'};
            comp.derivativesMethod_2.Value = 'Horn-Shunck';
            comp.FiltersizeEditField_2Label = uilabel(comp.GridLayout2_2);
            comp.FiltersizeEditField_2Label.Text = 'Filter size';
            comp.FiltersizeEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.FiltersizeEditField_2.Limits = [0 Inf];
            comp.FiltersizeEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.FiltersizeEditField_2.Tag = 'set-filter';
            comp.FiltersizeEditField_2.Tooltip = {'Gausian filter size for removing random noise in images'};
            comp.statusLabel_2 = uilabel(comp.GridLayout2_2);
            comp.statusLabel_2.Text = 'status';
            comp.Panel_2 = uipanel(comp.gl);
            comp.GridLayout2 = uigridlayout(comp.Panel_2);
            comp.MethodDropDownLabel = uilabel(comp.GridLayout2);
            comp.MethodDropDownLabel.Text = 'Method';
            comp.MethodDropDown = uidropdown(comp.GridLayout2);
            comp.MethodDropDown.Items = {'Horn-Schunck (fast)', 'Liu-Shen (slow)'};
            comp.MethodDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.MethodDropDown.Tag = 'set-method';
            comp.MethodDropDown.Tooltip = {'Optical flow velocity field calculation method'};
            comp.MethodDropDown.Value = 'Horn-Schunck (fast)';
            comp.ResizeEditFieldLabel = uilabel(comp.GridLayout2);
            comp.ResizeEditFieldLabel.Text = 'Resize';
            comp.ResizeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ResizeEditField.Limits = [0 1];
            comp.ResizeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ResizeEditField.Tag = 'set-resize';
            comp.ResizeEditField.Tooltip = {'Image scale resizing (x100%)'};
            comp.ResizeEditField.Value = 1;
            comp.ViewDropDownLabel = uilabel(comp.GridLayout2);
            comp.ViewDropDownLabel.Text = 'View';
            comp.ViewDropDown = uidropdown(comp.GridLayout2);
            comp.ViewDropDown.Items = {'Image', 'Warp', 'uv', 'u', 'v', 'U', 'Vorticity', 'Continuity', 'Streamfunction'};
            comp.ViewDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ViewDropDown.Tag = 'set-view';
            comp.ViewDropDown.Tooltip = {'Output viewing modes'};
            comp.ViewDropDown.Value = 'Image';
            comp.OverlayDropDownLabel = uilabel(comp.GridLayout2);
            comp.OverlayDropDownLabel.Text = 'Overlay';
            comp.OverlayDropDown = uidropdown(comp.GridLayout2);
            comp.OverlayDropDown.Items = {'None', 'Vectors', 'Streamlines'};
            comp.OverlayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.OverlayDropDown.Tag = 'set-overlay';
            comp.OverlayDropDown.Value = 'None';
            comp.methodtab = uitabgroup(comp.GridLayout2);
            comp.HornSchunckTab = uitab(comp.methodtab);
            comp.HornSchunckTab.Tooltip = {'Solves the optical flow equation'};
            comp.HornSchunckTab.Title = 'Horn-Schunck';
            comp.GridLayout6 = uigridlayout(comp.HornSchunckTab);
            comp.IterationsEditField = uieditfield(comp.GridLayout6, 'numeric');
            comp.IterationsEditField.Limits = [1 Inf];
            comp.IterationsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.IterationsEditField.Tag = 'set-iterations';
            comp.IterationsEditField.Tooltip = {'Number of iterations for velocity field convergence'};
            comp.IterationsEditField.Value = 200;
            comp.IterationsEditFieldLabel = uilabel(comp.GridLayout6);
            comp.IterationsEditFieldLabel.Text = 'Iterations';
            comp.LiuShenTab = uitab(comp.methodtab);
            comp.LiuShenTab.Tooltip = {'Solves the optical flow equation and applies a refinement to preserve fluid continuity'};
            comp.LiuShenTab.Title = 'Liu-Shen';
            comp.GridLayout6_2 = uigridlayout(comp.LiuShenTab);
            comp.LambdaEditField = uieditfield(comp.GridLayout6_2, 'numeric');
            comp.LambdaEditField.Limits = [1 Inf];
            comp.LambdaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.LambdaEditField.Tag = 'set-lambda';
            comp.LambdaEditField.Tooltip = {'Liu-Shen estimator for refined estimation'};
            comp.LambdaEditField.Value = 200;
            comp.LambdaEditFieldLabel = uilabel(comp.GridLayout6_2);
            comp.LambdaEditFieldLabel.Text = 'Lambda';
            comp.IlluminationEditFieldLabel = uilabel(comp.GridLayout6_2);
            comp.IlluminationEditFieldLabel.Text = 'Illumination';
            comp.IlluminationEditField = uieditfield(comp.GridLayout6_2, 'numeric');
            comp.IlluminationEditField.Limits = [0 Inf];
            comp.IlluminationEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.IlluminationEditField.Tag = 'set-illumination';
            comp.IlluminationEditField.Tooltip = {'Local illumination intensity adjustment, To bypass it, set to 0'};
            comp.AlphaEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.AlphaEditField.Limits = [1 Inf];
            comp.AlphaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.AlphaEditField.Tag = 'set-alpha';
            comp.AlphaEditField.Tooltip = {'Smoothing velocity field'};
            comp.AlphaEditField.Value = 100;
            comp.AlphaEditFieldLabel = uilabel(comp.GridLayout2);
            comp.AlphaEditFieldLabel.Text = 'Alpha';
            comp.ProcessButton = uibutton(comp.GridLayout2, 'push');
            comp.ProcessButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.ProcessButton.Tag = 'force';
            comp.ProcessButton.Tooltip = {'Process the current frame'};
            comp.ProcessButton.Text = 'Process';
            comp.AutoprocessButton = uibutton(comp.GridLayout2, 'state');
            comp.AutoprocessButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.AutoprocessButton.Tag = 'auto';
            comp.AutoprocessButton.Tooltip = {'Toggle automatic processing of Optical Flow'};
            comp.AutoprocessButton.Text = 'Auto-process';
            comp.DerivativesmethodLabel = uilabel(comp.GridLayout2);
            comp.DerivativesmethodLabel.Text = {'Derivatives'; 'method'};
            comp.derivativesMethod = uidropdown(comp.GridLayout2);
            comp.derivativesMethod.Items = {'Horn-Shunck', 'Difference', 'Barron', 'Liu-Shen'};
            comp.derivativesMethod.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.derivativesMethod.Tag = 'set-diff';
            comp.derivativesMethod.Tooltip = {'Optical flow velocity field derivative computation method'};
            comp.derivativesMethod.Value = 'Horn-Shunck';
            comp.FiltersizeEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FiltersizeEditFieldLabel.Text = 'Filter size';
            comp.FiltersizeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.FiltersizeEditField.Limits = [0 Inf];
            comp.FiltersizeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueChangedCallback, true);
            comp.FiltersizeEditField.Tag = 'set-filter';
            comp.FiltersizeEditField.Tooltip = {'Gausian filter size for removing random noise in images'};
            comp.statusLabel = uilabel(comp.GridLayout2);
            comp.statusLabel.Text = 'status';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end