classdef uiSchlierenPatternDesigner < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        ColorindexEditField          matlab.ui.control.EditField
        ColorindexEditFieldLabel     matlab.ui.control.Label
        colorField                   matlab.ui.control.EditField
        OpenvideoButton              matlab.ui.control.Button
        OpenvideofolderButton        matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        GenerateButton               matlab.ui.control.Button
        FramesizexypxEditField       matlab.ui.control.EditField
        FramesizexypxEditFieldLabel  matlab.ui.control.Label
        InvertButton                 matlab.ui.control.StateButton
        DurationEditField            matlab.ui.control.NumericEditField
        DurationEditFieldLabel       matlab.ui.control.Label
        BasetimesEditField           matlab.ui.control.EditField
        BasetimesEditFieldLabel      matlab.ui.control.Label
        RemoveButton                 matlab.ui.control.Button
        LambdapxEditField            matlab.ui.control.NumericEditField
        LambdapxEditFieldLabel       matlab.ui.control.Label
        PatternDropDown              matlab.ui.control.DropDown
        PatternDropDownLabel         matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    properties (Access = public)
        BaseTime (1,1) double = 1;
        design
        savepath string {mustBeFolder(savepath)} = pwd;
    end
    

    methods
        function set.BaseTime(comp,val)
            comp.BaseTime = val;
            updateBaseTime(comp)
        end
        function updateBaseTime(comp)
            comp.BasetimesEditField.Value = num2str(comp.BaseTime);
            updateDesign(comp,struct('Value',comp.BaseTime,'Source',comp.BasetimesEditField))
        end
        function set.savepath(comp,val)
            comp.savepath = val;
            updateLabel(comp)
        end
        function updateLabel(comp)
            comp.Label.Text = codifyFilename(comp);
        end
        function reset(comp)
            comp.Label.Text     = codifyFilename(comp);
            comp.UITable.Data   = [];
        end
        function readMetadata(comp)
            p = comp.savepath;
            f = dir(fullfile(p,'*ss_pattern*.xml'));
            T = comp.UITable;
            if isempty(f)
                return
            end
            try
                s = readstruct(fullfile(f.folder,f.name)).table;
                s = arrayfun(@(x) split(x,','),s,'UniformOutput',false);
                s = arrayfun(@(x) convertStringsToChars(s{x}),1:numel(s),'UniformOutput',false);
                s = [s{1} s{2} s{3} s{4}]';
                s2 = cellfun(@str2double,s(2:end,:));
                for i=1:width(s)
                    T.Data(:,i) =  [s(1,i) s2(1,i) s2(2,i) s2(3,i)]';
                end
            catch
                return
            end
        end
        function autoComplete(comp)
            generateCallback(comp)
            openVideo(comp)
        end
    end
    methods (Access = private)
        function createMetadata(comp)
            md = struct;
            md.table = comp.UITable.Data;
            md.table = join(cellfun(@num2str,md.table,'UniformOutput',false),',');
            writestruct(md,fullfile(comp.savepath,'ss_pattern.xml'),"FileType","xml")
        end
        function updateTime(comp)
            t = comp.UITable;
            if isempty(t.Data)
                return
            end
            t.ColumnName = arrayfun(@(x) sprintf('Pattern %i',x),1:width(t.Data),'UniformOutput',false);
            t.ColumnEditable = true(1,width(t.Data));

            T = t.Data(2,:);
            try
                bt = eval(comp.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            t.Data(end,:) = arrayfun(@(x) x,cumsum(cell2mat(T)*bt), ...
                'UniformOutput',false);

            % update label
            comp.Label.Text = codifyFilename(comp);
        end
        function [fname,root] = codifyFilename(comp)
            d   = comp.UITable.Data;
            root = comp.savepath;

            if isempty(d)
                fname = root;
                return
            end

            try
                bt = eval(comp.BasetimesEditField.Value);
            catch
                bt = 1;
            end
            sequence = sprintf('tau_%.2fs_Ttot_%.1fs',bt,d{end});
            state = 'wb';
            if comp.InvertButton.Value
                state = 'bw';
            end

            for i=1:width(d)
                sequence = sprintf('%s-%s%i_%s',sequence,upper(d{1,i}(1)),d{2,i},state);
            end
            fname = fullfile(root,sprintf( ...
                'iw_%s.avi',sequence));
        end
        function value = getColor(comp)
            val = comp.ColorindexEditField.Value;
            if isa(val,'char')&&~any(isstrprop(val,"digit"))
                val = strrep(val,"'",'');
                val = strrep(val,'"','');
                switch val
                    case 'b';value = [0 0 1];
                    case 'r';value = [1 0 0];
                    case 'y';value = [1 1 0];
                    case 'w';value = [1 1 1];
                    case 'k';value = [0 0 0];
                    case 'm';value = [1 0 1];
                    case 'g';value = [0 1 0];
                    case 'c';value = [0 1 1];
                end
                return
            elseif any(isstrprop(val,"digit"))
                try
                    eval([val ';']);
                catch
                    if ~all(isstrprop(val,"digit"))
                        val = (cell2mat((join(['[' join((split(val,' ')),' ') ,']']))));
                    end
                end
            end
            try
                value=eval(val);
            catch
                value = [1 1 1];
            end
            if numel(value)>1
                value(value>1)=1;
                value(value<0)=0;
                comp.ColorindexEditField.Value = sprintf('[%g %g %g]',value(1),value(2),value(3));
            else
                value = value.*[1 1 1]/255;
                value(value>1)=1;
            end
            %value = value/255;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            calledWithoutParent(comp,'Synthetic Schlieren Pattern Designer')
            updateLabel(comp)
            addDesign(comp)

            comp.UITable.CellEditCallback       = @updateDesignFcn;
            try
                comp.UITable.SelectionChangedFcn    = @updateDesignFcn;
            end

            try %#ok<*TRYNC> 
                root = fullfile(filepartsn(which('iwdata.m'),1),'reference_patterns','videos');
                comp.savepath = folderOnPath(root);
            end

            function updateDesignFcn(~,event)
                updateDesign(comp,event)
            end
        end

        % Value changed function: BasetimesEditField, DurationEditField, 
        % ...and 3 other components
        function updateDesign(comp, event)
            t = comp.UITable;
            if event.Source==comp.FramesizexypxEditField
                if numel(split(event.Value,' '))~=2
                    comp.FramesizexypxEditField.Value = '[1920 1080]';
                end
            end
            if isempty(t.Data)||isempty(t.Selection)
                return
            end

            idx = t.Selection;
            switch event.Source
                case comp.PatternDropDown
                    t.Data(1,idx) = {event.Value};
                case comp.DurationEditField
                    t.Data(2,idx) = {event.Value};
                case comp.LambdapxEditField
                    t.Data(3,idx) = {event.Value};
                case comp.UITable
                    d = t.Data(:,idx);
                    comp.PatternDropDown.Value   = d{1};
                    comp.DurationEditField.Value = d{2};
                    comp.LambdapxEditField.Value = d{3};
            end
            updateTime(comp)

        end

        % Value changed function: ColorindexEditField, InvertButton
        function changeColor(comp, event)
            value = getColor(comp);


            if comp.InvertButton.Value
                value = 1-value;
            end
            comp.colorField.BackgroundColor = value;
        end

        % Button pushed function: GenerateButton
        function generateCallback(comp, event)
            d   = comp.UITable.Data;
            ref = eval(comp.FramesizexypxEditField.Value);
            % codify sequence
            fname = codifyFilename(comp);
            try
                bt = eval(comp.BasetimesEditField.Value);
            catch
                bt = 1;
            end

            time        = cell2mat(d(3,:));
            if isfile(fname)
                comp.Label.Text = ['[File already exists] ' fname];
                return
            end

            V           = VideoWriter(fname);
            V.FrameRate = 5/bt;
            %V.Quality   = 75;
            try
                open(V)
                iw = iwdata;
                for i=1:numel(time)
                    % generate pattern
                    lambda  = d{3,i};
                    pattern = d{1,i};
                    if ~strcmp(pattern,'blank')
                        im      = iw.ss_pattern(pattern, ...
                            'lambda',lambda, ...
                            'ref',ref);
                        im = repmat(im,[1 1 3]);
                        %im = round(im*255)+1;
                    else
                        im = ones(ref(2),ref(1),3).*reshape(getColor(comp),1,1,[]);
                    end
                    if comp.InvertButton.Value
                        im = 1-im;
                    end

                    % repeat n frames
                    for j=1:d{2,i}*5
                        comp.Label.Text = ['[Writing] ' fname];
                        writeVideo(V,im2frame(im))
                    end
                end
            catch ME
                close(V)
                rethrow(ME)
            end
            close(V)
            createMetadata(comp)
            comp.Label.Text = ['[Saved] ' fname];
            if isfile(fname)
                comp.OpenvideoButton.Enable = 'on';
            end
        end

        % Button pushed function: OpenvideofolderButton
        function openFolder(comp, event)
            [~,root] = codifyFilename(comp);
            winopen(root)
        end

        % Button pushed function: OpenvideoButton
        function openVideo(comp, event)
            winopen(codifyFilename(comp))
        end

        % Button pushed function: AddButton
        function addDesign(comp, event)
            t = comp.UITable;
            try
                bt = eval(comp.BasetimesEditField.Value);
            catch
                bt = 1;
            end

            newval = [{comp.PatternDropDown.Value} ...
                comp.DurationEditField.Value...
                comp.LambdapxEditField.Value ...
                bt]';
            if isempty(t.Data)
                t.Data = newval;
            else
                t.Data(:,end+1)=newval;
            end
            updateTime(comp)
        end

        % Button pushed function: RemoveButton
        function removeDesign(comp, event)
            s = comp.UITable.Selection;
            comp.UITable.Data(:,s)=[];
            updateTime(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 688 440];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Pattern'};
            comp.UITable.RowName = {'Pattern'; 'Duration (τ)'; 'Lambda (px)'; 'Time (s)'};
            comp.UITable.SelectionType = 'column';
            comp.Panel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.AddButton = uibutton(comp.GridLayout2, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addDesign, true);
            comp.AddButton.Tooltip = {'Adds a new default pattern to the table. '};
            comp.AddButton.Text = 'Add';
            comp.PatternDropDownLabel = uilabel(comp.GridLayout2);
            comp.PatternDropDownLabel.Text = 'Pattern';
            comp.PatternDropDown = uidropdown(comp.GridLayout2);
            comp.PatternDropDown.Items = {'dot', 'checker', 'lattice', 'row', 'column', 'blank'};
            comp.PatternDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateDesign, true);
            comp.PatternDropDown.Value = 'dot';
            comp.LambdapxEditFieldLabel = uilabel(comp.GridLayout2);
            comp.LambdapxEditFieldLabel.Text = 'Lambda (px)';
            comp.LambdapxEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.LambdapxEditField.Limits = [2 Inf];
            comp.LambdapxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateDesign, true);
            comp.LambdapxEditField.Tooltip = {'Wavelength of the desired pattern (except ''blank'').'};
            comp.LambdapxEditField.Value = 2;
            comp.RemoveButton = uibutton(comp.GridLayout2, 'push');
            comp.RemoveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeDesign, true);
            comp.RemoveButton.Tooltip = {'Removes the selected patterns from the table.'};
            comp.RemoveButton.Text = 'Remove';
            comp.BasetimesEditFieldLabel = uilabel(comp.GridLayout2);
            comp.BasetimesEditFieldLabel.Text = 'Base time τ (s)';
            comp.BasetimesEditField = uieditfield(comp.GridLayout2, 'text');
            comp.BasetimesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateDesign, true);
            comp.BasetimesEditField.Tooltip = {'Minimum time for a duration cycle.'};
            comp.BasetimesEditField.Value = '1';
            comp.DurationEditFieldLabel = uilabel(comp.GridLayout2);
            comp.DurationEditFieldLabel.Text = 'Duration';
            comp.DurationEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.DurationEditField.Limits = [1 Inf];
            comp.DurationEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateDesign, true);
            comp.DurationEditField.Tooltip = {'Multiplicative factor of the base time.'};
            comp.DurationEditField.Value = 1;
            comp.InvertButton = uibutton(comp.GridLayout2, 'state');
            comp.InvertButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeColor, true);
            comp.InvertButton.Tooltip = {'Inverts the color index.'};
            comp.InvertButton.Text = 'Invert';
            comp.FramesizexypxEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FramesizexypxEditFieldLabel.Text = 'Frame size [x y] (px)';
            comp.FramesizexypxEditField = uieditfield(comp.GridLayout2, 'text');
            comp.FramesizexypxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateDesign, true);
            comp.FramesizexypxEditField.Value = '[1920 1080]';
            comp.GenerateButton = uibutton(comp.GridLayout2, 'push');
            comp.GenerateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @generateCallback, true);
            comp.GenerateButton.Text = 'Generate';
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.Text = '';
            comp.OpenvideofolderButton = uibutton(comp.GridLayout2, 'push');
            comp.OpenvideofolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openFolder, true);
            comp.OpenvideofolderButton.Text = {'Open video'; 'folder'};
            comp.OpenvideoButton = uibutton(comp.GridLayout2, 'push');
            comp.OpenvideoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openVideo, true);
            comp.OpenvideoButton.Enable = 'off';
            comp.OpenvideoButton.Text = 'Open video';
            comp.colorField = uieditfield(comp.GridLayout2, 'text');
            comp.colorField.Editable = 'off';
            comp.ColorindexEditFieldLabel = uilabel(comp.GridLayout2);
            comp.ColorindexEditFieldLabel.Tooltip = {''};
            comp.ColorindexEditFieldLabel.Text = 'Color index';
            comp.ColorindexEditField = uieditfield(comp.GridLayout2, 'text');
            comp.ColorindexEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeColor, true);
            comp.ColorindexEditField.Tooltip = {'Specified as either a RGB triplet (e.g. [1 1 1]) or color name (e.g. ''b'').'};
            comp.ColorindexEditField.Value = '1';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end