classdef uiSliderScrollable < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SliderObject  matlab.ui.control.Slider
    end

    properties (Access = public)
        Slider
    end
    
    
    methods (Access = private)
        function hFound = pointerOnSlider(comp,fig)
            pos         = fig.CurrentPoint;
            hFound      = [];
            tmp         = findall(fig);
            hTag = tmp(tmp==comp);
            hPos = getpixelposition(hTag,true);  % recursive to find absoulute position
            if within(pos(1),hPos(1).*[1 1]+[0 hPos(3)])&&within(pos(2),hPos(2).*[1 1]+[0 hPos(4)])
                hFound = hTag;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Slider = comp.SliderObject;
            fig = ancestor(comp,'Figure');
            fig.WindowScrollWheelFcn = @(~,event) scrollWheelFcn(comp,event);

            function scrollWheelFcn(~,event)
                verticalScrollAmount    = event.VerticalScrollAmount; %#ok<NASGU>
                verticalScrollCount     = event.VerticalScrollCount;

                hFound = pointerOnSlider(comp,fig);

                if isempty(hFound)
                    return
                end


                if contains(class(hFound),{'Slider','Grid'})
                    if contains(class(hFound),'Grid')
                        for i=1:length(hFound.Children)
                            if contains(class(hFound.Children(i)),'Slider')&&hFound.Children(i).Visible
                                hFound = hFound.Children(i);
                                break
                            end
                        end
                    end
                end
                if contains(class(hFound),'Slider')
                    step    = (hFound.Limits(2)-hFound.Limits(1))/100;
                    if app.scrollmult
                        switch app.scrolltype
                            case 'shift'
                                step = step*5;
                            case 'control'
                                step = 1;
                        end
                    end

                    val     = hFound.Value - verticalScrollCount*step;

                    if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = hFound.Value - verticalScrollCount*step;
                    end
                    imageSliderCallback(app,event)
                else
                    return
                end
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 643 91];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SliderObject = uislider(comp);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end