classdef uiSliderValuedLimits < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout    matlab.ui.container.GridLayout
        SliderLimMax  matlab.ui.control.NumericEditField
        SliderLimMin  matlab.ui.control.NumericEditField
        SliderVal     matlab.ui.control.NumericEditField
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        SliderValueChanged
        SliderValueChanging
    end

    properties (Access = public)
        Limits (1,2) double = [0,1]; % Slider limits
        RoundFractionalValues (1,1) logical = false; % Rounds to the nearest integer
        Text char = 'Slider'; % Slider label text
        Value (1,1) double = 0; % Slider value
    end
    
    %% SET functions
    methods
        function set.Text(comp,val)
            comp.Text = val;
            setText(comp)
        end
        function set.Limits(comp,val)
            comp.Limits = val;
            setLimits(comp)
        end
        function set.Value(comp,val)
            comp.Value = val;
            setValue(comp)
        end
        function set.RoundFractionalValues(comp,val)
            comp.RoundFractionalValues = val;
            setRoundFractionalValues(comp)
        end
        
    end
    %% SET-callbacks
    methods (Access = private)
        function setLimits(comp)
            comp.Slider.Limits = comp.Limits;
            getLimits(comp)
        end
        function setText(comp)
            comp.SliderLabel.Text = comp.Text;
        end
        function setValue(comp)
            comp.Slider.Value = comp.Value;
            comp.SliderVal.Value = comp.Value;
        end
        function setRoundFractionalValues(comp)
            set([comp.SliderVal ...
                comp.SliderLimMax ...
                comp.SliderLimMin],'RoundFractionalValue',"on");
            comp.Slider.Value = round(comp.Slider.Value);
        end
    end

    %% Internal functions
    methods (Access = private)
        function getLimits(comp)
            lims=comp.Slider.Limits;
            comp.SliderLimMin.Value = min(lims);
            comp.SliderLimMax.Value = max(lims);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            getLimits(comp)
        end

        % Value changed function: Slider
        function changedFcn(comp, event)
            if comp.RoundFractionalValues
                comp.Slider.Value = round(comp.Slider.Value);
            end
            comp.Value = comp.Slider.Value;
            notify(comp,'SliderValueChanged',event);
        end

        % Value changing function: Slider
        function changingFcn(comp, event)
            try val = event.Value;end
            if nargin<2
                val = comp.Slider.Value;
            end
            comp.SliderVal.Value = val;
            notify(comp,'SliderValueChanging',event);
        end

        % Value changed function: SliderVal
        function changeValue(comp, event)
            %pv = comp.Slider.Value;
            try
                comp.Slider.Value = comp.SliderVal.Value;
            catch
                comp.SliderVal.Value = event.PreviousValue;
            end
            comp.Value = comp.SliderVal.Value;
            notify(comp,'SliderValueChanged',event);
%             matlab.ui.eventdata.ValueChangedData
%             event = struct('Value',comp.Slider.Value,'PreviousValue',pv,'Source',comp.Slider,'EventName','SliderValueChanged');
%             changedFcn(comp,event)
        end

        % Value changed function: SliderLimMax, SliderLimMin
        function changeLimits(comp, event)
            newlims = [comp.SliderLimMin.Value comp.SliderLimMax.Value];
            try %#ok<*TRYNC> 
%                 pv = comp.Slider.Value;
                comp.Slider.Limits = sort(newlims);
                comp.SliderVal.Value =  comp.Slider.Value; 
                comp.Value = comp.SliderVal.Value;
                notify(comp,'SliderValueChanged',event);
%                 event = struct('Value',comp.Slider.Value,'PreviousValue',pv,'Source',comp.Slider,'EventName','SliderValueChanged');
%                 changedFcn(comp,event)
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 501 63];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.SliderLabel = uilabel(comp.GridLayout);
            comp.SliderLabel.Text = 'Slider';
            comp.Slider = uislider(comp.GridLayout);
            comp.Slider.Limits = [0 1];
            comp.Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changedFcn, true);
            comp.Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @changingFcn, true);
            comp.SliderVal = uieditfield(comp.GridLayout, 'numeric');
            comp.SliderVal.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeValue, true);
            comp.SliderVal.Tooltip = {'Slider value'};
            comp.SliderLimMin = uieditfield(comp.GridLayout, 'numeric');
            comp.SliderLimMin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeLimits, true);
            comp.SliderLimMin.Tooltip = {'Slider limits (min)'};
            comp.SliderLimMax = uieditfield(comp.GridLayout, 'numeric');
            comp.SliderLimMax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeLimits, true);
            comp.SliderLimMax.Tooltip = {'Slider limits (max)'};
            comp.SliderLimMax.Value = 1;
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end