classdef uiStratification < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl                              matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MixingdeviceTab                 matlab.ui.container.Tab
        glMixing                        matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        StratificationprofilegeneratorPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GeneratefileButton_2            matlab.ui.control.Button
        file2read                       matlab.ui.control.EditField
        CalibrationfileEditFieldLabel   matlab.ui.control.Label
        ITef                            matlab.ui.control.NumericEditField
        IcethicknessmEditFieldLabel     matlab.ui.control.Label
        Stef                            matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel        matlab.ui.control.Label
        Fef                             matlab.ui.control.NumericEditField
        FlowrateLminEditFieldLabel      matlab.ui.control.Label
        PreviewoffButton                matlab.ui.control.StateButton
        lockButton                      matlab.ui.control.StateButton
        Nef                             matlab.ui.control.NumericEditField
        NradsLabel                      matlab.ui.control.Label
        rhoaef                          matlab.ui.control.NumericEditField
        rho0ef                          matlab.ui.control.NumericEditField
        kgmLabel                        matlab.ui.control.Label
        ChangeButton_3                  matlab.ui.control.Button
        profileDir                      matlab.ui.control.EditField
        LoggingdirectoryEditField_2Label  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        maxrhoMPef                      matlab.ui.control.NumericEditField
        minrhoMPef                      matlab.ui.control.NumericEditField
        DensitysaturationpositionsEditFieldLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        FilterTree                      matlab.ui.container.CheckBoxTree
        Tree                            matlab.ui.container.CheckBoxTree
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        toDate                          matlab.ui.control.DatePicker
        ToLabel                         matlab.ui.control.Label
        fromDate                        matlab.ui.control.DatePicker
        FromLabel                       matlab.ui.control.Label
        UsedatefilterButton             matlab.ui.control.StateButton
        FilesfoundLabel                 matlab.ui.control.Label
        ChangeButton_2                  matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        LoggingdirectoryEditField       matlab.ui.control.EditField
        LoggingdirectoryEditFieldLabel  matlab.ui.control.Label
        durationstepLabel               matlab.ui.control.Label
        GeneratefileButton              matlab.ui.control.Button
        mp_step                         matlab.ui.control.NumericEditField
        mp_end                          matlab.ui.control.NumericEditField
        mp_start                        matlab.ui.control.NumericEditField
        SamplemotorpositionsEditFieldLabel  matlab.ui.control.Label
        mp_t                            matlab.ui.control.Spinner
        CalibrationdurationminSpinnerLabel  matlab.ui.control.Label
        ChangeButton                    matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CalibrationdirectoryEditField   matlab.ui.control.EditField
        CalibrationdirectoryEditFieldLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ConductivityprofilerTab         matlab.ui.container.Tab
        glConductivity                  matlab.ui.container.GridLayout
        GridLayout4_3                   matlab.ui.container.GridLayout
        SaveButton_2                    matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        GridLayout4_2                   matlab.ui.container.GridLayout
        conductivity_dd1                matlab.ui.control.DropDown
        conductivity_dd2                matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        toDate_2                        matlab.ui.control.DatePicker
        ToLabel_2                       matlab.ui.control.Label
        fromDate_2                      matlab.ui.control.DatePicker
        FromLabel_2                     matlab.ui.control.Label
        FilesfoundLabel_2               matlab.ui.control.Label
        conductivityToggleDateFiltering  matlab.ui.control.StateButton
        FilterTree_2                    matlab.ui.container.CheckBoxTree
        Tree_2                          matlab.ui.container.CheckBoxTree
        ConductivityprofiledirectoryEditField  matlab.ui.control.EditField
        ConductivityprofiledirectoryEditFieldLabel  matlab.ui.control.Label
        ChangeButton_4                  matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ConductivityProfileLogged
        ProfileGenerated
        ProfileLogged
    end

    properties (Access = private)
        BucketTime double
        BucketVolume double
        FileSystemWatcher System.IO.FileSystemWatcher = System.IO.FileSystemWatcher;
        activeTree
        axLegend
        condData struct
        internalCommand (1,1) logical = false;
        toggleDateFiltering logical = false;
    end
    
    properties (Access = public)
        FlowRate (1,1) double {mustBeNumeric(FlowRate)} = 3.2;
        H (1,1) double = 1.1; % Height of tank (m)
        IceThickness (1,1) double {mustBeNumeric(IceThickness)} = 0;
        L (1,1) double = 1.5; % Height of tank (m)
        N (1,1) double {mustBeNumeric(N)} = 0;
        Stratification struct = struct('time',[],'rho',[],'mp',[],'file',[]);
        W (1,1) double = 0.2; % Width of tank
        calibrationPath string {mustBeFolder(calibrationPath)}
        conductivityData struct = struct('data',[],'files',[]);
        conductivityPath string {mustBeFolder(conductivityPath)}
        dates datetime = datetime;
        loggingPath string {mustBeFolder(loggingPath)}
        profilePath string {mustBeFolder(profilePath)}
        rho (1,2) double {mustBeVector(rho), mustBeNumeric(rho)} = [999.8 999.8];
    end
    
    %% SET functions
    methods
        function set.conductivityData(comp,val)
            fields = {'data','files'};
            compare = ismember(fields,fieldnames(val));
            condition =  ~all(compare);
            if condition
                missing = join(cellfun(@(x) x,fields(~condition),'UniformOutput',false),' ');
                warning('Missing one of the expected fields in the conductivity stucture\nFields required:\t"data" and "files"\nField missing:\t\t"%s"', ...
                    missing{1})
                return
            end
            comp.conductivityData = val;
            updateData(comp)
        end
        function set.Stratification(comp,val)
            fields = {'time','rho','mp','file'};
            compare = ismember(fields,fieldnames(val));
            condition =  ~all(compare);
            if condition
                missing = join(cellfun(@(x) x,fields(~condition),'UniformOutput',false),' ');
                warning('Missing one of the expected fields in the conductivity stucture\nFields required:\t"time" and "rho" and "mp"\nField missing:\t\t"%s"', ...
                    missing{1})
                return
            end
            comp.Stratification= val;
            updateData(comp)

        end
        function set.loggingPath(comp,val)
            comp.loggingPath = val;
            updateRoot(comp)
            updateWatcher(comp)
        end
        function set.calibrationPath(comp,val)
            comp.calibrationPath = val;
            updateRoot(comp)
        end
        function set.profilePath(comp,val)
            comp.profilePath = val;
            updateRoot(comp)
        end
        function set.rho(comp,val)
            comp.rho = val;
            updateProfile(comp,'rho')
        end
        function set.N(comp,val)
            comp.N = val;
            updateProfile(comp,'N')
        end
        function set.IceThickness(comp,val)
            comp.IceThickness = val;
            updateProfile(comp,'IceThickness')
        end
        function set.FlowRate(comp,val)
            comp.FlowRate = val;
            updateProfile(comp,'FlowRate')
        end
        function set.conductivityPath(comp,val)
            comp.conductivityPath = val;
            updateRoot(comp)
        end
        function set.dates(comp,val)
            comp.dates = val;
            updateDateFilters(comp)
        end
        %% SET-additional functions
        function updateDateFilters(comp)
            if comp.internalCommand
                return
            end
            if numel(comp.dates)==1
                comp.dates = [comp.dates datetime];
                return
            end
            set([comp.fromDate comp.fromDate_2],'Value',min(comp.dates),'Enable','on')
            set([comp.toDate comp.toDate_2],'Value',max(comp.dates),'Enable','on')
            set([comp.ToLabel comp.ToLabel_2 comp.FromLabel comp.FromLabel_2],'Enable','on')
            set([comp.UsedatefilterButton comp.conductivityToggleDateFiltering],'Value',true)
            
            switch comp.TabGroup.SelectedTab
                case comp.MixingdeviceTab
                    src = comp.UsedatefilterButton;
                case comp.ConductivityprofilerTab
                    src = comp.conductivityToggleDateFiltering;
            end
            toggleDateFilter(comp,struct('Source',src,'Value',true))
        end
        function updateData(comp)
            comp.condData = comp.conductivityData.data;
        end
    end
    methods (Access = private)
        function updateProfile(comp,type)
            %% Profile limits + plotting
            set(comp.Nef,'Limits',[0 inf])
            set([comp.rho0ef comp.rhoaef],'Limits',[980 1050])
            switch type
                case 'rho'
                    comp.rho0ef.Value = comp.rho(1);
                    comp.rhoaef.Value = comp.rho(2);
                    RHO = [comp.rho0ef.Value comp.rhoaef.Value];                    
                    comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                case 'N'
                    
                    comp.Nef.Value = comp.N;
                    RHO = [comp.rho0ef.Value comp.rhoaef.Value];
                    n   = comp.Nef.Value;
                    comp.rhoaef.Value = round(comp.rho0ef.Value+n^2*min(RHO)/9.81*comp.H,2);
                case 'FlowRate'
                    comp.Fef.Value = comp.FlowRate;
                case 'IceThickness'
                    comp.ITef.Value = comp.IceThickness;
            end
            if comp.PreviewoffButton.Value
                plotPreview(comp)
            end
            
        end
        function updateWatcher(comp)
            comp.FileSystemWatcher.Path = comp.loggingPath;
            checkLoggingFiles(comp)
        end
        function updateRoot(comp)
            comp.CalibrationdirectoryEditField.Value    = comp.folderOnPath(comp.calibrationPath);
            comp.LoggingdirectoryEditField.Value        = comp.folderOnPath(comp.loggingPath);
            comp.profileDir.Value                       = comp.folderOnPath(comp.profilePath);
            comp.ConductivityprofiledirectoryEditField.Value = comp.folderOnPath(comp.conductivityPath);
        end
        function root = folderOnPath(~,root)
            if isempty(root)
                return
            end
            label = split(root,'\');
            onPWD = ismember(split(pwd,'\'),label);
            if all(onPWD)
                root = join(label(~ismember(label,split(pwd,'\'))),'\');
            end
        end
        
    end
    %% PUBLIC functions
    methods (Access = public)
        %% Mixing Tab
       function seeCalibrationProfile(comp,export)
           %% Calibration profile plotting + export
            if nargin<2
                export = false;
            end
            calibrationrange = [comp.mp_start.Value comp.mp_end.Value];
            reversed = false;
            if diff(calibrationrange)<0
                calibrationrange = sort(calibrationrange);
                reversed = true;
            end
            if ~diff(calibrationrange)
                return
            end
            steps = comp.mp_step.Value;
            if steps>1e2
                comp.mp_step.Value = Inf;
            end
            t       = 0:comp.mp_t.Value*60;
            if ~max(t)
                return
            end
            comp.durationstepLabel.Text = '';
            if isinf(steps)
                valve_time   = linspace(calibrationrange(1),calibrationrange(2),length(t));
            else
                valve   = linspace(calibrationrange(1),calibrationrange(2),steps);
                times   = 1:round(length(t)/length(valve)):max(t);
                valve_time = ones(1,length(t))*max(valve);
                for ii=1:length(times)-1
                    valve_time(times(ii):times(ii+1)) = valve(ii);
                end
                comp.durationstepLabel.Text = sprintf('Each step is %is',round(length(t)/length(valve)));
            end

            if reversed
                valve_time = fliplr(valve_time);
            end
            plot(comp.UIAxes,t/60,valve_time);
            set(comp.UIAxes,'YLim',calibrationrange,'XLim',[0 max(t)/60])

            if ~export||~isfolder(comp.calibrationPath)
                return
            end
            filename = fullfile(comp.calibrationPath,sprintf('cal_%g-%g_%ipts_%gmin.txt',valve_time(1),valve_time(end),steps,max(t)/60));
            writetable(table(valve_time'), ...
                filename, ...
                'WriteVariableNames',false)

        end
    end
    %% PRIVATE functions
    methods (Access = private)
        function setupWatcher(comp)
            %% File system watcher initiation
            warning off
            comp.FileSystemWatcher.Filter = '*.csv';
            comp.FileSystemWatcher.EnableRaisingEvents = true;
            ValidEvents = events(comp.FileSystemWatcher);
            cellfun(@(event) addlistener(comp.FileSystemWatcher,event,@fsEvent),ValidEvents);
            warning on
            function fsEvent(~,event)
                warning off
                if ~isfolder(comp.loggingPath)
                    return
                end
                warning on
                checkLoggingFiles(comp,event)
            end
        end
        function checkLoggingFiles(comp,event)
            %% Files in logging directory + treenode creation/handling
            % gather files
            activeTab = comp.TabGroup.SelectedTab;
            mtab = comp.MixingdeviceTab;
            ctab = comp.ConductivityprofilerTab;
            switch activeTab
                case mtab
                    ActiveTabTree       = comp.Tree;
                    ActiveTabFilterTree = comp.FilterTree;
                case ctab
                    ActiveTabTree       = comp.Tree_2;
                    ActiveTabFilterTree = comp.FilterTree_2;
            end
            
            files = dir(fullfile( ...
                string(comp.FileSystemWatcher.Path), ...
                string(comp.FileSystemWatcher.Filter)));

            % check dates within specified range
            date = datetime({files.date});

            comp.DropDown.Enable='off';
            filenames = fullfile(files(1).folder,{files.name})';

            nodes = ActiveTabTree.Children;
            buildNode = @(files) arrayfun(@(file) uitreenode( ...
                "Text",file.name, ...
                "UserData",fullfile(file.folder,file.name),...
                'Parent',ActiveTabTree),files);

            if nargin<2
                event = [];
            end


            %% Node creation/update
            if ~isempty(event)
                % File Watcher Updates
                buildTree = false;
                if islogical(event)
                    buildTree = true;
                end
                if isempty(nodes)&&buildTree
                    buildNode(files);
                    sortNodes(comp)
                elseif buildTree
                else
                    [~,idxa,idx]=setxor({nodes.UserData}',filenames);
                    switch char(event.ChangeType)
                        case 'Renamed'
                            arrayfun(@(node,file) set(node, ...
                                'Text',file.name, ...
                                'UserData',fullfile(file.folder,file.name)), ...
                                nodes(idx),files(idx))
                        case 'Created'
                            buildNode(files(idx));
                        case 'Deleted'
                            delete(nodes(idxa))
                        case 'Changed'
                            idx = ismember({comp.activeTree.Children.Text},char(event.Name));
                            isSelected = ActiveTabTree.SelectedNodes==nodes(idx);
                            
                            switch activeTab
                                case mtab
                                    if isSelected
                                        mixingTreeSelectionCallback(comp)
                                    end
                                case ctab
                                    return
                                    %plotConductivityProfile(comp,true)
                            end
                    end
                end
                if ~isempty(ActiveTabTree.Children)
                    comp.DropDown.Enable='on';
                end
            end

            %% Node filtering
            if comp.toggleDateFiltering
                if contains(comp.activeTree.Tag,'Filter')
                    toggleDateFilter(comp)
                end

                range = [comp.dates datetime('today')];
                if numel(comp.dates)>1
                    range = comp.dates;
                end
                range = range+day([0 1])-seconds([0 1]); %#ok<*TNDAY>
                files = files(within(date,range));
                ftreenodes = ActiveTabFilterTree.Children;
                valid = false(size(ActiveTabTree.Children));
                if ~isempty(ftreenodes)
                    valid = ismember({ActiveTabTree.Children.UserData},{ftreenodes.UserData});
                end
                copyobj(ActiveTabTree.Children(~valid),ActiveTabFilterTree)
                if isempty(ActiveTabFilterTree.Children)
                    return
                end
                valid = (~ismember({ActiveTabFilterTree.Children.Text},{files.name}));
                delete(ActiveTabFilterTree.Children(valid))
                sortNodes(comp)
            end
            comp.PreviewoffButton.Enable = 'off';
            
            %% Nested functions
            function sortNodes(comp)
                if isempty(comp.activeTree.Children)
                    return
                end
                [~,map]=sort({comp.activeTree.Children.Text});
                comp.activeTree.Children=comp.activeTree.Children(map);
            end
        end
        %% Mixing Tab
        function [valve_time,rho_set,f]=generateProfile(comp)
            %% Mixing profile creation function
            if isempty(comp.file2read.Value)||~isfile(comp.file2read.UserData)
                return
            end
            rho0     = [comp.rho0ef.Value comp.rhoaef.Value];
            if ~diff(rho0);return;end
            data    = readmatrix(comp.file2read.UserData);
            MP      = data(:,3);

            RHO_MP  = data(:,end);
            RHO_MP(imdilate(abs(gradient(RHO_MP))>1e2,[1 1; 1 1]))=nan;
            RHO_MP(RHO_MP>1050)=1050;
            RHO_MP(RHO_MP<960)=nan;
            
            tmax    = (comp.H*comp.W*(comp.L-comp.ITef.Value))*1e3/(comp.Fef.Value*60);
            mixing  = linspace(0,1,tmax*60^2);
            t       = 1:tmax*60^2;

            [valve_time,rho_set,f] = getMotorPosition(mixing,t);
            
            function [valve_time,rho_set,f] = getMotorPosition(mixing,t)

                % density smoothing (calibration step "pulse" time)
                period = 4*round(mean(pulseperiod(gradient(MP))));
                period(isnan(period))=50;
                RHO_MP = smooth(RHO_MP,period);

                % resize and rescaling of average valve behaviour
                v       = imresize(RHO_MP,[length(t) 1]);
                v_rho   = v-1e3;
                if diff(MP([1 end]))<0
                    MP      = flipud(MP);
                    RHO_MP  = flipud(RHO_MP);
                end
                v_pos   = linspace(min(MP),max(MP),length(t));
                                                
                % find valve positions at density 
                rho_set = fliplr(min(rho0)*mixing+(1-mixing)*max(rho0));
                valve_time=arrayfun(@(val) find(v_rho+1e3>val,1,'first'),rho_set,'UniformOutput',false);
                valve_time(cellfun(@isempty,valve_time))={nan};
                valve_time=cell2mat(valve_time);
                valve_time=fillmissing(valve_time,'previous');
                if diff(MP([1 end]))>0
                    v_pos = fliplr(v_pos);
                end
                valve_time=v_pos(valve_time);

                % bucket volume consumption
                f = rescale(v_rho,0,1);
                satrhoMP = [comp.minrhoMPef.Value comp.maxrhoMPef.Value];
                validsat = within(f,satrhoMP);
                f = f(validsat);
                valid = within(v_rho(validsat),[comp.rho0ef.Value comp.rhoaef.Value]-1e3);
                f = f(valid);
                f = imresize(f,[numel(t) 1]);
                
                comp.BucketVolume   = [cumsum(1-f) cumsum(f)]*comp.Fef.Value/60;
                comp.BucketTime     = t/60;
            end

        end
        function plotPreview(comp,export)
            %% Mixing profile plotting + export
            if nargin<2
                export = false;
            end
            [t,RHO]=generateProfile(comp);
            
            tvec = (0:numel(t)-1)/60;
            idx = find(tvec*60>=comp.Stef.Value,1,'first');
            plot(comp.UIAxes,tvec,t,tvec(idx),t(idx),'ob')
            leg = findall(comp.UIAxes_2.Parent,'Type','legend');
            try leg.Visible='off';end
            
            switch comp.DropDown.Value
                case 'Time'
                    plot(comp.UIAxes_2,tvec,RHO,tvec(idx),RHO(idx),'ob')
                case 'Volume'
                    tvec = comp.BucketTime;
                    idx = find(tvec*60>=comp.Stef.Value,1,'first');
                    val = comp.BucketVolume;
                    plot(comp.UIAxes_2,tvec,val,tvec(idx),val(idx,:),'ob')
                    
                    ylim(comp.UIAxes_2,[0 inf])
                    leg=legend(comp.UIAxes_2,{'Fresh','Salty'},'Location','northwest','Tag','leg');
                    leg.Visible = 'on';
            end
            set([comp.UIAxes comp.UIAxes_2],'XLim',[0 inf])
   
            if ~export||~isfolder(comp.profilePath)
                return
            end


            tstart = comp.Stef.Value;
            theta = 0;
            F = comp.Fef.Value;
            if tstart>1
                time_str = sprintf('t0-tstart_');
            else
                time_str = '';
            end

            filename = fullfile(comp.calibrationPath, ...
                sprintf('SET-%s_%stheta-%i_rho-%ito%i_F-%sLpMIN_rho',...
                'motor',time_str,...
                round(theta),...\
                round(min(RHO)),round(max(RHO)),...
                strrep(num2str(F),'.',"'")));
            writetable(table(t'), ...
                filename, ...
                'WriteVariableNames',false)
        end
        %% Conductivity Tab
        function plotConductivityProfile(comp,FileWatcherEvent)
            if nargin<2
                FileWatcherEvent = false;
            end
            if FileWatcherEvent
                return
            end
            if isempty(comp.activeTree.CheckedNodes)||numel(comp.activeTree.CheckedNodes)==1
                set(comp.conductivity_dd2,Value = 'Single',Enable='off')
            end
            switch comp.conductivity_dd2.Value
                case 'Single'
                    filename    = {comp.activeTree.SelectedNodes.UserData};
                case {'Lineplot','Hovmoller'}
                    filename    = {comp.activeTree.CheckedNodes.UserData};
            end
            files   = cellfun(@dir,filename);
            tf      = datetime({files.date});

            if comp.UIAxes2.UserData
                data    = unpackData(cellfun(@readFile,filename,UniformOutput=false));
                comp.condData = data;
            else
                data = comp.condData;
            end
            
            % Profile
            vel     = mean(data.z./data.t)*1e-3; %5mm/s
            offset  = 12e-2;
            Z       = data.z*1e-3+offset;
            TIME    = tf-seconds(max(data.t,[],'omitnan'));

            % sorting
            [TIME,map] = sort(TIME);


            switch comp.conductivity_dd1.Value
                case 'Salinity'
                    plotType(comp, ...
                        data.S(:,map),Z,TIME, ...
                        'Salinity (V)')
                case 'Temperature'
                    %% Conversions
                    % T(V) to T(degC) (linear at 0psu)
                    m   = -0.22; %-.1414
                    c   = 3.13; %1.647
                    mun = 0.0255;
                    cun = 0.6572;
                    TdC     = @(Tv) (Tv-c)./m;
                    %             TdCun   = @(T,Tv) T.*abs(mun./(Tv+273))+cun;
                    plotType(comp, ...
                        TdC(data.T(:,map)),Z,TIME, ...
                        'Temperature (°C)')
            end
           
            function plotType(comp,cdata,xdata,ydata,cstr)
                ax      = comp.UIAxes2;
                imdata  = findall(ax,'Type','surface');
                linedata= findall(ax,'Type','Line','-not','Tag','timeline');
                selected= (ismember(filename,comp.activeTree.SelectedNodes.UserData));
                cbar    = findall(ax.Parent,'Type','Colorbar');
                switch comp.conductivity_dd2.Value
                    case 'Hovmoller'
                        % cdata = fillmissing(cdata,'nearest');
                        % ydata = hours(ydata-ydata(1));
                        xdata = mean(xdata,2,'omitnan');
                        delete(linedata)
                        if isempty(imdata)
                            pcolor(ax,ydata,xdata,cdata)
                            shading(ax,'interp')
                            axis(ax,'tight')
                            addlabels(ax,'y','z (m)','x','Time')
                            line([ydata(selected) ydata(selected)],ylim(ax), ...
                                'LineStyle','-', ...
                                'Color','r', ...
                                'Tag','timeline', ...
                                'Parent',ax)
                            if isempty(cbar)
                                addColorbar(ax,'title',cstr,'cmap','thermal')
                            else
                                cbar.Visible = 'on';cbar.Label.String=cstr;
                            end
                        else
                            set(imdata,CData=cdata,ZData=0*cdata,XData=ydata,YData=xdata)
                            try cbar.Visible = 'on';cbar.Label.String=cstr;end
                            try
                                line([ydata(selected) ydata(selected)],ylim(ax), ...
                                'LineStyle','-', ...
                                'Color','r', ...
                                'Tag','timeline', ...
                                'Parent',ax)
                            end
                        end
                    otherwise
                        try cbar.Visible = 'off';end
                        delete(imdata)
                        if isempty(linedata)
                            plot(ax,cdata,xdata,'k')
                            addlabels(ax,'x',cstr,'y','z (m)')
                            axis(ax,'ij')
                        else
                            switch comp.conductivity_dd2.Value
                                case 'Single'
                                    if numel(linedata)>1
                                        delete(linedata(2:end))
                                        linedata = linedata(1);
                                    end
                                    set(linedata,XData=cdata,YData=xdata,Color='k')
                                case 'Lineplot'
                                    copies = (size(cdata,2)-numel(linedata));
                                    if copies<0
                                        delete(linedata(end+copies+1:end))
                                    elseif copies==0&&~comp.conductivity_dd1.UserData
                                        selected= ismember(filename,comp.activeTree.SelectedNodes.UserData);
                                        set(linedata(selected),'Color','k','LineWidth',2)
                                        arrayfun(@(ld) set(ld,'Color',ld.UserData,'LineWidth',1),linedata(~selected))
                                        return
                                    else
                                        delete(linedata(2:end))
                                        linedata = linedata(1);
                                        copies =  1:size(cdata,2)-1;
                                        arrayfun(@(~)copyobj(linedata(1),ax), ...
                                            copies)
                                    end
                                    linedata= findall(ax,'Type','Line');
                                    arrayfun(@(idx) set(linedata(idx), ...
                                        XData=cdata(:,idx), ...
                                        Ydata=xdata(:,idx), ...
                                        LineWidth=1), ...
                                        1:numel(linedata))
                                    comp.conductivity_dd1.UserData = true;

                            end
                        end
                        linedata= findall(ax,'Type','Line');
                        if numel(linedata)>1
                            cmap = parula;
                            cmap = interp1(1:size(cmap,1), cmap, linspace(1,size(cmap,1),numel(linedata)),'linear');
                            arrayfun(@(idx) set(linedata(idx),'Color',cmap(idx,:),'UserData',cmap(idx,:)),1:numel(linedata))
                            set(linedata(selected),'Color','k','LineWidth',2)
                        end
                        
                        
                end
            end
            function data = readFile(filename)
                formatting  = {'time','pos','s','t'};
                file        = readmatrix(filename);
                data        = array2table(file(:,1:4),'VariableNames',formatting);
            end
            function data = unpackData(data)
                s = cell([1 numel(data)]);
                z = s;
                t = s;
                T = s;

                for i=1:numel(data)
                    tmp  = data{i};
                    s{i} = tmp.s;
                    z{i} = tmp.pos;
                    t{i} = tmp.t;
                    T{i} = tmp.time;
                end

                % Normalize length
                s = resizer(s);
                z = resizer(z);
                t = resizer(t);
                T = resizer(T);

                time  = sum(T,2,'omitnan')/60;
                tlim  = [time(1) time(end)];
                tstep = cumsum(T(end,:))/60;
                data = struct('S',s, ...
                    'T',t, ...
                    'z',z, ...
                    't',T, ...
                    'tsum',time, ...
                    'tlim',tlim, ...
                    'tstep',tstep);
                [];
                function out = resizer(in,maxlength)
                    if nargin<2
                        maxlength = max(cell2mat(cellfun(@numel,in,'UniformOutput',false)));
                    end
                    out = cellfun(@(x) interp1(1:numel(x),x,1:maxlength),in','UniformOutput',false);
                    out = cell2mat(out)';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            % Deployment
            % if isdeployed
            %     locate_externapp = which(fullfile('LabStratification.exe'));
            % end
            
            % Called without parent?
            if isa(comp.Parent,'matlab.ui.Figure')
                ss = get(0,'ScreenSize');
                comp.Parent.Position = [[ss([3 4])/2-comp.Position([3 4])/2] comp.Position([3 4])]; %#ok<NBRAK2>
                comp.Parent.Name = 'Stratification designer';
            end
            comp.activeTree = comp.Tree;
            try if isempty(comp.loggingPath);comp.loggingPath           = "labview_logs\raw_data\MV stratifier data";end;end %#ok<*TRYNC> 
            try if isempty(comp.calibrationPath);comp.calibrationPath   = "labview_logs\mixing_setpoints";end;end
            try if isempty(comp.profilePath);comp.profilePath           = "labview_logs\profile";end;end
            try if isempty(comp.conductivityPath);comp.conductivityPath = "labview_logs\raw_data\conductivity_data";end;end
            seeCalibrationProfile(comp)
            setupWatcher(comp)
            checkLoggingFiles(comp,true)
            set([comp.fromDate comp.toDate comp.fromDate_2 comp.toDate_2],'Value',datetime('today'))
        end

        % Selection change function: TabGroup
        function tabChanged(comp, event)
            selectedTab = comp.TabGroup.SelectedTab;
            % update filewatcher path
            switch selectedTab
                case comp.MixingdeviceTab
                    comp.FileSystemWatcher.Path     = comp.loggingPath;
                    comp.FileSystemWatcher.Filter   = '*.csv';
                case comp.ConductivityprofilerTab
                    comp.FileSystemWatcher.Path     = comp.conductivityPath;
                    comp.FileSystemWatcher.Filter   = '*.txt';
            end
            setActiveTree(comp,selectedTab)
            checkLoggingFiles(comp,true)

            function setActiveTree(comp,selectedTab)
                switch selectedTab
                    case comp.MixingdeviceTab
                        ftreec  = comp.FilterTree;
                        ntreec  = comp.Tree;
                        db      = comp.UsedatefilterButton.Value;
                    case comp.ConductivityprofilerTab
                        ftreec  = comp.FilterTree_2;
                        ntreec  = comp.Tree_2;
                        db      =comp.conductivityToggleDateFiltering.Value;
                end
                if db
                    comp.activeTree = ftreec;
                else
                    comp.activeTree = ntreec;
                end
            end
        end

        % Button pushed function: Button, Button_2, Button_3
        function openDirectory(comp, event)
            switch event.Source
                case comp.Button
                    root = comp.CalibrationdirectoryEditField.Value;
                case comp.Button_2
                    root = comp.LoggingdirectoryEditField.Value;
                case comp.Button_3
                    root = comp.ConductivityprofiledirectoryEditField.Value;
            end
            winopen(root)
        end

        % Value changed function: UsedatefilterButton, 
        % ...and 5 other components
        function toggleDateFilter(comp, event)
            exitearly = false;
            if nargin<2
                exitearly = true;
            end

            switch comp.TabGroup.SelectedTab
                case comp.MixingdeviceTab
                    filterButton    = comp.UsedatefilterButton;
                    filterTree      = comp.FilterTree;
                    normalTree      = comp.Tree;
                    dateButtons     = [comp.fromDate comp.toDate comp.FromLabel comp.ToLabel];
                    GL              = comp.glMixing;
                    GL0             = {20,20,20,20,50,20,20,'1x',0,20,'fit'};
                    GLA             = {20,20,20,20,50,20,20,0,'1x',20,'fit'};
                case comp.ConductivityprofilerTab
                    filterButton    = comp.conductivityToggleDateFiltering;
                    filterTree      = comp.FilterTree_2;
                    normalTree      = comp.Tree_2;
                    dateButtons     = [comp.fromDate_2 comp.toDate_2 comp.FromLabel_2 comp.ToLabel_2];
                    GL              = comp.glConductivity;
                    GL0             = {20,20,'1x',0,20};
                    GLA             = {20,20,0,'1x',20};

            end
            if exitearly
                event = struct('Source',filterButton,'Value',filterButton.Value);
            end
            if event.Source==filterButton
                switch event.Value
                    case true
                        comp.activeTree = filterTree;
                        glrh = GLA;
                    case false
                        comp.activeTree = normalTree;
                        glrh = GL0;
                end
                GL.RowHeight = glrh;
                comp.toggleDateFiltering = event.Value;
                set(dateButtons,'Enable',event.Value)
            end
            if exitearly
                return
            end
            
            comp.internalCommand = true;
            comp.dates = [dateButtons(1).Value dateButtons(2).Value];
            comp.internalCommand = false;
            checkLoggingFiles(comp)
        end

        % Value changed function: mp_end, mp_start, mp_step, mp_t
        function mixingCalibrationCallback(comp, event)
            seeCalibrationProfile(comp)
        end

        % Button pushed function: GeneratefileButton
        function mixingCreateCalibrationFile(comp, event)
            seeCalibrationProfile(comp,true)
        end

        % Callback function: ChangeButton_2, LoggingdirectoryEditField
        function mixingChangeLogPath(comp, event)
            switch event.Source
                case comp.ChangeButton
                    root = pwd;
                    if ~isempty(comp.loggingPath)
                        root = comp.loggingPath;
                    end
                    root = uigetdir(root);
                case comp.LoggingdirectoryEditField
                    root = event.Value;
                    if ~isfolder(root)
                        return
                    end
            end
            if ~root
                return
            end
            comp.loggingPath = root;
        end

        % Callback function: ChangeButton_3, profileDir
        function mixingChangeProfilePath(comp, event)
            switch event.Source
                case comp.ChangeButton_3
                    root = pwd;
                    if ~isempty(comp.profilePath)
                        root = comp.profilePath;
                    end
                    root = uigetdir(root);
                case comp.profileDir
                    root = event.Value;
                    if ~isfolder(root)
                        return
                    end
            end
            if ~root
                return
            end
            comp.profilePath = root;
        end

        % Button pushed function: ChangeButton
        function mixingChangeCalibrationPath(comp, event)
            root = pwd;
            if ~isempty(comp.calibrationPath)
                root = comp.calibrationPath;
            end
            root = uigetdir(root);
            if ~root
                return
            end
            comp.calibrationPath = root;
        end

        % Selection changed function: FilterTree, Tree
        function mixingTreeSelectionCallback(comp, event)
            if isempty(comp.activeTree.CheckedNodes)||~strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                delete(findall(comp.UIAxes_2,'Tag','rholim'))
            end
            selectedNodes = comp.activeTree.SelectedNodes;
            data = readmatrix(selectedNodes.UserData);

            t   = data(:,2)/60;
            RHO = data(:,end);
            MP  = data(:,3);
            RHO(imdilate(abs(gradient(RHO))>1e2,[1 1; 1 1]))=nan;
            RHO(RHO>1050)   = 1050;
            RHO(RHO<960)    = nan;
            rhof            = nan(size(t));

            if contains(selectedNodes.Text,{'FILL','+f'})
                set(comp.PreviewoffButton,'Enable','off','Value',false)
                set(comp.StratificationprofilegeneratorPanel,'Enable','off')
                set(comp.SaveButton,'Enable','on')
                if all(~isnan(RHO))
                    f = polyfit(t,RHO,1);
                    rhof = polyval(f,t);
                end
            elseif contains(selectedNodes.Text,{'+c'})
                set(comp.SaveButton,'Enable','off')
                set(comp.StratificationprofilegeneratorPanel,'Enable','on')
                period = 4*round(mean(pulseperiod(gradient(MP))));
                period(isnan(period))=50;
                rhof = smooth(RHO,period);
            end
            
            linedata = findall(comp.UIAxes_2,'Type','Line','Tag','data');
            ylabel(comp.UIAxes_2,'\rho (kg m^{-3})')
            val = RHO;
            set(findall(comp.UIAxes_2.Parent,'Type','legend'),'Visible','off')
            switch comp.DropDown.Value
                case 'Time'
                    if isempty(linedata)
                        plot(comp.UIAxes_2,t,rhof,'--r',t,RHO,'k','Tag','data')
                    else
                        set(linedata(2),'XData',t,'Ydata',rhof)
                        set(linedata(1),'XData',t,'Ydata',RHO)
                    end
                    xlabel(comp.UIAxes_2,'Time (min)')
                    xlim(comp.UIAxes_2,[0 max(t)])
                case 'Motor Position'
                    if isempty(linedata)
                        plot(comp.UIAxes_2,MP,rhof,'--r',MP,RHO,'k','Tag','data')
                    else
                        set(linedata(2),'XData',MP,'Ydata',rhof)
                        set(linedata(1),'XData',MP,'Ydata',RHO)
                    end
                    xlabel(comp.UIAxes_2,'Motor position')
                    try 
                        xlim(comp.UIAxes_2,[min(MP) max(MP)])
                    catch
                        xlim(comp.UIAxes_2,[0 1])
                    end
                    
                case 'Volume'
                    T   = comp.BucketTime;
                    val = comp.BucketVolume;
                    plot(comp.UIAxes_2,T,val,'Tag','data');
                    xlabel(comp.UIAxes_2,'Time (min)')
                    ylabel(comp.UIAxes_2,'Volume (L)')
                    xlim(comp.UIAxes_2,[0 max(T)])
            end
            if all(isnan(val))
                return
            end
            set(comp.UIAxes_2,'YLim',[min(val,[],'all')*.99 max(val,[],'all')*1.01])

            mp = data(:,3);
            plot(comp.UIAxes,t,mp)
            try
            set(comp.UIAxes,'XLim',[0 max(t)],'YLim',[min(mp) max(mp)])
            end
            if strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                mixingProfileParametersChanged(comp)
            end
        end

        % Callback function: FilterTree, Tree
        function mixingTreeCheckedCallback(comp, event)
            % reject duplicate checks
            event.Source.CheckedNodes = event.CheckedNodes( ...
                ~ismember(event.CheckedNodes,event.PreviousCheckedNodes));
            
            checkedNodes = comp.activeTree.CheckedNodes;

            % reject fill profiles checks
            if ~isempty(checkedNodes)
                if contains(checkedNodes.Text,{'+f','FILL'})
                    comp.activeTree.CheckedNodes = [];
                    return
                end
            end
            comp.DropDown.Items = {'Time','Motor Position'};
            if ~isempty(checkedNodes)
                comp.file2read.Value    = checkedNodes.Text;
                comp.file2read.UserData = checkedNodes.UserData;

                set([ ...
                    comp.DensitysaturationpositionsEditFieldLabel ...
                    comp.maxrhoMPef ...
                    comp.minrhoMPef ...
                    comp.PreviewoffButton],'Enable','on')
                
                if comp.PreviewoffButton.Value
                    comp.DropDown.Items = {'Time','Motor Position','Volume'};
                end

                % update rho limits
                data = readmatrix(checkedNodes.UserData);
                RHO = data(:,end);
                RHO(imdilate(abs(gradient(RHO))>1e2,[1 1; 1 1]))=nan;
                RHO(RHO>1050)=1050;
                RHO(RHO<960)=960;
                comp.Nef.Limits = [0 inf];
                comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2);
                set([comp.rho0ef comp.rhoaef],'Limits',[min(RHO) max(RHO)])
                comp.rho0ef.Value = min(RHO);
                comp.rhoaef.Value = max(RHO);
                set(comp.Nef,'Limits',[0 round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2)])

                mixingProfileParametersChanged(comp)
            else
                
                set([ ...
                    comp.DensitysaturationpositionsEditFieldLabel ...
                    comp.maxrhoMPef ...
                    comp.minrhoMPef ...
                    comp.PreviewoffButton],'Enable','off')
            end

            % if comp.activeTree==comp.FilterTree
            %     comp.Tree.SelectedNodes = comp.activeTree.SelectedNodes;
            %     comp.Tree.CheckedNodes  = comp.activeTree.CheckedNodes;
            % end
        end

        % Value changed function: DropDown, Fef, ITef, Nef, Stef, 
        % ...and 5 other components
        function mixingProfileParametersChanged(comp, event)
            if nargin<2
                event = struct('Source',comp.Nef);
            end
            RHO = [comp.rho0ef.Value comp.rhoaef.Value];
            %set(comp.Nef,'Limits',[0 round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2)])
            n   = comp.Nef.Value;
            Drho=@() round(n^2*min(RHO)/9.81*comp.H,2);
            switch event.Source
                case comp.Nef
                    val = comp.rho0ef.Value+Drho();
                    if val>max(comp.rhoaef.Limits)
                        comp.rhoaef.Value = max(comp.rhoaef.Limits);
                        RHO = [comp.rho0ef.Value comp.rhoaef.Value];
                        comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                    else
                        comp.rhoaef.Value = val;
                    end
                case comp.lockButton
                    switch event.Value
                        case true
                            icon = 'functions/appdesigner/components/resources/icons/icon-fa-lock.png';
                        case false
                            icon = 'functions/appdesigner/components/resources/icons/icon-fa-unlock-alt.png';
                    end
                    comp.lockButton.Icon=icon;
                    return
                case {comp.rhoaef,comp.rho0ef}
                    if ~comp.lockButton.Value
                        comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                    else
                        lims = event.Source.Limits;
                        switch event.Source
                            case comp.rho0ef
                                val     = event.Value+Drho();   % rhoa density
                                comple  = comp.rhoaef;          % rhoa object
                                idx     = [false true];
                            case comp.rhoaef
                                val     = event.Value-Drho();
                                comple  = comp.rho0ef;
                                idx     = [true false];
                        end
                        if within(val,lims)
                            comple.Value = val;
                        else
                            dif = val-lims(idx);
                            % adjust source value
                            adjust = event.Value-dif;
                            event.Source.Value = adjust; %#ok<STRNU>
                            % adjust complement value
                            comple.Value = lims(idx);
                        end
                    end
                
            end
           
            RHO = [comp.rho0ef.Value comp.rhoaef.Value];
            if strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                delete(findall(comp.UIAxes_2,'Tag','rholim'))
                line(comp.UIAxes_2,xlim(comp.UIAxes_2),RHO(1).*[1 1],'LineStyle',':','Color','r','Tag','rholim')
                line(comp.UIAxes_2,xlim(comp.UIAxes_2),RHO(2).*[1 1],'LineStyle',':','Color','r','Tag','rholim')
            end
            
            if comp.PreviewoffButton.Value
                plotPreview(comp)
            end
        end

        % Value changed function: PreviewoffButton
        function mixingPreviewProfile(comp, event)
            value = comp.PreviewoffButton.Value;
            switch value
                case 0
                    comp.DropDown.Items = {'Time','Motor Position'};
                    event.Source.Text = 'Preview (off)';
                    mixingTreeSelectionCallback(comp)
                    
                case 1
                    comp.DropDown.Items = {'Time','Motor Position','Volume'};
                    event.Source.Text = 'Preview (on)';
                    plotPreview(comp)
            end
        end

        % Button pushed function: GeneratefileButton_2
        function mixingGenerateProfileCallback(comp, event)
            plotPreview(comp,true)
            notify(comp, 'ProfileGenerated');
        end

        % Button pushed function: SaveButton
        function mixingProfileLogged(comp, event)
            selectedNodes = comp.activeTree.SelectedNodes;
            data = readmatrix(selectedNodes.UserData);

            t   = data(:,2)/60;
            RHO = data(:,end);
            MP  = data(:,3);
            RHO(imdilate(abs(gradient(RHO))>1e2,[1 1; 1 1]))=nan;
            RHO(RHO>1050)   = 1050;
            RHO(RHO<960)    = nan;

            comp.Stratification.time    = duration(minutes(t));
            comp.Stratification.rho     = RHO;
            comp.Stratification.mp      = MP;
            comp.Stratification.file    = selectedNodes.UserData;

            notify(comp, 'ProfileLogged');
        end

        % Callback function: FilterTree_2, Tree_2
        function conductivityTreeCheckedCallback(comp, event)
            checkedNodes = comp.activeTree.CheckedNodes;
            if isempty(checkedNodes)
                set([comp.conductivity_dd2 ...
                    comp.SaveButton_2],'Enable','off')
                return
            end
            set([comp.conductivity_dd2 ...
                comp.SaveButton_2],'Enable','on')
            comp.UIAxes2.UserData = true;
            plotConductivityProfile(comp)
        end

        % Selection changed function: FilterTree_2, Tree_2
        function conductivityTreeSelectedCallback(comp, event)
            selectedNodes = event.Source.SelectedNodes;
            if isempty(selectedNodes)
                 set([comp.conductivity_dd1],'Enable','off')
                return
            end
            set([comp.conductivity_dd1],'Enable','on')

            checkedNodes = comp.activeTree.CheckedNodes;
            comp.UIAxes2.UserData = true;
            if ismember(selectedNodes,checkedNodes)||isempty(checkedNodes)
                if ~isempty(checkedNodes)
                    comp.UIAxes2.UserData = false;
                end
                plotConductivityProfile(comp) 
            end
        end

        % Value changed function: conductivity_dd1, conductivity_dd2
        function conductivityViewChanged(comp, event)
            event.Source.UserData = true;
            plotConductivityProfile(comp)
        end

        % Button pushed function: DeselectallButton, SelectallButton
        function conductivityFileSelectionCallback(comp, event)
            lbl=event.Source.Text;
            if isempty(comp.activeTree.Children)
                return
            end
            switch lbl
                case 'Select all'
                    valid = comp.activeTree.Children;
                    set(comp.conductivity_dd2,Value = 'Lineplot',Enable='on')
                    comp.activeTree.SelectedNodes = comp.activeTree.Children(1);
                case 'Deselect all'
                    set(comp.conductivity_dd2,Value = 'Single',Enable='off')
                    valid = [];
            end
            comp.activeTree.CheckedNodes = valid;
            if ~isempty(valid)
                conductivityTreeCheckedCallback(comp)
            elseif ~isempty(comp.activeTree.SelectedNodes)
                plotConductivityProfile(comp)
            end
        end

        % Button pushed function: SaveButton_2
        function conductivityLogged(comp, event)
            comp.conductivityData = struct('data',comp.condData,'files',{comp.activeTree.CheckedNodes.UserData});
            notify(comp, 'ConductivityProfileLogged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 735 665];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.gl = uigridlayout(comp);
            comp.TabGroup = uitabgroup(comp.gl);
            comp.TabGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @tabChanged, true);
            comp.MixingdeviceTab = uitab(comp.TabGroup);
            comp.MixingdeviceTab.Title = 'Mixing device';
            comp.glMixing = uigridlayout(comp.MixingdeviceTab);
            comp.UIAxes = uiaxes(comp.glMixing);
            xlabel(comp.UIAxes, 'Time (min)')
            ylabel(comp.UIAxes, 'Motor position')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes_2 = uiaxes(comp.glMixing);
            xlabel(comp.UIAxes_2, 'Time (min)')
            ylabel(comp.UIAxes_2, '\rho (kg m^{-3})')
            zlabel(comp.UIAxes_2, 'Z')
            comp.CalibrationdirectoryEditFieldLabel = uilabel(comp.glMixing);
            comp.CalibrationdirectoryEditFieldLabel.Text = 'Calibration directory';
            comp.CalibrationdirectoryEditField = uieditfield(comp.glMixing, 'text');
            comp.Button = uibutton(comp.glMixing, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button.Tooltip = {'Open directory'};
            comp.Button.Text = '';
            comp.ChangeButton = uibutton(comp.glMixing, 'push');
            comp.ChangeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeCalibrationPath, true);
            comp.ChangeButton.Text = 'Change';
            comp.CalibrationdurationminSpinnerLabel = uilabel(comp.glMixing);
            comp.CalibrationdurationminSpinnerLabel.Text = 'Calibration duration (min)';
            comp.mp_t = uispinner(comp.glMixing);
            comp.mp_t.Limits = [0 Inf];
            comp.mp_t.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_t.Value = 10;
            comp.SamplemotorpositionsEditFieldLabel = uilabel(comp.glMixing);
            comp.SamplemotorpositionsEditFieldLabel.Text = 'Sample (motor positions)';
            comp.mp_start = uieditfield(comp.glMixing, 'numeric');
            comp.mp_start.Limits = [0 1];
            comp.mp_start.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_start.Value = 0.2;
            comp.mp_end = uieditfield(comp.glMixing, 'numeric');
            comp.mp_end.Limits = [0 1];
            comp.mp_end.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_end.Value = 0.7;
            comp.mp_step = uieditfield(comp.glMixing, 'numeric');
            comp.mp_step.Limits = [1 Inf];
            comp.mp_step.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_step.Value = Inf;
            comp.GeneratefileButton = uibutton(comp.glMixing, 'push');
            comp.GeneratefileButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingCreateCalibrationFile, true);
            comp.GeneratefileButton.Text = 'Generate file';
            comp.durationstepLabel = uilabel(comp.glMixing);
            comp.durationstepLabel.Text = '';
            comp.LoggingdirectoryEditFieldLabel = uilabel(comp.glMixing);
            comp.LoggingdirectoryEditFieldLabel.Text = 'Logging directory';
            comp.LoggingdirectoryEditField = uieditfield(comp.glMixing, 'text');
            comp.LoggingdirectoryEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeLogPath, true);
            comp.Button_2 = uibutton(comp.glMixing, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button_2.Tooltip = {'Open directory'};
            comp.Button_2.Text = '';
            comp.ChangeButton_2 = uibutton(comp.glMixing, 'push');
            comp.ChangeButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeLogPath, true);
            comp.ChangeButton_2.Text = 'Change';
            comp.FilesfoundLabel = uilabel(comp.glMixing);
            comp.FilesfoundLabel.Text = 'Files found:';
            comp.UsedatefilterButton = uibutton(comp.glMixing, 'state');
            comp.UsedatefilterButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.UsedatefilterButton.Text = 'Use date filter';
            comp.Panel = uipanel(comp.glMixing);
            comp.GridLayout3 = uigridlayout(comp.Panel);
            comp.FromLabel = uilabel(comp.GridLayout3);
            comp.FromLabel.Enable = 'off';
            comp.FromLabel.Text = 'From:';
            comp.fromDate = uidatepicker(comp.GridLayout3);
            comp.fromDate.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.fromDate.Enable = 'off';
            comp.ToLabel = uilabel(comp.GridLayout3);
            comp.ToLabel.Enable = 'off';
            comp.ToLabel.Text = 'To:';
            comp.toDate = uidatepicker(comp.GridLayout3);
            comp.toDate.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.toDate.Enable = 'off';
            comp.Tree = uitree(comp.glMixing, 'checkbox');
            comp.Tree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.Tree.Tag = 'mixingTree';
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeCheckedCallback, true);
            comp.FilterTree = uitree(comp.glMixing, 'checkbox');
            comp.FilterTree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.FilterTree.Tag = 'mixingFilterTree';
            % Assign Checked Nodes
            comp.FilterTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeCheckedCallback, true);
            comp.Panel_2 = uipanel(comp.glMixing);
            comp.GridLayout4 = uigridlayout(comp.Panel_2);
            comp.DropDown = uidropdown(comp.GridLayout4);
            comp.DropDown.Items = {'Time', 'Motor Position'};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.DropDown.Enable = 'off';
            comp.DropDown.Tooltip = {'Set x-axis'};
            comp.DropDown.Value = 'Time';
            comp.DensitysaturationpositionsEditFieldLabel = uilabel(comp.GridLayout4);
            comp.DensitysaturationpositionsEditFieldLabel.Enable = 'off';
            comp.DensitysaturationpositionsEditFieldLabel.Text = 'Density saturation positions';
            comp.minrhoMPef = uieditfield(comp.GridLayout4, 'numeric');
            comp.minrhoMPef.Limits = [0 1];
            comp.minrhoMPef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.minrhoMPef.Enable = 'off';
            comp.minrhoMPef.Tooltip = {'Motor position of minimum ρ'};
            comp.minrhoMPef.Value = 0.7;
            comp.maxrhoMPef = uieditfield(comp.GridLayout4, 'numeric');
            comp.maxrhoMPef.Limits = [0 1];
            comp.maxrhoMPef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.maxrhoMPef.Enable = 'off';
            comp.maxrhoMPef.Tooltip = {'Motor position of maximum ρ'};
            comp.maxrhoMPef.Value = 0.2;
            comp.StratificationprofilegeneratorPanel = uipanel(comp.glMixing);
            comp.StratificationprofilegeneratorPanel.Title = 'Stratification profile generator';
            comp.GridLayout2 = uigridlayout(comp.StratificationprofilegeneratorPanel);
            comp.LoggingdirectoryEditField_2Label = uilabel(comp.GridLayout2);
            comp.LoggingdirectoryEditField_2Label.Text = 'Profile directory';
            comp.profileDir = uieditfield(comp.GridLayout2, 'text');
            comp.profileDir.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeProfilePath, true);
            comp.ChangeButton_3 = uibutton(comp.GridLayout2, 'push');
            comp.ChangeButton_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeProfilePath, true);
            comp.ChangeButton_3.Text = 'Change';
            comp.kgmLabel = uilabel(comp.GridLayout2);
            comp.kgmLabel.Text = 'ρ (kg m⁻³)';
            comp.rho0ef = uieditfield(comp.GridLayout2, 'numeric');
            comp.rho0ef.Limits = [980 Inf];
            comp.rho0ef.ValueDisplayFormat = '%.1f';
            comp.rho0ef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.rho0ef.Value = 998;
            comp.rhoaef = uieditfield(comp.GridLayout2, 'numeric');
            comp.rhoaef.Limits = [980 Inf];
            comp.rhoaef.ValueDisplayFormat = '%.1f';
            comp.rhoaef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.rhoaef.Value = 998;
            comp.NradsLabel = uilabel(comp.GridLayout2);
            comp.NradsLabel.Text = 'N (rad s⁻¹)';
            comp.Nef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Nef.Limits = [0 Inf];
            comp.Nef.ValueDisplayFormat = '%.2f';
            comp.Nef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.lockButton = uibutton(comp.GridLayout2, 'state');
            comp.lockButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.lockButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-fa-unlock-alt.png');
            comp.lockButton.Text = '';
            comp.PreviewoffButton = uibutton(comp.GridLayout2, 'state');
            comp.PreviewoffButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingPreviewProfile, true);
            comp.PreviewoffButton.Text = 'Preview (off)';
            comp.FlowrateLminEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FlowrateLminEditFieldLabel.Text = 'Flow rate (L min⁻¹)';
            comp.Fef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Fef.Limits = [0 Inf];
            comp.Fef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.Fef.Value = 3.2;
            comp.StarttimesEditFieldLabel = uilabel(comp.GridLayout2);
            comp.StarttimesEditFieldLabel.Text = 'Start-time (s)';
            comp.Stef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Stef.Limits = [0 Inf];
            comp.Stef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.IcethicknessmEditFieldLabel = uilabel(comp.GridLayout2);
            comp.IcethicknessmEditFieldLabel.Text = 'Ice thickness (m)';
            comp.ITef = uieditfield(comp.GridLayout2, 'numeric');
            comp.ITef.Limits = [0 Inf];
            comp.ITef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.CalibrationfileEditFieldLabel = uilabel(comp.GridLayout2);
            comp.CalibrationfileEditFieldLabel.Text = 'Calibration file';
            comp.file2read = uieditfield(comp.GridLayout2, 'text');
            comp.GeneratefileButton_2 = uibutton(comp.GridLayout2, 'push');
            comp.GeneratefileButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingGenerateProfileCallback, true);
            comp.GeneratefileButton_2.Text = 'Generate file';
            comp.SaveButton = uibutton(comp.glMixing, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileLogged, true);
            comp.SaveButton.Enable = 'off';
            comp.SaveButton.Text = 'Save';
            comp.ConductivityprofilerTab = uitab(comp.TabGroup);
            comp.ConductivityprofilerTab.Title = 'Conductivity profiler';
            comp.glConductivity = uigridlayout(comp.ConductivityprofilerTab);
            comp.UIAxes2 = uiaxes(comp.glConductivity);
            title(comp.UIAxes2, 'Title')
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.Button_3 = uibutton(comp.glConductivity, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button_3.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button_3.Tooltip = {'Open directory'};
            comp.Button_3.Text = '';
            comp.ChangeButton_4 = uibutton(comp.glConductivity, 'push');
            comp.ChangeButton_4.Text = 'Change';
            comp.ConductivityprofiledirectoryEditFieldLabel = uilabel(comp.glConductivity);
            comp.ConductivityprofiledirectoryEditFieldLabel.Text = 'Conductivity profile directory';
            comp.ConductivityprofiledirectoryEditField = uieditfield(comp.glConductivity, 'text');
            comp.Tree_2 = uitree(comp.glConductivity, 'checkbox');
            comp.Tree_2.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeSelectedCallback, true);
            comp.Tree_2.Tag = 'condTree';
            % Assign Checked Nodes
            comp.Tree_2.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeCheckedCallback, true);
            comp.FilterTree_2 = uitree(comp.glConductivity, 'checkbox');
            comp.FilterTree_2.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeSelectedCallback, true);
            comp.FilterTree_2.Tag = 'condFilterTree';
            % Assign Checked Nodes
            comp.FilterTree_2.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeCheckedCallback, true);
            comp.conductivityToggleDateFiltering = uibutton(comp.glConductivity, 'state');
            comp.conductivityToggleDateFiltering.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.conductivityToggleDateFiltering.Text = 'Use date filter';
            comp.FilesfoundLabel_2 = uilabel(comp.glConductivity);
            comp.FilesfoundLabel_2.Text = 'Files found:';
            comp.Panel_3 = uipanel(comp.glConductivity);
            comp.GridLayout3_2 = uigridlayout(comp.Panel_3);
            comp.FromLabel_2 = uilabel(comp.GridLayout3_2);
            comp.FromLabel_2.Enable = 'off';
            comp.FromLabel_2.Text = 'From:';
            comp.fromDate_2 = uidatepicker(comp.GridLayout3_2);
            comp.fromDate_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.fromDate_2.Enable = 'off';
            comp.ToLabel_2 = uilabel(comp.GridLayout3_2);
            comp.ToLabel_2.Enable = 'off';
            comp.ToLabel_2.Text = 'To:';
            comp.toDate_2 = uidatepicker(comp.GridLayout3_2);
            comp.toDate_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.toDate_2.Enable = 'off';
            comp.GridLayout4_2 = uigridlayout(comp.glConductivity);
            comp.conductivity_dd2 = uidropdown(comp.GridLayout4_2);
            comp.conductivity_dd2.Items = {'Single', 'Lineplot', 'Hovmoller'};
            comp.conductivity_dd2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityViewChanged, true);
            comp.conductivity_dd2.Enable = 'off';
            comp.conductivity_dd2.Tooltip = {''};
            comp.conductivity_dd2.Value = 'Single';
            comp.conductivity_dd1 = uidropdown(comp.GridLayout4_2);
            comp.conductivity_dd1.Items = {'Salinity', 'Temperature'};
            comp.conductivity_dd1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityViewChanged, true);
            comp.conductivity_dd1.Enable = 'off';
            comp.conductivity_dd1.Tooltip = {''};
            comp.conductivity_dd1.Value = 'Salinity';
            comp.GridLayout4_3 = uigridlayout(comp.glConductivity);
            comp.SelectallButton = uibutton(comp.GridLayout4_3, 'push');
            comp.SelectallButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityFileSelectionCallback, true);
            comp.SelectallButton.Text = 'Select all';
            comp.DeselectallButton = uibutton(comp.GridLayout4_3, 'push');
            comp.DeselectallButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityFileSelectionCallback, true);
            comp.DeselectallButton.Text = 'Deselect all';
            comp.SaveButton_2 = uibutton(comp.GridLayout4_3, 'push');
            comp.SaveButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityLogged, true);
            comp.SaveButton_2.Enable = 'off';
            comp.SaveButton_2.Text = 'Save';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end