classdef uiStratificationCheckBox_2022b < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl                              matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MixingdeviceTab                 matlab.ui.container.Tab
        glMixing                        matlab.ui.container.GridLayout
        AdjustmenttimesEditField        matlab.ui.control.NumericEditField
        AdjustmenttimesEditFieldLabel   matlab.ui.control.Label
        ReturntooriginButton            matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        StratificationprofilegeneratorPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TestfileButton                  matlab.ui.control.Button
        rho_avLabel                     matlab.ui.control.Label
        GeneratefileButton_2            matlab.ui.control.Button
        file2read                       matlab.ui.control.EditField
        CalibrationfileEditFieldLabel   matlab.ui.control.Label
        ITef                            matlab.ui.control.NumericEditField
        IcethicknessmEditFieldLabel     matlab.ui.control.Label
        Stef                            matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel        matlab.ui.control.Label
        Fef                             matlab.ui.control.NumericEditField
        FlowrateLminEditFieldLabel      matlab.ui.control.Label
        PreviewoffButton                matlab.ui.control.StateButton
        lockButton                      matlab.ui.control.StateButton
        Nef                             matlab.ui.control.NumericEditField
        NradsLabel                      matlab.ui.control.Label
        rhoaef                          matlab.ui.control.NumericEditField
        rho0ef                          matlab.ui.control.NumericEditField
        kgmLabel                        matlab.ui.control.Label
        ChangeButton_3                  matlab.ui.control.Button
        profileDir                      matlab.ui.control.EditField
        LoggingdirectoryEditField_2Label  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        maxrhoMPef                      matlab.ui.control.NumericEditField
        minrhoMPef                      matlab.ui.control.NumericEditField
        DensitysaturationpositionsEditFieldLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        FilterTree                      matlab.ui.container.CheckBoxTree
        MTree                           matlab.ui.container.CheckBoxTree
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        toDate                          matlab.ui.control.DatePicker
        ToLabel                         matlab.ui.control.Label
        fromDate                        matlab.ui.control.DatePicker
        FromLabel                       matlab.ui.control.Label
        UsedatefilterButton             matlab.ui.control.StateButton
        FilesfoundLabel                 matlab.ui.control.Label
        ChangeButton_2                  matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        LoggingdirectoryEditField       matlab.ui.control.EditField
        LoggingdirectoryEditFieldLabel  matlab.ui.control.Label
        durationstepLabel               matlab.ui.control.Label
        GeneratefileButton              matlab.ui.control.Button
        mp_step                         matlab.ui.control.NumericEditField
        mp_end                          matlab.ui.control.NumericEditField
        mp_start                        matlab.ui.control.NumericEditField
        SamplemotorpositionsEditFieldLabel  matlab.ui.control.Label
        mp_t                            matlab.ui.control.Spinner
        CalibrationdurationminSpinnerLabel  matlab.ui.control.Label
        ChangeButton                    matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CalibrationdirectoryEditField   matlab.ui.control.EditField
        CalibrationdirectoryEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ConductivityprofilerTab         matlab.ui.container.Tab
        glConductivity                  matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        CTree                           uiFileSystemCheckBox
        GridLayout4_3                   matlab.ui.container.GridLayout
        PackageselectionButton          matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        GridLayout4_2                   matlab.ui.container.GridLayout
        conductivity_dd3                matlab.ui.control.DropDown
        Panel_4                         matlab.ui.container.Panel
        conductivity_dd1                matlab.ui.control.DropDown
        conductivity_dd2                matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        toDate_2                        matlab.ui.control.DatePicker
        ToLabel_2                       matlab.ui.control.Label
        fromDate_2                      matlab.ui.control.DatePicker
        FromLabel_2                     matlab.ui.control.Label
        FilesfoundLabel_2               matlab.ui.control.Label
        conductivityToggleDateFiltering  matlab.ui.control.StateButton
        ConductivityprofiledirectoryEditField  matlab.ui.control.EditField
        ConductivityprofiledirectoryEditFieldLabel  matlab.ui.control.Label
        ChangeButton_4                  matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ConductivityProfileLogged
        ProfileGenerated
        ProfileLogged
    end

    properties (Access = private)
        BucketTime double
        BucketVolume double
        FileSystemWatcher System.IO.FileSystemWatcher = System.IO.FileSystemWatcher;
        activeTree
        axLegend
        condData struct
        hasStarted logical = false;
        internalCommand (1,1) logical = false;
        toggleDateFiltering logical = false;
    end
    
    properties (Access = public)
        ActivateFileWatcher (1,1) logical = false;
        ConductivityTree
        FlowRate (1,1) double {mustBeNumeric(FlowRate)} = 3.2;
        H (1,1) double = 1.1; % Height of tank (m)
        IceThickness (1,1) double {mustBeNumeric(IceThickness)} = 0;
        L (1,1) double = 1.5; % Height of tank (m)
        LabelSearch string
        MixingTree
        N (1,1) double {mustBeNumeric(N)} = 0;
        Stratification struct = struct('time',[],'rho',[],'mp',[],'file',[]);
        W (1,1) double = 0.2; % Width of tank
        calibrationPath string {mustBeFolder(calibrationPath)}
        conductivityData struct = struct('data',[],'files',[]);
        conductivityPath string {mustBeFolder(conductivityPath)}
        dates datetime = datetime;
        loggingPath string {mustBeFolder(loggingPath)}
        profilePath string {mustBeFolder(profilePath)}
        rho (1,2) double {mustBeVector(rho), mustBeNumeric(rho)} = [999.8 999.8];
    end
    
    %% SET functions
    methods
        function set.ActivateFileWatcher(comp,val)
            comp.ActivateFileWatcher = val;
            updateFileWatcherState(comp)
        end
        function set.LabelSearch(comp,val)
            comp.LabelSearch = val;
            updateSearch(comp)
        end
        function set.conductivityData(comp,val)
            fields = {'data','files'};
            compare = ismember(fields,fieldnames(val));
            condition =  ~all(compare);
            if all(~compare)
                comp.conductivityData = struct;
                return
            end
            if condition
                missing = join(cellfun(@(x) x,fields(~condition),'UniformOutput',false),' ');
                warning('Missing one of the expected fields in the conductivity stucture\nFields required:\t"data" and "files"\nField missing:\t\t"%s"', ...
                    missing{1})
                return
            end
            comp.conductivityData = val;
            updateData(comp)
        end
        function set.Stratification(comp,val)
            fields = {'time','rho','mp','file'};
            compare = ismember(fields,fieldnames(val));
            condition =  ~all(compare);
            if all(~compare)
                comp.Stratification = struct;
                return
            end
            if condition
                missing = join(cellfun(@(x) x,fields(~condition),'UniformOutput',false),' ');
                warning('Missing one of the expected fields in the conductivity stucture\nFields required:\t"time" and "rho" and "mp"\nField missing:\t\t"%s"', ...
                    missing{1})
                return
            end
            comp.Stratification= val;
            updateData(comp)

        end
        function set.loggingPath(comp,val)
            comp.loggingPath = val;
            updateRoot(comp)
            updateWatcher(comp)
        end
        function set.calibrationPath(comp,val)
            comp.calibrationPath = val;
            updateRoot(comp)
        end
        function set.profilePath(comp,val)
            comp.profilePath = val;
            updateRoot(comp)
        end
        function set.rho(comp,val)
            comp.rho = val;
            updateProfile(comp,'rho')
        end
        function set.N(comp,val)
            comp.N = val;
            updateProfile(comp,'N')
        end
        function set.IceThickness(comp,val)
            comp.IceThickness = val;
            updateProfile(comp,'IceThickness')
        end
        function set.FlowRate(comp,val)
            comp.FlowRate = val;
            updateProfile(comp,'FlowRate')
        end
        function set.conductivityPath(comp,val)
            comp.conductivityPath = val;
            updateFileSystem(comp)
            updateRoot(comp)
        end
        function set.dates(comp,val)
            comp.dates = val;
            updateDateFilters(comp)
        end
        %% Call functions
        function node = callConductivityTree(comp)
            comp.conductivity_dd1.Enable = 'on';
            Cc      = comp.CTree.ActiveTree.Children;
            try
                comp.CTree.ActiveTree.CheckedNodes   = Cc(1);
                comp.CTree.ActiveTree.SelectedNodes  = Cc(1);
                node = Cc(1);
            catch
                return
            end
            plotConductivityProfile(comp,true)
        end
        function node = callMixingTree(comp)
            c = comp.FilterTree.Children;
            comp.FilterTree.SelectedNodes = c(contains({c.Text},'+f'));
            mixingTreeSelectionCallback(comp)
            [t,MP,RHO,rhof,MP0] = readMixerData(comp,comp.FilterTree.SelectedNodes.UserData);
            f       = fit(t,RHO,'poly1','Robust','Bisquare');
            rhof    = polyval(coeffvalues(f),t);
            node.t  = t;
            node.mp = MP;
            node.rho = RHO;
            node.f  = f;
        end
        %% SET-additional functions
        function updateFileWatcherState(comp)
            comp.hasStarted = comp.ActivateFileWatcher;
        end
        function updateSearch(comp)
            comp.CTree.LabelSearch = comp.LabelSearch;
        end
        function updateDateFilters(comp)
            if comp.internalCommand
                return
            end
            if numel(comp.dates)==1
                comp.dates = [comp.dates datetime];
                return
            end
            set([comp.fromDate comp.fromDate_2],'Value',min(comp.dates),'Enable','on')
            set([comp.toDate comp.toDate_2],'Value',max(comp.dates),'Enable','on')
            set([comp.ToLabel comp.ToLabel_2 comp.FromLabel comp.FromLabel_2],'Enable','on')
            set([comp.UsedatefilterButton comp.conductivityToggleDateFiltering],'Value',true)
            
            switch comp.TabGroup.SelectedTab
                case comp.MixingdeviceTab
                    src = comp.UsedatefilterButton;
                case comp.ConductivityprofilerTab
                    src = comp.conductivityToggleDateFiltering;
            end
            toggleDateFilter(comp,struct('Source',src,'Value',true))
        end
        function updateData(comp)
            if comp.internalCommand
                return
            end
            if ~isempty(fieldnames(comp.conductivityData))
                comp.condData = comp.conductivityData.data;
                try
                    comp.activeTree.CheckedNodes = comp.activeTree.Children(ismember({comp.activeTree.Children.UserData},comp.condData.files));
                    comp.activeTree.SelectedNodes = comp.activeTree.CheckedNodes(1);
                    conductivityTreeCheckedCallback(comp)
                    return
                catch
                    comp.activeTree.CheckedNodes = [];
                end
                comp.activeTree.CheckedNodes = [];
            end
            if ~isempty(fieldnames(comp.Stratification))
                % attempt to find and select fill profile file
                try
                    idx = ismember({comp.activeTree.Children.UserData},comp.Stratification.file);
                    comp.activeTree.SelectedNodes = comp.activeTree.Children(idx);
                    mixingTreeSelectionCallback(comp)
                    return
                catch 
                    comp.activeTree.SelectedNodes = [];
                end
                comp.activeTree.SelectedNodes = [];
            end
        end
        function updateRoot(comp)
            comp.CalibrationdirectoryEditField.Value    = comp.folderOnPath(comp.calibrationPath);
            comp.LoggingdirectoryEditField.Value        = comp.folderOnPath(comp.loggingPath);
            comp.profileDir.Value                       = comp.folderOnPath(comp.profilePath);
            comp.ConductivityprofiledirectoryEditField.Value = comp.folderOnPath(comp.conductivityPath);
        end
        function updateFileSystem(comp)
            comp.CTree.root = comp.conductivityPath;
        end
    end
    methods (Access = private)
        function updateProfile(comp,type)
            %% Profile limits + plotting
            set(comp.Nef,'Limits',[0 inf])
            set([comp.rho0ef comp.rhoaef],'Limits',[980 1050])
            switch type
                case 'rho'
                    comp.rho0ef.Value = comp.rho(1);
                    comp.rhoaef.Value = comp.rho(2);
                    RHO = [comp.rho0ef.Value comp.rhoaef.Value];                    
                    comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                case 'N'
                    
                    comp.Nef.Value = comp.N;
                    RHO = [comp.rho0ef.Value comp.rhoaef.Value];
                    n   = comp.Nef.Value;
                    comp.rhoaef.Value = round(comp.rho0ef.Value+n^2*min(RHO)/9.81*comp.H,2);
                case 'FlowRate'
                    comp.Fef.Value = comp.FlowRate;
                case 'IceThickness'
                    comp.ITef.Value = comp.IceThickness;
            end
            if comp.PreviewoffButton.Value
                plotPreview(comp)
            end
            
        end
        function updateWatcher(comp)
            comp.FileSystemWatcher.Path = comp.loggingPath;
            checkLoggingFiles(comp)
        end
        
        function root = folderOnPath(~,root)
            if isempty(root)
                return
            end
            label = split(root,'\');
            onPWD = ismember(split(pwd,'\'),label);
            if all(onPWD)
                root = join(label(~ismember(label,split(pwd,'\'))),'\');
            end
        end
        
    end
    %% PUBLIC functions
    methods (Access = public)
        %% Mixing Tab
        function [t,MP,RHO,rhof,MP0] = readMixerData(comp,filename,keepReturnToOrigin)
            if nargin<3
                keepReturnToOrigin = false;
            end
            data = readmatrix(filename);
            t   = data(:,2)/60;
            RHO = fixRHO(data(:,end));
            MP  = data(:,3);
            rhof= nan(size(t));
            
            % assume a 8s delay between mp and rho
            adjust  = comp.AdjustmenttimesEditField.Value;
            MP0     = MP;
            try
                MP      = MP+diff(MP([adjust+1 1]));
            end

            if ~keepReturnToOrigin
                return
            end
            if diff(MP([1 end]))~=0
                return
            end
            if MP(1)-max(MP)<1e-3
                [~,idx]=min(MP);
            elseif MP(1)-min(MP)<1e-3
                [~,idx]=max(MP);
            end
            MP(idx:end)     = [];
            t(idx:end)      = [];
            RHO(idx:end)    = [];
            rhof(idx:end)   = [];
            MP0(idx:end)    = [];


            function RHO = fixRHO(RHO)
                RHO(imdilate(abs(gradient(RHO))>1e2,[1 1; 1 1]))=nan;
                RHO(RHO>1100)=1100;
                RHO(RHO<960)=960;
            end

            function discreteMotorPositionDensity(mp,rho,t)
                pp = pulseperiod(gradient(mp));
                if isempty(pp)
                    return
                end
                %%
                valid = within(t,[2 9]);
                [~,~,~,sat_time]=pulseresponse( ...
                    gradient(mp(valid)), ...
                    60*t(valid), ...
                    rho(valid));

            end
        end
        function seeCalibrationProfile(comp,export)
           %% Calibration profile plotting + export
            if nargin<2
                export = false;
            end
            calibrationrange = [comp.mp_start.Value comp.mp_end.Value];
            reversed = false;
            if diff(calibrationrange)<0
                calibrationrange = sort(calibrationrange);
                reversed = true;
            end
            if ~diff(calibrationrange)
                return
            end
            steps = comp.mp_step.Value;
            if steps>1e2
                comp.mp_step.Value = Inf;
            end
            t       = 0:comp.mp_t.Value*60;
            if ~max(t)
                return
            end
            comp.durationstepLabel.Text = '';
            if isinf(steps)
                valve_time   = linspace(calibrationrange(1),calibrationrange(2),length(t));
            else
                valve   = linspace(calibrationrange(1),calibrationrange(2),steps);
                times   = 1:round(length(t)/length(valve)):max(t);
                valve_time = ones(1,length(t))*max(valve);
                for ii=1:length(times)-1
                    valve_time(times(ii):times(ii+1)) = valve(ii);
                end
                comp.durationstepLabel.Text = sprintf('Each step is %is',round(length(t)/length(valve)));
            end

            if reversed
                valve_time = fliplr(valve_time);
            end

            r2origin = '';
            if comp.ReturntooriginButton.Value
                % add three steps (25s each) back to starting value
                duration    = 25;
                direction   = 'descend';
                if diff(valve_time([1 end]))<0
                    direction = 'ascend';
                end
                valve_time(end+1)=valve_time(1);
                % vt_append = sort(reshape(repmat(interp1(1:2,valve_time([1 end]), ...
                %     linspace(1,2,4))',[1 duration]),1,[]),direction);
                % valve_time = cat(2,valve_time,vt_append);
                t = 1:numel(valve_time);
                r2origin = '_reset2origin';
            end

            plot(comp.UIAxes,t/60,valve_time);
            set(comp.UIAxes,'YLim',calibrationrange,'XLim',[0 max(t)/60])

            if ~export||~isfolder(comp.calibrationPath)
                return
            end
            filename = fullfile(comp.calibrationPath, ...
                sprintf('cal_%g-%g_%ipts_%gmin%s.txt', ...
                valve_time(1),valve_time(end),steps,round(max(t)/60),r2origin));
            writetable(table(valve_time'), ...
                filename, ...
                'WriteVariableNames',false)

        end
    end
    %% PRIVATE functions
    methods (Access = private)
        %% FileWactcher
        function setupWatcher(comp)
            %% File system watcher initiation
            warning off
            comp.FileSystemWatcher.Filter = '*.csv';
            comp.FileSystemWatcher.EnableRaisingEvents = true;
            ValidEvents = events(comp.FileSystemWatcher);
            cellfun(@(event) addlistener(comp.FileSystemWatcher,event,@fsEvent),ValidEvents);
            warning on
            function fsEvent(~,event)
                warning off
                if ~isfolder(comp.loggingPath)
                    return
                end
                warning on
                if comp.hasStarted
                    checkLoggingFiles(comp,event)
                end
            end
        end
        function checkLoggingFiles(comp,event)
            %% Files in logging directory + treenode creation/handling
            % gather files
            activeTab = comp.TabGroup.SelectedTab;
            mtab = comp.MixingdeviceTab;
            ctab = comp.ConductivityprofilerTab;
            switch activeTab
                case mtab
                    ActiveTabTree       = comp.MTree;
                    ActiveTabFilterTree = comp.FilterTree;
                case ctab
                    ActiveTabTree       = comp.CTree.Tree;
                    ActiveTabFilterTree = comp.CTree.HiddenTree;
            end
            
            files = dir(fullfile( ...
                string(comp.FileSystemWatcher.Path), ...
                string(comp.FileSystemWatcher.Filter)));

            % check dates within specified range
            date = datetime({files.date});

            comp.DropDown.Enable='off';
            filenames = fullfile(files(1).folder,{files.name})';

            nodes = ActiveTabTree.Children;
            buildNode = @(files) arrayfun(@(file) uitreenode( ...
                "Text",file.name, ...
                "UserData",fullfile(file.folder,file.name),...
                'Parent',ActiveTabTree),files);

            if nargin<2
                event = [];
            end


            %% Node creation/update
            if ~isempty(event)&&activeTab==mtab
                % File Watcher Updates
                buildTree = false;
                if islogical(event)
                    buildTree = true;
                end
                if isempty(nodes)&&buildTree
                    buildNode(files);
                    sortNodes(comp)
                elseif buildTree
                else
                    [~,idxa,idx]=setxor({nodes.UserData}',filenames);
                    switch char(event.ChangeType)
                        case 'Renamed'
                            arrayfun(@(node,file) set(node, ...
                                'Text',file.name, ...
                                'UserData',fullfile(file.folder,file.name)), ...
                                nodes(idx),files(idx))
                        case 'Created'
                            buildNode(files(idx));
                        case 'Deleted'
                            delete(nodes(idxa))
                        case 'Changed'
                            idx = ismember({comp.activeTree.Children.Text},char(event.Name));
                            if all(~idx)
                                return
                            end
                            isSelected = ActiveTabTree.SelectedNodes==nodes(idx);
                            % isChecked  = ActiveTabTree.CheckedNodes==nodes(idx);
                            if isSelected %&&~isChecked
                                mixingTreeSelectionCallback(comp)
                            end
                    end
                end
                if ~isempty(ActiveTabTree.Children)
                    comp.DropDown.Enable='on';
                end
            end

            %% Node filtering
            if comp.toggleDateFiltering
                switch activeTab
                    case mtab
                        %% Mixing Tab
                        if contains(comp.activeTree.Tag,'Filter')
                            toggleDateFilter(comp)
                        end

                        range = [comp.dates datetime('today')];
                        if numel(comp.dates)>1
                            range = comp.dates;
                        end
                        range = range+day([0 1])-seconds([0 1]); %#ok<*TNDAY>
                        files = files(within(date,range));
                        ftreenodes = ActiveTabFilterTree.Children;
                        valid = false(size(ActiveTabTree.Children));
                        if ~isempty(ftreenodes)
                            valid = ismember({ActiveTabTree.Children.UserData},{ftreenodes.UserData});
                        end
                        copyobj(ActiveTabTree.Children(~valid),ActiveTabFilterTree)
                        if isempty(ActiveTabFilterTree.Children)
                            return
                        end
                        valid = (~ismember({ActiveTabFilterTree.Children.Text},{files.name}));
                        delete(ActiveTabFilterTree.Children(valid))
                        sortNodes(comp)
                    case ctab
                        %% Conductivity Tab
                        comp.CTree.DateFilters = [comp.fromDate_2.Value comp.toDate_2.Value];
                end
            end            
            %% Nested functions
            function sortNodes(comp)
                if isempty(comp.activeTree.Children)
                    return
                end
                [~,map]=sort({comp.activeTree.Children.Text});
                comp.activeTree.Children=comp.activeTree.Children(map);
            end
        end
        %% Mixing Tab
        function [valve_time,rho_set,f]=generateProfile(comp)
            %% Mixing profile creation function
            if isempty(comp.file2read.Value)||~isfile(comp.file2read.UserData)
                return
            end
            rho0     = [comp.rho0ef.Value comp.rhoaef.Value];
            if ~diff(rho0);return;end
            [~,MP,RHO_MP] = readMixerData(comp,comp.file2read.UserData,true);            
            tmax    = (comp.H*comp.W*(comp.L-comp.ITef.Value))*1e3/(comp.Fef.Value*60);
            mixing  = linspace(0,1,tmax*60^2);
            t       = 1:tmax*60^2;

            [valve_time,rho_set,f] = getMotorPosition(mixing,t);
            
            function [valve_time,rho_set,f] = getMotorPosition(mixing,t)

                % density smoothing (calibration step "pulse" time)
                period = 4*round(mean(pulseperiod(gradient(MP))));
                period(isnan(period))=50;
                RHO_MP = smooth(RHO_MP,period);

                % resize and rescaling of average valve behaviour
                v       = imresize(RHO_MP,[length(t) 1]);
                v_rho   = v;
                if diff(MP([1 end]))<0
                    MP      = flipud(MP);
                    RHO_MP  = flipud(RHO_MP);
                end
                v_pos   = linspace(min(MP),max(MP),length(t));
                                                
                % find valve positions at density 
                rho_set = fliplr(min(rho0)*mixing+(1-mixing)*max(rho0));
                valve_time=arrayfun(@(val) find(v_rho>val,1,'first'),rho_set,'UniformOutput',false);
                valve_time(cellfun(@isempty,valve_time))={nan};
                valve_time=cell2mat(valve_time);
                valve_time=fillmissing(valve_time,'previous');
                if diff(MP([1 end]))>0
                    v_pos = fliplr(v_pos);
                end
                valve_time=v_pos(valve_time);

                % bucket volume consumption
                satrhoMP    = [comp.minrhoMPef.Value comp.maxrhoMPef.Value];
                % rescale rho motor position to saturation limits
                f           = rescale(v_rho,min(satrhoMP),max(satrhoMP));
                % find logical indicies of desired density limits
                valid       = within(v_rho,[comp.rho0ef.Value comp.rhoaef.Value]);
                % get mixing ratio of new limits
                f           = f(valid);
                % rescale for time array (seconds)
                f           = imresize(f,[numel(t) 1]);                
                % calculate consumed volume of each bucket from mixing ratio
                comp.BucketVolume   = [cumsum(1-f) cumsum(f)]*comp.Fef.Value/60;
                comp.BucketTime     = t/60;
            end

        end
        function plotMixingProfile(comp,selectedNodes)
            [t,MP,RHO,rhof,MP0] = readMixerData(comp,selectedNodes.UserData);
            ms_file = selectedNodes.UserData;
            assignin('base','ms_file',ms_file)
            assignin('base','RHO',RHO)
            assignin('base','t',t)

            newplot = false;
            if comp.AdjustmenttimesEditField.Value
                newplot = true;
            end
            adjustmentButtons = [comp.AdjustmenttimesEditField ...
                    comp.AdjustmenttimesEditFieldLabel];
            profileButtons = [comp.StratificationprofilegeneratorPanel adjustmentButtons];
            if contains(selectedNodes.Text,{'FILL','+f'})
                set(comp.PreviewoffButton,'Enable','off','Value',false)
                set(profileButtons,'Enable','off')
                set(comp.SaveButton,'Enable','on')
                t(isnan(RHO))=[];
                RHO(isnan(RHO))=[];
                if all(~isnan(RHO))
                    f   = fit(t,RHO,'poly1','Robust','Bisquare');
                    rhof = polyval(coeffvalues(f),t);
                end
            elseif contains(selectedNodes.Text,{'+c'})
                set(comp.SaveButton,'Enable','off')
                set(profileButtons,'Enable','on')
                period = 4*round(mean(pulseperiod(gradient(MP))));
                period(isnan(period))=50;
                rhof = smooth(RHO,period);
            end

            % plotting
            linedata = findall(comp.UIAxes_2,'Type','Line','Tag','data');
            ylabel(comp.UIAxes_2,'\rho (kg m^{-3})')
            val = RHO;
            RHO = movmean(RHO,50);
            set(findall(comp.UIAxes_2.Parent,'Type','legend'),'Visible','off')
            switch comp.DropDown.Value
                case 'Time'
                    if isempty(linedata)||newplot
                        plot(comp.UIAxes_2, ...
                            t,rhof,'--r', ...
                            t,RHO,'k', ...%MP0,RHO,':k',...
                            'Tag','data')
                    else
                        set(linedata(2),'XData',t,'Ydata',rhof,'LineStyle','--','Color','r')
                        set(linedata(1),'XData',t,'Ydata',RHO,'LineStyle','-','Color','k')
                    end
                    xlabel(comp.UIAxes_2,'Time (min)')
                    xlim(comp.UIAxes_2,[0 max(t)])
                case 'Motor Position'
                    if isempty(linedata)||newplot
                        plot(comp.UIAxes_2, ...
                            MP,rhof,'--r', ...
                            MP,RHO,'k', ...
                            MP0,RHO,':k',...
                            'Tag','data')
                    else
                        set(linedata(2),'XData',MP,'Ydata',rhof)
                        set(linedata(1),'XData',MP,'Ydata',RHO)
                    end
                    xlabel(comp.UIAxes_2,'Motor position')
                    try
                        xlim(comp.UIAxes_2,[.1 .7])
                        % xlim(comp.UIAxes_2,[min(MP) max(MP)])
                    catch
                        xlim(comp.UIAxes_2,[.1 .7])
                    end

                case 'Volume'
                    T   = comp.BucketTime;
                    val = comp.BucketVolume;
                    plot(comp.UIAxes_2,T,val,'Tag','data');
                    xlabel(comp.UIAxes_2,'Time (min)')
                    ylabel(comp.UIAxes_2,'Volume (L)')
                    xlim(comp.UIAxes_2,[0 max(T)])
            end
            if all(isnan(val))
                return
            end

            YLIM = [min(val,[],'all')*.99 max(val,[],'all')*1.01];
            try
                YLIM = mean(val,'all','omitnan')+2*std(val,[],'all','omitnan').*[-1 1];
            catch ME
                assignin('base','ME',ME)
            end

            set(comp.UIAxes_2,'YLim',YLIM)
            plot(comp.UIAxes,t,MP)
            try
                set(comp.UIAxes,'XLim',[0 max(t)],'YLim',[min(MP) max(MP)])
            end
            if strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                mixingProfileParametersChanged(comp)
            end
        end
        function plotPreview(comp,export,testfile)
            %% Mixing profile plotting + export
            if nargin<2
                export = false;
            end
            if nargin<3
                testfile = false;
            end
            [t,RHO]=generateProfile(comp);
            
            tvec = (0:numel(t)-1)/60;
            idx = find(tvec*60>=comp.Stef.Value,1,'first');
            plot(comp.UIAxes,tvec,t,tvec(idx),t(idx),'ob')
            leg = findall(comp.UIAxes_2.Parent,'Type','legend');
            try leg.Visible='off';end
            
            switch comp.DropDown.Value
                case 'Time'
                    plot(comp.UIAxes_2,tvec,RHO,tvec(idx),RHO(idx),'ob')
                    set(comp.UIAxes_2,'XLim',[0 inf])
                case 'Volume'
                    tvec = comp.BucketTime;
                    idx = find(tvec*60>=comp.Stef.Value,1,'first');
                    val = comp.BucketVolume;
                    plot(comp.UIAxes_2,tvec,val,tvec(idx),val(idx,:),'ob')
                    
                    ylim(comp.UIAxes_2,[0 inf])
                    lim = round(max(val),1);
                    leg=legend(comp.UIAxes_2, ...
                        {sprintf('Fresh (%gL)',lim(1)),sprintf('Salty (%gL)',lim(2))}, ...
                        'Location','northwest','Tag','leg');
                    leg.Visible = 'on';
                    set(comp.UIAxes_2,'XLim',[0 inf])
            end
            set(comp.UIAxes,'YLim',[-inf inf],'XLim',[0 inf])
   
            if ~export||~isfolder(comp.profilePath)
                return
            end


            tstart = comp.Stef.Value;
            theta = 0;
            F = comp.Fef.Value;
            if tstart>1
                time_str = sprintf('t0-tstart_');
            else
                time_str = '';
            end

            filename = fullfile(comp.calibrationPath, ...
                sprintf('SET-%s_%stheta-%i_rho-%ito%i_F-%sLpMIN_rho',...
                'motor',time_str,...
                round(theta),...\
                round(min(RHO)),round(max(RHO)),...
                strrep(num2str(F),'.',"'")));
            

            if testfile
                t = reshape(repmat(arrayfun(@(tf) t(find(t<=tf,1,'first')), ...
                    linspace(min(t),max(t),20)), ...
                    [25 1]),1,[]);
                filename = sprintf('%s_test',filename);
            end

            writetable(table(t'), ...
                filename, ...
                'WriteVariableNames',false)
        end
        %% Conductivity Tab
        function data = updateConductivityData(comp,filename,isIncomplete)
            %% Reads .txt conductivity profiler data and updates "comp.condData"
            if nargin<3
                isIncomplete = false;
            end
            comp.CTree.Notification = 'Processing';
            data    = cellfun(@readFile,filename,UniformOutput=false);
            d0      = data;
            assignin('base','d',data)
            if isIncomplete&&~isempty(comp.condData)
                % update data on incomplete file (assuming SystemFileWatcher detects changes to file)
                [maxlength,filecount] = size(comp.condData.S);
                newMaxLength    = max(cellfun(@ (d) size(d,1),data))>maxlength;
                isOneFile       = filecount==1;
                if newMaxLength&&~isOneFile
                    % need to reinterpolate entire dataset...
                    maxlength               = max(cellfun(@ (d) size(d,1),data));
                    comp.condData.S         = interpolater(comp,comp.condData.S,maxlength,true);
                    comp.condData.T         = interpolater(comp,comp.condData.T,maxlength,true);
                    comp.condData.z         = interpolater(comp,comp.condData.z,maxlength);
                    comp.condData.t         = interpolater(comp,comp.condData.t,maxlength);
                    comp.condData.TdegC     = interpolater(comp,comp.condData.TdegC,maxlength);
                    comp.condData.Spsu      = interpolater(comp,comp.condData.Spsu,maxlength);
                    comp.condData.Sstate    = interpolater(comp,comp.condData.Sstate,maxlength);
                else
                    data = comp.unpackData(data,filename,maxlength);
                end
                if isempty(data)
                    return
                end
                % get valid filename logical index
                valid = ismember(comp.condData.files,filename);
                if isOneFile
                    d0 = d0{:};
                    comp.condData.S = d0.s;
                    comp.condData.T = d0.t;
                    comp.condData.z = d0.pos;
                    comp.condData.t = d0.time;
                    comp.condData.TdegC  = c0.T;
                    comp.condData.Spsu   = c0.S;
                    comp.condData.Sstate = c0.Sstate;
                else
                    comp.condData.S(:,valid) = d0.S;
                    comp.condData.T(:,valid) = d0.T;
                    comp.condData.z(:,valid) = d0.z;
                    comp.condData.t(:,valid) = d0.t;
                    comp.condData.TdegC(:,valid)  = c0.T;
                    comp.condData.Spsu(:,valid)   = c0.S;
                    comp.condData.Sstate(:,valid) = c0.Sstate;
                end
                return
            else
                data = comp.unpackData(data,filename);
            end

            if isempty(data)
                return
            end

            comp.UIAxes2.UserData   = false;
            comp.condData           = data;
            function data = readFile(filename)
                formatting  = {'time','pos','s','t'};
                try
                    file        = readmatrix(filename);
                catch
                    data = {};
                    return
                end
                try
                    data        = array2table(file(:,1:4),'VariableNames',formatting);
                catch
                    data = {};
                end
            end
        end
        function data = unpackData(comp,data,filename,maxlength)
            %% Unpacks data into a matrix of size {n,maxlength} where maxlength is the longest data vector
            if nargin<4
                maxlength = [];
            end
            s = cell([1 numel(data)]);
            z = s;
            t = s;
            T = s;

            for i=1:numel(data)
                try 
                    tmp  = data{i};
                catch
                    continue
                end
                if isempty(tmp)
                    continue
                end
                s{i} = tmp.s;
                z{i} = tmp.pos;
                t{i} = tmp.t;
                T{i} = tmp.time;

                comp.CTree.Notification = sprintf('Processing data (%.0f%s)',i/numel(data)*1e2,'%');
                pause(1e-5)
            end
            comp.CTree.resetNotification;

            % Interpolate to same length
            s = interpolater(comp,s,maxlength,true);
            t = interpolater(comp,t,maxlength,true);
            T = interpolater(comp,T,maxlength);
            z = interpolater(comp,z,maxlength);

            % attempt to convert
            data = struct('S',s, ...
                'T',t, ...
                'z',z, ...
                't',T, ...
                'files',{filename});

            [S,T,Sstate] = conductivityConvert(data,comp.CTree);
            data.TdegC  = T;
            data.Spsu   = S;
            data.Sstate = Sstate;
            comp.CTree.resetNotification;

        end
        function out = interpolater(~,in,maxlength,padinf)
            %% Interpolates data [cell,double {n,{m,1}}||matrix,double {n,m}] into matrix data {maxlength,n}
            if nargin<4
                padinf = false;
            end
            if isa(in,'double')
                out = cell2mat(arrayfun(@(idx) interp1(1:numel(in(:,idx)),in(:,idx),1:maxlength)',1:size(in,2),'UniformOutput',false));
                return
            end
            if nargin<3
                maxlength = max(cell2mat(cellfun(@numel,in,'UniformOutput',false)));
            end
            if isempty(maxlength)
                maxlength = max(cell2mat(cellfun(@numel,in,'UniformOutput',false)));
            end
            if padinf
                in = cellfun(@(x) removeNans(x),in','UniformOutput',false)';
            end
            out = cellfun(@(x) interp1(1:numel(x),x,1:maxlength),in', ...
                'UniformOutput',false,'ErrorHandler',@(~,~) inf(1,maxlength));
            out = cell2mat(out)';

            if padinf
                nanmap = isnan(out);
                infmap = isinf(out);
                out(nanmap) = inf;
                out(infmap) = nan;
            end
            function output = removeNans(input)
                input(isnan(input))=inf;
                output = input;
            end
        end
        function [data,filename,addLine,map,TIME] = loadConductivityData(comp,getData)
            cn = comp.activeTree.CheckedNodes;
            sn = comp.activeTree.SelectedNodes;

            % look for selected/checked files
            switch comp.conductivity_dd2.Value
                case 'Single'
                    filename    = {sn.UserData};
                case {'Lineplot','Hovmoller'}
                    filename    = {cn.UserData};
            end
            filename    = filename(~cellfun(@isempty,filename));
            addLine     = true;
            mfile       = {};
            hasFolder   = any(isfolder(filename));

            % check for multiple checked nodes
            if numel(cn)>1&&strcmp(comp.conductivity_dd2.Value ,'Single')
                comp.conductivity_dd2.Value = 'Lineplot';
            end
            % checked for empty/singular checked nodes
            if isempty(cn)||numel(cn)==1&&~hasFolder
                set(comp.conductivity_dd2,Value = 'Single',Enable='off')
                set(comp.conductivity_dd3,'Enable','off')
            end

            % handle selected/checked folders
            filename0 = filename;
            if hasFolder
                mfile = what(string(filename));
                
                % get children
                files = dir(fullfile(filename{1},'*.txt'));
                if isempty(files)
                    return
                end
                filename = filename(isfolder(filename));
                filename = fullfile(filename,{files.name});
                if getData
                    if numel(files)>1&&strcmp(comp.conductivity_dd2.Value,'Single')
                        comp.conductivity_dd2.Value = 'Lineplot';
                    end
                end
                addLine = false;
            else
                files   = cellfun(@dir,filename);
            end
            
            % check for new/updated data
            data    = [];
            % attempt to load data
            hasSaved = false;
            if hasFolder
                if ~isempty(mfile.mat)
                    hasSaved = true;
                    comp.condData = load(string(fullfile(mfile.path,mfile.mat))).data;
                    comp.UIAxes2.UserData = false;
                end
                % enable buttons
                set([comp.conductivity_dd2 comp.conductivity_dd3],'Enable','on')
            end

            if comp.UIAxes2.UserData
                if getData
                    data = updateConductivityData(comp,filename);
                end
            else
                data = comp.condData;
            end

            if hasFolder&&~hasSaved
                % save folder data
                save(string(fullfile(filename0,'ct_data')),"data")
            end

            % get time of file modification
            tf      = datetime({files.date});
            TIME    = tf-seconds(max(data.t,[],'omitnan')); % subtract profiler final time to get profiler initiation time

            % sorting
            [TIME,map]          = sort(TIME);
            TIME                = (data.t(1,:)+seconds(TIME-TIME(1)))/60^2;
            comp.condData.time  = TIME;
            assignin('base',"data",comp.condData)


            items = {'Salinity','Temperature'};
            if comp.condData.Sstate
                items = {'Salinity','Temperature','Density','N²'};
            end
            comp.conductivity_dd1.Items = items;
        end
        function plotConductivityProfile(comp,getData)
            %% Reads and plots conductivity profiler data
            if nargin<2
                getData = false;
            end

            cn = comp.activeTree.CheckedNodes;
            sn = comp.activeTree.SelectedNodes;

            if all([isempty(cn) isempty(sn)])
                return
            end
            
            [data,filename,addLine,map,TIME] = comp.loadConductivityData(getData);

            if isempty(data)
                return
            end
            
            % basic profile arguments
            vel     = mean(data.z./data.t,'omitnan')*1e-3; %5mm/s
            offset  = 12e-2;                % profiler offset from free-surface
            Z       = data.z*1e-3+offset;   
            

            slabel = 'Salinity (‰)';
            if comp.condData.Sstate
                slabel = 'Salinity (‰)';
            end
            % handle plotting
            
            ydata = Z;
            ylabel(comp.UIAxes2,'z (m)')
            [~,fname] = fileparts(filename);
            if any(contains(fname,'_log'))
                ydata = data.t/60;
                ylabel(comp.UIAxes2,'Time (h)')
            end

            delete(findall(comp.UIAxes2,'Tag','Nav'))
            switch comp.conductivity_dd1.Value
                case 'Salinity'
                    plotType(comp, ...
                        smooth2a(data.Spsu(:,map),10,0), ...
                        ydata,TIME, ...
                        slabel)
                case 'Temperature'
                    plotType(comp, ...
                        data.TdegC(:,map),ydata,TIME, ...
                        'Temperature (°C)')
                case 'Density'
                    try
                        cdata = density(data.Spsu(:,map),data.TdegC(:,map));
                    catch 
                        return
                    end
                    cdata = smooth2a(cdata,10,0);
                    plotType(comp, ...
                        cdata, ...
                        ydata,TIME, ...
                        'Density (kg m⁻³)')
                case 'N²'
                    try
                        cdata = density(data.Spsu(:,map),data.TdegC(:,map));
                    catch
                        return
                    end
                    cdata = smooth2a(cdata,20,0);
                    dzdc  = cell2mat(arrayfun(@(idx) gradient(cdata(:,idx),ydata(:,idx)),1:size(cdata,2),'UniformOutput',false));
                    cdata = 9.81/999.8*dzdc;
                    cdata = smooth2a(cdata,20,0);
                    cdata = fillmissing(cdata,'previous');
                    cdata(isinf(cdata))=nan;
                    %cdata(cdata<0)=nan;
                    %cdata = sqrt(cdata);
                    plotType(comp, ...
                        cdata, ...
                        ydata,TIME, ...
                        'N² (rad s⁻¹)')

                    ax = comp.UIAxes2;
                    lim = [-1 1];
                    if strcmp(comp.conductivity_dd2.Value,'Lineplot')
                        xlim(ax,lim)
                        % add mean N2 value
                        try
                            % selected line
                            tmp = findall(ax,'Tag','front');
                            tmp.XData(isinf(tmp.XData))=nan;
                            line(ax,mean(tmp.XData,"omitnan")*[1 1],ylim(ax),...
                                'Tag','Nav','Color','r','LineStyle',':', ...
                                'LineWidth',3)
                            % first measurement reference line
                            tmp = cdata(:,1);
                            tmp(isinf(tmp))=nan;
                            line(ax,mean(tmp,"omitnan")*[1 1],ylim(ax),...
                                'Tag','Nav','Color','k','LineStyle',':', ...
                                'LineWidth',3)
                        end
                        return
                    else
                        clim(ax,lim)
                    end
            end
            xlim(comp.UIAxes2,[-inf inf])
           
            function plotType(comp,cdata,xdata,ydata,cstr)
                %% Method for efficient plotting
                ax      = comp.UIAxes2;
                % check for existing plotted data
                imdata  = findall(ax,'Type','surface');
                linedata= findall(ax,'Type','Line','-not','Tag','timeline','-not','Tag','front');
                delete(findall(ax,'Tag','front'))
                cbar    = findall(ax.Parent,'Type','Colorbar');

                % get logical index of tree selection
                selected= (ismember(filename,comp.activeTree.SelectedNodes.UserData));

                switch comp.conductivity_dd2.Value
                    case 'Hovmoller'
                        %% Plot surface data
                        %xdata = mean(xdata,2,'omitnan');
                        cdata = fillmissing(cdata,'previous');
                        cdata(isinf(cdata))=nan;
                        cdata = toggleField(comp,ydata,xdata,cdata);

                        delete(linedata)
                        delete(findall(ax,'Type','Line','Tag','timeline'))
                        if isempty(imdata)
                            %% Create
                            disableDefaultInteractivity(ax)
                            pcolor(ax,ydata,xdata,cdata)
                            shading(ax,'interp')
                            axis(ax,'tight')
                            addlabels(ax,'y','z (m)','x','Time (h)')
                            if addLine
                                line([ydata(selected) ydata(selected)],ylim(ax), ...
                                    'LineStyle','-', ...
                                    'Color','r', ...
                                    'Tag','timeline', ...
                                    'Parent',ax)
                            end
                            if isempty(cbar)
                                addColorbar(ax,'title',cstr,'cmap','thermal')
                            else
                                cbar.Visible = 'on';cbar.Label.String=cstr;
                            end
                        else
                            %% Update
                            set(imdata,CData=cdata,ZData=0*cdata,XData=ydata,YData=xdata)
                            set(ax,XLim=[min(ydata) max(ydata)])
                            try cbar.Visible = 'on';cbar.Label.String=cstr;end
                            try
                                line([ydata(selected) ydata(selected)],ylim(ax), ...
                                'LineStyle','-', ...
                                'Color','r', ...
                                'Tag','timeline', ...
                                'Parent',ax)
                            end
                        end
                        if contains(comp.conductivity_dd3.Value,'Gradient')
                           clim(ax,[-1 1]*2*abs(std(cdata,[],'all','omitnan')))
                           set(ax,'Colormap',cmapCheck('balance'))
                        else
                            clim(ax,[-inf inf])
                            set(ax,'Colormap',parula)
                        end
                    otherwise
                        enableDefaultInteractivity(ax)
                        try cbar.Visible = 'off';end
                        delete(imdata)
                        if isempty(linedata)
                            %% Create
                            plot(ax,cdata,xdata,'k')
                            addlabels(ax,'x',cstr,'y','z (m)')
                            axis(ax,'ij')
                        else
                            %% Update
                            xlabel(ax,cstr)

                            % Handle changes to viewing type
                            switch comp.conductivity_dd2.Value
                                case 'Single'
                                    % delete extra linedata and update
                                    % remaining
                                    if numel(linedata)>1
                                        delete(linedata(2:end))
                                        linedata = linedata(1);
                                    end
                                    try set(linedata,XData=cdata,YData=xdata,Color='k');end
                                case 'Lineplot'
                                    % copy linedata and update or delete
                                    % extra linedata
                                    copies = (size(cdata,2)-numel(linedata));
                                    state = false;
                                    if ~isempty(comp.conductivity_dd1.UserData)
                                        state = ~comp.conductivity_dd1.UserData;
                                    end
                                    if copies==0&&state
                                        % Emphasise active tree selection
                                        selected= ismember(filename,comp.activeTree.SelectedNodes.UserData);
                                        set(linedata(selected),'Color','k','LineWidth',2)
                                        arrayfun(@(ld) set(ld,'Color',ld.UserData,'LineWidth',1),linedata(~selected))
                                        return
                                    elseif copies<0
                                        % Delete extra linedata entries
                                        delete(linedata(end+copies+1:end))
                                    else
                                        % Copy linedata and update
                                        delete(linedata(2:end))
                                        linedata = linedata(1);
                                        copies =  1:size(cdata,2)-1;
                                        arrayfun(@(~)copyobj(linedata(1),ax), ...
                                            copies)
                                    end
                                    % Set linedata
                                    linedata= findall(ax,'Type','Line');
                                    arrayfun(@(idx) set(linedata(idx), ...
                                        XData=cdata(:,idx), ...
                                        Ydata=xdata(:,idx), ...
                                        LineWidth=1), ...
                                        1:numel(linedata))
                                    comp.conductivity_dd1.UserData = true;

                            end
                        end
                        % Assign color to each linedata relative to initiation time
                        linedata= findall(ax,'Type','Line');
                        
                        if numel(linedata)>1
                            cmap = parula;
                            cmap = interp1(1:size(cmap,1), cmap, linspace(1,size(cmap,1),numel(linedata)),'linear');
                            arrayfun(@(idx) set(linedata(idx),'Color',cmap(idx,:),'UserData',cmap(idx,:)),1:numel(linedata))
                            set(linedata(selected),'Color','r','LineWidth',2)
                        end
                        cop = copyobj(linedata(selected),ax);
                        cop.Tag = 'front';
                        cop.LineWidth = 3;
                        
                        
                end
            end
        end
        function cdata = toggleField(comp,xdata,ydata,cdata)
            dx = hours(seconds(mean(diff(xdata))));
            dy = mean(diff(ydata),"all",'omitnan');
            [dtdc,dzdc] = gradient(cdata);
            switch comp.conductivity_dd3.Value
                case 'Field'
                    return
                case 'Gradient (z)'
                    cdata = dzdc*dx;
                case 'Gradient (t)'
                    cdata = dtdc*dy;
            end
            %cdata = smooth2a(cdata,10,10);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            % Deployment
            % if isdeployed
            %     locate_externapp = which(fullfile('LabStratification.exe'));
            % end
            
            % Provide the component with a formatted figure if not called
            % with a parent
            isIndependent = calledWithoutParent(comp,'Stratification designer');
            
            % Set active tree
            comp.MixingTree         = comp.MTree;
            comp.ConductivityTree   = comp.CTree;
            comp.activeTree         = comp.MTree;

            % Assign default paths
            try if isempty(comp.loggingPath);comp.loggingPath           = "labview_logs\raw_data\MV stratifier data";end;end %#ok<*TRYNC> 
            try if isempty(comp.calibrationPath);comp.calibrationPath   = "labview_logs\mixing_setpoints";end;end
            try if isempty(comp.profilePath);comp.profilePath           = "labview_logs\profile";end;end
            try 
                if isempty(comp.conductivityPath)
                    comp.conductivityPath = "labview_logs\raw_data\conductivity_data";
                end
            end
            
            % Create calibration profile
            seeCalibrationProfile(comp)
            % Initiate SystemFileWatcher for "Mixing Tab"
            if isIndependent
                comp.hasStarted = true;
            end
            setupWatcher(comp)
            % Update tree
            checkLoggingFiles(comp,true)
            % Set date filters to today
            set([comp.fromDate comp.toDate comp.fromDate_2 comp.toDate_2],'Value',datetime('today'))
            comp.CTree.HiddenTreeVisible = false;
        end

        % Selection change function: TabGroup
        function tabChanged(comp, event)
            selectedTab = comp.TabGroup.SelectedTab;
            % update filewatcher path
            switch selectedTab
                case comp.MixingdeviceTab
                    comp.FileSystemWatcher.Path     = comp.loggingPath;
                    comp.FileSystemWatcher.Filter   = '*.csv';
            end
            setActiveTree(comp,selectedTab)
            checkLoggingFiles(comp,true)

            function setActiveTree(comp,selectedTab)
                switch selectedTab
                    case comp.MixingdeviceTab
                        ftreec  = comp.FilterTree;
                        ntreec  = comp.MTree;
                        db      = comp.UsedatefilterButton.Value;
                    case comp.ConductivityprofilerTab
                        ftreec  = comp.CTree.HiddenTree;
                        ntreec  = comp.CTree.Tree;
                        db      = comp.conductivityToggleDateFiltering.Value;
                end
                if db
                    comp.activeTree = ftreec;
                else
                    comp.activeTree = ntreec;
                end
            end
        end

        % Button pushed function: Button, Button_2, Button_3
        function openDirectory(comp, event)
            switch event.Source
                case comp.Button
                    root = comp.CalibrationdirectoryEditField.Value;
                case comp.Button_2
                    root = comp.LoggingdirectoryEditField.Value;
                case comp.Button_3
                    root = comp.ConductivityprofiledirectoryEditField.Value;
            end
            winopen(root)
        end

        % Value changed function: UsedatefilterButton, 
        % ...and 5 other components
        function toggleDateFilter(comp, event)
            exitEarly = false;
            if nargin<2
                exitEarly = true;
            end
            switch comp.TabGroup.SelectedTab
                case comp.MixingdeviceTab
                    filterButton    = comp.UsedatefilterButton;
                    filterTree      = comp.FilterTree;
                    normalTree      = comp.MTree;
                    dateButtons     = [comp.fromDate comp.toDate comp.FromLabel comp.ToLabel];
                    GL              = comp.glMixing;
                    GL0             = {20,20,20,20,50,20,20,'1x',0,20,'fit'};
                    GLA             = {20,20,20,20,50,20,20,0,'1x',20,'fit'};
                case comp.ConductivityprofilerTab
                    filterButton    = comp.conductivityToggleDateFiltering;
                    filterTree      = comp.CTree.HiddenTree;
                    normalTree      = comp.CTree.Tree;
                    dateButtons     = [comp.fromDate_2 comp.toDate_2 comp.FromLabel_2 comp.ToLabel_2];
                    GL              = comp.glConductivity;
                    GL0             = {20,20,'1x',20};
                    GLA             = {20,20,'1x',20};

            end

            datevalues =  [dateButtons(1).Value dateButtons(2).Value];

            if exitEarly
                event = struct('Source',filterButton,'Value',filterButton.Value);
            end
            if event.Source==filterButton
                switch event.Value
                    case true
                        comp.activeTree = filterTree;
                        glrh = GLA;
                    case false
                        comp.activeTree = normalTree;
                        glrh = GL0;
                end
                GL.RowHeight = glrh;
                comp.toggleDateFiltering = event.Value;
                if comp.TabGroup.SelectedTab==comp.ConductivityprofilerTab
                    comp.CTree.toggleHiddenTree(event.Value)
                end
                set(dateButtons,'Enable',event.Value)
            end
            if exitEarly
                return
            end
            
            comp.internalCommand = true;
            if datevalues(1)>datevalues(2)
                datevalues(2)           = datevalues(1);
                dateButtons(2).Value    = datevalues(2);
            end
            comp.dates = datevalues;
            comp.internalCommand = false;
            checkLoggingFiles(comp)
        end

        % Value changed function: ReturntooriginButton, mp_end, mp_start, 
        % ...and 2 other components
        function mixingCalibrationCallback(comp, event)
            seeCalibrationProfile(comp)
        end

        % Button pushed function: GeneratefileButton
        function mixingCreateCalibrationFile(comp, event)
            seeCalibrationProfile(comp,true)
        end

        % Callback function: ChangeButton_2, LoggingdirectoryEditField
        function mixingChangeLogPath(comp, event)
            switch event.Source
                case comp.ChangeButton
                    root = pwd;
                    if ~isempty(comp.loggingPath)
                        root = comp.loggingPath;
                    end
                    root = uigetdir(root);
                case comp.LoggingdirectoryEditField
                    root = event.Value;
                    if ~isfolder(root)
                        return
                    end
            end
            if ~root
                return
            end
            comp.loggingPath = root;
        end

        % Callback function: ChangeButton_3, profileDir
        function mixingChangeProfilePath(comp, event)
            switch event.Source
                case comp.ChangeButton_3
                    root = pwd;
                    if ~isempty(comp.profilePath)
                        root = comp.profilePath;
                    end
                    root = uigetdir(root);
                case comp.profileDir
                    root = event.Value;
                    if ~isfolder(root)
                        return
                    end
            end
            if ~root
                return
            end
            comp.profilePath = root;
        end

        % Button pushed function: ChangeButton
        function mixingChangeCalibrationPath(comp, event)
            root = pwd;
            if ~isempty(comp.calibrationPath)
                root = comp.calibrationPath;
            end
            root = uigetdir(root);
            if ~root
                return
            end
            comp.calibrationPath = root;
        end

        % Callback function: AdjustmenttimesEditField, DropDown,
        % FilterTree, 
        % ...and 1 other component
        function mixingTreeSelectionCallback(comp, event)
            if isempty(comp.activeTree.CheckedNodes)||~strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                delete(findall(comp.UIAxes_2,'Tag','rholim'))
            end
            selectedNodes = comp.activeTree.SelectedNodes;

            adjustmentButtons = [comp.AdjustmenttimesEditField ...
                    comp.AdjustmenttimesEditFieldLabel];
            if isempty(selectedNodes)
                set([comp.DropDown adjustmentButtons],'Enable','off')
            else
                set([comp.DropDown adjustmentButtons],'Enable','on')
            end

            plotMixingProfile(comp,selectedNodes)
        end

        % Callback function: FilterTree, MTree
        function mixingTreeCheckedCallback(comp, event)
            % reject duplicate checks
            event.Source.CheckedNodes = event.CheckedNodes( ...
                    ~ismember(event.CheckedNodes,event.PreviousCheckedNodes));
            checkedNodes = comp.activeTree.CheckedNodes;

            ax = comp.UIAxes_2;
            % reject fill profiles checks
            if ~isempty(checkedNodes)
                if contains(checkedNodes.Text,{'+f','FILL'})
                    comp.activeTree.CheckedNodes = [];
                    return
                end
            else
                delete(findall(ax,'Tag','rholim'))
            end

            profileButtons = [ ...
                    comp.DensitysaturationpositionsEditFieldLabel ...
                    comp.maxrhoMPef ...
                    comp.minrhoMPef ...
                    comp.PreviewoffButton];
            
            comp.DropDown.Items = {'Time','Motor Position'};
            if ~isempty(checkedNodes)
                comp.file2read.Value    = checkedNodes.Text;
                comp.file2read.UserData = checkedNodes.UserData;

                set(profileButtons,'Enable','on')
                
                if comp.PreviewoffButton.Value
                    comp.DropDown.Items = {'Time','Motor Position','Volume'};
                end

                % update rho limits
                [~,~,RHO,~] = readMixerData(comp,checkedNodes.UserData);
                
                comp.Nef.Limits = [0 inf];
                comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2);
                set([comp.rho0ef comp.rhoaef],'Limits',[min(RHO) max(RHO)])
                comp.rho0ef.Value = min(RHO);
                comp.rhoaef.Value = max(RHO);
                set(comp.Nef,'Limits',[0 round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2)])

                mixingProfileParametersChanged(comp)
            else
                
                set(profileButtons,'Enable','off')
            end

            % if comp.activeTree==comp.FilterTree
            %     comp.MTree.SelectedNodes = comp.activeTree.SelectedNodes;
            %     comp.MTree.CheckedNodes  = comp.activeTree.CheckedNodes;
            % end
        end

        % Value changed function: Fef, ITef, Nef, Stef, lockButton, 
        % ...and 4 other components
        function mixingProfileParametersChanged(comp, event)
            if nargin<2
                event = struct('Source',comp.Nef);
            end
            RHO = [comp.rho0ef.Value comp.rhoaef.Value];
            %set(comp.Nef,'Limits',[0 round(real(sqrt(9.81/min(RHO)*diff([min(RHO) max(RHO)])/comp.H)),2)])
            n   = comp.Nef.Value;
            Drho=@() round(n^2*min(RHO)/9.81*comp.H,2);
            switch event.Source
                case comp.Nef
                    val = comp.rho0ef.Value+Drho();
                    if val>max(comp.rhoaef.Limits)
                        comp.rhoaef.Value = max(comp.rhoaef.Limits);
                        RHO = [comp.rho0ef.Value comp.rhoaef.Value];
                        comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                    else
                        comp.rhoaef.Value = val;
                    end
                case comp.lockButton
                    switch event.Value
                        case true
                            icon = 'functions/appdesigner/components/resources/icons/icon-fa-lock.png';
                        case false
                            icon = 'functions/appdesigner/components/resources/icons/icon-fa-unlock-alt.png';
                    end
                    comp.lockButton.Icon=icon;
                    return
                case {comp.rhoaef,comp.rho0ef}
                    if ~comp.lockButton.Value
                        comp.Nef.Value = round(real(sqrt(9.81/min(RHO)*diff(RHO)/comp.H)),2);
                    else
                        lims = event.Source.Limits;
                        switch event.Source
                            case comp.rho0ef
                                val     = event.Value+Drho();   % rhoa density
                                comple  = comp.rhoaef;          % rhoa object
                                idx     = [false true];
                            case comp.rhoaef
                                val     = event.Value-Drho();
                                comple  = comp.rho0ef;
                                idx     = [true false];
                        end
                        if within(val,lims)
                            comple.Value = val;
                        else
                            dif = val-lims(idx);
                            % adjust source value
                            adjust = event.Value-dif;
                            event.Source.Value = adjust; %#ok<STRNU>
                            % adjust complement value
                            comple.Value = lims(idx);
                        end
                    end
                
            end
           
            RHO = [comp.rho0ef.Value comp.rhoaef.Value];
            comp.rho_avLabel.Text = sprintf('(%.1f)',mean(RHO));
            ax = comp.UIAxes_2;
            if strcmp(comp.activeTree.SelectedNodes.Text,comp.file2read.Value)
                varg = {'LineStyle',':','Color','r','PickableParts','none'};
                delete(findall(ax,'Tag','rholim'))
                line(ax,xlim(ax),RHO(1).*[1 1],'Tag','rholim',varg{:})
                line(ax,xlim(ax),RHO(2).*[1 1],'Tag','rholim',varg{:})
                if strcmp(comp.DropDown.Value,'Motor Position')
                    varg = {'LineStyle','-','Color','b','PickableParts','none'};
                    mplim = [comp.minrhoMPef.Value comp.maxrhoMPef.Value];
                    line(ax,mplim(1).*[1 1],ylim(ax),'Tag','rholim',varg{:})
                    line(ax,mplim(2).*[1 1],ylim(ax),'Tag','rholim',varg{:})
                end
                [~,idx]=sort(ax.Children,'descend');
                ax.Children = comp.UIAxes_2.Children(idx);
            end
            
            if comp.PreviewoffButton.Value
                plotPreview(comp)
            end
        end

        % Value changed function: PreviewoffButton
        function mixingPreviewProfile(comp, event)
            value = comp.PreviewoffButton.Value;
            switch value
                case 0
                    comp.DropDown.Items = {'Time','Motor Position'};
                    event.Source.Text = 'Preview (off)';
                    mixingTreeSelectionCallback(comp)
                    
                case 1
                    comp.DropDown.Items = {'Time','Motor Position','Volume'};
                    event.Source.Text = 'Preview (on)';
                    plotPreview(comp)
            end
        end

        % Button pushed function: GeneratefileButton_2, TestfileButton
        function mixingGenerateProfileCallback(comp, event)
            switch event.Source
                case comp.GeneratefileButton_2
                    plotPreview(comp,true)
                case comp.TestfileButton
                    plotPreview(comp,true,true)
            end
            notify(comp, 'ProfileGenerated');
        end

        % Button pushed function: SaveButton
        function mixingProfileLogged(comp, event)
            selectedNodes = comp.activeTree.SelectedNodes;
            [t,MP,RHO] = readMixerData(comp,selectedNodes.UserData);            
            comp.internalCommand = true;
            comp.Stratification.time    = duration(minutes(t));
            comp.Stratification.rho     = RHO;
            comp.Stratification.mp      = MP;
            comp.Stratification.file    = selectedNodes.UserData;
            comp.internalCommand = false;
            notify(comp, 'ProfileLogged');
        end

        % Callback function: CTree
        function conductivityFileWatcherEvent(comp, event)
            try
                lastEvent   = event.Source.LastFileWatcherEvent;
            catch
                return
            end
            if strcmp(string(lastEvent.ChangeType),'Created')
                return
            end
            Snode       = comp.activeTree.SelectedNodes;
            Cnode       = comp.activeTree.CheckedNodes;
            if isempty(Snode)&&isempty(Cnode)
                return
            end
            node = Cnode;
            if isempty(Cnode)
                node = Snode;
            end
            if strcmp(string(lastEvent.ChangeType),'Deleted')
                return
            end

            if isempty(node)
                return
            end
            try
                EventNode = ismember(folderOnPath({node.UserData}),string(lastEvent.FullPath));
                node = node(EventNode);
                switch string(lastEvent.ChangeType)
                    case 'Changed'
                        if ~isempty(node)
                            % update data
                            updateConductivityData(comp,{node.UserData},true);
                            plotConductivityProfile(comp,false)
                        end
                end
            end
        end

        % Callback function: CTree
        function conductivityTreeCheckedCallback(comp, event)
            if comp.CTree.HiddenTreeVisible
                comp.activeTree = comp.CTree.HiddenTree;
            else
                comp.activeTree = comp.CTree.Tree;
            end
            
            checkedNodes = comp.activeTree.CheckedNodes;

            % toggle buttons
            comp.PackageselectionButton.Enable = 'off';
            if isempty(checkedNodes)
                set([comp.conductivity_dd2 ...
                    comp.conductivity_dd3 ...
                    comp.SaveButton_2],'Enable','off')
                return
            end

            % get userdata
            checkedNodes = checkedNodes(~cellfun(@isempty,{checkedNodes.UserData}));
            if any(cellfun(@isfolder,{checkedNodes.UserData}))
                comp.activeTree.CheckedNodes = comp.activeTree.SelectedNodes;
            end

            % toggle buttons
            set([comp.conductivity_dd2 ...
                comp.SaveButton_2],'Enable','on')

            % toggle data
            comp.UIAxes2.UserData = true;

            if ~any(isfolder({checkedNodes.UserData}))
                comp.PackageselectionButton.Enable = 'on';
            end
            plotConductivityProfile(comp,true)
        end

        % Callback function: CTree
        function conductivityTreeSelectedCallback(comp, event)
            selectedNodes = event.Source.SelectedNodes;
            if ~isvalid(selectedNodes)
                return
            end
            if isempty(selectedNodes)
                 set([comp.conductivity_dd1],'Enable','off')
                return
            end
            set([comp.conductivity_dd1],'Enable','on')

            checkedNodes = comp.activeTree.CheckedNodes;

%             if isempty(checkedNodes)
%                 comp.conductivity_dd2.Value = 'Single';
%             end
            comp.UIAxes2.UserData = true;
            
            if isfolder(selectedNodes.UserData)
                return
            end

            if ismember(selectedNodes,checkedNodes)||isempty(checkedNodes)
                if ~isempty(checkedNodes)
                    comp.UIAxes2.UserData = false;
                end
                plotConductivityProfile(comp,true) 
            end
        end

        % Value changed function: conductivity_dd1, conductivity_dd2
        function conductivityViewChanged(comp, event)
            event.Source.UserData = true;
            if event.Source==comp.conductivity_dd2
                switch event.Value
                    case 'Hovmoller'
                        set(comp.conductivity_dd3,'Enable','on')
                    otherwise
                        set(comp.conductivity_dd3,'Enable','off')
                end
            end
            plotConductivityProfile(comp)
        end

        % Button pushed function: DeselectallButton, SelectallButton
        function conductivityFileSelectionCallback(comp, event)
            lbl=event.Source.Text;
            if isempty(comp.activeTree.Children)
                return
            end
            validnodes = findall(comp.activeTree.Children,'Tag','file');
            switch lbl
                case 'Select all'
                    valid = validnodes;
                    set(comp.conductivity_dd2,Value = 'Lineplot',Enable='on')
                case 'Deselect all'
                    set(comp.conductivity_dd2,Value = 'Single',Enable='off')
                    set(comp.conductivity_dd3,'Enable','off')
                    valid = [];
            end
            comp.activeTree.SelectedNodes   = validnodes(1);
            comp.activeTree.CheckedNodes    = valid;

            if comp.activeTree==comp.CTree.HiddenTree
                fs = comp.CTree;
                ht = fs.HiddenTree;
                nt = fs.Tree;
                nt.CheckedNodes = [];
                if ~isempty(ht.CheckedNodes)
                    valid = ismember({nt.Children.UserData},{ht.CheckedNodes.UserData});
                    nt.CheckedNodes = nt.Children(valid);
                end
            end

            if ~isempty(valid)
                conductivityTreeCheckedCallback(comp)
            elseif ~isempty(comp.activeTree.SelectedNodes)
                comp.UIAxes2.UserData = true;
                plotConductivityProfile(comp,true)
            end
        end

        % Button pushed function: SaveButton_2
        function conductivityLogged(comp, event)
            comp.internalCommand = true;
            comp.conductivityData = struct('data',comp.condData,'files',{{comp.activeTree.CheckedNodes.UserData}});
            comp.internalCommand = false;
            notify(comp, 'ConductivityProfileLogged');
        end

        % Button pushed function: ChangeButton_4
        function conductivityPathChanged(comp, event)
            val = uigetdir(comp.conductivityPath);
            if ~val
                return
            end
            comp.conductivityPath = val;
        end

        % Button pushed function: PackageselectionButton
        function conductivityPackageCallback(comp, event)
            cn      = comp.CTree.Tree.CheckedNodes;
            
            DATES   = datetime(extractBefore({cn.Text},'T'),'Format','yyyy-MM-dd');
            range   = [min(DATES) max(DATES)];
            lbl     = sprintf("%s-%s",string(range(1),'yyyy_MM_dd'),string(range(2),'yyyy_MM_dd'));
            newfolder = fullfile(comp.conductivityPath,lbl);
            if ~isfolder(newfolder)
                mkdir(newfolder)
            end
            % debug
            file2move = {cn.UserData};
            cellfun(@(from) movefile(from,newfolder),file2move)
            if ~strcmp(comp.CTree.root,comp.conductivityPath)
                comp.CTree.root=comp.conductivityPath;
            else
                nt = comp.CTree.Tree;
                ft = comp.CTree.HiddenTree;
                valid = nt.Children(~cellfun(@isempty,{nt.Children.UserData}));
                delete(nt.Children(ismember({valid.UserData},file2move)))
                valid = ft.Children(~cellfun(@isempty,{ft.Children.UserData}));
                delete(ft.Children(ismember({valid.UserData},file2move)))
                if comp.activeTree==ft&&isempty(comp.activeTree.Children)
                end
            end
            checkLoggingFiles(comp)
        end

        % Value changed function: conductivity_dd3
        function conductivityFieldChanged(comp, event)
            plotConductivityProfile(comp)            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 735 665];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.gl = uigridlayout(comp);
            comp.TabGroup = uitabgroup(comp.gl);
            comp.TabGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @tabChanged, true);
            comp.MixingdeviceTab = uitab(comp.TabGroup);
            comp.MixingdeviceTab.Title = 'Mixing device';
            comp.glMixing = uigridlayout(comp.MixingdeviceTab);
            comp.UIAxes_2 = uiaxes(comp.glMixing);
            xlabel(comp.UIAxes_2, 'Time (min)')
            ylabel(comp.UIAxes_2, '\rho (kg m^{-3})')
            zlabel(comp.UIAxes_2, 'Z')
            comp.UIAxes = uiaxes(comp.glMixing);
            xlabel(comp.UIAxes, 'Time (min)')
            ylabel(comp.UIAxes, 'Motor position')
            zlabel(comp.UIAxes, 'Z')
            comp.CalibrationdirectoryEditFieldLabel = uilabel(comp.glMixing);
            comp.CalibrationdirectoryEditFieldLabel.Text = 'Calibration directory';
            comp.CalibrationdirectoryEditField = uieditfield(comp.glMixing, 'text');
            comp.Button = uibutton(comp.glMixing, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button.Tooltip = {'Open directory'};
            comp.Button.Text = '';
            comp.ChangeButton = uibutton(comp.glMixing, 'push');
            comp.ChangeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeCalibrationPath, true);
            comp.ChangeButton.Text = 'Change';
            comp.CalibrationdurationminSpinnerLabel = uilabel(comp.glMixing);
            comp.CalibrationdurationminSpinnerLabel.Text = 'Calibration duration (min)';
            comp.mp_t = uispinner(comp.glMixing);
            comp.mp_t.Limits = [0 Inf];
            comp.mp_t.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_t.Value = 10;
            comp.SamplemotorpositionsEditFieldLabel = uilabel(comp.glMixing);
            comp.SamplemotorpositionsEditFieldLabel.Text = 'Sample (motor positions)';
            comp.mp_start = uieditfield(comp.glMixing, 'numeric');
            comp.mp_start.Limits = [0 1];
            comp.mp_start.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_start.Value = 0.2;
            comp.mp_end = uieditfield(comp.glMixing, 'numeric');
            comp.mp_end.Limits = [0 1];
            comp.mp_end.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_end.Value = 0.7;
            comp.mp_step = uieditfield(comp.glMixing, 'numeric');
            comp.mp_step.Limits = [1 Inf];
            comp.mp_step.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.mp_step.Value = Inf;
            comp.GeneratefileButton = uibutton(comp.glMixing, 'push');
            comp.GeneratefileButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingCreateCalibrationFile, true);
            comp.GeneratefileButton.Text = 'Generate file';
            comp.durationstepLabel = uilabel(comp.glMixing);
            comp.durationstepLabel.Text = '';
            comp.LoggingdirectoryEditFieldLabel = uilabel(comp.glMixing);
            comp.LoggingdirectoryEditFieldLabel.Text = 'Logging directory';
            comp.LoggingdirectoryEditField = uieditfield(comp.glMixing, 'text');
            comp.LoggingdirectoryEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeLogPath, true);
            comp.Button_2 = uibutton(comp.glMixing, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button_2.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button_2.Tooltip = {'Open directory'};
            comp.Button_2.Text = '';
            comp.ChangeButton_2 = uibutton(comp.glMixing, 'push');
            comp.ChangeButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeLogPath, true);
            comp.ChangeButton_2.Text = 'Change';
            comp.FilesfoundLabel = uilabel(comp.glMixing);
            comp.FilesfoundLabel.Text = 'Files found:';
            comp.UsedatefilterButton = uibutton(comp.glMixing, 'state');
            comp.UsedatefilterButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.UsedatefilterButton.Text = 'Use date filter';
            comp.Panel = uipanel(comp.glMixing);
            comp.GridLayout3 = uigridlayout(comp.Panel);
            comp.FromLabel = uilabel(comp.GridLayout3);
            comp.FromLabel.Enable = 'off';
            comp.FromLabel.Text = 'From:';
            comp.fromDate = uidatepicker(comp.GridLayout3);
            comp.fromDate.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.fromDate.Enable = 'off';
            comp.ToLabel = uilabel(comp.GridLayout3);
            comp.ToLabel.Enable = 'off';
            comp.ToLabel.Text = 'To:';
            comp.toDate = uidatepicker(comp.GridLayout3);
            comp.toDate.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.toDate.Enable = 'off';
            comp.MTree = uitree(comp.glMixing, 'checkbox');
            comp.MTree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.MTree.Tag = 'mixingTree';
            % Assign Checked Nodes
            comp.MTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeCheckedCallback, true);
            comp.FilterTree = uitree(comp.glMixing, 'checkbox');
            comp.FilterTree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.FilterTree.Tag = 'mixingFilterTree';
            % Assign Checked Nodes
            comp.FilterTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeCheckedCallback, true);
            comp.Panel_2 = uipanel(comp.glMixing);
            comp.GridLayout4 = uigridlayout(comp.Panel_2);
            comp.DropDown = uidropdown(comp.GridLayout4);
            comp.DropDown.Items = {'Time', 'Motor Position'};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.DropDown.Enable = 'off';
            comp.DropDown.Tooltip = {'Set x-axis'};
            comp.DropDown.Value = 'Time';
            comp.DensitysaturationpositionsEditFieldLabel = uilabel(comp.GridLayout4);
            comp.DensitysaturationpositionsEditFieldLabel.Enable = 'off';
            comp.DensitysaturationpositionsEditFieldLabel.Text = 'Density saturation positions';
            comp.minrhoMPef = uieditfield(comp.GridLayout4, 'numeric');
            comp.minrhoMPef.Limits = [0 1];
            comp.minrhoMPef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.minrhoMPef.Enable = 'off';
            comp.minrhoMPef.Tooltip = {'Motor position of minimum ρ'};
            comp.minrhoMPef.Value = 0.7;
            comp.maxrhoMPef = uieditfield(comp.GridLayout4, 'numeric');
            comp.maxrhoMPef.Limits = [0 1];
            comp.maxrhoMPef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.maxrhoMPef.Enable = 'off';
            comp.maxrhoMPef.Tooltip = {'Motor position of maximum ρ'};
            comp.maxrhoMPef.Value = 0.2;
            comp.StratificationprofilegeneratorPanel = uipanel(comp.glMixing);
            comp.StratificationprofilegeneratorPanel.Title = 'Stratification profile generator';
            comp.GridLayout2 = uigridlayout(comp.StratificationprofilegeneratorPanel);
            comp.LoggingdirectoryEditField_2Label = uilabel(comp.GridLayout2);
            comp.LoggingdirectoryEditField_2Label.Text = 'Profile directory';
            comp.profileDir = uieditfield(comp.GridLayout2, 'text');
            comp.profileDir.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeProfilePath, true);
            comp.ChangeButton_3 = uibutton(comp.GridLayout2, 'push');
            comp.ChangeButton_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingChangeProfilePath, true);
            comp.ChangeButton_3.Text = 'Change';
            comp.kgmLabel = uilabel(comp.GridLayout2);
            comp.kgmLabel.Text = 'ρ (kg m⁻³)';
            comp.rho0ef = uieditfield(comp.GridLayout2, 'numeric');
            comp.rho0ef.Limits = [980 Inf];
            comp.rho0ef.ValueDisplayFormat = '%.1f';
            comp.rho0ef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.rho0ef.Value = 998;
            comp.rhoaef = uieditfield(comp.GridLayout2, 'numeric');
            comp.rhoaef.Limits = [980 Inf];
            comp.rhoaef.ValueDisplayFormat = '%.1f';
            comp.rhoaef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.rhoaef.Value = 998;
            comp.NradsLabel = uilabel(comp.GridLayout2);
            comp.NradsLabel.Text = 'N (rad s⁻¹)';
            comp.Nef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Nef.Limits = [0 Inf];
            comp.Nef.ValueDisplayFormat = '%.2f';
            comp.Nef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.lockButton = uibutton(comp.GridLayout2, 'state');
            comp.lockButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.lockButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'icon-fa-unlock-alt.png');
            comp.lockButton.Text = '';
            comp.PreviewoffButton = uibutton(comp.GridLayout2, 'state');
            comp.PreviewoffButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingPreviewProfile, true);
            comp.PreviewoffButton.Text = 'Preview (off)';
            comp.FlowrateLminEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FlowrateLminEditFieldLabel.Text = 'Flow rate (L min⁻¹)';
            comp.Fef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Fef.Limits = [0 Inf];
            comp.Fef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.Fef.Value = 3.2;
            comp.StarttimesEditFieldLabel = uilabel(comp.GridLayout2);
            comp.StarttimesEditFieldLabel.Text = 'Start-time (s)';
            comp.Stef = uieditfield(comp.GridLayout2, 'numeric');
            comp.Stef.Limits = [0 Inf];
            comp.Stef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.IcethicknessmEditFieldLabel = uilabel(comp.GridLayout2);
            comp.IcethicknessmEditFieldLabel.Text = 'Ice thickness (m)';
            comp.ITef = uieditfield(comp.GridLayout2, 'numeric');
            comp.ITef.Limits = [0 Inf];
            comp.ITef.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileParametersChanged, true);
            comp.CalibrationfileEditFieldLabel = uilabel(comp.GridLayout2);
            comp.CalibrationfileEditFieldLabel.Text = 'Calibration file';
            comp.file2read = uieditfield(comp.GridLayout2, 'text');
            comp.GeneratefileButton_2 = uibutton(comp.GridLayout2, 'push');
            comp.GeneratefileButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingGenerateProfileCallback, true);
            comp.GeneratefileButton_2.Text = 'Generate file';
            comp.rho_avLabel = uilabel(comp.GridLayout2);
            comp.rho_avLabel.Text = '';
            comp.TestfileButton = uibutton(comp.GridLayout2, 'push');
            comp.TestfileButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingGenerateProfileCallback, true);
            comp.TestfileButton.Tooltip = {'Same as generate file, but only samples 20 equidistant motor position set points'};
            comp.TestfileButton.Text = 'Test file';
            comp.SaveButton = uibutton(comp.glMixing, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @mixingProfileLogged, true);
            comp.SaveButton.Enable = 'off';
            comp.SaveButton.Text = 'Save';
            comp.ReturntooriginButton = uibutton(comp.glMixing, 'state');
            comp.ReturntooriginButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingCalibrationCallback, true);
            comp.ReturntooriginButton.Text = 'Return to origin';
            comp.AdjustmenttimesEditFieldLabel = uilabel(comp.glMixing);
            comp.AdjustmenttimesEditFieldLabel.Enable = 'off';
            comp.AdjustmenttimesEditFieldLabel.Text = 'Adjustment time (s)';
            comp.AdjustmenttimesEditField = uieditfield(comp.glMixing, 'numeric');
            comp.AdjustmenttimesEditField.Limits = [0 Inf];
            comp.AdjustmenttimesEditField.RoundFractionalValues = 'on';
            comp.AdjustmenttimesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mixingTreeSelectionCallback, true);
            comp.AdjustmenttimesEditField.Enable = 'off';
            comp.ConductivityprofilerTab = uitab(comp.TabGroup);
            comp.ConductivityprofilerTab.Title = 'Conductivity profiler';
            comp.glConductivity = uigridlayout(comp.ConductivityprofilerTab);
            comp.UIAxes2 = uiaxes(comp.glConductivity);
            title(comp.UIAxes2, 'Title')
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.Button_3 = uibutton(comp.glConductivity, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @openDirectory, true);
            comp.Button_3.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'folder_win10.png');
            comp.Button_3.Tooltip = {'Open directory'};
            comp.Button_3.Text = '';
            comp.ChangeButton_4 = uibutton(comp.glConductivity, 'push');
            comp.ChangeButton_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityPathChanged, true);
            comp.ChangeButton_4.Text = 'Change';
            comp.ConductivityprofiledirectoryEditFieldLabel = uilabel(comp.glConductivity);
            comp.ConductivityprofiledirectoryEditFieldLabel.Text = 'Conductivity profile directory';
            comp.ConductivityprofiledirectoryEditField = uieditfield(comp.glConductivity, 'text');
            comp.conductivityToggleDateFiltering = uibutton(comp.glConductivity, 'state');
            comp.conductivityToggleDateFiltering.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.conductivityToggleDateFiltering.Text = 'Use date filter';
            comp.FilesfoundLabel_2 = uilabel(comp.glConductivity);
            comp.FilesfoundLabel_2.Text = 'Files found:';
            comp.Panel_3 = uipanel(comp.glConductivity);
            comp.GridLayout3_2 = uigridlayout(comp.Panel_3);
            comp.FromLabel_2 = uilabel(comp.GridLayout3_2);
            comp.FromLabel_2.Enable = 'off';
            comp.FromLabel_2.Text = 'From:';
            comp.fromDate_2 = uidatepicker(comp.GridLayout3_2);
            comp.fromDate_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.fromDate_2.Enable = 'off';
            comp.ToLabel_2 = uilabel(comp.GridLayout3_2);
            comp.ToLabel_2.Enable = 'off';
            comp.ToLabel_2.Text = 'To:';
            comp.toDate_2 = uidatepicker(comp.GridLayout3_2);
            comp.toDate_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleDateFilter, true);
            comp.toDate_2.Enable = 'off';
            comp.GridLayout4_2 = uigridlayout(comp.glConductivity);
            comp.conductivity_dd2 = uidropdown(comp.GridLayout4_2);
            comp.conductivity_dd2.Items = {'Single', 'Lineplot', 'Hovmoller'};
            comp.conductivity_dd2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityViewChanged, true);
            comp.conductivity_dd2.Enable = 'off';
            comp.conductivity_dd2.Tooltip = {''};
            comp.conductivity_dd2.Value = 'Single';
            comp.conductivity_dd1 = uidropdown(comp.GridLayout4_2);
            comp.conductivity_dd1.Items = {'Salinity', 'Temperature'};
            comp.conductivity_dd1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityViewChanged, true);
            comp.conductivity_dd1.Enable = 'off';
            comp.conductivity_dd1.Tooltip = {''};
            comp.conductivity_dd1.Value = 'Salinity';
            comp.Panel_4 = uipanel(comp.GridLayout4_2);
            comp.conductivity_dd3 = uidropdown(comp.GridLayout4_2);
            comp.conductivity_dd3.Items = {'Measured', 'Gradient (z)', 'Gradient (t)'};
            comp.conductivity_dd3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityFieldChanged, true);
            comp.conductivity_dd3.Enable = 'off';
            comp.conductivity_dd3.Tooltip = {''};
            comp.conductivity_dd3.Value = 'Measured';
            comp.GridLayout4_3 = uigridlayout(comp.glConductivity);
            comp.SelectallButton = uibutton(comp.GridLayout4_3, 'push');
            comp.SelectallButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityFileSelectionCallback, true);
            comp.SelectallButton.Text = 'Select all';
            comp.DeselectallButton = uibutton(comp.GridLayout4_3, 'push');
            comp.DeselectallButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityFileSelectionCallback, true);
            comp.DeselectallButton.Text = 'Deselect all';
            comp.SaveButton_2 = uibutton(comp.GridLayout4_3, 'push');
            comp.SaveButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityLogged, true);
            comp.SaveButton_2.Enable = 'off';
            comp.SaveButton_2.Text = 'Save';
            comp.PackageselectionButton = uibutton(comp.GridLayout4_3, 'push');
            comp.PackageselectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @conductivityPackageCallback, true);
            comp.PackageselectionButton.Enable = 'off';
            comp.PackageselectionButton.Text = 'Package selection';
            comp.GridLayout5 = uigridlayout(comp.glConductivity);
            comp.CTree = uiFileSystemCheckBox(comp.GridLayout5);
            comp.CTree.DateFilters = [datetime([2023 10 25]) datetime([2023 10 25])];
            comp.CTree.HiddenTreeVisible = true;
            comp.CTree.toggleFiles = true;
            comp.CTree.FileWatcherEventFcn = matlab.apps.createCallbackFcn(comp, @conductivityFileWatcherEvent, true);
            comp.CTree.NodesCheckedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeCheckedCallback, true);
            comp.CTree.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @conductivityTreeSelectedCallback, true);
            % Execute the startup function
            startupFcn(comp)
        end
    end
end