classdef uiWaveTank < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        close_PS               matlab.ui.control.Button
        open_PS                matlab.ui.control.Button
        ParameterspacePanel    matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        acceptParams_3         matlab.ui.control.Button
        acceptParams_2         matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        acceptParams           matlab.ui.control.Button
        GridLayout2            matlab.ui.container.GridLayout
        AttenuationButton      matlab.ui.control.StateButton
        EditField              matlab.ui.control.NumericEditField
        HarmonicsButton        matlab.ui.control.StateButton
        RaylengthmSlider       matlab.ui.control.Slider
        RaylengthmSliderLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ParameterSpaceChanged
    end

    properties (Access = private)
        Z
        angle
        h
        ice
        iceth
        internalProcess (1,1) logical = false;
        k
        maxdist (1,1) double = 0;
        negsource
        radangle
        source
    end
    
    properties (Access = public)
        N (1,1) double {mustBeNumeric(N), mustBeGreaterThan(N, 0)} = 0.1;
        ParameterSpace double
        ParameterSpaceData table
        XLIM (1,1) double {mustBeNumeric(XLIM), mustBeGreaterThanOrEqual(XLIM, 0)} = 1.5; % Limits of the tank
        YLIM (1,1) double {mustBeNumeric(YLIM), mustBeGreaterThanOrEqual(YLIM, 0)} = 1.1; % Vertical limits of tank
        h0 (1,1) double = 0.05; % Wavemaker profile height
        hasStarted logical = false;
        k0
        m0 (1,1) double {mustBePositive(m0), mustBeNumeric(m0), mustBeNonzero(m0)} = 1/8.5e-2;
        omega (1,1) double {mustBeNumeric(omega), mustBeGreaterThanOrEqual(omega, 0)} = 0;
        rayceil
        rayfloor
    end
    
    %% Set functions
    methods
        function set.omega(comp,val)
            comp.omega=val;
            setSource(comp)
        end
        function set.N(comp,val)
            comp.N=val;
            setSource(comp)
        end
        function set.m0(comp,val)
            comp.m0=val;
            setWavemaker(comp)
        end
        function set.ParameterSpaceData(comp,val)
            comp.ParameterSpaceData=val;
            setParameterSpace(comp)
        end
    end
    %% Internal functions
    methods (Access = private)
        %% WaveTank calculations / UI
        function waveMakerChangingFcn(comp,src)
            comp.internalProcess=true;
            wm                  = findall(comp.UIAxes,'Tag','wavemaker');
            src.Position(:,1)   = 0;
            dif                 = (abs(src.Position(:,2)-comp.source.Position(1,2)));

            switch sign(diff(dif))
                case -1
                    M0   = min(dif);
                case 1
                    M0   = max(dif);
                case 0
                    M0   = mean(dif);
            end
            comp.m0     = 1/M0;
            comp.k0                     = comp.k(deg2rad(comp.angle(comp.source)));
            src.Position(:,2)           = M0.*[1 -1]+comp.source.Position(1,2);
            wm.Vertices(:,1)            = comp.h(comp.source.Position(1,2));
            wm.Vertices([1 end],1)      = 0;
            updateLabel(comp)
            comp.internalProcess=false;
            
            clearMessageAfter
            function clearMessageAfter
                src.LabelAlpha          = 0;
                src.Label               = sprintf('%.2f cm',1/comp.m0*1e2);
                src.LabelTextColor      = 'c';
                delayedFunction(@(~,~) set(src,'Label',''),2)
            end
        end
        function lineChangingFcn(comp,iwect)
            %% Handles user changes to the setup
            comp.internalProcess=true;

            % Manage objects
            switch iwect.Tag
                case 'ice'
                    comp.iceth.setPosition([comp.ice.Position(1) comp.YLIM/3;sum(comp.ice.Position([1 3])) comp.YLIM/3]);
                case 'source'
                    iwect.Position(1,1)=0;
                    fixWavemaker(comp)
                case {'floor','ceil'}
                    rayBounds(comp)
            end

            % Check rays do not exceed ice limit
            if comp.source.Position(2,1)>=comp.ice.Position(1)
                comp.source.Position(2,1)=comp.ice.Position(1);
            end

            % Calculate fundamental horizontal wavenumber
            comp.k0      = comp.k(deg2rad(comp.angle(comp.source)));

            % Update ui
            updateLabel(comp)
            updateRays(comp)
            comp.internalProcess=false;

            function fixWavemaker(comp)
                % Draws wavemaker and fixes profile 
                wm = findall(comp.UIAxes,'Tag','wavemaker');
                M0 = findall(comp.UIAxes,'Tag','m0');
                zdiff                   = abs(mean(M0.Position(:,2))-M0.Position(1,2));
                wm.Vertices(:,1)        = comp.h(comp.source.Position(1,2));
                wm.Vertices([1 end],1)  = 0;
                M0.Position(:,2) = comp.source.Position(1,2)+[-1;1]*zdiff;
            end
            function updateRays(comp)
                % Draws centreline of wavebeams
                comp.negsource.Position = comp.source.Position-[0 0;0 2*diff(comp.source.Position(:,2))];
                ch = get(comp.UIAxes,'Children');
                delete(findall(ch,'Tag','intercept'))
                drawRays(comp)
            end
            function rayBounds(comp)
                % Fixes raytracing when floor and ceiling are moved
                src = comp.source.Position(1,2);
                pos = iwect.Position(2);
                switch iwect.Tag
                    case 'floor'
                        if pos>src
                            iwect.Position(2) = src;
                        end
                    case 'ceil'
                        if pos<src
                            iwect.Position(2) = src;
                        end
                end

                set(findall(comp.UIAxes,'Tag',sprintf('%s-line',iwect.Tag)),YData = iwect.Position(2)*[1 1])
            end
        end
        function updateLabel(comp,~)
            comp.omega = comp.N*sin(deg2rad(comp.angle(comp.source)));
            nu      = 1e-6;
            K       = sqrt(comp.k0^2+comp.m0^2);
            Alpha   = @(theta) nu/(2*comp.N)*K^3/sin(pi-theta);%nu/2*(k0./omega).^3./sqrt(N.^2.*(1-omega.^2));
            Eloss   = @(theta,distance) exp(-Alpha(theta)*distance);

            n = 2;

            omega_n = comp.omega;
            while true
                if n>numel(comp.RaylengthmSlider.UserData)-1
                    break
                end
                if comp.omega/comp.N<1/n
                    omega_n(end+1) = comp.omega*n; %#ok<AGROW>
                else
                    break
                end
                n = n+1;
            end

            omega_n = asin(omega_n./comp.N);
            try
                dists   = comp.RaylengthmSlider.UserData(2:1+numel(omega_n));
                Eloss   = arrayfun(@(x,y) Eloss(x,y),omega_n,dists);
                Eloss_str = arrayfun(@(x) sprintf('%.2f',x),Eloss*1e2,'UniformOutput',false);
                Eloss_str = join(Eloss_str,',');
                comp.source.Label = sprintf('%.1f deg (%.2fN) h=%.2f A=(%s)%s',...
                    rad2deg(comp.radangle(comp.source)),...
                    comp.omega/comp.N,...
                    comp.source.Position(1,2), ...
                    Eloss_str{1},'%');
            catch
                comp.source.Label = sprintf('%.1f deg (%.2fN) h=%.2f',...
                    rad2deg(comp.radangle(comp.source)),...
                    comp.omega/comp.N,...
                    comp.source.Position(1,2));
            end
        end
        function drawRays(comp,~)
            comp.RaylengthmSlider.Tag = 'normal';
            if comp.maxdist==0
                comp.RaylengthmSlider.Tag = 'intercept';
            end
            calculateRayTrajectory(comp)
            updateLabel(comp)
            % lineChangingFcn(source)
        end
        function calculateRayTrajectory(comp)
            %%
            theta   = deg2rad(comp.angle(comp.source));

            pts     = 5e4;      % number of points per ray
            cutoff  = comp.maxdist;
            if strcmp(comp.RaylengthmSlider.Tag,'intercept')
                cutoff = 10;
            end

            bounces = cutoff*[sin(theta) cos(theta)]./[comp.XLIM-comp.ice.Position(3) comp.YLIM];
            pathlength = max(bounces);
            if comp.omega/comp.N>.99
%                 pathlength = tan(theta)*pathlength;
                pts = 1e5;
            elseif comp.omega/comp.N<.2
                pts = 2e5;
            end
            if comp.maxdist<.4
                pts = 2e5;
                pathlength = 100;
            end
            maxX    = comp.XLIM-comp.ice.Position(3);
            origin  = comp.source.Position(1,2);

            comp.RaylengthmSlider.UserData = cutoff;
            if theta==pi/2
                return
            end
            [X0,Y0] = getTrajectory(theta);

            comp.omega = comp.N*sin(deg2rad(comp.angle(comp.source)));
            

            warning off
            toggleView(comp)
            warning on

            children = comp.UIAxes.Children;
            [~,csort]=sort(arrayfun(@(x) (isequal(x,findall(children,'Type','Images','Tag','source'))),children),'descend');
            comp.UIAxes.Children = comp.UIAxes.Children(csort);

            function toggleView(comp)
                rayplot     = findall(comp.UIAxes,'Tag','ray');
                delete(findall(comp.UIAxes,'Type','surface'))
                if comp.AttenuationButton.Value
                    delete(rayplot)
                    E = comp.calculateAttenutation(X0,Y0(:,1)');
                    coloredLine(X0,Y0(:,1),E,comp.UIAxes)
                    coloredLine(X0,Y0(:,2),E,comp.UIAxes)
                    addColorbar(comp.UIAxes, ...
                        'limits',[0 100], ...
                        'title','Wave energy (%)', ...
                        'cmap','thermal', ...
                        'levels',10)
                    return
                end
                if isempty(rayplot)
                    hold(comp.UIAxes,'on')
                    plot(comp.UIAxes,X0,Y0,':r','Tag','ray','SelectionHighlight','off')
                    plot(comp.UIAxes,X0,Y0,'--r','Tag','ray','SelectionHighlight','off')
                    plot(comp.UIAxes,X0,Y0,'-r','Tag','ray','SelectionHighlight','off')
                    hold(comp.UIAxes,'off')
                else
                    set(rayplot(1),XData=X0,YData=Y0(:,1))
                    set(rayplot(2),XData=X0,YData=Y0(:,2))
                    set(rayplot(3:end),'Visible','off')

                    if comp.HarmonicsButton.Value
                        if comp.omega/comp.N<.5
                            [X,Y] = getTrajectory(asin(2*comp.omega/comp.N));
                            set(rayplot(3),XData=X,YData=Y(:,1))
                            set(rayplot(4),XData=X,YData=Y(:,2))
                            set(rayplot(3:4),'Visible','on')
                        end
                        if comp.omega/comp.N<1/3
                            [X,Y] = getTrajectory(asin(3*comp.omega/comp.N));
                            set(rayplot(5),XData=X,YData=Y(:,1))
                            set(rayplot(6),XData=X,YData=Y(:,2))
                            set(rayplot(4:6),'Visible','on')
                        end
                    end
                end

            end
            function [X,Y,S] = getTrajectory(theta)
                %% Calculates vectors for ray beam trajectory
                minY    = comp.rayfloor.Position(2);
                maxY    = comp.rayceil.Position(2);
                X       = linspace(0,comp.XLIM*pathlength,pts);
                Y       = linspace(0,comp.YLIM*pathlength,pts);

                % Use sawtooth function for rectangular periodic domain
                % Frequency of each sawtooth is:
                % 
                Xfreq   = tan(comp.YLIM/comp.XLIM)/(maxX*comp.YLIM*tan(theta));%1.125
                Yfreq   = comp.YLIM^2/(maxY-minY);%*1.41*(YLIM/maxX)^-1.7;

                Xs      = rescale(sawtooth(X*Xfreq*2*pi,.5),0,maxX);
                Ys      = rescale(sawtooth(Y*Yfreq*2*pi,.5),minY,maxY);

                % Downward propagating ray
                Ysn     = -Ys;

                % Phase shifting s.t. origin (wavemaker position h(z)) is
                % zeroed.
                idx     = find(Ys>=origin,1);
                idx2    = find(Ys>=maxY-origin,1);
                Xs      = Xs(1:end-idx);
                Ys      = Ys(1+idx:end);
                Ysn     = Ysn(1+idx2:end)+maxY+minY;
                Ysn     = Ysn(1+find(Ysn<origin,1):end);

                if numel(Ysn)>numel(Ys)
                    Ysn     = Ysn(1:numel(Ys));
                else
                    Ys     = Ys(1:numel(Ysn));
                    Xs     = Xs(1:numel(Ysn));
                end

                % Hide beyond cutoff length
                s=hypot( ...
                    mean(diff(X))*cumsum(abs(gradient(Xs,mean(diff(X))))), ...
                    mean(diff(Y))*cumsum(abs(gradient(Ys,mean(diff(Y))))));

                if strcmp(comp.RaylengthmSlider.Tag,'intercept')
                    cutoff =  s(find(Xs>maxX*(1-.01),1,'first'));
                end
                try %#ok<*TRYNC>
                    comp.RaylengthmSlider.UserData(end+1) = cutoff;
                    idx             = find(s>cutoff,1);
                    Xs(idx:end)     = [];
                    Ys(idx:end)     = [];
                    Ysn(idx:end)    = [];
                end
                warning off
                S = s(s<cutoff);
                X = Xs;
                Y = [Ys' Ysn'];
                warning on
            end
        end
        function Eloss = calculateAttenutation(comp,X,Y)
            distance = hypot( ...
                    mean(diff(X))*cumsum(abs(gradient(X,mean(diff(X))))), ...
                    mean(diff(Y))*cumsum(abs(gradient(Y,mean(diff(Y))))));
            nu      = 1e-6;
            K       = sqrt(comp.k0^2+comp.m0^2);
            Alpha   = @(theta) nu/(2*comp.N)*K^3/sin(pi-theta);
            Eloss   = exp(-Alpha(deg2rad(comp.angle(comp.source)))*distance)*1e2;

        end
    end
    methods
        %% Component functions
        function togglePSTab(comp,state)
            switch state
                case {true,'on'}
                    comp.GridLayout.ColumnWidth = {'1x','fit','fit'};
                case {false,'off'}
                    comp.GridLayout.ColumnWidth = {'1x','fit',0};
            end
        end
        function configurationChanged(comp)
            d = comp.UITable.Data;
            if isempty(d)
                return
            end
            s = comp.UITable.Selection;
            if numel(s)>2
                return
            end
            ON = d(s(1),1);
            H  = d(s(1),4);
            M0 = d(s(1),5);

            setWavemaker(comp,M0,H)
            setSource(comp,ON,H)
        end
        function setParameterSpace(comp)
            comp.UITable.Data = table2array(comp.ParameterSpaceData);
        end
        function setWavemaker(comp,M0,H)
            if ~comp.hasStarted||comp.internalProcess
                return
            end
            if nargin<2
                M0 = comp.m0;
                H  = comp.YLIM/2;
            end
            m0line = findall(comp.UIAxes,'Tag','m0');
            m0line.Position(:,2) = H+1/M0.*[-1;1];
            waveMakerChangingFcn(comp,m0line)
        end
        function setSource(comp,ON,H)
            if ~comp.hasStarted||comp.internalProcess
                return
            end
            if nargin<2
                ON  = comp.omega/comp.N;
                H   = comp.YLIM/2;
            end
            % set source coordinates
            r = norm(comp.source.Position);
            theta = -asin(ON);
            x = r*cos(theta);
            y = r*sin(theta)+H;
            y(y>comp.YLIM)=comp.YLIM;
            y(y<0)=0;
            pos = [x y];
            comp.source.Position = [0 H;pos];
            lineChangingFcn(comp,comp.source)
        end
    end

    methods (Access = public)

        function startProgram(comp)
            if comp.hasStarted
                return
            end
            set(comp.UIAxes,...
                'XLim',[0 comp.XLIM],...
                'YLim',[0 comp.YLIM],...
                'Box','on')
            comp.UIAxes.Toolbar.Visible='off';
            set(comp.UIAxes,'YDir','reverse')
            comp.UIAxes.XLabel.String = 'Position (m)';
            comp.UIAxes.YLabel.String = 'Depth (m)';

            comp.ice     = drawrectangle('Position',[comp.XLIM-.2 0 .2 comp.YLIM],'Tag','ice','Parent',comp.UIAxes);
            comp.iceth   = imdistline(comp.UIAxes,[comp.ice.Position(1) sum(comp.ice.Position([1 3]))],comp.YLIM/3.*[1 1]);
            comp.source  = drawline('Position',[0 comp.YLIM/2;comp.ice.Position(1)/2 comp.YLIM/4],...
                'Label','',...
                'LabelAlpha',0,'Tag','source','Parent',comp.UIAxes);
            comp.angle       = @(source) abs(rad2deg(atan(diff(source.Position(:,2))/diff(source.Position(:,1)))));
            comp.radangle    = @(source) atan(diff(source.Position(:,2))/diff(source.Position(:,1)));

            %% Wavemaker
            comp.Z           = linspace(0,comp.YLIM,1e3);
            comp.h           = @(z0) comp.h0*exp(-((comp.Z-z0)*comp.m0).^2);
            patch(comp.UIAxes,comp.h(comp.YLIM/2),comp.Z,'k','Tag','wavemaker');

            %% Rays
            comp.negsource           = drawline(comp.UIAxes,'Position',comp.source.Position,'Color',...
                'k','Tag','negsource',...
                'InteractionsAllowed','none');
            comp.negsource.Position  = comp.source.Position-[0 0;0 2*diff(comp.source.Position(:,2))];
            line(comp.UIAxes, ...
                [0 comp.XLIM], ...
                [0 0], ...
                'Tag','floor-line', ...
                'LineStyle','--', ...
                'Color','b', ...
                'SelectionHighlight','off')
            line(comp.UIAxes, ...
                [0 comp.XLIM], ...
                [comp.YLIM comp.YLIM], ...
                'Tag','ceil-line', ...
                'LineStyle','--', ...
                'Color','b', ...
                'SelectionHighlight','off')
            comp.rayfloor    = drawpoint(comp.UIAxes,'Position',[comp.XLIM/2 0],'Tag','floor');
            comp.rayceil     = drawpoint(comp.UIAxes,'Position',[comp.XLIM/2 comp.YLIM],'Tag','ceil');
            m0line = drawline('Parent',comp.UIAxes,'Label','','Tag','m0','Position',[0 comp.YLIM/2+1/comp.m0;0 comp.YLIM/2-1/comp.m0]);
            %% Listeners
            warning off verbose
            addlistener(comp.rayfloor,'MovingROI',@(varargin)lineChangingFcn(comp,comp.rayfloor));
            addlistener(comp.rayceil,'MovingROI',@(varargin)lineChangingFcn(comp,comp.rayceil));
            addlistener(m0line,'MovingROI',@(varargin)waveMakerChangingFcn(comp,m0line));
            addlistener(comp.ice,'MovingROI',@(varargin)lineChangingFcn(comp,comp.ice));
            addlistener(comp.source,'MovingROI',@(varargin)lineChangingFcn(comp,comp.source));
            warning on
            %% Initiate

% k       = @(theta,m) m/tan(deg2rad(theta));%sqrt(m.^2/(sec(theta)-1));
            comp.k       = @(theta) comp.m0/tan(theta);%sqrt(comp.m0.^2/(sec(theta)-1));
            comp.k0      = comp.k(deg2rad(comp.angle(comp.source)));

            calculateRayTrajectory(comp)
            comp.hasStarted = true;

            %% App conflict callback functions
            comp.UITable.SelectionChangedFcn = @changedConfiguration;
            comp.UITable.ClickedFcn = @changedConfiguration;

            function changedConfiguration(~,~)
                if isempty(comp.UITable.Selection)
                    comp.acceptParams_2.Text = 'Add configuration';
                    comp.acceptParams_3.Enable = 'off';
                else
                    comp.acceptParams_2.Text = 'Update configuration';
                    comp.acceptParams_3.Enable = 'on';
                    configurationChanged(comp)
                end
            end
        end
        
        function [ON,h,m0]=getTerms(comp)
            ON = comp.omega/comp.N;
            h  = comp.source.Position(1,2);
            m0 = comp.m0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            state = calledWithoutParent(comp,'Wave Tank Designer');
            togglePSTab(comp,'off')
            if state
                comp.startProgram;
            end
        end

        % Value changed function: HarmonicsButton, RaylengthmSlider
        function updatePlot(comp, event)
            try value = event.Value;end
            if nargin<2
                value = comp.RaylengthmSlider.Value;
            end
            comp.internalProcess=true;
            comp.maxdist = value;
            drawRays(comp)
            comp.internalProcess=false;
        end

        % Button pushed function: close_PS, open_PS
        function togglePS(comp, event)
            switch event.Source
                case comp.close_PS
                    togglePSTab(comp,'off')
                case comp.open_PS
                    togglePSTab(comp,'on')
            end
        end

        % Button pushed function: acceptParams
        function parameterSpaceCallback(comp, event)
            d = array2table(comp.UITable.Data, ...
                'VariableNames',{'omegaN','A_mmPs','t_hr','h_m','m0_1Pm'});
            comp.ParameterSpaceData=d;
            notify(comp,'ParameterSpaceChanged')
        end

        % Cell edit callback: UITable
        function changedParameter(comp, event)
            indices = event.Indices;
            if isnan(event.NewData)&&~strcmpi(event.EditData,'nan')
                val=[];try val=eval(event.EditData);end
                if isempty(val);return;end
                event.Source.Data(indices(1),indices(2))=val;
            end
            comp.UITable.Selection=indices;
            configurationChanged(comp)
        end

        % Button pushed function: acceptParams_2
        function addConfigurationCallback(comp, event)
            d = comp.UITable.Data;
            s = comp.UITable.Selection;
            [ON,H,M0]=getTerms(comp);
            val = [ON nan nan H M0];
            if isempty(d)
                comp.UITable.Data = val;
                return
            end
            if isempty(s)
                comp.UITable.Data(end+1,:) = val;
            else
                comp.UITable.Data(s(1),:) = val;
            end
        
        end

        % Button pushed function: acceptParams_3
        function removeConfigurationCallback(comp, event)
            s = comp.UITable.Selection;
            if isempty(s)
                return
            end
            comp.UITable.Data(s(:,1),:)=[];
            comp.UITable.Selection = [];
            %changedConfiguration(comp)
        end

        % Key press function: UITable
        function TableKeyPressedFcn(comp, event)
            switch event.Key
                case 'delete'
                    removeConfigurationCallback(comp)
                case 'escape'
                    comp.UITable.Selection=[];
            end
            
        end

        % Value changed function: EditField
        function changeMaxRayLength(comp, event)
            value = comp.EditField.Value;
            comp.RaylengthmSlider.Limits = [0 value];
            comp.RaylengthmSlider.MajorTicks = 0:value;
            updatePlot(comp)
        end

        % Value changed function: AttenuationButton
        function toggleAttenutation(comp, event)
            drawRays(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 906 499];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.RaylengthmSliderLabel = uilabel(comp.GridLayout2);
            comp.RaylengthmSliderLabel.Text = 'Ray length (m)';
            comp.RaylengthmSlider = uislider(comp.GridLayout2);
            comp.RaylengthmSlider.Limits = [0 5];
            comp.RaylengthmSlider.MajorTicks = [0 1 2 3 4 5];
            comp.RaylengthmSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlot, true);
            comp.RaylengthmSlider.Tag = 'raylength';
            comp.HarmonicsButton = uibutton(comp.GridLayout2, 'state');
            comp.HarmonicsButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlot, true);
            comp.HarmonicsButton.Text = 'Harmonics';
            comp.EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.EditField.Limits = [0 Inf];
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @changeMaxRayLength, true);
            comp.EditField.Value = 5;
            comp.AttenuationButton = uibutton(comp.GridLayout2, 'state');
            comp.AttenuationButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @toggleAttenutation, true);
            comp.AttenuationButton.Text = 'Attenuation';
            comp.ParameterspacePanel = uipanel(comp.GridLayout);
            comp.ParameterspacePanel.Title = 'Parameter space';
            comp.GridLayout2_2 = uigridlayout(comp.ParameterspacePanel);
            comp.acceptParams = uibutton(comp.GridLayout2_2, 'push');
            comp.acceptParams.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @parameterSpaceCallback, true);
            comp.acceptParams.Text = 'Accept';
            comp.UITable = uitable(comp.GridLayout2_2);
            comp.UITable.ColumnName = {'ω/N'; 'A (mm s⁻¹)'; 't (hours)'; 'h (m)'; 'm₀ (m⁻¹)'};
            comp.UITable.RowName = {};
            comp.UITable.ColumnEditable = [true true true true true];
            comp.UITable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @changedParameter, true);
            comp.UITable.KeyPressFcn = matlab.apps.createCallbackFcn(comp, @TableKeyPressedFcn, true);
            comp.acceptParams_2 = uibutton(comp.GridLayout2_2, 'push');
            comp.acceptParams_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addConfigurationCallback, true);
            comp.acceptParams_2.Text = 'Add configuration';
            comp.acceptParams_3 = uibutton(comp.GridLayout2_2, 'push');
            comp.acceptParams_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeConfigurationCallback, true);
            comp.acceptParams_3.Text = 'Remove configuration';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.open_PS = uibutton(comp.GridLayout3, 'push');
            comp.open_PS.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @togglePS, true);
            comp.open_PS.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronWestUI.svg');
            comp.open_PS.Tooltip = {'Open parameter space'};
            comp.open_PS.Text = '';
            comp.close_PS = uibutton(comp.GridLayout3, 'push');
            comp.close_PS.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @togglePS, true);
            comp.close_PS.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'chevronEastUI.svg');
            comp.close_PS.Tooltip = {'Hide parameter space'};
            comp.close_PS.Text = '';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end